classdef Analysis_further_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CalibrationMenu                 matlab.ui.container.Menu
        ManualSelection_withinROIMenu   matlab.ui.container.Menu
        ManualSelection_overall_areaMenu  matlab.ui.container.Menu
        Calibration_overallEditField    matlab.ui.control.EditField
        Calibration_overallEditFieldLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Calibration_withinROIEditField  matlab.ui.control.EditField
        Calibration_withinROIEditFieldLabel  matlab.ui.control.Label
        ROImaskpixelcountEditField      matlab.ui.control.NumericEditField
        ROImaskpixelcountEditFieldLabel  matlab.ui.control.Label
        ROIedit_remove                  matlab.ui.control.Button
        ROIedit_include                 matlab.ui.control.Button
        SudoROIeditLabel                matlab.ui.control.Label
        SaveDatasetforanalyzingdataButton  matlab.ui.control.Button
        PPGsignalLabel                  matlab.ui.control.Label
        RespirationbeltsignalLabel      matlab.ui.control.Label
        TemporalPCMRIsignalLabel        matlab.ui.control.Label
        LoadPhysiologysignalButton      matlab.ui.control.Button
        SpectralsignalWithinROILabel    matlab.ui.control.Label
        TemporalsignalWithinROILabel    matlab.ui.control.Label
        AnalysistypeButtonGroup         matlab.ui.container.ButtonGroup
        AveragesignalButton             matlab.ui.control.RadioButton
        OverallmasksignalButton         matlab.ui.control.RadioButton
        SinglesignaloverlayButton       matlab.ui.control.RadioButton
        ColorbarlowEditField            matlab.ui.control.NumericEditField
        ColorbarlowEditFieldLabel       matlab.ui.control.Label
        ColorbarhighEditField           matlab.ui.control.NumericEditField
        ColorbarhighEditFieldLabel      matlab.ui.control.Label
        SignaloverlaynumberSpinner      matlab.ui.control.Spinner
        SignaloverlaynumberSpinnerLabel  matlab.ui.control.Label
        UIAxes_Temporal_Cardio          matlab.ui.control.UIAxes
        UIAxes_Temporal_Resp            matlab.ui.control.UIAxes
        UIAxes_Temporal_2               matlab.ui.control.UIAxes
        UIAxes_Spectral                 matlab.ui.control.UIAxes
        UIAxes_Temporal                 matlab.ui.control.UIAxes
        UIAxes_maskROI                  matlab.ui.control.UIAxes
        UIAxes_selectedROI              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        MainApp % % Reference to the MainApp for accessing its properties
        ParentSubWindow % Reference to the parent subwindow
        CalibrationWindow % (Child window) Window for Calibration
        CalibrationWindow_Overall
        % PCMRI % PCMRI data
        Selected_region_PCMRI % rotatedFlippedData_fusion_selectedRegion
        Selected_region_averaged_image
        Selected_region_std_image
        % IsROIActive % Flag to indicate if ROI selection is active
        Selected_P_f % Spectrum data.
        % I_Pi_forapp % Matrix storing the I_Pi values
        % M_ROI_forapp % ROI matrix after noise filtering
        % M_ROI_mask_forapp % Binary mask after thresholding
        % freq_range % Frequency range used for FFT
        % deltafreq_for_CF_Auto % Frequency step size for CF_Auto algorithm
        % time_range % Time range of the PCMRI data
        % AnalysisWindow % Description
        %Selected_region_PCMRI
        %PCMRI
        M_ROI_mask_forapp
        ROI_P_f_max
        ROI_dc
        ROI_P_f_real
        ROI_f_max_mat_forapp

        % -----------------------------
        % Physiology signal containers
        % -----------------------------
        Resp_forapp
        Cardio_forapp
        Seq_end_marker
        Mean_value_Resp_forapp
        STD_value_Resp_forapp
        Mean_value_Cardio_forapp
        STD_value_Cardio_forapp
        Scalenorm_Resp_forapp
        Scalenorm_Cardio_forapp
        Resp_Fs_forapp
        Time_Resp_forapp
        Time_Resp_new_forapp
        Time_Setpoint_forapp
        Resp_new_forapp
        Scalenorm_Resp_new_forapp
        Cardio_new_forapp
        Scalenorm_Cardio_new_forapp
        
        
        % -----------------------------
        % ROI pixel-count bookkeeping
        % -----------------------------
        ROI_Base_pixel_count
        ROI_last_pixel_count

        % -----------------------------
        % Single-ROI overlay outputs
        % -----------------------------
        Signal_single_overlay_temporal
        Signal_single_overlay_spectral
        
        % -----------------------------
        % Overall (full-field) outputs
        % -----------------------------
        Signal_Overall_temporal
        Signal_Overall_spectral
        Signal_Overall_ROI_P_f_max
        Signal_Overall_ROI_f_max
        Signal_Overall_dc
        Signal_Overall_M_ROI_real
        Signal_Overall_M_ROI_real_firstsample

        % -----------------------------
        % Overall outputs (calibrated)
        % -----------------------------
        Signal_Overall_temporal_cali
        Signal_Overall_spectral_cali
        Signal_Overall_ROI_P_f_max_cali
        Signal_Overall_ROI_f_max_cali
        Signal_Overall_dc_cali
        Signal_Overall_M_ROI_real_cali
        Signal_Overall_M_ROI_real_firstsample_cali

        % -----------------------------
        % Overall outputs (original / uncalibrated)
        % -----------------------------
        Signal_Overall_temporal_origin
        Signal_Overall_spectral_origin
        Signal_Overall_ROI_P_f_max_origin
        Signal_Overall_ROI_f_max_origin
        Signal_Overall_dc_origin
        Signal_Overall_M_ROI_real_origin
        Signal_Overall_M_ROI_real_firstsample_origin

        % -----------------------------
        % Mean (ROI-aggregated) signals
        % -----------------------------
        Signal_Mean_temporal
        Signal_Mean_spectral

        % -----------------------------
        % Calibration workflow bookkeeping
        % -----------------------------
        Flag_calibration_state_withinROI
        Flag_calibration_state_overall
        Mask_calibration_work
        Mask_calibration_work_overall
        Mask_calibration_work_overall_crop
        Mean_temporal_value_from_calibration_mask  %<--- calibration signal. should be compensated to base signal.
        Mean_spectrum_value_from_calibration_mask
        roi_mask_count_cali
        Mean_temporal_value_from_calibration_mask_overall  %<--- calibration signal. should be compensated to base signal.
        Mean_spectrum_value_from_calibration_mask_overall
        roi_mask_count_cali_overall

        % -----------------------------
        % Mean signals (calibrated vs original)
        % -----------------------------
        Signal_Mean_temporal_cali
        Signal_Mean_spectral_cali

        Signal_Mean_temporal_origin
        Signal_Mean_spectral_origin
        
        % -----------------------------
        % Calibrated PCMRI and FFT components (ROI-level)
        % -----------------------------
        Selected_region_PCMRI_calibrated
        pi_t_calibrated
        P_F_calibrated
        P_angle_calibrated
        P_complex_calibrated
        P_real_calibrated

        % -----------------------------
        % Overall PCMRI context (for ROI extraction + global analysis)
        % -----------------------------
        Overall_PCMRI
        Overall_PCMRI_Std
        Overall_averagedImage
        x_pointing_fortheapp_boundarybox
        y_pointing_fortheapp_boundarybox
        selected_box_size
        selected_halfSize
        xMin_fortheapp
        xMax_fortheapp
        yMin_fortheapp
        yMax_fortheapp

        % -----------------------------
        % Overall (full-field) spectral caches
        % -----------------------------
        P_F_overall_forapp
        P_angle_overall_forapp
        P_complex_overall_forapp
        P_real_overall_forapp
        P_f_max_overall_forapp
        dc_overall_forapp
        f_max_mat_overall_forapp

    end
    
    properties (Access = private)
        ClickedPoints = []; % Stores user-clicked pixel coordinates as [x, y]
        ClickedPoints_1 = []; % Secondary clicked-point buffer (e.g., for an alternate mode/axes)
        PlottedSignals = {}; % Stores extracted PCMRI time-domain signals corresponding to clicked points
        PlottedSpectra = {}; % Stores extracted frequency-domain spectra corresponding to clicked points
        
        PlottedSignals_2 = {}; % Stores PCMRI signals plotted in the respiration-related visualization panel

        RedDots = []; % Graphics handles for red dot markers used to indicate selected points
        RedDots_1 = []; % Secondary set of red dot handles (for an alternate mode/axes)
        CurrentPlotMode % Tracks the currently selected plotting mode (e.g., from radio buttons)
        OverlaidSignals = []; % Collection of time-domain signals currently overlaid in the plot
        OverlaidSpectra = []; % Collection of spectra currently overlaid in the plot

        LastSelectedPoint = []; % Most recently selected point [x, y] (used for ROI mask editing actions)
        LastSelectedPoint_1 = []; % Secondary "last selected point" (used for alternate ROI edit mode/axes)

        ROIImageHandle % Handle to the ROI image display (for updating/refreshing visualization)

        DragData % Temporary state for mouse click-and-drag interactions
        ImageHandles % Graphics handles used to support click/drag editing on images

        %----- Menubar state flags
        CalibrationFlag = false; % Indicates whether calibration mode is currently enabled
        AliasingFlag = false; % Indicates whether aliasing-handling mode is currently enabled
        
        %----- Calibration visualization
        MaskImageHandle % Handle to the calibration mask overlay/image object

    end
    
    methods (Access = public)
        
        function setMainApp(app, mainAppReference, ParentAppReference)
            app.MainApp = mainAppReference;
            app.ParentSubWindow = ParentAppReference;

            % Register a close-request callback to ensure child windows are closed cleanly
            app.UIFigure.CloseRequestFcn = @(src, event) app.closeRequest(event);
        end


        function initializeColorbarLimits(app, imageData)
            % Initialize colorbar limits using the min/max values of the input image
            minValue = min(imageData(:));
            maxValue = max(imageData(:));

            % Populate colorbar limit edit fields with the initial range
            app.ColorbarlowEditField.Value = minValue;
            app.ColorbarhighEditField.Value = maxValue;

            % Apply the color axis limits to the ROI display axes
            caxis(app.UIAxes_selectedROI, [minValue, maxValue]);
            %caxis(app.UIAxes_fusionimg, [minValue, maxValue]);
        end

        function initializeROIcount(app, roiMask)
            % Validate ROI mask input before computing pixel counts
            if isempty(roiMask)
                disp('ROI mask is empty.');
                return;
            end

            % Count ROI pixels (mask == 1) to track ROI size at baseline
            roiPixelCount = nnz(roiMask == 1);

            % Report/store the baseline ROI pixel count
            app.MainApp.logMessage(['Number of Base ROI mask pixels: ', num2str(roiPixelCount)]);
            app.ROImaskpixelcountEditField.Value = roiPixelCount;

            % Cache the baseline pixel count for later comparisons (e.g., after edits/calibration)
            app.ROI_Base_pixel_count = roiPixelCount;
        end

        function updateROIcount(app, roiMask)
            % Validate the input ROI mask before updating the pixel count
            if isempty(roiMask)
                disp('ROI mask is empty.');
                return;
            end

            % Compute the number of pixels included in the updated ROI mask (mask == 1)
            roiPixelCount = nnz(roiMask == 1);

            % Report and display the updated ROI pixel count
            app.MainApp.logMessage(['Number of update ROI mask pixels: ', num2str(roiPixelCount)]);
            app.ROImaskpixelcountEditField.Value = roiPixelCount;

            % Store the updated ROI pixel count for tracking changes after edits
            %app.ROI_Base_pixel_count = roiPixelCount;
            app.ROI_last_pixel_count = roiPixelCount;
        end

        function LastROIcount(app, roiMask)
            % Validate the input ROI mask before computing the final pixel count
            if isempty(roiMask)
                disp('ROI mask is empty.');
                return;
            end

            % Compute the number of pixels included in the final ROI mask (mask == 1)
            roiPixelCount = nnz(roiMask == 1);

            % Log the final ROI pixel count after all modifications are complete
            app.MainApp.logMessage(['Number of Last ROI mask pixels: ', num2str(roiPixelCount)]);

            % Log the final ROI pixel count after all modifications are complete
            app.ROI_last_pixel_count = roiPixelCount;
        end

     
        function Adjusting_Calibration_to_Variables(app)
            % Match the calibration time series to the PCMRI data dimensions for implicit broadcasting
            [rows, cols, frames] = size(app.Selected_region_PCMRI);
            avg_temporal = reshape(app.Mean_temporal_value_from_calibration_mask, [1, 1, frames]);

            % Apply calibration by removing the calibration-derived temporal baseline from every ROI pixel
            app.Selected_region_PCMRI=app.Selected_region_PCMRI - avg_temporal;            
        end

        function Adjusting_Calibration_to_Variables_overall(app)
            % Match the full-field calibration time series to the PCMRI data dimensions for implicit broadcasting
            [rows, cols, frames] = size(app.Selected_region_PCMRI);
            avg_temporal = reshape(app.Mean_temporal_value_from_calibration_mask_overall, [1, 1, frames]);

            % Apply calibration globally: subtract the calibration-derived temporal baseline from
            % (1) the full PCMRI field and (2) the currently selected ROI subset
            app.Overall_PCMRI=app.Overall_PCMRI-avg_temporal;
            app.Selected_region_PCMRI=app.Selected_region_PCMRI - avg_temporal;            
        end

        function Adjusting_Calibration_mask(app)
            % Create an RGB overlay image from the ROI-level calibration mask for visualization
            [rows, cols] = size(app.Mask_calibration_work);
            RGBMask = zeros(rows, cols, 3); % Initialize RGB mask container
            maskcolor = 'Red';      % Fixed overlay color (can be generalized later
            % Map the binary calibration mask into a single RGB channel (default: red)
            switch maskcolor
                case 'Red'
                    RGBMask(:, :, 1) = app.Mask_calibration_work; % Set red channel where mask is 1
                case 'Blue'
                    RGBMask(:, :, 3) = app.Mask_calibration_work; % Set red channel where mask is 1
                case 'Green'
                    RGBMask(:, :, 2) = app.Mask_calibration_work; % Set red channel where mask is 1
                otherwise
                    RGBMask(:, :, 1) = app.Mask_calibration_work; % Set red channel where mask is 1
            end

            % Overlay the calibration mask on top of the mask-ROI axes with a fixed transparency
            hold(app.UIAxes_maskROI, 'on');
            app.MaskImageHandle = imagesc(app.UIAxes_maskROI, RGBMask, 'AlphaData', 0.35);

        end

        function Adjusting_Calibration_mask_overall(app)
            % Create an RGB overlay image from the cropped full-field calibration mask for visualization
            [rows, cols] = size(app.Mask_calibration_work_overall_crop);
            RGBMask = zeros(rows, cols, 3); % Initialize RGB mask container
            maskcolor = 'Red';      % Fixed overlay color (can be generalized later)
            % Map the binary calibration mask into a single RGB channel (default: red)
            switch maskcolor
                case 'Red'
                    RGBMask(:, :, 1) = app.Mask_calibration_work_overall_crop; % Set red channel where mask is 1
                case 'Blue'
                    RGBMask(:, :, 3) = app.Mask_calibration_work_overall_crop; % Set red channel where mask is 1
                case 'Green'
                    RGBMask(:, :, 2) = app.Mask_calibration_work_overall_crop; % Set red channel where mask is 1
                otherwise
                    RGBMask(:, :, 1) = app.Mask_calibration_work_overall_crop; % Set red channel where mask is 1
            end

            % Overlay the calibration mask on top of the mask-ROI axes with a fixed transparency
            hold(app.UIAxes_maskROI, 'on');
            app.MaskImageHandle = imagesc(app.UIAxes_maskROI, RGBMask, 'AlphaData', 0.35);

        end

        function Canceling_Calibration_to_Variables(app)
            % Revert ROI PCMRI data to the original (pre-calibration) version stored in the parent subwindow
            app.Selected_region_PCMRI=app.ParentSubWindow.Selected_region_PCMRI;
        end
        function Canceling_Calibration_mask(app)
            % Restore/refresh the ROI mask visualization after canceling calibration-related overlays/edits
            app.updateROIMaskVisualization();
        end

        function Initial_dataset(app, PCMRI_Data, ROI_MASK)
            % -----------------------------
            % Initialize / reset calibration-related state variables (added 2025-02-11)
            % -----------------------------
            app.Flag_calibration_state_withinROI=0;
            app.Flag_calibration_state_overall=0;
            app.Mask_calibration_work=[];
            app.Mask_calibration_work_overall=[];
            app.Mask_calibration_work_overall_crop=[];
            app.Mean_temporal_value_from_calibration_mask=[];
            app.Mean_spectrum_value_from_calibration_mask=[];
            % Preallocate buffers for calibrated FFT outputs (ROI-level) using ROI dimensions
            [x_size_forcal, y_size_forcal, t_size_forcal] = size(app.ParentSubWindow.Selected_region_PCMRI);
            app.P_F_calibrated = zeros(x_size_forcal, y_size_forcal, length(app.ParentSubWindow.freq_range_forapp));
            app.P_angle_calibrated = zeros(x_size_forcal, y_size_forcal, length(app.ParentSubWindow.freq_range_forapp));
            app.P_complex_calibrated = zeros(x_size_forcal, y_size_forcal, length(app.ParentSubWindow.freq_range_forapp));
            app.P_real_calibrated = zeros(x_size_forcal, y_size_forcal, length(app.ParentSubWindow.freq_range_forapp));
            % Reset calibrated “overall” signal containers
            app.Signal_Overall_temporal_cali = [];
            app.Signal_Overall_spectral_cali = [];
            app.Signal_Overall_ROI_P_f_max_cali = [];
            app.Signal_Overall_ROI_f_max_cali = [];
            app.Signal_Overall_dc_cali = [];
            app.Signal_Overall_M_ROI_real_cali = [];
            app.Signal_Overall_M_ROI_real_firstsample_cali = [];
            %-----------------------------------------
            

            % -----------------------------
            % Load/assign base ROI data and visualization inputs
            % -----------------------------
            app.Selected_region_PCMRI=app.ParentSubWindow.Selected_region_PCMRI;
            app.Selected_region_averaged_image = PCMRI_Data;
            app.Selected_region_std_image=app.ParentSubWindow.Selected_region_std_image;
            

            % -----------------------------
            % Cache ROI mask + precomputed spectral/feature maps from the parent subwindow
            % -----------------------------
            app.M_ROI_mask_forapp = ROI_MASK;
            app.Selected_P_f = app.ParentSubWindow.P_F_forapp;
            app.ROI_P_f_max = app.ParentSubWindow.P_f_max_forapp;
            app.ROI_dc = app.ParentSubWindow.dc_forapp;
            app.ROI_P_f_real = app.ParentSubWindow.P_f_real_forapp;
            app.ROI_f_max_mat_forapp = app.ParentSubWindow.f_max_mat_forapp;
            time_range = app.ParentSubWindow.time_range_forapp;
            freq_range = app.ParentSubWindow.freq_range_forapp;

            % -----------------------------
            % Initialize / cache overall (full-field) data and analysis products for global calibration/visualization
            % -----------------------------
            app.Overall_PCMRI = app.ParentSubWindow.Overall_PCMRI;
            app.Overall_PCMRI_Std = app.ParentSubWindow.Overall_PCMRI_Std;
            app.Overall_averagedImage = app.ParentSubWindow.Overall_averagedImage;
            % ROI bounding-box metadata (in full-image coordinates)
            app.x_pointing_fortheapp_boundarybox = app.ParentSubWindow.x_pointing_fortheapp_boundarybox;
            app.y_pointing_fortheapp_boundarybox = app.ParentSubWindow.y_pointing_fortheapp_boundarybox;
            app.selected_box_size = app.ParentSubWindow.selected_box_size;
            app.selected_halfSize = app.ParentSubWindow.selected_halfSize;
            app.xMin_fortheapp = app.ParentSubWindow.xMin_fortheapp; 
            app.xMax_fortheapp = app.ParentSubWindow.xMax_fortheapp;
            app.yMin_fortheapp = app.ParentSubWindow.yMin_fortheapp;
            app.yMax_fortheapp = app.ParentSubWindow.yMax_fortheapp;

            % Full-field FFT caches
            app.P_F_overall_forapp = app.ParentSubWindow.P_F_overall_forapp;
            app.P_angle_overall_forapp = app.ParentSubWindow.P_angle_overall_forapp;
            app.P_complex_overall_forapp = app.ParentSubWindow.P_complex_overall_forapp;
            app.P_real_overall_forapp = app.ParentSubWindow.P_real_overall_forapp;
            app.P_f_max_overall_forapp = app.ParentSubWindow.P_f_max_overall_forapp;
            app.dc_overall_forapp = app.ParentSubWindow.dc_overall_forapp;
            app.f_max_mat_overall_forapp = app.ParentSubWindow.f_max_mat_overall_forapp;

            
            % -----------------------------
            % Initialize display settings and ROI bookkeeping
            % -----------------------------
            app.initializeColorbarLimits(app.Selected_region_PCMRI(:, :, 1));

            % Use current UI-specified colorbar limits for image display
            app.initializeROIcount(ROI_MASK);

            % For color range.
            lowLimit = app.ColorbarlowEditField.Value;
            highLimit = app.ColorbarhighEditField.Value;

            % -----------------------------
            % Render averaged ROI image (selection/reference view)
            % -----------------------------
            cla(app.UIAxes_selectedROI);
            imgHandle = imagesc(app.UIAxes_selectedROI, PCMRI_Data);
            colormap(app.UIAxes_selectedROI, 'gray');
            axis(app.UIAxes_selectedROI, 'image');
            axis(app.UIAxes_selectedROI, 'tight');
            colorbar(app.UIAxes_selectedROI);clim(app.UIAxes_selectedROI, [lowLimit, highLimit]);
            xlabel(app.UIAxes_selectedROI, 'Pixels');
            ylabel(app.UIAxes_selectedROI, 'Pixels');
            title(app.UIAxes_selectedROI, 'Averaged Image - Select ROI');

            % -----------------------------
            % Render ROI mask image (editable/interactive view)
            % -----------------------------
            cla(app.UIAxes_maskROI);
            imgHandle1 = imagesc(app.UIAxes_maskROI, ROI_MASK);
            colormap(app.UIAxes_maskROI, 'gray');
            axis(app.UIAxes_maskROI, 'image');
            axis(app.UIAxes_maskROI, 'tight');
            colorbar(app.UIAxes_maskROI);clim(app.UIAxes_maskROI, [0, 1]);
            xlabel(app.UIAxes_maskROI, 'Pixels');
            ylabel(app.UIAxes_maskROI, 'Pixels');
            title(app.UIAxes_maskROI, 'Averaged Image - Select ROI');
            
            % Save processing outputs from the parent subwindow (baseline export)
            app.ParentSubWindow.saveData()

            % -----------------------------
            % Initialize drag-edit state to support ROI mask click/drag interactions
            % -----------------------------
            app.DragData = struct('isDragging', false, 'startPoint', [], 'rectHandle', []);
            %Get image dimensions
            app.DragData.imgSize = size(get(imgHandle, 'CData')); % Get image data size

            %set(imgHandle, 'ButtonDownFcn', @(src, event) mousePress(app, src, event, app.UIAxes_selectedROI));
            %set(imgHandle1, 'ButtonDownFcn', @(src, event) mousePress(app, src, event, app.UIAxes_maskROI));

            % % Set up mouse event callbacks for both axes
            % fig = ancestor(app.UIAxes_selectedROI, 'figure');
            % 
            % % Attach callbacks to handle both click and drag
            % set(fig, 'WindowButtonDownFcn', @(src, event) mousePress(app, src, event));
            % set(fig, 'WindowButtonMotionFcn', @(src, event) mouseDrag(app, src, event));
            % set(fig, 'WindowButtonUpFcn', @(src, event) mouseRelease(app, imgHandle, app.DragData.imgSize, time_range, freq_range));

            % -----------------------------
            % Register mouse callbacks (click / drag / release) on the figure containing the ROI mask axes
            % -----------------------------
            fig1 = ancestor(app.UIAxes_maskROI, 'figure');

            % Attach callbacks to handle both click and drag
            set(fig1, 'WindowButtonDownFcn', @(src, event) mousePress(app, src, event));
            set(fig1, 'WindowButtonMotionFcn', @(src, event) mouseDrag(app, src, event));
            set(fig1, 'WindowButtonUpFcn', @(src, event) mouseRelease(app, imgHandle, app.DragData.imgSize, time_range, freq_range));

            % Store image handles for downstream updates (e.g., refresh, overlay, hit-testing)
            app.ImageHandles = {imgHandle, imgHandle1};


        end

        function mousePress(app, ~, ~)
            % Ensure DragData exists and initialize default fields if missing
            if isempty(app.DragData)
                app.DragData = struct('isDragging', false, 'startPoint', [], 'rectHandle', []);
            end

            % Read the mouse click position in the ROI mask axes (rounded to integer pixel coordinates)
            cp = get(app.UIAxes_maskROI, 'CurrentPoint');
            clickX = round(cp(1, 1));  % ✅ Ensure integer values
            clickY = round(cp(1, 2));

            % Retrieve current axes limits to enforce valid interaction bounds
            xLimits = xlim(app.UIAxes_maskROI);
            yLimits = ylim(app.UIAxes_maskROI);

            % Reject clicks outside the visible axes region and mark the click as invalid
            if clickX < xLimits(1) || clickX > xLimits(2) || ...
                    clickY < yLimits(1) || clickY > yLimits(2)
                % Click is outside the axes - Do nothing
                app.DragData.isValidClick = false;  % ✅ Mark click as invalid
                app.DragData.startPoint = [];  % ✅ Reset stored point
                disp('Click ignored: Outside the axes boundaries.');
                return;
            end

            % Store the valid start point and reset drag state for a new interaction
            app.DragData.startPoint = [clickX, clickY];
            app.DragData.isDragging = false;
            app.DragData.isValidClick = true;  % ✅ Mark click as valid

            % Optional debug printout for interaction tracking
            disp(['Click registered at: (', num2str(clickX), ', ', num2str(clickY), ')']);

        end

        function mouseDrag(app, ~, ~)
            % Skip dragging if DragData is not initialized or no valid start point was recorded
            if isempty(app.DragData) || isempty(app.DragData.startPoint)
                return; % Skip if DragData is not initialized
            end

            % Read the current mouse position and clamp it to the image bounds
            cp = get(app.UIAxes_maskROI, 'CurrentPoint');
            currentPoint = clampPoint(app, cp(1, 1:2), app.DragData.imgSize);
            % Compute cursor displacement from the initial click location
            startPoint = app.DragData.startPoint;
            movement = norm(currentPoint - startPoint);

            % Activate drag-selection mode once movement exceeds a small threshold
            if movement > 2
                app.DragData.isDragging = true;

                % Build a rectangle constrained to valid image coordinates
                xMin = max(1, min(startPoint(1), currentPoint(1)));
                yMin = max(1, min(startPoint(2), currentPoint(2)));
                xMax = min(app.DragData.imgSize(2), max(startPoint(1), currentPoint(1)));
                yMax = min(app.DragData.imgSize(1), max(startPoint(2), currentPoint(2)));

                rectPos = [xMin, yMin, xMax - xMin, yMax - yMin];

                % Draw a new selection rectangle or update the existing one during dragging
                if isempty(app.DragData.rectHandle) || ~isvalid(app.DragData.rectHandle)
                    hold(app.UIAxes_maskROI, 'on');
                    app.DragData.rectHandle = rectangle(app.UIAxes_maskROI, ...
                        'Position', rectPos, 'EdgeColor', 'r', 'LineStyle', '--', 'LineWidth', 1);
                    hold(app.UIAxes_maskROI, 'off');
                else
                    set(app.DragData.rectHandle, 'Position', rectPos);
                end
            end
        end

        function mouseRelease(app, imgHandle, imgSize, time_range, freq_range)
            % Ignore release events if DragData has not been initialized
            if isempty(app.DragData)
                return;
            end

            % If the initial click was invalid (outside axes), skip processing entirely
            if ~app.DragData.isValidClick
                return;
            end

            % Read and clamp the release point to the image bounds (integer pixel coordinates)
            cp = get(app.UIAxes_maskROI, 'CurrentPoint');
            cp = clampPoint(app, round(cp(1, 1:2)), app.DragData.imgSize);

            % Retrieve axes limits to reject releases outside the visible axes region
            xLimits = xlim(app.UIAxes_maskROI);
            yLimits = ylim(app.UIAxes_maskROI);

            % Reject releases outside the axes boundaries
            if cp(1) < xLimits(1) || cp(1) > xLimits(2) || ...
                    cp(2) < yLimits(1) || cp(2) > yLimits(2)
                disp('Mouse release ignored: Outside the axes boundaries.');
                return;  % Do nothing if outside the axes
            end

            if app.DragData.isDragging
                % Drag selection: convert the rectangle into pixel index ranges
                rectPos = get(app.DragData.rectHandle, 'Position');
                xRange = max(1, round(rectPos(1))) : min(imgSize(2), round(rectPos(1) + rectPos(3)));
                yRange = max(1, round(rectPos(2))) : min(imgSize(1), round(rectPos(2) + rectPos(4)));

                % Apply the action to every pixel inside the dragged rectangle
                for x = xRange
                    for y = yRange
                        plotRedDotOnImage(app, [x, y], imgHandle, time_range, freq_range);
                    end
                end
                % Remove the temporary selection rectangle after applying the operation
                delete(app.DragData.rectHandle);
            else
                % Single click: apply the action only at the clicked pixel location
                if cp(1) >= 1 && cp(1) <= imgSize(2) && cp(2) >= 1 && cp(2) <= imgSize(1)
                    plotRedDotOnImage(app, cp, imgHandle, time_range, freq_range);
                else
                    title(app.UIAxes_maskROI, 'Click is outside the image bounds');
                end
            end

            % Reset interaction state for the next click/drag operation
            app.DragData.isDragging = false;
            app.DragData.startPoint = [];
            app.DragData.rectHandle = [];
        end

        % Plotting function for a single point
        function plotRedDotOnImage(app, point, imgHandle, time_range, freq_range)
            % Extract integer pixel coordinates from the selected point
            x = point(1);
            y = point(2);

            % Get image dimensions from the displayed image object (CData)
            imgSize = size(get(imgHandle, 'CData'));
            % ------------------------------------------------------------
            % (1) Register the selected point and draw a red marker on the ROI mask axes
            % ------------------------------------------------------------
            if x >= 1 && x <= imgSize(2) && y >= 1 && y <= imgSize(1)
                % Append the selected point to the mask-click list and cache it as the last selection
                app.ClickedPoints_1 = [app.ClickedPoints_1; x, y];
                app.LastSelectedPoint = [x, y];

                % Draw a red dot marker on the ROI mask display
                hold(app.UIAxes_maskROI, 'on');
                redDot = plot(app.UIAxes_maskROI, x, y, 'ro', 'MarkerSize', 4, 'LineWidth', 2);
                hold(app.UIAxes_maskROI, 'off');

                % Store the graphics handle for later deletion/updates
                app.RedDots = [app.RedDots, redDot];

                % Update overlay counter controls based on the number of selected points
                app.SignaloverlaynumberSpinner.Limits = [0, size(app.ClickedPoints_1, 1)];
                app.SignaloverlaynumberSpinner.Value = size(app.ClickedPoints_1, 1);

                % Update the axes title to reflect the most recently selected coordinate
                title(app.UIAxes_maskROI, sprintf('Selected at X: %d, Y: %d', x, y));

                % (Optional) Plot per-voxel signal/spectrum here if needed
                %signal = squeeze(app.Selected_region_PCMRI(y, x, :));
                %spectrum = squeeze(app.Selected_P_f(y, x, :));

                %plotSignal(app, time_range, signal, x, y);
                %plotSpectrum(app, freq_range, spectrum, x, y);
            end

            % ------------------------------------------------------------
            % (2) Register the selected point and draw a red marker on the ROI image axes
            % ------------------------------------------------------------
            if x >= 1 && x <= imgSize(2) && y >= 1 && y <= imgSize(1)
                % Append the selected point to the ROI-click list and cache it as the last selection
                app.ClickedPoints = [app.ClickedPoints; x, y];
                app.LastSelectedPoint = [x, y];

                % Draw a red dot marker on the ROI image display
                hold(app.UIAxes_selectedROI, 'on');
                redDot = plot(app.UIAxes_selectedROI, x, y, 'ro', 'MarkerSize', 4, 'LineWidth', 2);
                hold(app.UIAxes_selectedROI, 'off');

                % Store the graphics handle for later deletion/updates
                app.RedDots_1 = [app.RedDots_1, redDot];
                % Update the axes title to reflect the most recently selected coordinate
                title(app.UIAxes_selectedROI, sprintf('Selected at X: %d, Y: %d', x, y));

                % (Optional) Plot per-voxel signal/spectrum here if needed
                %signal = squeeze(app.Selected_region_PCMRI(y, x, :));
                %spectrum = squeeze(app.Selected_P_f(y, x, :));

                %plotSignal(app, time_range, signal, x, y);
                %plotSpectrum(app, freq_range, spectrum, x, y);
            end

            % ------------------------------------------------------------
            % (3) Plot the voxel-wise time series and spectrum corresponding to the selected point
            % ------------------------------------------------------------
            if x >= 1 && x <= imgSize(2) && y >= 1 && y <= imgSize(1)
                % Extract voxel time-series and frequency-domain spectrum at (x, y)
                signal = squeeze(app.Selected_region_PCMRI(y, x, :));
                spectrum = squeeze(app.Selected_P_f(y, x, :));

                % ---- Time-series plot (primary temporal axes)
                hold(app.UIAxes_Temporal, 'on');
                signalPlot = plot(app.UIAxes_Temporal,time_range,  signal, 'DisplayName', sprintf('Point (%d, %d)', x, y));axis(app.UIAxes_Temporal ,'tight');
                hold(app.UIAxes_Temporal, 'off');
                % Store plot handle for future overlay management
                app.PlottedSignals{end + 1} = signalPlot;
                % Label/format temporal axes
                xlabel(app.UIAxes_Temporal, 'Time Points [sec]');
                ylabel(app.UIAxes_Temporal, 'Signal Intensity (cm/s)');
                title(app.UIAxes_Temporal, 'Single Time-Series Data of specific voxels in ROI');

                % ---- Time-series plot (secondary temporal axes for respiration panel)
                hold(app.UIAxes_Temporal_2, 'on');
                signalPlot2 = plot(app.UIAxes_Temporal_2,time_range,  signal, 'DisplayName', sprintf('Point (%d, %d)', x, y));axis(app.UIAxes_Temporal_2 ,'tight');
                hold(app.UIAxes_Temporal_2, 'off');
                % Store plot handle for future overlay management (respiration panel)
                app.PlottedSignals_2{end + 1} = signalPlot2;
                % Label/format secondary temporal axes
                xlabel(app.UIAxes_Temporal_2, 'Time Points [sec]');
                ylabel(app.UIAxes_Temporal_2, 'Signal Intensity (cm/s)');
                title(app.UIAxes_Temporal_2, 'Single Time-Series Data of specific voxels in ROI');

                % ---- Spectrum plot (frequency-domain axes)
                hold(app.UIAxes_Spectral, 'on');
                spectrumPlot = plot(app.UIAxes_Spectral,freq_range,  spectrum, 'DisplayName', sprintf('Point (%d, %d)', x, y));axis(app.UIAxes_Spectral ,'tight');
                hold(app.UIAxes_Spectral, 'off');
                % Store plot handle for future overlay management
                app.PlottedSpectra{end + 1} = spectrumPlot;
                % Restrict x-axis to the Nyquist-relevant half of the spectrum
                xlim(app.UIAxes_Spectral,[0 freq_range(length(freq_range)/2)]);
                % Label/format spectral axes
                xlabel(app.UIAxes_Spectral,'Spectrum (Hz)');
                ylabel(app.UIAxes_Spectral,'Magnitude');
                title(app.UIAxes_Spectral,'Single Spectrum Data of specific voxels in ROI');

            else
                % Placeholder for logging or UI feedback when the click is outside valid image bounds
                % e.g., app.MainApp.logMessage('[Warning] Selected point is outside image bounds.');
            end
            
        end

        % Helper Function: Clamp a Point to Stay Within Image Bounds
        function clampedPoint = clampPoint(app, point, imgSize)
            % Constrain the X coordinate to the valid column range: [1, image width]
            clampedPoint(1) = min(max(1, point(1)), imgSize(2)); % Clamp X within [1, width]
            % Constrain the Y coordinate to the valid row range: [1, image height]
            clampedPoint(2) = min(max(1, point(2)), imgSize(1)); % Clamp Y within [1, height]
        end

        
        % Update ROI Mask Visualization
        function updateROIMaskVisualization(app)
            % Clear previously plotted ROI selection markers (red dots)
            app.clearRedDots();

            % Retrieve time/frequency axes used for signal and spectrum plotting
            time_range = app.ParentSubWindow.time_range_forapp;
            freq_range = app.ParentSubWindow.freq_range_forapp;
            
            % ------------------------------------------------------------
            % (1) Redraw the current ROI mask on the mask display axes
            % ------------------------------------------------------------
            cla(app.UIAxes_maskROI);
            imgHandle1 = imagesc(app.UIAxes_maskROI, app.M_ROI_mask_forapp);
            colormap(app.UIAxes_maskROI, 'gray');
            axis(app.UIAxes_maskROI, 'image');
            axis(app.UIAxes_maskROI, 'tight');
            colorbar(app.UIAxes_maskROI);clim(app.UIAxes_maskROI, [0, 1]); % Mask is binary in [0, 1]
            xlabel(app.UIAxes_maskROI, 'Pixels');
            ylabel(app.UIAxes_maskROI, 'Pixels');
            title(app.UIAxes_maskROI, 'Averaged Image - Select ROI');
            % If within-ROI calibration is active, overlay the calibration mask visualization
            if app.Flag_calibration_state_withinROI == 1
                app.Adjusting_Calibration_mask();
            end
            % Placeholder: handle visualization if overall calibration is active
            if app.Flag_calibration_state_overall == 1
                % (Optional) Add overall-calibration overlay logic here
            end
            % ------------------------------------------------------------
            % (2) Update ROI pixel count based on the current mask
            % ------------------------------------------------------------
            app.updateROIcount(app.M_ROI_mask_forapp)

            % ------------------------------------------------------------
            % (3) Clear existing signal/spectrum plots to refresh the view
            % ------------------------------------------------------------
            cla(app.UIAxes_Temporal);
            cla(app.UIAxes_Spectral);
            % Clear any existing plots at respiration signal part.
            cla(app.UIAxes_Temporal_2);   % Respiration-panel temporal axes
            
            % Set up mouse click callback on the axes
            % imgHandle1.ButtonDownFcn = @(src, event) app.plotRedDotOnImage(event.IntersectionPoint, imgHandle1);

            % app.ParentSubWindow.saveData()

            % ------------------------------------------------------------
            % (4) Reinitialize drag/click state and register mouse callbacks
            %     for interactive point selection and drag-based region selection
            % ------------------------------------------------------------
            app.DragData = struct('isDragging', false, 'startPoint', [], 'rectHandle', []);
            % Cache the image dimensions for boundary checks during interaction
            app.DragData.imgSize = size(get(imgHandle1, 'CData')); % Get image data size
            %disp(app.DragData.imgSize)

            %set(imgHandle, 'ButtonDownFcn', @(src, event) mousePress(app, src, event, app.UIAxes_selectedROI));
            %set(imgHandle1, 'ButtonDownFcn', @(src, event) mousePress(app, src, event, app.UIAxes_maskROI));

            % % Set up mouse event callbacks for both axes
            % fig = ancestor(app.UIAxes_selectedROI, 'figure');
            % 
            % % Attach callbacks to handle both click and drag
            % set(fig, 'WindowButtonDownFcn', @(src, event) mousePress(app, src, event));
            % set(fig, 'WindowButtonMotionFcn', @(src, event) mouseDrag(app, src, event));
            % set(fig, 'WindowButtonUpFcn', @(src, event) mouseRelease(app, imgHandle, app.DragData.imgSize, time_range, freq_range));

            % Bind mouse event callbacks to the figure containing the mask axes
            fig1 = ancestor(app.UIAxes_maskROI, 'figure');

            % Attach callbacks to handle both click and drag
            set(fig1, 'WindowButtonDownFcn', @(src, event) mousePress(app, src, event));
            set(fig1, 'WindowButtonMotionFcn', @(src, event) mouseDrag(app, src, event));
            set(fig1, 'WindowButtonUpFcn', @(src, event) mouseRelease(app, imgHandle1, app.DragData.imgSize, time_range, freq_range));

            % (Optional) Store image handles for later use if needed
            %app.ImageHandles = {imgHandle, imgHandle1};

        end


        function plotOverallMaskSignal(app)
            % --- Load time/frequency axes from ParentSubWindow (shared parameters)
            time_range = app.ParentSubWindow.time_range_forapp;
            freq_range = app.ParentSubWindow.freq_range_forapp;
            %------------ step7. extract single voxel data from ROI mask.
            % --- Load PCMRI data (ROI-cropped) and ROI binary mask (same XY size)
            PCMRI_data = app.Selected_region_PCMRI;
            M_ROI_mask = app.M_ROI_mask_forapp;
            % ---------------------------------------------------------------------
            % Step 1) Apply ROI mask to PCMRI 3D data (optional; not used downstream)
            %   - Replicate 2D mask along time dimension to match PCMRI_data size
            %   - Element-wise multiplication keeps voxel signal only inside the mask
            % ---------------------------------------------------------------------
            ROI_mask_3D = repmat(M_ROI_mask, [1, 1, size(PCMRI_data, 3)]);
            masked_M_ROI_data = PCMRI_data .* ROI_mask_3D; % Element-wise multiplication to apply mask

            % ---------------------------------------------------------------------
            % Step 2) Identify ROI voxels (mask==1) and preallocate output matrices
            %   - find() returns row/col indices of voxels inside the mask
            %   - num_voxels: number of ROI voxels
            % ---------------------------------------------------------------------
            [mask_x, mask_y] = find(M_ROI_mask == 1);
            num_voxels = numel(mask_x); % Number of voxels in the mask

            % --- Preallocate (Nvox x T) or (Nvox x 1) containers
            masked_M_ROI_signals = zeros(num_voxels, size(PCMRI_data, 3)); % (num_voxels x 720)
            masked_M_ROI_spectrum = zeros(num_voxels, size(PCMRI_data, 3)); % (num_voxels x 720)
            masked_M_ROI_P_f_max = zeros(num_voxels,1); % (num_voxels x 1)
            masked_M_ROI_f_max = zeros(num_voxels,1); % (num_voxels x 1)
            masked_M_ROI_dc = zeros(num_voxels,1); % (num_voxels x 1)
            masked_M_ROI_real = zeros(num_voxels, size(PCMRI_data, 3)); % (num_voxels x 720)
            masked_M_ROI_real_firstsample = zeros(num_voxels,1); % (num_voxels x 1)

            % ---------------------------------------------------------------------
            % Step 3) Extract per-voxel time-series/spectrum/features for all ROI voxels
            %   - For each ROI voxel (x,y):
            %       * PCMRI_data(x,y,:)        -> time-series
            %       * Selected_P_f(x,y,:)      -> spectrum magnitude (or stored spectrum)
            %       * ROI_P_f_max(x,y)         -> max spectral magnitude
            %       * ROI_f_max_mat_forapp(x,y)-> frequency at max magnitude
            %       * ROI_dc(x,y)              -> DC component
            %       * ROI_P_f_real(x,y,:)      -> real part of FFT
            % ---------------------------------------------------------------------
            for i = 1:num_voxels
                x = mask_x(i); % row index
                y = mask_y(i); % col index
                masked_M_ROI_signals(i, :) = squeeze(PCMRI_data(x, y, :)); % Extract and store time-series
                masked_M_ROI_spectrum(i, :) = squeeze(app.Selected_P_f(x, y, :)); % Extract and store time-series
                masked_M_ROI_P_f_max(i) = squeeze(app.ROI_P_f_max(x, y)); % Extract and store time-series
                masked_M_ROI_f_max(i) = squeeze(app.ROI_f_max_mat_forapp(x, y)); % Extract and store time-series
                masked_M_ROI_dc(i) = squeeze(app.ROI_dc(x, y)); % Extract and store time-series
                masked_M_ROI_real(i, :) = squeeze(app.ROI_P_f_real(x, y, :)); % Extract and store time-series
                masked_M_ROI_real_firstsample(i) = squeeze(app.ROI_P_f_real(x, y, 1)); % Extract and store time-series
            end

            % ---------------------------------------------------------------------
            % Step 4) Plot voxel-wise time-series (UIAxes_Temporal)
            %   - Plots each voxel signal as a separate line
            % ---------------------------------------------------------------------
            hold(app.UIAxes_Temporal, 'on');
            for i = 1:num_voxels
                signal = masked_M_ROI_signals(i,:);
                plot(app.UIAxes_Temporal, time_range, signal, 'DisplayName', sprintf('Pixel (%d, %d)', mask_x(i), mask_y(i)));axis(app.UIAxes_Temporal ,'tight');
            end
            hold(app.UIAxes_Temporal, 'off');
            xlabel(app.UIAxes_Temporal, 'Time Points [sec]');
            ylabel(app.UIAxes_Temporal, 'Signal Intensity (cm/s)');
            title(app.UIAxes_Temporal, 'Time-Series Data of Masked Voxels in ROI');

            % ---------------------------------------------------------------------
            % Step 5) Plot voxel-wise time-series again (UIAxes_Temporal_2)
            %   - Typically used for comparing with respiration-related plots
            % ---------------------------------------------------------------------
            hold(app.UIAxes_Temporal_2, 'on');
            for i = 1:num_voxels
                signal = masked_M_ROI_signals(i,:);
                plot(app.UIAxes_Temporal_2, time_range, signal, 'DisplayName', sprintf('Pixel (%d, %d)', mask_x(i), mask_y(i)));axis(app.UIAxes_Temporal_2 ,'tight');
            end
            hold(app.UIAxes_Temporal_2, 'off');
            xlabel(app.UIAxes_Temporal_2, 'Time Points [sec]');
            ylabel(app.UIAxes_Temporal_2, 'Signal Intensity (cm/s)');
            title(app.UIAxes_Temporal_2, 'Time-Series Data of Masked Voxels in ROI');
           

            % ---------------------------------------------------------------------
            % Step 6) Plot voxel-wise spectrum (UIAxes_Spectral)
            %   - Plots spectrum per voxel as a separate line
            %   - Limits x-axis to Nyquist/2 range for readability
            % ---------------------------------------------------------------------
            hold(app.UIAxes_Spectral, 'on');
            for i = 1:num_voxels
                signal = masked_M_ROI_spectrum(i,:);
                plot(app.UIAxes_Spectral, freq_range, signal, 'DisplayName', sprintf('Pixel (%d, %d)', mask_x(i), mask_y(i)));axis(app.UIAxes_Spectral ,'tight');
            end
            hold(app.UIAxes_Spectral, 'off');
            xlim(app.UIAxes_Spectral,[0 freq_range(length(freq_range)/2)]);
            xlabel(app.UIAxes_Spectral,'Spectrum (Hz)');
            ylabel(app.UIAxes_Spectral,'Magnitude');
            title(app.UIAxes_Spectral,'Spectrum Data of Masked Voxels in ROI');

            % ---------------------------------------------------------------------
            % Step 7) Store extracted signals/features into app variables for reuse
            % ---------------------------------------------------------------------
            app.Signal_Overall_temporal = masked_M_ROI_signals;
            app.Signal_Overall_spectral = masked_M_ROI_spectrum;
            app.Signal_Overall_ROI_P_f_max = masked_M_ROI_P_f_max;
            app.Signal_Overall_ROI_f_max = masked_M_ROI_f_max;
            app.Signal_Overall_dc = masked_M_ROI_dc;
            app.Signal_Overall_M_ROI_real = masked_M_ROI_real;
            app.Signal_Overall_M_ROI_real_firstsample = masked_M_ROI_real_firstsample;

        end


        function plotAverageSignal(app)
            % --- Load time/frequency axes from ParentSubWindow
            time_range = app.ParentSubWindow.time_range_forapp;
            freq_range = app.ParentSubWindow.freq_range_forapp;
            % --- Load ROI-cropped PCMRI data and current ROI mask
            PCMRI_data = app.Selected_region_PCMRI;
            M_ROI_mask = app.M_ROI_mask_forapp;
            % ---------------------------------------------------------------------
            % Step 1) Apply ROI mask to PCMRI 3D data (optional; not used downstream)
            %   - Replicate 2D mask along time dimension
            %   - Masked 3D data keeps signals only inside ROI voxels
            % ---------------------------------------------------------------------
            ROI_mask_3D = repmat(M_ROI_mask, [1, 1, size(PCMRI_data, 3)]);
            masked_M_ROI_data = PCMRI_data .* ROI_mask_3D; % Element-wise multiplication to apply mask

            % ---------------------------------------------------------------------
            % Step 2) Find ROI voxels (mask==1) and preallocate per-voxel containers
            %   - find() returns row/col indices (x=row, y=col)
            % ---------------------------------------------------------------------
            [mask_x, mask_y] = find(M_ROI_mask == 1);
            num_voxels = numel(mask_x); % Number of voxels in the mask

            % Preallocate for storing the time-series data of masked voxels
            masked_M_ROI_signals = zeros(num_voxels, size(PCMRI_data, 3)); % (num_voxels x 720)
            masked_M_ROI_spectrum = zeros(num_voxels, size(PCMRI_data, 3)); % (num_voxels x 720)
            masked_M_ROI_P_f_max = zeros(num_voxels,1); % (num_voxels x 1)
            masked_M_ROI_f_max = zeros(num_voxels,1); % (num_voxels x 1)
            masked_M_ROI_dc = zeros(num_voxels,1); % (num_voxels x 1)
            masked_M_ROI_real = zeros(num_voxels, size(PCMRI_data, 3)); % (num_voxels x 720)
            masked_M_ROI_real_firstsample = zeros(num_voxels,1); % (num_voxels x 1)

            % ---------------------------------------------------------------------
            % Step 3) Extract voxel-wise signals/spectra/features for ROI voxels
            % ---------------------------------------------------------------------
            for i = 1:num_voxels
                x = mask_x(i);
                y = mask_y(i);
                masked_M_ROI_signals(i, :) = squeeze(PCMRI_data(x, y, :)); % Extract and store time-series
                masked_M_ROI_spectrum(i, :) = squeeze(app.Selected_P_f(x, y, :)); % Extract and store time-series
                masked_M_ROI_P_f_max(i) = squeeze(app.ROI_P_f_max(x, y)); % Extract and store time-series
                masked_M_ROI_f_max(i) = squeeze(app.ROI_f_max_mat_forapp(x, y)); % Extract and store time-series
                masked_M_ROI_dc(i) = squeeze(app.ROI_dc(x, y)); % Extract and store time-series
                masked_M_ROI_real(i, :) = squeeze(app.ROI_P_f_real(x, y, :)); % Extract and store time-series
                masked_M_ROI_real_firstsample(i) = squeeze(app.ROI_P_f_real(x, y, 1)); % Extract and store time-series
            end

            % ---------------------------------------------------------------------
            % Step 4) Compute ROI-mean time-series and ROI-mean spectrum
            %   - mean across voxels (dimension 1)
            % ---------------------------------------------------------------------
            Mean_masked_M_ROI_signals = mean(masked_M_ROI_signals, 1);
            Mean_masked_M_ROI_spectrals = mean(masked_M_ROI_spectrum, 1);
            % ---------------------------------------------------------------------
            % Step 5) Optional: compute calibration-mask mean and origin mean signals
            %   - If withinROI calibration flag is ON:
            %       * calibration mask: app.Mask_calibration_work (same ROI-cropped space)
            %       * origin data     : app.ParentSubWindow.Selected_region_PCMRI
            %   - If overall calibration flag is ON:
            %       * calibration data: app.Overall_PCMRI & spectral-related overall variables
            %       * origin data     : app.ParentSubWindow.Selected_region_PCMRI
            % ---------------------------------------------------------------------
            if app.Flag_calibration_state_withinROI == 1
                % --- Calibration mask (withinROI)
                Calibration_M_ROI_mask = app.Mask_calibration_work;

                [mask_x_cali, mask_y_cali] = find(Calibration_M_ROI_mask == 1);
                num_voxels_cali = numel(mask_x_cali); % Number of voxels in the mask

                masked_M_ROI_signals_cali = zeros(num_voxels_cali, size(PCMRI_data, 3)); % (num_voxels x 720)
                masked_M_ROI_spectrum_cali = zeros(num_voxels_cali, size(PCMRI_data, 3)); % (num_voxels x 720)
                masked_M_ROI_P_f_max_cali = zeros(num_voxels_cali,1); % (num_voxels x 1)
                masked_M_ROI_f_max_cali = zeros(num_voxels_cali,1); % (num_voxels x 1)
                masked_M_ROI_dc_cali = zeros(num_voxels_cali,1); % (num_voxels x 1)
                masked_M_ROI_real_cali = zeros(num_voxels_cali, size(PCMRI_data, 3)); % (num_voxels x 720)
                masked_M_ROI_real_firstsample_cali = zeros(num_voxels_cali,1); % (num_voxels x 1)

                for ii = 1:num_voxels_cali
                    x = mask_x_cali(ii);
                    y = mask_y_cali(ii);
                    masked_M_ROI_signals_cali(ii, :) = squeeze(PCMRI_data(x, y, :)); % Extract and store time-series
                    masked_M_ROI_spectrum_cali(ii, :) = squeeze(app.Selected_P_f(x, y, :)); % Extract and store time-series
                    masked_M_ROI_P_f_max_cali(ii) = squeeze(app.ROI_P_f_max(x, y)); % Extract and store time-series
                    masked_M_ROI_f_max_cali(ii) = squeeze(app.ROI_f_max_mat_forapp(x, y)); % Extract and store time-series
                    masked_M_ROI_dc_cali(ii) = squeeze(app.ROI_dc(x, y)); % Extract and store time-series
                    masked_M_ROI_real_cali(ii, :) = squeeze(app.ROI_P_f_real(x, y, :)); % Extract and store time-series
                    masked_M_ROI_real_firstsample_cali(ii) = squeeze(app.ROI_P_f_real(x, y, 1)); % Extract and store time-series
                end

                % Mean data
                Mean_masked_M_ROI_signals_cali = mean(masked_M_ROI_signals_cali, 1);
                Mean_masked_M_ROI_spectrals_cali = mean(masked_M_ROI_spectrum_cali, 1);
                
                % --- Origin dataset (pre-calibration) using ParentSubWindow
                PCMRI_data_origin = app.ParentSubWindow.Selected_region_PCMRI;

                [mask_x_origin, mask_y_origin] = find(M_ROI_mask == 1);
                num_voxels_origin = numel(mask_x_origin); % Number of voxels in the mask

                masked_M_ROI_signals_origin = zeros(num_voxels_origin, size(PCMRI_data_origin, 3)); % (num_voxels x 720)
                masked_M_ROI_spectrum_origin = zeros(num_voxels_origin, size(PCMRI_data_origin, 3)); % (num_voxels x 720)
                masked_M_ROI_P_f_max_origin = zeros(num_voxels_origin,1); % (num_voxels x 1)
                masked_M_ROI_f_max_origin = zeros(num_voxels_origin,1); % (num_voxels x 1)
                masked_M_ROI_dc_origin = zeros(num_voxels_origin,1); % (num_voxels x 1)
                masked_M_ROI_real_origin = zeros(num_voxels_origin, size(PCMRI_data_origin, 3)); % (num_voxels x 720)
                masked_M_ROI_real_firstsample_origin = zeros(num_voxels_origin,1); % (num_voxels x 1)

                for iii = 1:num_voxels_origin
                    x = mask_x_origin(iii);
                    y = mask_y_origin(iii);
                    masked_M_ROI_signals_origin(iii, :) = squeeze(PCMRI_data_origin(x, y, :)); % Extract and store time-series
                    masked_M_ROI_spectrum_origin(iii, :) = squeeze(app.Selected_P_f(x, y, :)); % Extract and store time-series
                    masked_M_ROI_P_f_max_origin(iii) = squeeze(app.ROI_P_f_max(x, y)); % Extract and store time-series
                    masked_M_ROI_f_max_origin(iii) = squeeze(app.ROI_f_max_mat_forapp(x, y)); % Extract and store time-series
                    masked_M_ROI_dc_origin(iii) = squeeze(app.ROI_dc(x, y)); % Extract and store time-series
                    masked_M_ROI_real_origin(iii, :) = squeeze(app.ROI_P_f_real(x, y, :)); % Extract and store time-series
                    masked_M_ROI_real_firstsample_origin(iii) = squeeze(app.ROI_P_f_real(x, y, 1)); % Extract and store time-series
                end

                % Mean data
                Mean_masked_M_ROI_signals_origin = mean(masked_M_ROI_signals_origin, 1);
                Mean_masked_M_ROI_spectrals_origin = mean(masked_M_ROI_spectrum_origin, 1);                

            end

            if app.Flag_calibration_state_overall == 1
                % --- Calibration mask (overall) and overall PCMRI dataset
                Calibration_M_ROI_mask = app.Mask_calibration_work_overall;
                PCMRI_data_overall = app.Overall_PCMRI;
                [mask_x_cali, mask_y_cali] = find(Calibration_M_ROI_mask == 1);
                num_voxels_cali = numel(mask_x_cali); % Number of voxels in the mask

                masked_M_ROI_signals_cali = zeros(num_voxels_cali, size(PCMRI_data_overall, 3)); % (num_voxels x 720)
                masked_M_ROI_spectrum_cali = zeros(num_voxels_cali, size(PCMRI_data_overall, 3)); % (num_voxels x 720)
                masked_M_ROI_P_f_max_cali = zeros(num_voxels_cali,1); % (num_voxels x 1)
                masked_M_ROI_f_max_cali = zeros(num_voxels_cali,1); % (num_voxels x 1)
                masked_M_ROI_dc_cali = zeros(num_voxels_cali,1); % (num_voxels x 1)
                masked_M_ROI_real_cali = zeros(num_voxels_cali, size(PCMRI_data_overall, 3)); % (num_voxels x 720)
                masked_M_ROI_real_firstsample_cali = zeros(num_voxels_cali,1); % (num_voxels x 1)

                for ii = 1:num_voxels_cali
                    x = mask_x_cali(ii);
                    y = mask_y_cali(ii);
                    masked_M_ROI_signals_cali(ii, :) = squeeze(PCMRI_data_overall(x, y, :)); % Extract and store time-series
                    masked_M_ROI_spectrum_cali(ii, :) = squeeze(app.P_F_overall_forapp(x, y, :)); % Extract and store time-series
                    masked_M_ROI_P_f_max_cali(ii) = squeeze(app.P_f_max_overall_forapp(x, y)); % Extract and store time-series
                    masked_M_ROI_f_max_cali(ii) = squeeze(app.f_max_mat_overall_forapp(x, y)); % Extract and store time-series
                    masked_M_ROI_dc_cali(ii) = squeeze(app.dc_overall_forapp(x, y)); % Extract and store time-series
                    masked_M_ROI_real_cali(ii, :) = squeeze(app.P_real_overall_forapp(x, y, :)); % Extract and store time-series
                    masked_M_ROI_real_firstsample_cali(ii) = squeeze(app.P_real_overall_forapp(x, y, 1)); % Extract and store time-series
                end

                % Mean data
                Mean_masked_M_ROI_signals_cali = mean(masked_M_ROI_signals_cali, 1);
                Mean_masked_M_ROI_spectrals_cali = mean(masked_M_ROI_spectrum_cali, 1);
                
                % --- Origin dataset (pre-calibration) using ParentSubWindow
                PCMRI_data_origin = app.ParentSubWindow.Selected_region_PCMRI;

                [mask_x_origin, mask_y_origin] = find(M_ROI_mask == 1);
                num_voxels_origin = numel(mask_x_origin); % Number of voxels in the mask

                masked_M_ROI_signals_origin = zeros(num_voxels_origin, size(PCMRI_data_origin, 3)); % (num_voxels x 720)
                masked_M_ROI_spectrum_origin = zeros(num_voxels_origin, size(PCMRI_data_origin, 3)); % (num_voxels x 720)
                masked_M_ROI_P_f_max_origin = zeros(num_voxels_origin,1); % (num_voxels x 1)
                masked_M_ROI_f_max_origin = zeros(num_voxels_origin,1); % (num_voxels x 1)
                masked_M_ROI_dc_origin = zeros(num_voxels_origin,1); % (num_voxels x 1)
                masked_M_ROI_real_origin = zeros(num_voxels_origin, size(PCMRI_data_origin, 3)); % (num_voxels x 720)
                masked_M_ROI_real_firstsample_origin = zeros(num_voxels_origin,1); % (num_voxels x 1)

                for iii = 1:num_voxels_origin
                    x = mask_x_origin(iii);
                    y = mask_y_origin(iii);
                    masked_M_ROI_signals_origin(iii, :) = squeeze(PCMRI_data_origin(x, y, :)); % Extract and store time-series
                    masked_M_ROI_spectrum_origin(iii, :) = squeeze(app.Selected_P_f(x, y, :)); % Extract and store time-series
                    masked_M_ROI_P_f_max_origin(iii) = squeeze(app.ROI_P_f_max(x, y)); % Extract and store time-series
                    masked_M_ROI_f_max_origin(iii) = squeeze(app.ROI_f_max_mat_forapp(x, y)); % Extract and store time-series
                    masked_M_ROI_dc_origin(iii) = squeeze(app.ROI_dc(x, y)); % Extract and store time-series
                    masked_M_ROI_real_origin(iii, :) = squeeze(app.ROI_P_f_real(x, y, :)); % Extract and store time-series
                    masked_M_ROI_real_firstsample_origin(iii) = squeeze(app.ROI_P_f_real(x, y, 1)); % Extract and store time-series
                end

                % Mean data
                Mean_masked_M_ROI_signals_origin = mean(masked_M_ROI_signals_origin, 1);
                Mean_masked_M_ROI_spectrals_origin = mean(masked_M_ROI_spectrum_origin, 1);                

            end


            % ---------------------------------------------------------------------
            % Step 6) Plot mean time-series (and overlay calibration mean if active)
            %   - Note: the DisplayName currently uses mask_x(i), mask_y(i) which is
            %           not meaningful for a mean curve (and i may be out of scope).
            %           Consider using a fixed label like 'ROI mean'.
            % ---------------------------------------------------------------------
            plot(app.UIAxes_Temporal, time_range, Mean_masked_M_ROI_signals, 'DisplayName', sprintf('Pixel (%d, %d)', mask_x(i), mask_y(i)));axis(app.UIAxes_Temporal ,'tight');
            if app.Flag_calibration_state_withinROI == 1
                % Overlay the mask image
                hold(app.UIAxes_Temporal, 'on');
                plot(app.UIAxes_Temporal, time_range, Mean_masked_M_ROI_signals_cali, 'k', 'DisplayName', sprintf('Pixel (%d, %d)', mask_x_cali(ii), mask_y_cali(ii)));axis(app.UIAxes_Temporal ,'tight');
            end
            if app.Flag_calibration_state_overall == 1
                % Overlay the mask image
                hold(app.UIAxes_Temporal, 'on');
                plot(app.UIAxes_Temporal, time_range, Mean_masked_M_ROI_signals_cali, 'k', 'DisplayName', sprintf('Pixel (%d, %d)', mask_x_cali(ii), mask_y_cali(ii)));axis(app.UIAxes_Temporal ,'tight');
            end
            xlabel(app.UIAxes_Temporal, 'Time Points [sec]');
            ylabel(app.UIAxes_Temporal, 'Signal Intensity (cm/s)');
            title(app.UIAxes_Temporal, 'Averaged Time-Series Data of Masked Voxels in ROI');

            % ---------------------------------------------------------------------
            % Step 7) Plot mean spectrum (and overlay calibration mean if active)
            % ---------------------------------------------------------------------
            plot(app.UIAxes_Spectral, freq_range, Mean_masked_M_ROI_spectrals, 'DisplayName', sprintf('Pixel (%d, %d)', mask_x(i), mask_y(i)));axis(app.UIAxes_Spectral ,'tight');
            if app.Flag_calibration_state_withinROI == 1
                hold(app.UIAxes_Spectral, 'on');
                plot(app.UIAxes_Spectral, freq_range, Mean_masked_M_ROI_spectrals_cali, 'k', 'DisplayName', sprintf('Pixel (%d, %d)', mask_x_cali(ii), mask_y_cali(ii)));axis(app.UIAxes_Spectral ,'tight');
            end
            if app.Flag_calibration_state_overall == 1
                hold(app.UIAxes_Spectral, 'on');
                plot(app.UIAxes_Spectral, freq_range, Mean_masked_M_ROI_spectrals_cali, 'k', 'DisplayName', sprintf('Pixel (%d, %d)', mask_x_cali(ii), mask_y_cali(ii)));axis(app.UIAxes_Spectral ,'tight');
            end
            xlim(app.UIAxes_Spectral,[0 freq_range(length(freq_range)/2)]);
            xlabel(app.UIAxes_Spectral, 'Spectrum [Hz]');
            ylabel(app.UIAxes_Spectral, 'Signal Intensity (cm/s)');
            title(app.UIAxes_Spectral, 'Averaged Spectrum Data of Masked Voxels in ROI');

            % ---------------------------------------------------------------------
            % Step 8) Plot mean time-series on the second temporal axes (resp panel)
            % ---------------------------------------------------------------------
            plot(app.UIAxes_Temporal_2, time_range, Mean_masked_M_ROI_signals, 'DisplayName', sprintf('Pixel (%d, %d)', mask_x(i), mask_y(i)));axis(app.UIAxes_Temporal_2 ,'tight');
            xlabel(app.UIAxes_Temporal_2, 'Time Points [sec]');
            ylabel(app.UIAxes_Temporal_2, 'Signal Intensity (cm/s)');
            title(app.UIAxes_Temporal_2, 'Averaged Time-Series Data of Masked Voxels in ROI');

            % ---------------------------------------------------------------------
            % Step 9) Save mean outputs
            % ---------------------------------------------------------------------
            app.Signal_Mean_temporal = Mean_masked_M_ROI_signals;
            app.Signal_Mean_spectral =Mean_masked_M_ROI_spectrals;

            % ---------------------------------------------------------------------
            % Step 10) Save voxel-wise outputs for current ROI mask
            % ---------------------------------------------------------------------
            app.Signal_Overall_temporal = masked_M_ROI_signals;
            app.Signal_Overall_spectral = masked_M_ROI_spectrum;
            app.Signal_Overall_ROI_P_f_max = masked_M_ROI_P_f_max;
            app.Signal_Overall_ROI_f_max = masked_M_ROI_f_max;
            app.Signal_Overall_dc = masked_M_ROI_dc;
            app.Signal_Overall_M_ROI_real = masked_M_ROI_real;
            app.Signal_Overall_M_ROI_real_firstsample = masked_M_ROI_real_firstsample;

            % ---------------------------------------------------------------------
            % Step 11) Save calibration/origin outputs if calibration is active
            % ---------------------------------------------------------------------
            if app.Flag_calibration_state_withinROI == 1
                app.Signal_Mean_temporal_cali = Mean_masked_M_ROI_signals_cali;
                app.Signal_Mean_spectral_cali =Mean_masked_M_ROI_spectrals_cali;
                % Save signal to app variables.
                app.Signal_Overall_temporal_cali = masked_M_ROI_signals_cali;
                app.Signal_Overall_spectral_cali = masked_M_ROI_spectrum_cali;
                app.Signal_Overall_ROI_P_f_max_cali = masked_M_ROI_P_f_max_cali;
                app.Signal_Overall_ROI_f_max_cali = masked_M_ROI_f_max_cali;
                app.Signal_Overall_dc_cali = masked_M_ROI_dc_cali;
                app.Signal_Overall_M_ROI_real_cali = masked_M_ROI_real_cali;
                app.Signal_Overall_M_ROI_real_firstsample_cali = masked_M_ROI_real_firstsample_cali;


                app.Signal_Mean_temporal_origin = Mean_masked_M_ROI_signals_origin;
                app.Signal_Mean_spectral_origin =Mean_masked_M_ROI_spectrals_origin;
                % Save signal to app variables.
                app.Signal_Overall_temporal_origin = masked_M_ROI_signals_origin;
                app.Signal_Overall_spectral_origin = masked_M_ROI_spectrum_origin;
                app.Signal_Overall_ROI_P_f_max_origin = masked_M_ROI_P_f_max_origin;
                app.Signal_Overall_ROI_f_max_origin = masked_M_ROI_f_max_origin;
                app.Signal_Overall_dc_origin = masked_M_ROI_dc_origin;
                app.Signal_Overall_M_ROI_real_origin = masked_M_ROI_real_origin;
                app.Signal_Overall_M_ROI_real_firstsample_origin = masked_M_ROI_real_firstsample_origin;
            end
            if app.Flag_calibration_state_overall == 1
                app.Signal_Mean_temporal_cali = Mean_masked_M_ROI_signals_cali;
                app.Signal_Mean_spectral_cali =Mean_masked_M_ROI_spectrals_cali;
                % Save signal to app variables.
                app.Signal_Overall_temporal_cali = masked_M_ROI_signals_cali;
                app.Signal_Overall_spectral_cali = masked_M_ROI_spectrum_cali;
                app.Signal_Overall_ROI_P_f_max_cali = masked_M_ROI_P_f_max_cali;
                app.Signal_Overall_ROI_f_max_cali = masked_M_ROI_f_max_cali;
                app.Signal_Overall_dc_cali = masked_M_ROI_dc_cali;
                app.Signal_Overall_M_ROI_real_cali = masked_M_ROI_real_cali;
                app.Signal_Overall_M_ROI_real_firstsample_cali = masked_M_ROI_real_firstsample_cali;


                app.Signal_Mean_temporal_origin = Mean_masked_M_ROI_signals_origin;
                app.Signal_Mean_spectral_origin =Mean_masked_M_ROI_spectrals_origin;
                % Save signal to app variables.
                app.Signal_Overall_temporal_origin = masked_M_ROI_signals_origin;
                app.Signal_Overall_spectral_origin = masked_M_ROI_spectrum_origin;
                app.Signal_Overall_ROI_P_f_max_origin = masked_M_ROI_P_f_max_origin;
                app.Signal_Overall_ROI_f_max_origin = masked_M_ROI_f_max_origin;
                app.Signal_Overall_dc_origin = masked_M_ROI_dc_origin;
                app.Signal_Overall_M_ROI_real_origin = masked_M_ROI_real_origin;
                app.Signal_Overall_M_ROI_real_firstsample_origin = masked_M_ROI_real_firstsample_origin;
            end
        end


        function updatePlot(app)
            % Clear previously selected points/markers/plots
            app.clearRedDots();

            % Dispatch plotting based on the selected mode
            switch app.CurrentPlotMode
                case 'OverlaySignal'
                    % Update ROI mask visualization and re-enable interactive selection
                    app.updateROIMaskVisualization();
                case 'OverallMaskSignal'
                    % Plot time-series/spectra for every voxel inside ROI mask
                    app.plotOverallMaskSignal();
                case 'AverageSignal'
                    % Plot mean (averaged) time-series and spectrum across ROI mask voxels
                    app.plotAverageSignal();
            end
        end

        % -------------------------------------------------------------------------
        % Remove all red-dot markers and reset selection-related state.
        % -------------------------------------------------------------------------
        function clearRedDots(app)
            % Delete red dots on UIAxes_maskROI (stored in app.RedDots)
            for i = 1:numel(app.RedDots)
                delete(app.RedDots(i));
            end
            % Clear the RedDots array
            app.RedDots = [];

            % Delete red dots on UIAxes_selectedROI (stored in app.RedDots_1)
            for i = 1:numel(app.RedDots_1)
                delete(app.RedDots_1(i));
            end
            % Clear the RedDots array
            app.RedDots_1 = [];


            % Reset overlay spinner
            app.SignaloverlaynumberSpinner.Value = 0;
            app.SignaloverlaynumberSpinner.Limits = [0, 0];

            % Reset stored selection and plot handles
            app.ClickedPoints = []; % selected points in selectedROI axes
            app.ClickedPoints_1 = []; % selected points in maskROI axes
            app.PlottedSignals = {}; % handles for temporal plots (single-voxel)
            app.PlottedSpectra = {}; % handles for spectral plots (single-voxel)

        end

        % -------------------------------------------------------------------------
        % Save processed signal/ROI data for the current “Process_data_2” stage.
        % -------------------------------------------------------------------------
        function saveData_wo_physio(app)
            save_folderpath = app.MainApp.Save_folder;
            current_plot_mode = app.CurrentPlotMode;

            % --------------------------
            % 1) Session / parameter info
            % --------------------------
            data.Filename = app.MainApp.Load_Data_info_filename;
            data.Path = app.MainApp.Load_Data_info_path;
            data.ROIwindowsize = app.ParentSubWindow.ROIsizeDropDown.Value;
            data.VENC = app.ParentSubWindow.VENC_forapp;
            data.AnalysisRegion = app.ParentSubWindow.Analysis_RegionDropDown.Value;
            data.ThresholdtypeFlow = app.ParentSubWindow.ThresholdtypesDropDown.Value;
            % ROI pixel counts
            data.ROImaskSize = app.ROI_Base_pixel_count;
            data.ROImaskSize_update = app.ROI_last_pixel_count ;
            data.ROImaskSize_cali = app.roi_mask_count_cali;

            % Predefined mask inputs (if used)
            data.predefined_mask_forapp = app.ParentSubWindow.Predefined_mask_fortheapp;
            data.segmendted_predefined_mask_forapp = app.ParentSubWindow.Segmented_predefined_mask_fortheapp;
            data.CurrentROIprocessmode = app.ParentSubWindow.CurrentROIprocessmode;
            
            % --------------------------
            % 2) PCMRI image data + basic scaling/time parameters
            % --------------------------
            data.PCMRI_rawData = app.MainApp.PCMRI_Data;
            data.PCMRI_INFO = app.MainApp.PCMRI_Info;
            % Original ROI-cropped PCMRI (before any calibration edits)
            data.PCMRIData_Origin=app.ParentSubWindow.Selected_region_PCMRI;
            % Current ROI-cropped PCMRI (may reflect calibration subtraction)
            data.PCMRIData = app.Selected_region_PCMRI;
            data.PCMRIData_averageimage = app.Selected_region_averaged_image;
            data.PCMRIData_stdimage = app.Selected_region_std_image;
            
            data.Delta_time = app.MainApp.Delta_time;
            data.MultiplicativeScalingFactor = app.MainApp.MultiplicativeScaling_Factor;
            data.AdditiveOffsetFactor = app.MainApp.AdditiveOffset_Factor;

            data.Freq_range=app.ParentSubWindow.freq_range_forapp;
            data.deltafreq_for_CF_Auto=app.ParentSubWindow.deltafreq_for_CF_Auto_forapp;
            data.Time_range=app.ParentSubWindow.time_range_forapp;

            % --------------------------
            % 3) ROI segmentation / spectral feature maps from CF_Auto pipeline
            % --------------------------
            data.I_Pi=app.ParentSubWindow.I_Pi_forapp;
            data.P_F=app.ParentSubWindow.P_F_forapp;
            data.P_f_max=app.ParentSubWindow.P_f_max_forapp;
            data.dc=app.ParentSubWindow.dc_forapp;
            data.P_f_real=app.ParentSubWindow.P_f_real_forapp;
            data.f_max_mat=app.ParentSubWindow.f_max_mat_forapp;
            data.M_ROI_th = app.ParentSubWindow.M_ROI_th_forapp;
            data.P_angle = app.ParentSubWindow.P_angle_forapp;
            data.P_complex = app.ParentSubWindow.P_complex_forapp;
            data.ROI_labels = app.ParentSubWindow.ROI_labels_forapp;
            data.Auto_CF_Image = app.ParentSubWindow.Auto_CF_Image_forapp;
            % ROI masks
            data.M_ROI=app.ParentSubWindow.M_ROI_forapp;
            data.M_ROI_mask=app.ParentSubWindow.M_ROI_mask_forapp;
            data.M_ROI_mask_new=app.M_ROI_mask_forapp; % updated mask.
            % Save current display state (plot mode)
            data.savemoment_plot_mode = current_plot_mode;

            % --------------------------
            % 4) Calibration info (if calibration mode is used)
            % --------------------------
            data.Flag_calibration_state_withinROI = app.Flag_calibration_state_withinROI;
            data.Mask_calibration_work=app.Mask_calibration_work;
            data.Mask_calibration_work_overall=app.Mask_calibration_work_overall;
            data.Mean_temporal_value_from_calibration_mask = app.Mean_temporal_value_from_calibration_mask;
            data.Mean_spectrum_value_from_calibration_mask = app.Mean_spectrum_value_from_calibration_mask;

            % --------------------------
            % 5) Physiology signals (even though function name says wo_physio)
            % --------------------------
            data.Resp = app.Resp_forapp;
            data.Cardio = app.Cardio_forapp;
            data.Meanvalue_RESP = app.Mean_value_Resp_forapp;
            data.STDvalue_RESP = app.STD_value_Resp_forapp;
            data.Meanvalue_Cardio = app.Mean_value_Cardio_forapp;
            data.STDvalue_Cardio = app.STD_value_Cardio_forapp;
            data.Scalenorm_RESP = app.Scalenorm_Resp_forapp;
            data.Scalenorm_Cardio = app.Scalenorm_Cardio_forapp;
            data.Physio_Fs = app.Resp_Fs_forapp;
            data.Time_Physio = app.Time_Resp_forapp;
            % Synced physiology signals (to PCMRI time base)
            data.Time_synced_Physio = app.Time_Resp_new_forapp;
            data.Time_Setpoint_Physio = app.Time_Setpoint_forapp;
            data.Resp_synced = app.Resp_new_forapp;
            data.Scalenorm_RESP_synced = app.Scalenorm_Resp_new_forapp;
            data.Cardio_synced = app.Cardio_new_forapp;
            data.Scalenorm_Cardio_synced = app.Scalenorm_Cardio_new_forapp;


            % --------------------------
            % 6) Extracted ROI signals (mean + voxel-wise)
            % --------------------------
            data.Mean_temporal_signal_ROI = app.Signal_Mean_temporal;
            data.Mean_spectral_signal_ROI = app.Signal_Mean_spectral;

            % Voxel-wise ROI signals/features (current ROI mask)
            data.Single_Overall_temporal_signal_ROI = app.Signal_Overall_temporal;
            data.Single_Overall_spectral_signal_ROI = app.Signal_Overall_spectral; % <---- should be updated by if data is calibrated, spectral data should be re calculated base calibration signal.
            data.Single_Overall_ROI_P_f_max = app.Signal_Overall_ROI_P_f_max;
            data.Single_Overall_ROI_f_max = app.Signal_Overall_ROI_f_max;
            data.Single_Overal_dc = app.Signal_Overall_dc;
            data.Single_Overall_M_ROI_real = app.Signal_Overall_M_ROI_real;
            data.Single_Overall_M_ROI_real_firstsample = app.Signal_Overall_M_ROI_real_firstsample;

            % Calibration-derived ROI signals (if available)
            data.Mean_temporal_signal_ROI_cali = app.Signal_Mean_temporal_cali;
            data.Mean_spectral_signal_ROI_cali = app.Signal_Mean_spectral_cali;

            data.Single_Overall_temporal_signal_ROI_cali = app.Signal_Overall_temporal_cali;
            data.Single_Overall_spectral_signal_ROI_cali = app.Signal_Overall_spectral_cali;
            data.Single_Overall_ROI_P_f_max_cali = app.Signal_Overall_ROI_P_f_max_cali;
            data.Single_Overall_ROI_f_max_cali = app.Signal_Overall_ROI_f_max_cali;
            data.Single_Overal_dc_cali = app.Signal_Overall_dc_cali;
            data.Single_Overall_M_ROI_real_cali = app.Signal_Overall_M_ROI_real_cali;
            data.Single_Overall_M_ROI_real_firstsample_cali = app.Signal_Overall_M_ROI_real_firstsample_cali;

            % Origin (pre-calibration) ROI signals (for validating calibration effect)
            data.Mean_temporal_signal_ROI_origin = app.Signal_Mean_temporal_origin;
            data.Mean_spectral_signal_ROI_origin = app.Signal_Mean_spectral_origin;

            data.Single_Overall_temporal_signal_ROI_origin = app.Signal_Overall_temporal_origin;
            data.Single_Overall_spectral_signal_ROI_origin = app.Signal_Overall_spectral_origin;
            data.Single_Overall_ROI_P_f_max_origin = app.Signal_Overall_ROI_P_f_max_origin;
            data.Single_Overall_ROI_f_max_origin = app.Signal_Overall_ROI_f_max_origin;
            data.Single_Overal_dc_origin = app.Signal_Overall_dc_origin;
            data.Single_Overall_M_ROI_real_origin = app.Signal_Overall_M_ROI_real_origin;
            data.Single_Overall_M_ROI_real_firstsample_origin = app.Signal_Overall_M_ROI_real_firstsample_origin;
            % --------------------------
            % 7) Timestamp + save to MAT
            % --------------------------
            data.SaveTime = datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss'); % Add save time

            save(strcat(save_folderpath, '/Process_data_2_Signal_Within_segROI_', current_plot_mode ,'.mat'), '-struct', 'data'); % Save as a MAT file
            app.MainApp.logMessage('[System info] Data saved successfully. "Processed data signal values within segmentated ROI"');
        end

        % Save all current plots (ROI / mask / temporal / spectral / physio) as PNG files
        function saveAllImages(app)
            % Create the folder for saving images
            saveFolder = fullfile(app.MainApp.Save_folder, 'SavedImages_Process_data_2'); % Save in save directory
            % if ~exist(saveFolder, 'dir')
            %     mkdir(saveFolder);
            % end
            mkdir(saveFolder);
            % Axes to export
            axesList = {app.UIAxes_selectedROI, app.UIAxes_maskROI, app.UIAxes_Temporal, app.UIAxes_Spectral, app.UIAxes_Temporal_2, app.UIAxes_Temporal_Resp, app.UIAxes_Temporal_Cardio};
            % Output filenames (same order as axesList)
            axesNames = {'SegmentedWindow_ROI', 'ROI_Mask_updated', 'Temporal_PCMRI_signal', 'Spectrum_of_PCMRI_signal', 'Temporal_PCMRI_signal_2', 'Respiratiory_belt_signal', 'PPG_signal'}; % Corresponding names for saved files

            % ----------------------------
            % (A) Save ROI/mask snapshots
            % ----------------------------
            for i = 1:2
                ax = axesList{i};
                if isempty(ax) || ~isvalid(ax)
                    continue; % Skip if axis is missing/invalid
                end

                % Create a hidden figure and copy axes into it
                fig = figure('Visible', 'off'); % Create a hidden figure
                copyobj(ax, fig); % Copy the axes content to the new figure
                % Resize/position the copied axes to fill the figure canvas
                set(gca, 'Position', [0, 0, 600, 400]); % Adjust axes position in the new figure
                % Save to file
                saveFileName = fullfile(saveFolder, [axesNames{i}, '.png']);
                saveas(fig, saveFileName); % Save as PNG
                close(fig); % Close the hidden figure
            end

            % -------------------------------------------
            % (B) Save temporal/spectral/physio time plots
            % -------------------------------------------
            for i = 3:numel(axesList)
                ax = axesList{i};
                if isempty(ax) || ~isvalid(ax)
                    continue; % Skip if axis is missing/invalid
                end

                % Wide/short figure for time-series readability
                fig = figure('Visible', 'off', 'Position', [0 0 1920 1080/4]); % Create a hidden figure
                copyobj(ax, fig); % Copy the axes content to the new figure
                % Fill the figure with the copied axes
                set(gca, 'Position', [0, 0, 1920, 1080/4]); % Adjust axes position in the new figure
                % Save to file
                saveFileName = fullfile(saveFolder, [axesNames{i}, '.png']);
                saveas(fig, saveFileName); % Save as PNG
                close(fig); % Close the hidden figure
            end

            % Log/console message
            app.MainApp.logMessage(['All images saved in folder: ', saveFolder]);
            disp(['All images saved in folder: ', saveFolder]);
        end

        
        function syncPCPhysio(app)
          
            delta_time = app.MainApp.Delta_time;
            PCMRI_data = app.Selected_region_PCMRI;
            PCMRI_data_length = size(PCMRI_data, 3);
            Resp = app.Resp_forapp;
            Value_Resp_Zscore_infunc = app.Scalenorm_Resp_forapp;
            ppg = app.Cardio_forapp;
            Value_ppg_Zscore_infunc = app.Scalenorm_Cardio_forapp;
            
            delta_freq = 1/delta_time;
            % PCMRI time axis
            Time_s = linspace(0, PCMRI_data_length*delta_time, PCMRI_data_length);
            % Physio sampling frequency (should match acquisition setting)
            Resp_Fs = 496; % Sampling freq.

            % Physio time axis (full length, before cropping)
            Time_resp = linspace(0, length(Resp)/Resp_Fs, length(Resp));

            % Target: align the end of physio to the end of PCMRI
            Time_setpoint = Time_resp(end)-Time_s(end);

            % Find physio index whose time is closest to the setpoint
            Time_setpoint_value = abs(Time_resp-Time_setpoint);
            idx_min_set = find(Time_setpoint_value==min(Time_setpoint_value));

            % Crop signals from the computed start index
            Resp_comp = Resp(idx_min_set:end); Value_Resp_Zscore_comp_infunc = Value_Resp_Zscore_infunc(idx_min_set:end);
            % New physio time axis after cropping
            Time_resp_new = linspace(0, length(Resp_comp)/Resp_Fs, length(Resp_comp));
            ppg_comp = ppg(idx_min_set:end); Value_ppg_Zscore_comp_infunc = Value_ppg_Zscore_infunc(idx_min_set:end);

            % Save synchronized outputs to app variables
            app.Resp_Fs_forapp = Resp_Fs;
            app.Time_Resp_forapp = Time_resp;
            app.Time_Resp_new_forapp = Time_resp_new;
            app.Time_Setpoint_forapp = Time_setpoint;
            app.Resp_new_forapp = Resp_comp;
            app.Scalenorm_Resp_new_forapp = Value_Resp_Zscore_comp_infunc;
            app.Cardio_new_forapp = ppg_comp;
            app.Scalenorm_Cardio_new_forapp = Value_ppg_Zscore_comp_infunc;

        end

        function plotRESP(app)
            % ---------------------------------------------------------------------
            % Plot synchronized respiratory belt signal (z-scored / normalized)
            % into app.UIAxes_Temporal_Resp.
            % ---------------------------------------------------------------------
            plot(app.UIAxes_Temporal_Resp, app.Time_Resp_new_forapp, app.Scalenorm_Resp_new_forapp);axis(app.UIAxes_Temporal_Resp ,'tight');
            xlabel(app.UIAxes_Temporal_Resp, 'Time Points [sec]');
            ylabel(app.UIAxes_Temporal_Resp, 'A.U.');
            title(app.UIAxes_Temporal_Resp, 'Respiratory belt signal');

            
        end

        function plotPPG(app)
            % ---------------------------------------------------------------------
            % Plot synchronized PPG signal (z-scored / normalized)
            % into app.UIAxes_Temporal_Cardio.
            % ---------------------------------------------------------------------
            plot(app.UIAxes_Temporal_Cardio, app.Time_Resp_new_forapp, app.Scalenorm_Cardio_new_forapp);axis(app.UIAxes_Temporal_Cardio ,'tight');
            xlabel(app.UIAxes_Temporal_Cardio, 'Time Points [sec]');
            ylabel(app.UIAxes_Temporal_Cardio, 'A.U.');
            title(app.UIAxes_Temporal_Cardio, 'PPG signal');

        end


    end
    
    methods (Access = private)
        % Callback for parent subwindow close
        function closeRequest(app, event)
            % Check if the subwindow of the subwindow exists
            if ~isempty(app.CalibrationWindow) && isvalid(app.CalibrationWindow)
                delete(app.CalibrationWindow); % Close the subwindow of the subwindow
            end

            % Check if the subwindow of the subwindow exists
            if ~isempty(app.CalibrationWindow_Overall) && isvalid(app.CalibrationWindow_Overall)
                delete(app.CalibrationWindow_Overall); % Close the subwindow of the subwindow
            end


            % Close the parent subwindow
            delete(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ColorbarhighEditField
        function ColorbarhighEditFieldValueChanged(app, event)
            % Update the colorbar limits
            lowLimit = app.ColorbarlowEditField.Value;
            highLimit = app.ColorbarhighEditField.Value;
            clim(app.UIAxes_selectedROI, [lowLimit, highLimit]);
            %clim(app.UIAxes_fusionimg, [lowLimit, highLimit]);
        end

        % Value changed function: ColorbarlowEditField
        function ColorbarlowEditFieldValueChanged(app, event)
            % Update the colorbar limits
            lowLimit = app.ColorbarlowEditField.Value;
            highLimit = app.ColorbarhighEditField.Value;
            clim(app.UIAxes_selectedROI, [lowLimit, highLimit]);
            %clim(app.UIAxes_fusionimg, [lowLimit, highLimit]);
            
        end

        % Value changed function: SignaloverlaynumberSpinner
        function SignaloverlaynumberSpinnerValueChanged(app, event)
            newValue = app.SignaloverlaynumberSpinner.Value;

            % Remove excess points if the spinner value decreases
            while size(app.ClickedPoints, 1) > newValue
                % Remove the last red dot
                delete(app.RedDots(end));
                app.RedDots(end) = [];

                % Remove the last red dot
                delete(app.RedDots_1(end));
                app.RedDots_1(end) = [];

                % Remove the last plotted signal and spectrum
                delete(app.PlottedSignals{end});
                delete(app.PlottedSpectra{end});
                app.PlottedSignals(end) = [];
                app.PlottedSpectra(end) = [];
                
                % Remove the last plotted signal and spectrum part in respiration part.
                delete(app.PlottedSignals_2{end});
                app.PlottedSignals_2(end) = [];

                % Remove the last clicked point
                app.ClickedPoints(end, :) = [];
                app.ClickedPoints_1(end,:) = [];
            end

            % Update spinner limits
            app.SignaloverlaynumberSpinner.Limits = [0, size(app.ClickedPoints, 1)];
            
        end

        % Selection changed function: AnalysistypeButtonGroup
        function AnalysistypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.AnalysistypeButtonGroup.SelectedObject;
            if selectedButton == app.SinglesignaloverlayButton
                app.CurrentPlotMode = 'OverlaySignal';
            elseif selectedButton == app.OverallmasksignalButton
                app.CurrentPlotMode = 'OverallMaskSignal';
            elseif selectedButton == app.AveragesignalButton
                app.CurrentPlotMode = 'AverageSignal';
            end

            % Clear any existing plots
            cla(app.UIAxes_Temporal);
            cla(app.UIAxes_Spectral);
            
            % Clear any existing plots in respiration part axes
            cla(app.UIAxes_Temporal_2);

            % Execute the selected function
            app.updatePlot();


        end

        % Button pushed function: SaveDatasetforanalyzingdataButton
        function SaveDatasetforanalyzingdataButtonPushed(app, event)
            app.saveData_wo_physio();
            app.saveAllImages();
            
        end

        % Button pushed function: ROIedit_remove
        function ROIedit_removeButtonPushed(app, event)
            if isempty(app.LastSelectedPoint)
                app.MainApp.logMessage('[System error] No point selected. Click on the image first.');
                return;
            end
            
            % Update the ROI mask for all selected pixels
            for i = 1:size(app.ClickedPoints_1, 1)
                x = app.ClickedPoints_1(i, 1);
                y = app.ClickedPoints_1(i, 2);
                app.M_ROI_mask_forapp(y, x) = 0; % Include the pixel in the ROI mask
            end

            % Update the ROI visualization
            app.updateROIMaskVisualization();
        end

        % Button pushed function: ROIedit_include
        function ROIedit_includeButtonPushed(app, event)
            if isempty(app.LastSelectedPoint)
                app.MainApp.logMessage('[System error] No point selected. Click on the image first.');
                return;
            end
            
            % Update the ROI mask for all selected pixels
            for i = 1:size(app.ClickedPoints_1, 1)
                x = app.ClickedPoints_1(i, 1);
                y = app.ClickedPoints_1(i, 2);
                app.M_ROI_mask_forapp(y, x) = 1; % Include the pixel in the ROI mask
            end

            % Update the ROI visualization
            app.updateROIMaskVisualization();
        end

        % Button pushed function: LoadPhysiologysignalButton
        function LoadPhysiologysignalButtonPushed(app, event)
            % ------* Data load.
            [file, path] = uigetfile('*.log', 'Pick a MR physiology log format file');
            if isequal(file, 0)
                uialert(app.UIFigure, 'No file selected', 'Error');
                app.logMessage('[Error] No file selected');
                return;
            end
            
            % Physiology_data = readtable(strcat(path, file));
            % % column re-arrangement.
            % % column 5: ppu;
            % % column 6: resp;
            % % column 11: end sequence marker
            Cardio_column = 5;
            Resp_column = 6;
            Seq_end_marker_column = 11;
            Marker_integer_endofscansequence=20;
                
            Physiology_data = ReadPhilipsScanPhysLog(strcat(path, file));
                
            ppg = Physiology_data.C(:,Cardio_column);
            Resp = Physiology_data.C(:,Resp_column);
            Seq_end_marker = Physiology_data.C(:,Seq_end_marker_column);

            idx_end_of_seq = find(Seq_end_marker==Marker_integer_endofscansequence);
            last_idx_end_of_seq = idx_end_of_seq(end);

            ppg = double(ppg(1:last_idx_end_of_seq));
            Resp = double(Resp(1:last_idx_end_of_seq));
            Seq_end_marker = double(Seq_end_marker(1:last_idx_end_of_seq));

            % for zscoreing plot (there might be NaN value in data.)
            Value_Resp_without_NaN = Resp(~isnan(Resp));
            Value_Resp_mean = mean(Value_Resp_without_NaN);
            Value_Resp_SD = std(Value_Resp_without_NaN);
            Value_Resp_Zscore = (Resp-Value_Resp_mean)/Value_Resp_SD;

            % for zscoreing plot (there might be NaN value in data.)
            Value_ppg_without_NaN = ppg(~isnan(ppg));
            Value_ppg_mean = mean(Value_ppg_without_NaN);
            Value_ppg_SD = std(Value_ppg_without_NaN);
            Value_ppg_Zscore = (ppg-Value_ppg_mean)/Value_ppg_SD;

            %----- Save app variable.
            app.Resp_forapp = Resp;
            app.Cardio_forapp = ppg;
            app.Seq_end_marker = Seq_end_marker;
            app.Mean_value_Resp_forapp = Value_Resp_mean;
            app.STD_value_Resp_forapp = Value_Resp_SD;
            app.Mean_value_Cardio_forapp = Value_ppg_mean;
            app.STD_value_Cardio_forapp = Value_ppg_SD;
            app.Scalenorm_Resp_forapp = Value_Resp_Zscore;
            app.Scalenorm_Cardio_forapp = Value_ppg_Zscore;
            %------------------------
            
            app.MainApp.logMessage(strcat('[Data info] ', 'Path: ', path, ' Name: ', file));
            app.MainApp.logMessage('[System info] Physiology Data load success!');


            % ------------------------*
            % update ROI mask pixels count
            app.updateROIcount(app.M_ROI_mask_forapp)
            % Data sync and Display function.
            app.syncPCPhysio(); % not necessary.
            app.plotRESP();
            app.plotPPG();


        end

        % Menu selected function: ManualSelection_withinROIMenu
        function ManualSelection_withinROIMenuSelected(app, event)
            if isempty(app.CalibrationWindow) || ~isvalid(app.CalibrationWindow)
                [x_size, y_size, t_size] = size(app.Selected_region_PCMRI);
                if app.Flag_calibration_state_withinROI == 0
                    Calibration_ROImask_intial=zeros(x_size, y_size);
                elseif app.Flag_calibration_state_withinROI == 1
                    Calibration_ROImask_intial=app.Mask_calibration_work;
                end
               
                % Open the Popup window after data is loaded
                app.CalibrationWindow = Calibration_window;
                CalibrationWindowPositionPosition = app.CalibrationWindow.UIFigure.Position; % Get the default position of PopupApp

                 % Get the position of the main window (MainApp)
                mainPosition = app.UIFigure.Position;
                mainWidth = mainPosition(3); % Width of MainApp window

                % Get the default size of the new MagnitudeWindow
                defaultCalibrationWindowPosition = app.CalibrationWindow.UIFigure.Position;
                CalibrationWindowWidth = defaultCalibrationWindowPosition(3);
                CalibrationWindowHeight = defaultCalibrationWindowPosition(4);

                % Calculate the left position for the new window to place it to the right
                newWindowLeft = mainPosition(1) - CalibrationWindowWidth - 10; % 10-pixel gap to the right of MainApp
                newWindowBottom = mainPosition(2)*2; % Align vertically with MainApp

                % Set only the left and bottom position to place it next to MainApp
                app.CalibrationWindow.UIFigure.Position = [newWindowLeft, newWindowBottom, CalibrationWindowWidth, CalibrationWindowHeight];

                % Pass the Main App reference to OpenImageSlide
                app.CalibrationWindow.setMainApp(app.MainApp, app.ParentSubWindow, app); % Pass current 'app' as a reference
                app.CalibrationWindow.Initial_dataset(app.Selected_region_averaged_image,app.Selected_region_std_image, Calibration_ROImask_intial); % initial data set.
                
                %app.CalibrationWindow.setMagnitudeImage(magnitudeImage);
                app.MainApp.logMessage('[System info] Open Calibration window for manually set ROI stationary tissue segmentation!');
            else
                disp('Calibration window is already open.');
            end
        end

        % Menu selected function: ManualSelection_overall_areaMenu
        function ManualSelection_overall_areaMenuSelected(app, event)
            if isempty(app.CalibrationWindow_Overall) || ~isvalid(app.CalibrationWindow_Overall)
                [x_size, y_size, t_size] = size(app.Overall_PCMRI);
                if app.Flag_calibration_state_overall == 0
                    Calibration_ROImask_intial=zeros(x_size, y_size);
                elseif app.Flag_calibration_state_overall == 1
                    Calibration_ROImask_intial=app.Mask_calibration_work_overall;
                end
               
                % Open the Popup window after data is loaded
                app.CalibrationWindow_Overall = Calibration_window_overall;
                CalibrationWindowPositionPosition = app.CalibrationWindow_Overall.UIFigure.Position; % Get the default position of PopupApp

                 % Get the position of the main window (MainApp)
                mainPosition = app.UIFigure.Position;
                mainWidth = mainPosition(3); % Width of MainApp window

                % Get the default size of the new MagnitudeWindow
                defaultCalibrationWindowPosition = app.CalibrationWindow_Overall.UIFigure.Position;
                CalibrationWindowWidth = defaultCalibrationWindowPosition(3);
                CalibrationWindowHeight = defaultCalibrationWindowPosition(4);

                % Calculate the left position for the new window to place it to the right
                newWindowLeft = mainPosition(1) - CalibrationWindowWidth - 10; % 10-pixel gap to the right of MainApp
                newWindowBottom = mainPosition(2)*2; % Align vertically with MainApp

                % Set only the left and bottom position to place it next to MainApp
                app.CalibrationWindow_Overall.UIFigure.Position = [newWindowLeft, newWindowBottom, CalibrationWindowWidth, CalibrationWindowHeight];

                % Pass the Main App reference to OpenImageSlide
                app.CalibrationWindow_Overall.setMainApp(app.MainApp, app.ParentSubWindow, app); % Pass current 'app' as a reference
                app.CalibrationWindow_Overall.Initial_dataset(app.Selected_region_averaged_image,app.Selected_region_std_image, Calibration_ROImask_intial); % initial data set.
                
                %app.CalibrationWindow.setMagnitudeImage(magnitudeImage);
                app.MainApp.logMessage('[System info] Open Calibration window for manually set ROI stationary tissue segmentation!');
            else
                disp('Calibration window is already open.');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalibrationMenu = uimenu(app.UIFigure);
            app.CalibrationMenu.Text = 'Calibration';
            app.ManualSelection_withinROIMenu = uimenu(app.CalibrationMenu);
            app.ManualSelection_withinROIMenu.MenuSelectedFcn = createCallbackFcn(app, @ManualSelection_withinROIMenuSelected, true);
            app.ManualSelection_withinROIMenu.Text = 'ManualSelection_withinROI';
            app.ManualSelection_overall_areaMenu = uimenu(app.CalibrationMenu);
            app.ManualSelection_overall_areaMenu.MenuSelectedFcn = createCallbackFcn(app, @ManualSelection_overall_areaMenuSelected, true);
            app.ManualSelection_overall_areaMenu.Text = 'ManualSelection_overall_area';
            app.UIAxes_selectedROI = uiaxes(app.UIFigure);
            title(app.UIAxes_selectedROI, 'Title')
            xlabel(app.UIAxes_selectedROI, 'X')
            ylabel(app.UIAxes_selectedROI, 'Y')
            zlabel(app.UIAxes_selectedROI, 'Z')
            app.UIAxes_maskROI = uiaxes(app.UIFigure);
            title(app.UIAxes_maskROI, 'Title')
            xlabel(app.UIAxes_maskROI, 'X')
            ylabel(app.UIAxes_maskROI, 'Y')
            zlabel(app.UIAxes_maskROI, 'Z')
            app.UIAxes_Temporal = uiaxes(app.UIFigure);
            title(app.UIAxes_Temporal, 'Title')
            xlabel(app.UIAxes_Temporal, 'X')
            ylabel(app.UIAxes_Temporal, 'Y')
            zlabel(app.UIAxes_Temporal, 'Z')
            app.UIAxes_Spectral = uiaxes(app.UIFigure);
            title(app.UIAxes_Spectral, 'Title')
            xlabel(app.UIAxes_Spectral, 'X')
            ylabel(app.UIAxes_Spectral, 'Y')
            zlabel(app.UIAxes_Spectral, 'Z')
            app.UIAxes_Temporal_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_Temporal_2, 'Title')
            xlabel(app.UIAxes_Temporal_2, 'X')
            ylabel(app.UIAxes_Temporal_2, 'Y')
            zlabel(app.UIAxes_Temporal_2, 'Z')
            app.UIAxes_Temporal_Resp = uiaxes(app.UIFigure);
            title(app.UIAxes_Temporal_Resp, 'Title')
            xlabel(app.UIAxes_Temporal_Resp, 'X')
            ylabel(app.UIAxes_Temporal_Resp, 'Y')
            zlabel(app.UIAxes_Temporal_Resp, 'Z')
            app.UIAxes_Temporal_Cardio = uiaxes(app.UIFigure);
            title(app.UIAxes_Temporal_Cardio, 'Title')
            xlabel(app.UIAxes_Temporal_Cardio, 'X')
            ylabel(app.UIAxes_Temporal_Cardio, 'Y')
            zlabel(app.UIAxes_Temporal_Cardio, 'Z')
            app.SignaloverlaynumberSpinnerLabel = uilabel(app.UIFigure);
            app.SignaloverlaynumberSpinnerLabel.Text = 'Signal overlay number';
            app.SignaloverlaynumberSpinner = uispinner(app.UIFigure);
            app.SignaloverlaynumberSpinner.ValueChangedFcn = createCallbackFcn(app, @SignaloverlaynumberSpinnerValueChanged, true);
            app.ColorbarhighEditFieldLabel = uilabel(app.UIFigure);
            app.ColorbarhighEditFieldLabel.Text = 'Colorbar high';
            app.ColorbarhighEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColorbarhighEditField.ValueChangedFcn = createCallbackFcn(app, @ColorbarhighEditFieldValueChanged, true);
            app.ColorbarlowEditFieldLabel = uilabel(app.UIFigure);
            app.ColorbarlowEditFieldLabel.Text = 'Colorbar low';
            app.ColorbarlowEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColorbarlowEditField.ValueChangedFcn = createCallbackFcn(app, @ColorbarlowEditFieldValueChanged, true);
            app.AnalysistypeButtonGroup = uibuttongroup(app.UIFigure);
            app.AnalysistypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AnalysistypeButtonGroupSelectionChanged, true);
            app.AnalysistypeButtonGroup.Title = 'Analysis type';
            app.SinglesignaloverlayButton = uiradiobutton(app.AnalysistypeButtonGroup);
            app.SinglesignaloverlayButton.Text = 'Single signal overlay';
            app.SinglesignaloverlayButton.Value = true;
            app.OverallmasksignalButton = uiradiobutton(app.AnalysistypeButtonGroup);
            app.OverallmasksignalButton.Text = 'Overall mask signal';
            app.AveragesignalButton = uiradiobutton(app.AnalysistypeButtonGroup);
            app.AveragesignalButton.Text = 'Average signal';
            app.TemporalsignalWithinROILabel = uilabel(app.UIFigure);
            app.TemporalsignalWithinROILabel.Text = 'Temporal signal (Within ROI)';
            app.SpectralsignalWithinROILabel = uilabel(app.UIFigure);
            app.SpectralsignalWithinROILabel.Text = 'Spectral signal (Within ROI)';
            app.LoadPhysiologysignalButton = uibutton(app.UIFigure, 'push');
            app.LoadPhysiologysignalButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPhysiologysignalButtonPushed, true);
            app.LoadPhysiologysignalButton.Text = 'Load Physiology signal';
            app.TemporalPCMRIsignalLabel = uilabel(app.UIFigure);
            app.TemporalPCMRIsignalLabel.Text = 'Temporal PCMRI signal';
            app.RespirationbeltsignalLabel = uilabel(app.UIFigure);
            app.RespirationbeltsignalLabel.Text = 'Respiration belt signal';
            app.PPGsignalLabel = uilabel(app.UIFigure);
            app.PPGsignalLabel.Text = 'PPG signal';
            app.SaveDatasetforanalyzingdataButton = uibutton(app.UIFigure, 'push');
            app.SaveDatasetforanalyzingdataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDatasetforanalyzingdataButtonPushed, true);
            app.SaveDatasetforanalyzingdataButton.Text = {'Save '; 'Dataset for analyzing data'};
            app.SudoROIeditLabel = uilabel(app.UIFigure);
            app.SudoROIeditLabel.Text = 'Sudo ROI edit';
            app.ROIedit_include = uibutton(app.UIFigure, 'push');
            app.ROIedit_include.ButtonPushedFcn = createCallbackFcn(app, @ROIedit_includeButtonPushed, true);
            app.ROIedit_include.Text = '+';
            app.ROIedit_remove = uibutton(app.UIFigure, 'push');
            app.ROIedit_remove.ButtonPushedFcn = createCallbackFcn(app, @ROIedit_removeButtonPushed, true);
            app.ROIedit_remove.Text = '-';
            app.ROImaskpixelcountEditFieldLabel = uilabel(app.UIFigure);
            app.ROImaskpixelcountEditFieldLabel.Text = 'ROI mask pixel count';
            app.ROImaskpixelcountEditField = uieditfield(app.UIFigure, 'numeric');
            app.Calibration_withinROIEditFieldLabel = uilabel(app.UIFigure);
            app.Calibration_withinROIEditFieldLabel.Text = 'Calibration_withinROI';
            app.Calibration_withinROIEditField = uieditfield(app.UIFigure, 'text');
            app.Calibration_withinROIEditField.Value = 'None';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '--------------------------------------------------------------';
            app.Calibration_overallEditFieldLabel = uilabel(app.UIFigure);
            app.Calibration_overallEditFieldLabel.Text = 'Calibration_overall';
            app.Calibration_overallEditField = uieditfield(app.UIFigure, 'text');
            app.Calibration_overallEditField.Value = 'None';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Analysis_further_window

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end