classdef Analysis_signals_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TotalTemporalSignalPanel        matlab.ui.container.Panel
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        UnitsButtonGroup                matlab.ui.container.ButtonGroup
        mlsButton                       matlab.ui.control.RadioButton
        cmsButton                       matlab.ui.control.RadioButton
        UIAxes2Panel                    matlab.ui.container.Panel
        EditvalleySwitch                matlab.ui.control.Switch
        EditvalleySwitchLabel           matlab.ui.control.Label
        EditpeakSwitch                  matlab.ui.control.Switch
        EditpeakLabel                   matlab.ui.control.Label
        CSFsignaltypeButtonGroup        matlab.ui.container.ButtonGroup
        CalibrationButton               matlab.ui.control.RadioButton
        OriginButton                    matlab.ui.control.RadioButton
        Label_4                         matlab.ui.control.Label
        EditpeakvalleypointButton       matlab.ui.control.Button
        Pkdetect_1_EditField_14         matlab.ui.control.NumericEditField
        MinPeakHeightLabel_14           matlab.ui.control.Label
        Pkdetect_1_EditField_13         matlab.ui.control.NumericEditField
        MinPeakHeightLabel_13           matlab.ui.control.Label
        Pkdetect_1_EditField_12         matlab.ui.control.NumericEditField
        MinPeakHeightLabel_12           matlab.ui.control.Label
        Pkdetect_1_EditField_11         matlab.ui.control.NumericEditField
        MinPeakHeightLabel_11           matlab.ui.control.Label
        PeakLabel_3                     matlab.ui.control.Label
        ValleyLabel_3                   matlab.ui.control.Label
        PeakdetectionparametersLabel_3  matlab.ui.control.Label
        SecondsteppeakvalleydetectionRESPButton  matlab.ui.control.Button
        SecondstepSettingPeakValleypointbasedonthefilteredsignalLabel  matlab.ui.control.Label
        FirststepSetfilteredrespsignalLabel  matlab.ui.control.Label
        Pkdetect_1_EditField_10         matlab.ui.control.NumericEditField
        MinPeakHeightLabel_10           matlab.ui.control.Label
        Pkdetect_1_EditField_9          matlab.ui.control.NumericEditField
        MinPeakHeightLabel_9            matlab.ui.control.Label
        Pkdetect_1_EditField_8          matlab.ui.control.NumericEditField
        MinPeakHeightLabel_8            matlab.ui.control.Label
        Pkdetect_1_EditField_7          matlab.ui.control.NumericEditField
        MinPeakHeightLabel_7            matlab.ui.control.Label
        PeakLabel_2                     matlab.ui.control.Label
        ValleyLabel_2                   matlab.ui.control.Label
        PeakdetectionparametersLabel_2  matlab.ui.control.Label
        RedopeakdetectionPPGButton      matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        PeakLabel                       matlab.ui.control.Label
        Pkdetect_1_EditField_6          matlab.ui.control.NumericEditField
        MinPeakHeightLabel_6            matlab.ui.control.Label
        Pkdetect_1_EditField_5          matlab.ui.control.NumericEditField
        MinPeakHeightLabel_5            matlab.ui.control.Label
        ValleyLabel                     matlab.ui.control.Label
        SpectrumfilterLabel             matlab.ui.control.Label
        Pkdetect_1_EditField_4          matlab.ui.control.NumericEditField
        MinPeakHeightLabel_4            matlab.ui.control.Label
        Pkdetect_1_EditField_3          matlab.ui.control.NumericEditField
        MinPeakHeightLabel_3            matlab.ui.control.Label
        Pkdetect_1_EditField_2          matlab.ui.control.NumericEditField
        MinPeakHeightLabel_2            matlab.ui.control.Label
        Pkdetect_1_EditField            matlab.ui.control.NumericEditField
        MinPeakHeightLabel              matlab.ui.control.Label
        PeakdetectionparametersLabel    matlab.ui.control.Label
        RedopeakvalleydetectionRESPButton  matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        ProcessingpipelinePanel         matlab.ui.container.Panel
        SaveAllDatasetButton            matlab.ui.control.Button
        CalibrationmethodButtonGroup    matlab.ui.container.ButtonGroup
        BothButton                      matlab.ui.control.RadioButton
        DetrendButton                   matlab.ui.control.RadioButton
        CalibrationButton_2             matlab.ui.control.RadioButton
        ChoosecalibrationmethodLabel    matlab.ui.control.Label
        ProcessingCSFwithinonebreathingcycleButton  matlab.ui.control.Button
        ProcessingCSFwithRESPButton     matlab.ui.control.Button
        PeakdetectionPPGButton          matlab.ui.control.Button
        PeakValleydetectionRespButton   matlab.ui.control.Button
        PPGsignalLabel                  matlab.ui.control.Label
        RespirationbeltsignalLabel      matlab.ui.control.Label
        TemporalPCMRIsignalLabel        matlab.ui.control.Label
        LoadPreprocessddatasetButton    matlab.ui.control.Button
        UIAxes6_4                       matlab.ui.control.UIAxes
        UIAxes6_3                       matlab.ui.control.UIAxes
        UIAxes6_2                       matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes2_5                       matlab.ui.control.UIAxes
        UIAxes2_4                       matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_Temporal_Cardio          matlab.ui.control.UIAxes
        UIAxes_Temporal_Resp            matlab.ui.control.UIAxes
        UIAxes_Temporal                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % ============================================================
        % Analysis / I/O
        % ============================================================
        PreprocessedData_GUI
        RespDetectResults
        Calibration_dataset
        SaveDirectory
        % ============================================================
        % Peak/Valley (PV) manual editing state
        % ============================================================
        IsEditPVMode logical = false

        % Which point type is currently being edited:
        %   'peak'   : peaks
        %   'valley' : valleys
        %   ''       : none / idle
        PVEditTarget char = ''

        % Reference respiration signal used for editing (filtered)
        Resp_t double = []
        Resp_filt double = []
        Resp_Fs double = []

        % ============================================================
        % Final (post-edit) peak/valley outputs
        % ============================================================
        Peak_locs_final double = []
        Peaks_final double = []
        Valley_locs_final double = []
        Valleys_final double = []

        % ============================================================
        % Plot handles (for efficient refresh without re-plotting)
        % ============================================================
        PeakPlotHandle matlab.graphics.chart.primitive.Line = matlab.graphics.chart.primitive.Line.empty
        ValleyPlotHandle matlab.graphics.chart.primitive.Line = matlab.graphics.chart.primitive.Line.empty

        % ============================================================
        % Interaction thresholds (all in seconds)
        % ============================================================
        MoveTolSec double = 0.30         % Within this distance of an existing point -> treat as "move"
        SnapWinSec double = 0.30      % Search radius for local max/min snapping
        MaxSnapDistSec double = 0.80  % If nearest candidate is farther than this -> ignore click
        RemoveTolSec double = 0.50    % Remove only when click is near an existing point
        DuplicateTolSec double = 0.20 % Prevent adding points too close to existing ones

        HasUserEditedPV logical = false   % Set true once any add/move/remove occurs
        
        % ============================================================
        % Axes / UI references
        % ============================================================
        SummaryAxes
        TotalTemporalAxes10
        popupApp_csfone

        % ============================================================
        % File metadata for provenance
        % ============================================================
        file_name_loaded
        file_path_name_loaded

    end
    
    properties (Access = public)
        MainApp
    end
    
    methods (Access = public)
        function setMainApp(app, mainAppReference)
            app.MainApp = mainAppReference;
            % Set the close request callback
            app.UIFigure.CloseRequestFcn = @(src, event) app.closeRequest(event);
        end


        function startupFcn(app)

            % ------------------------------------------------------------
            % Initialize default parameters
            % (consistent with the default parameters defined in the script)
            % ------------------------------------------------------------

            % --- Step 1: Parameters for raw respiration signal
            app.Pkdetect_1_EditField.Value   = 0.5;  % MinPeakHeight Peak
            app.Pkdetect_1_EditField_2.Value = 7;    % MinPeakDistance Peak
            app.Pkdetect_1_EditField_3.Value = 0.5;  % MinPeakHeight Valley
            app.Pkdetect_1_EditField_4.Value = 7;    % MinPeakDistance Valley
            app.Pkdetect_1_EditField_5.Value = 3;    % Spectrum upper
            app.Pkdetect_1_EditField_6.Value = 0.5;  % Spectrum lower

            % --- Step 2: Parameters for filtered respiration signal
            app.Pkdetect_1_EditField_11.Value = 0.5;  % MinPeakHeight (Peak) filtered
            app.Pkdetect_1_EditField_12.Value = 7;    % MinPeakDistance (Peak) filtered
            app.Pkdetect_1_EditField_13.Value = 0.5;  % MinPeakHeight (Valley) filtered
            app.Pkdetect_1_EditField_14.Value = 7;    % MinPeakDistance (Valley) filtered

        end

        function initializeButtons_forfurtheranalysis(app)
            % Disable buttons initially
            app.ProcessingCSFwithRESPButton.Enable = 'off';
            app.ProcessingCSFwithinonebreathingcycleButton.Enable = 'off';
            app.RedopeakvalleydetectionRESPButton.Enable = 'off';
            app.SecondsteppeakvalleydetectionRESPButton.Enable = 'off';
            app.EditpeakvalleypointButton.Enable = 'off';
            app.RedopeakdetectionPPGButton.Enable = 'off';
        end

        function [calData, saveDirName] = getCalibrationDataset(app)
            % Assume that `PreprocessedData_GUI` is a structure previously saved from the GUI
            % and contains preprocessed ROI-based temporal signals.
            preprocessed_vars = app.PreprocessedData_GUI;

            selectedBtn = app.CalibrationmethodButtonGroup.SelectedObject;

            if selectedBtn == app.CalibrationButton_2
                % set 1: Reference regions
                calData = preprocessed_vars.Mean_temporal_signal_ROI;
                saveDirName = 'MATLAB_CSF_analysis_datamat_Cali_Reference_region';

            elseif selectedBtn == app.DetrendButton
                % set 2: Detrend (origin)
                calData = detrend(preprocessed_vars.Mean_temporal_signal_ROI_origin);
                saveDirName = 'MATLAB_CSF_analysis_datamat_Cali_Detrend';

            elseif selectedBtn == app.BothButton
                % set 3: Reference regions + Detrend
                calData = detrend(preprocessed_vars.Mean_temporal_signal_ROI);
                saveDirName = 'MATLAB_CSF_analysis_datamat_Cali_Reference_region_Detrend';

            else
                % fallback
                calData = preprocessed_vars.Mean_temporal_signal_ROI_origin;
                saveDirName = 'MATLAB_CSF_analysis_datamat_Default';
            end
        end

        function params = getRespDetectParams(app)
            params.MinPeakHeight_peak   = app.Pkdetect_1_EditField.Value;
            params.MinPeakDistance_peak = app.Pkdetect_1_EditField_2.Value;
            params.MinPeakHeight_valley = app.Pkdetect_1_EditField_3.Value;
            params.MinPeakDistance_valley = app.Pkdetect_1_EditField_4.Value;
            params.SpecUpper = app.Pkdetect_1_EditField_5.Value;
            params.SpecLower = app.Pkdetect_1_EditField_6.Value;

            if params.MinPeakDistance_peak <= 0 || params.MinPeakDistance_valley <= 0
                error('MinPeakDistance must be > 0.');
            end
            if params.SpecUpper <= params.SpecLower
                error('Spectrum upper limit must be greater than lower limit.');
            end
        end

        function params2 = getRespDetectParamsFiltered(app)
            params2.MinPeakHeight_peak   = app.Pkdetect_1_EditField_11.Value;
            params2.MinPeakDistance_peak = app.Pkdetect_1_EditField_12.Value;
            params2.MinPeakHeight_valley = app.Pkdetect_1_EditField_13.Value;
            params2.MinPeakDistance_valley = app.Pkdetect_1_EditField_14.Value;

            if params2.MinPeakDistance_peak <= 0 || params2.MinPeakDistance_valley <= 0
                error('2nd-step MinPeakDistance must be > 0.');
            end
        end


        function runPeakValleyDetectionResp(app)

            % =========================================================
            % (A) Map / cache calibration dataset (optional for later use)
            % =========================================================
            [calData, saveDirName] = app.getCalibrationDataset();
            app.Calibration_dataset = calData;

            % =========================================================
            % (B) Read peak/valley detection parameters from GUI
            % =========================================================
            params = app.getRespDetectParams();

            % =========================================================
            % (C) Load synced respiration signal and its time vector
            % =========================================================
            Value_Resp_Zscore_comp = app.PreprocessedData_GUI.Scalenorm_RESP_synced;
            Time_resp_new          = app.PreprocessedData_GUI.Time_synced_Physio;

            % =========================================================
            % (D) Safety checks
            %     - Ensure vectors exist and match length
            %     - Ensure time is monotonic increasing (required by findpeaks with time)
            % =========================================================
            if isempty(Value_Resp_Zscore_comp) || isempty(Time_resp_new)
                error('Respiration data or time vector is empty.');
            end

            % =========================================================
            % (E) Peak / Valley detection on respiration signal
            %     - Peaks: local maxima
            %     - Valleys: local minima (detected as peaks on the negated signal)
            % =========================================================
            [peaks, peak_locs] = findpeaks(Value_Resp_Zscore_comp, Time_resp_new, ...
                'MinPeakHeight', params.MinPeakHeight_peak, ...
                'MinPeakDistance', params.MinPeakDistance_peak);

            [valleys_neg, valley_locs] = findpeaks(-Value_Resp_Zscore_comp, Time_resp_new, ...
                'MinPeakHeight', params.MinPeakHeight_valley, ...
                'MinPeakDistance', params.MinPeakDistance_valley);

            valleys = -valleys_neg;

            % =========================================================
            % (F) Store detection results (for redo / manual editing)
            % =========================================================
            app.RespDetectResults = struct();
            app.RespDetectResults.peaks = peaks;
            app.RespDetectResults.peak_locs = peak_locs;
            app.RespDetectResults.valleys = valleys;
            app.RespDetectResults.valley_locs = valley_locs;
            app.RespDetectResults.params = params;

            % =========================================================
            % (G) Plot respiration + detected peaks/valleys in the GUI
            % =========================================================
            cla(app.UIAxes);
            plot(app.UIAxes, Time_resp_new, Value_Resp_Zscore_comp, 'LineWidth', 1.5); hold(app.UIAxes, 'on');
            axis(app.UIAxes, 'tight');
            plot(app.UIAxes, peak_locs, peaks, 'ro', 'MarkerFaceColor', 'r');
            plot(app.UIAxes, valley_locs, valleys, 'go', 'MarkerFaceColor', 'g');
            hold(app.UIAxes, 'off');            
            grid(app.UIAxes, 'on');
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Respiration Signal (z)');
            title(app.UIAxes, 'Peak & Valley Detection (Resp)');

            % =========================================================
            % (H) Next step: spectrum-based filtering (user-defined)
            % =========================================================
            app.runSpectrumFilteringResp();
        end

        function runSpectrumFilteringResp(app)
            %==========================================================================
            % Spectrum-based respiration filtering
            %
            % Inputs (expected):
            %   app.PreprocessedData_GUI.Scalenorm_RESP_synced     : synced respiration (z-scored)
            %   app.PreprocessedData_GUI.Time_synced_Physio        : time vector (sec)
            %   app.PreprocessedData_GUI.Physio_Fs                 : sampling rate (Hz)
            %   app.RespDetectResults.peak_locs / valley_locs      : timestamps (sec)
            %   app.RespDetectResults.params.SpecUpper / SpecLower : band ratios from GUI
            %
            % Outputs (saved into app.PreprocessedData_GUI):
            %   Resp_estimatedRate_BPM, Resp_estimatedFreq_Hz
            %   Resp_filterBand_Hz, Resp_spectrum_freq, Resp_spectrum_power
            %   Resp_filtered
            %==========================================================================

            % -------------------------
            % (A) Load data
            % -------------------------
            x = app.PreprocessedData_GUI.Scalenorm_RESP_synced;
            t = app.PreprocessedData_GUI.Time_synced_Physio;
            Fs = app.PreprocessedData_GUI.Physio_Fs;
            % Basic validation
            if isempty(x) || isempty(t) || isempty(Fs) || Fs <= 0
                error('Resp signal/time/Fs are not valid.');
            end

            %-------------------------
            % (B) Load GUI parameters (reuse params saved during PV detection if available)
            %-------------------------
            if ~isfield(app.RespDetectResults,'params') || isempty(app.RespDetectResults.params)
                params = app.getRespDetectParams();
            else
                params = app.RespDetectResults.params;
            end

            SpecUpper = params.SpecUpper; % e.g., 3.0  (upper ratio around f0)
            SpecLower = params.SpecLower; % e.g., 0.5  (lower ratio around f0)

            %-------------------------
            % (C) Choose rate estimation anchor: Peak-based or Valley-based
            %   1 = peak timestamps, 2 = valley timestamps
            %-------------------------
            Spectrum_using_peak_or_valley = 2;

            if Spectrum_using_peak_or_valley == 1
                locs = app.RespDetectResults.peak_locs;
                methodName = 'Peak';
            else
                locs = app.RespDetectResults.valley_locs;
                methodName = 'Valley';
            end

            if isempty(locs) || numel(locs) < 2
                error('Not enough peak/valley locations to estimate respiration rate.');
            end

            %-------------------------
            % (D) Estimate respiration frequency from PV intervals
            %-------------------------
            intervals = diff(locs);
            avg_period = mean(intervals, 'omitnan');

            if isempty(avg_period) || avg_period <= 0
                error('Invalid breathing period estimated from peak/valley locations.');
            end

            resp_rate_BPM = 60 / avg_period;
            resp_freq_Hz  = resp_rate_BPM / 60;

            %-------------------------
            % (E) Compute spectrum (FFT) to find closest discrete bin to resp_freq_Hz
            %-------------------------
            N = numel(x);
            freq_spec = (0:N-1) * (Fs / N);
            spec_Resp = fft(x);
            Pxx = abs(spec_Resp).^2;

            % Find the nearest frequency bin to the estimated respiration frequency
            [~, idx0] = min(abs(freq_spec - resp_freq_Hz));
            f0 = freq_spec(idx0);

            %-------------------------
            % (F) Define band around f0 using ratio-based rule (legacy logic)
            %   fHigh = f0 + f0*SpecUpper
            %   fLow  = f0 - f0*SpecLower
            %-------------------------
            fHigh = f0 + f0 * SpecUpper;
            fLow  = f0 - f0 * SpecLower;

            %-------------------------
            % (G) Safety clamp (critical for filter stability)
            %   - enforce [>0, <Nyquist]
            %   - enforce fHigh > fLow with a minimum bandwidth
            %-------------------------
            nyq = Fs/2;

            fLow  = max(0.001, fLow);
            fHigh = min(nyq-0.001, fHigh);

            if fHigh <= fLow
                fLow  = max(0.001, f0 - 0.05);
                fHigh = min(nyq-0.001, f0 + 0.05);
            end

            if fHigh >= nyq
                fHigh = nyq - 0.001;
            end
            if fLow <= 0
                fLow = 0.001;
            end

            % -------------------------
            % (F) Bandpass filter (butter + filtfilt)
            % -------------------------
            order = 2;
            Wn = [fLow fHigh] / nyq;
            [b, a] = butter(order, Wn, 'bandpass');

            x_filt = filtfilt(b, a, x);

            % -------------------------
            % (G) Save results back to app
            % -------------------------
            app.PreprocessedData_GUI.Resp_estimatedRate_BPM = resp_rate_BPM;
            app.PreprocessedData_GUI.Resp_estimatedFreq_Hz  = resp_freq_Hz;
            app.PreprocessedData_GUI.Resp_filterBand_Hz     = [fLow fHigh];
            app.PreprocessedData_GUI.Resp_spectrum_freq     = freq_spec;
            app.PreprocessedData_GUI.Resp_spectrum_power    = Pxx;
            app.PreprocessedData_GUI.Resp_filtered          = x_filt;

            % -------------------------
            % (H) Plot in UI (no figure/saveas)
            % -------------------------

            % Spectrum plot
            if isprop(app,'UIAxes_2') && ~isempty(app.UIAxes_2)
                axS = app.UIAxes_2;
                cla(axS);
                plot(axS, freq_spec, Pxx, 'LineWidth', 1.2); hold(axS,'on');

                % Highlight selected band
                idxBand = (freq_spec >= fLow) & (freq_spec <= fHigh);
                plot(axS, freq_spec(idxBand), Pxx(idxBand), 'ro-', 'MarkerFaceColor', 'r', 'LineWidth', 1);

                hold(axS,'off');
                xlim(axS, [0 2]); % show 0~2 Hz window (adjust as needed)
                xlabel(axS, 'Frequency (Hz)');
                ylabel(axS, 'Power (a.u.)');
                title(axS, sprintf('Resp Spectrum (%s-based): f0=%.3fHz, band=[%.3f %.3f]Hz', ...
                    methodName, f0, fLow, fHigh));
                grid(axS,'on');
            end

            % Plot time-domain (raw vs filtered) + PV markers (UIAxes)
            axT = app.UIAxes;
            if ~isempty(axT)
                cla(axT);
                plot(axT, t, x, 'LineWidth', 1.2); hold(axT,'on');
                plot(axT, t, x_filt, 'r', 'LineWidth', 2);
                plot(app.UIAxes, app.RespDetectResults.peak_locs, app.RespDetectResults.peaks, 'ro', 'MarkerFaceColor', 'r');
                plot(app.UIAxes, app.RespDetectResults.valley_locs, app.RespDetectResults.valleys, 'go', 'MarkerFaceColor', 'g');
                hold(axT,'off');
                axis(axT,'tight');
                xlabel(axT,'Time (s)');
                ylabel(axT,'Resp signal (z)');
                title(axT, sprintf('Filtered Resp (band=[%.3f %.3f]Hz, %.1f BPM)', fLow, fHigh, resp_rate_BPM));
                grid(axT,'on');
            end

        end


        function runSecondStepPeakValleyDetectionResp(app)

            % -------------------------
            % (A) prerequisite check
            % -------------------------
            if ~isfield(app.PreprocessedData_GUI,'Resp_filtered') || isempty(app.PreprocessedData_GUI.Resp_filtered)
                error('Filtered respiration signal is missing. Run filtering first.');
            end

            x_raw  = app.PreprocessedData_GUI.Scalenorm_RESP_synced;
            x_filt = app.PreprocessedData_GUI.Resp_filtered;
            t      = app.PreprocessedData_GUI.Time_synced_Physio;

            if isempty(x_raw) || isempty(x_filt) || isempty(t)
                error('Resp signal/time is empty.');
            end

            peak_locs_backup   = [];
            peaks_backup       = [];
            valley_locs_backup = [];
            valleys_backup     = [];
            if isfield(app,'RespDetectResults') && ~isempty(app.RespDetectResults)
                if isfield(app.RespDetectResults,'peak_locs');   peak_locs_backup = app.RespDetectResults.peak_locs; end
                if isfield(app.RespDetectResults,'peaks');       peaks_backup     = app.RespDetectResults.peaks; end
                if isfield(app.RespDetectResults,'valley_locs'); valley_locs_backup = app.RespDetectResults.valley_locs; end
                if isfield(app.RespDetectResults,'valleys');     valleys_backup     = app.RespDetectResults.valleys; end
            end

            % -------------------------
            % (B) params from GUI (EditField 11~14)
            % -------------------------
            params2 = app.getRespDetectParamsFiltered();

            % -------------------------
            % (C) peak/valley detection on filtered signal
            % -------------------------
            [peaks2, peak_locs2] = findpeaks(x_filt, t, ...
                'MinPeakHeight', params2.MinPeakHeight_peak, ...
                'MinPeakDistance', params2.MinPeakDistance_peak);

            [valleys2_neg, valley_locs2] = findpeaks(-x_filt, t, ...
                'MinPeakHeight', params2.MinPeakHeight_valley, ...
                'MinPeakDistance', params2.MinPeakDistance_valley);

            valleys2 = -valleys2_neg;

            % -------------------------
            % (D) indices + interval stats
            % -------------------------
            peak_indices2 = arrayfun(@(x) find(t >= x, 1, 'first'), peak_locs2);
            valley_indices2 = arrayfun(@(x) find(t >= x, 1, 'first'), valley_locs2);

            Times_peak2  = t(peak_indices2);
            Times_valley2 = t(valley_indices2);

            diff_peak_locs2   = diff(peak_locs2);
            diff_valley_locs2 = diff(valley_locs2);

            stats2 = struct();
            stats2.mean_peak_period   = mean(diff_peak_locs2, 'omitnan');
            stats2.std_peak_period    = std(diff_peak_locs2,  'omitnan');
            stats2.mean_valley_period = mean(diff_valley_locs2,'omitnan');
            stats2.std_valley_period  = std(diff_valley_locs2, 'omitnan');

            % -------------------------
            % (E) save results for downstream
            % -------------------------
            app.PreprocessedData_GUI.Resp_secondStep = struct();
            app.PreprocessedData_GUI.Resp_secondStep.peaks = peaks2;
            app.PreprocessedData_GUI.Resp_secondStep.peak_locs = peak_locs2;
            app.PreprocessedData_GUI.Resp_secondStep.valleys = valleys2;
            app.PreprocessedData_GUI.Resp_secondStep.valley_locs = valley_locs2;
            app.PreprocessedData_GUI.Resp_secondStep.peak_indices = peak_indices2;
            app.PreprocessedData_GUI.Resp_secondStep.valley_indices = valley_indices2;
            app.PreprocessedData_GUI.Resp_secondStep.Times_peak = Times_peak2;
            app.PreprocessedData_GUI.Resp_secondStep.Times_valley = Times_valley2;
            app.PreprocessedData_GUI.Resp_secondStep.stats = stats2;
            app.PreprocessedData_GUI.Resp_secondStep.params = params2;

            % -------------------------
            % (F) plot in UIAxes (overlay raw + backup + filtered + new)
            % -------------------------
            ax = app.UIAxes_4;
            cla(ax);

            % raw (black)
            plot(ax, t, x_raw, 'k', 'LineWidth', 2); hold(ax, 'on');

            % 1st step backup (if exists)
            if ~isempty(peak_locs_backup)
                plot(ax, peak_locs_backup, peaks_backup, 'ko', 'DisplayName', 'Peaks origin');
            end
            if ~isempty(valley_locs_backup)
                plot(ax, valley_locs_backup, valleys_backup, 'ks', 'DisplayName', 'Valleys origin');
            end

            % filtered signal (orange-ish)
            plot(ax, t, x_filt, 'LineWidth', 2);

            % 2nd step result (red/blue markers)
            plot(ax, peak_locs2, peaks2, 'ro', 'MarkerFaceColor','r', 'DisplayName', 'Peaks (filtered)');
            plot(ax, valley_locs2, valleys2, 'bo', 'MarkerFaceColor','b', 'DisplayName', 'Valleys (filtered)');

            hold(ax, 'off');
            grid(ax, 'on');
            xlabel(ax, 'Time (s)');
            ylabel(ax, 'Respiration Signal');
            title(ax, sprintf('2nd-step Peak/Valley (filtered) | mean period: peak %.2fs, valley %.2fs', ...
                stats2.mean_peak_period, stats2.mean_valley_period));

            legend(ax, 'Location', 'northeastoutside');

            if ~isempty(app.MainApp)
                app.MainApp.logMessage(sprintf('[2nd-step Resp] peaks=%d, valleys=%d', numel(peaks2), numel(valleys2)));
            end

            app.Peak_locs_final   = peak_locs2(:)';
            app.Peaks_final       = peaks2(:)';
            app.Valley_locs_final = valley_locs2(:)';
            app.Valleys_final     = valleys2(:)';

            app.HasUserEditedPV = false;
        end


        function updatePVEditTargetFromSwitches(app)

            peakOn   = strcmpi(app.EditpeakSwitch.Value, 'On');
            valleyOn = strcmpi(app.EditvalleySwitch.Value, 'On');

            if peakOn && ~valleyOn
                app.PVEditTarget = 'peak';
            elseif valleyOn && ~peakOn
                app.PVEditTarget = 'valley';
            elseif peakOn && valleyOn
                % If both switches are ON, the UI may become ambiguous.
                % In this case, keep only one target active by priority.
                % If desired, this logic can be refined so that the most
                % recently changed switch determines the target.
                app.PVEditTarget = 'peak'; % Default priority (can be changed to 'valley')
            else
                app.PVEditTarget = '';
            end

            % Update guidance text on the axes (optional)
            ax = app.UIAxes_4;
            title(ax, sprintf('Edit Mode ON | Target: %s | Left=Add, Right=Remove', app.PVEditTarget));
        end

        function preparePVEditSession(app)

            % ------------------------------------------------------------
            % Prepare the filtered respiration signal and its time base
            % ------------------------------------------------------------
            app.Resp_t    = app.PreprocessedData_GUI.Time_synced_Physio;
            app.Resp_filt = app.PreprocessedData_GUI.Resp_filtered;
            app.Resp_Fs   = app.PreprocessedData_GUI.Physio_Fs;

            if isempty(app.Resp_t) || isempty(app.Resp_filt)
                error('Filtered respiration/time missing. Run filtering + 2nd-step detection first.');
            end

            % ------------------------------------------------------------
            % Load the existing "2nd-step" detection results as the initial
            % set of FINAL (user-editable) peak/valley points.
            % If no prior results exist, initialize to empty arrays.
            % ------------------------------------------------------------
            if isfield(app.PreprocessedData_GUI,'Resp_secondStep') && ~isempty(app.PreprocessedData_GUI.Resp_secondStep)
                s = app.PreprocessedData_GUI.Resp_secondStep;

                app.Peak_locs_final   = s.peak_locs(:)';
                app.Peaks_final       = s.peaks(:)';

                app.Valley_locs_final = s.valley_locs(:)';
                app.Valleys_final     = s.valleys(:)';

                [app.Peak_locs_final, ip] = sort(app.Peak_locs_final);   app.Peaks_final   = app.Peaks_final(ip);
                [app.Valley_locs_final, iv] = sort(app.Valley_locs_final); app.Valleys_final = app.Valleys_final(iv);
            else
                app.Peak_locs_final = []; app.Peaks_final = [];
                app.Valley_locs_final = []; app.Valleys_final = [];
            end
        end

        function onPVEditClick(app, src, event)

            if ~app.IsEditPVMode || isempty(app.PVEditTarget)
                return; % Do nothing if edit mode is OFF or no target is selected
            end

            ax = app.UIAxes_4;

            % ------------------------------------------------------------
            % Read click location in axes coordinates and reject out-of-axes clicks
            % ------------------------------------------------------------
            cp = ax.CurrentPoint;
            clickT = cp(1,1);
            clickY = cp(1,2);

            xL = xlim(ax); yL = ylim(ax);
            if clickT < xL(1) || clickT > xL(2) || clickY < yL(1) || clickY > yL(2)
                return;
            end

            % ------------------------------------------------------------
            % Determine mouse button
            % - In uifigure: event.Button (1=left, 3=right)
            % - Fallback: SelectionType ('normal'=left, 'alt'=right)
            % ------------------------------------------------------------
            btn = 1; % default left
            if ~isempty(event) && isprop(event,'Button') && ~isempty(event.Button)
                btn = event.Button;
            else
                % Fallback path for older versions / edge cases
                try
                    sel = get(src, 'SelectionType'); % 'normal'/'alt'
                    if strcmp(sel,'alt'); btn = 3; end
                catch
                end
            end

            if btn == 1
                app.addPVAtClick(clickT);
            elseif btn == 3
                app.removePVAtClick(clickT);
            else
                return;
            end

            app.refreshPVMarkersOnly();
            app.updateFinalPVStats();
        end


        function addPVAtClick(app, clickT)

            t = app.Resp_t;
            x = app.Resp_filt;

            if isempty(t) || isempty(x)
                return;
            end

            % -------------------------
            % 1) Snap to local extremum near the click
            % -------------------------
            [~, idx0] = min(abs(t - clickT));

            win = max(1, round(app.SnapWinSec * app.Resp_Fs));
            i1  = max(1, idx0 - win);
            i2  = min(numel(x), idx0 + win);

            if strcmpi(app.PVEditTarget,'peak')
                [val, rel] = max(x(i1:i2));
            else
                [val, rel] = min(x(i1:i2));
            end

            idx = i1 + rel - 1;
            loc = t(idx);

            % Reject if the snapped extremum is too far from the user's click
            if abs(loc - clickT) > app.MaxSnapDistSec
                return;
            end

            % -------------------------
            % 2) Load current final points for the chosen target
            % -------------------------
            moveTol = app.MoveTolSec;

            if strcmpi(app.PVEditTarget,'peak')
                locs = app.Peak_locs_final;
                vals = app.Peaks_final;
            else
                locs = app.Valley_locs_final;
                vals = app.Valleys_final;
            end

            % -------------------------
            % 3) Decide: move an existing point or add a new one
            %    - if nearest existing point is within MoveTolSec -> move it
            % -------------------------
            editIdx = [];
            if ~isempty(locs)
                [dMin, k] = min(abs(locs - clickT));
                if dMin <= moveTol
                    editIdx = k;  % edit (move) this point
                end
            end

            % -------------------------
            % 4) Duplicate prevention (applies to both add and move)
            %    - Add: reject if any existing point is within DuplicateTolSec
            %    - Move: reject if it would collide with any other point
            % -------------------------
            dupTol = app.DuplicateTolSec;

            if isempty(editIdx)
                % ---- (A) Add a new point ----
                if any(abs(locs - loc) < dupTol)
                    return;
                end
                locs(end+1) = loc;
                vals(end+1) = val;

                app.HasUserEditedPV = true;

            else
                % ---- (B) Move an existing point ----
                other = true(size(locs));
                other(editIdx) = false;  

                if any(abs(locs(other) - loc) < dupTol)
                    return; 
                end

                locs(editIdx) = loc;
                vals(editIdx) = val;

                app.HasUserEditedPV = true;   
            end

            % -------------------------
            % 5) Sort by time and write back to app state
            % -------------------------
            [locs, ord] = sort(locs);
            vals = vals(ord);

            if strcmpi(app.PVEditTarget,'peak')
                app.Peak_locs_final = locs;
                app.Peaks_final     = vals;
            else
                app.Valley_locs_final = locs;
                app.Valleys_final     = vals;
            end
        end

        function removePVAtClick(app, clickT)

            % -------------------------
            % 0) Safety checks
            % -------------------------
            if isempty(app.Resp_t) || isempty(app.Resp_filt) || isempty(app.PVEditTarget)
                return;
            end

            t = app.Resp_t;
            x = app.Resp_filt;

            % -------------------------
            % 1) Snap click time to nearest signal sample
            %    (consistent behavior even if the user clicks off the line)
            % -------------------------
            [dSnap, idx0] = min(abs(t - clickT));
            if dSnap > app.MaxSnapDistSec
                return;
            end
            clickT_snap = t(idx0);

            % -------------------------
            % 2) Select target (peak or valley) and delete nearest point
            % -------------------------
            if strcmpi(app.PVEditTarget,'peak')
                locs = app.Peak_locs_final(:);
                if isempty(locs); return; end

                [d, k] = min(abs(locs - clickT_snap));
                if d > app.RemoveTolSec
                    return; % 너무 멀면 삭제 안함
                end

                locs(k) = [];
                app.Peak_locs_final = locs;

                app.HasUserEditedPV = true;   

                if isempty(app.Peak_locs_final)
                    app.Peaks_final = [];
                else
                    app.Peaks_final = interp1(t, x, app.Peak_locs_final, 'linear', 'extrap');
                end

            else % valley
                locs = app.Valley_locs_final(:);
                if isempty(locs); return; end

                [d, k] = min(abs(locs - clickT_snap));
                if d > app.RemoveTolSec
                    return;
                end

                locs(k) = [];
                app.Valley_locs_final = locs;

                app.HasUserEditedPV = true;

                if isempty(app.Valley_locs_final)
                    app.Valleys_final = [];
                else
                    app.Valleys_final = interp1(t, x, app.Valley_locs_final, 'linear', 'extrap');
                end
            end

            % -------------------------
            % 3) Keep time-ordering consistent
            % -------------------------
            if strcmpi(app.PVEditTarget,'peak') && ~isempty(app.Peak_locs_final)
                [app.Peak_locs_final, ord] = sort(app.Peak_locs_final);
                app.Peaks_final = app.Peaks_final(ord);
            elseif strcmpi(app.PVEditTarget,'valley') && ~isempty(app.Valley_locs_final)
                [app.Valley_locs_final, ord] = sort(app.Valley_locs_final);
                app.Valleys_final = app.Valleys_final(ord);
            end

            % -------------------------
            % 4) Optional: persist edited PV to next step
            % -------------------------
            % app.saveEditedPVToNextStep();  
        end

        function refreshPVMarkersOnly(app)

            ax = app.UIAxes_4;
            cla(ax);
            
            x_raw  = app.PreprocessedData_GUI.Scalenorm_RESP_synced;
            x_filt = app.PreprocessedData_GUI.Resp_filtered;
            t      = app.PreprocessedData_GUI.Time_synced_Physio;

            % raw (black)
            plot(ax, t, x_raw, 'k', 'LineWidth', 2); hold(ax, 'on');
            plot(ax, t, x_filt, 'LineWidth', 2); hold(ax, 'off');

            if ~isempty(app.PeakPlotHandle) && isvalid(app.PeakPlotHandle)
                delete(app.PeakPlotHandle);
            end
            if ~isempty(app.ValleyPlotHandle) && isvalid(app.ValleyPlotHandle)
                delete(app.ValleyPlotHandle);
            end

            hold(ax,'on');
            % ---- Peak
            if ~isempty(app.Peak_locs_final)
                app.PeakPlotHandle = plot(ax, app.Peak_locs_final, app.Peaks_final, ...
                    'ro', 'MarkerFaceColor','r', 'LineWidth', 1.2);

                app.PeakPlotHandle.HitTest = 'off';
                app.PeakPlotHandle.PickableParts = 'none';
            end

            % ---- Valley
            if ~isempty(app.Valley_locs_final)
                app.ValleyPlotHandle = plot(ax, app.Valley_locs_final, app.Valleys_final, ...
                    'bo', 'MarkerFaceColor','b', 'LineWidth', 1.2);

                app.ValleyPlotHandle.HitTest = 'off';
                app.ValleyPlotHandle.PickableParts = 'none';
            end

            hold(ax,'off');
        end

        function commitFinalPVToData(app)

            % ------------------------------------------------------------
            % Commit the final, user-edited peak/valley results back to the
            % main data structure used for downstream analysis.
            %
            % Rationale:
            %  - updateFinalPVStats() continuously updates the "final" fields
            %    during editing.
            %  - This function is called once more at the end (e.g., on close
            %    or confirm) to ensure the latest edits are safely committed.
            % ------------------------------------------------------------

            % Ensure final PV statistics are up-to-date before committing
            app.updateFinalPVStats();

            % Overwrite the official second-step fields with the finalized results
            % so downstream analysis can directly access them without checking
            % intermediate states.
            if isfield(app.PreprocessedData_GUI,'Resp_secondStep') && isfield(app.PreprocessedData_GUI.Resp_secondStep,'final')
                f = app.PreprocessedData_GUI.Resp_secondStep.final;
                app.PreprocessedData_GUI.Resp_secondStep.peak_locs   = f.peak_locs;
                app.PreprocessedData_GUI.Resp_secondStep.valley_locs = f.valley_locs;
                app.PreprocessedData_GUI.Resp_secondStep.peaks       = f.peaks;
                app.PreprocessedData_GUI.Resp_secondStep.valleys     = f.valleys;
            end
        end

        function updateFinalPVStats(app)

            t = app.Resp_t;

            peak_indices = arrayfun(@(x) find(t >= x, 1, 'first'), app.Peak_locs_final);
            valley_indices = arrayfun(@(x) find(t >= x, 1, 'first'), app.Valley_locs_final);

            diff_peak = diff(app.Peak_locs_final);
            diff_valley = diff(app.Valley_locs_final);

            stats = struct();
            stats.mean_diff_peak_locs   = mean(diff_peak, 'omitnan');
            stats.std_diff_peak_locs    = std(diff_peak, 'omitnan');
            stats.mean_diff_valley_locs = mean(diff_valley, 'omitnan');
            stats.std_diff_valley_locs  = std(diff_valley, 'omitnan');

            if ~isfield(app.PreprocessedData_GUI,'Resp_secondStep') || isempty(app.PreprocessedData_GUI.Resp_secondStep)
                app.PreprocessedData_GUI.Resp_secondStep = struct();
            end

            app.PreprocessedData_GUI.Resp_secondStep.final = struct( ...
                'peak_locs', app.Peak_locs_final, ...
                'valley_locs', app.Valley_locs_final, ...
                'peaks', app.Peaks_final, ...
                'valleys', app.Valleys_final, ...
                'peak_indices', peak_indices, ...
                'valley_indices', valley_indices, ...
                'stats', stats ...
                );
        end


        function PV = getActivePV(app)
            %GETACTIVEPV Return the "best available" peak/valley set for respiration.
            %
            % Priority (highest → lowest):
            %   1) FINAL (manual-edited or finalized set in app properties)
            %   2) 2nd-step auto detection on filtered signal (saved in PreprocessedData_GUI.Resp_secondStep)
            %   3) 1st-step auto detection on original/synced signal (saved in RespDetectResults)
            %
            % Output:
            %   PV struct with fields:
            %     - peaks, peak_locs, valleys, valley_locs : row vectors (may be empty)
            %     - source : one of {'edited_final','auto_final','auto_2ndstep_filtered','auto_1ststep','none'}
            %
            % Notes:
            %   - If peaks/valleys amplitudes are missing or mismatched in length, they are
            %     filled using app.fillPVyIfNeeded(PV) (typically via interpolation on the
            %     current reference signal).

            % Default (no PV available)
            PV = struct('peaks',[],'peak_locs',[],'valleys',[],'valley_locs',[],'source','none');

            % ============================================================
            % 1) FINAL: prefer app-level "final" arrays (edited or auto-final)
            % ============================================================
            if isprop(app,'Peak_locs_final') && ~isempty(app.Peak_locs_final) && ...
                    isprop(app,'Valley_locs_final') && ~isempty(app.Valley_locs_final)

                PV.peak_locs   = app.Peak_locs_final(:)';
                PV.valley_locs = app.Valley_locs_final(:)';

                if isprop(app,'Peaks_final') && ~isempty(app.Peaks_final) && numel(app.Peaks_final)==numel(PV.peak_locs)
                    PV.peaks = app.Peaks_final(:)';
                end
                if isprop(app,'Valleys_final') && ~isempty(app.Valleys_final) && numel(app.Valleys_final)==numel(PV.valley_locs)
                    PV.valleys = app.Valleys_final(:)';
                end

                if isprop(app,'HasUserEditedPV') && app.HasUserEditedPV
                    PV.source = 'edited_final';
                else
                    PV.source = 'auto_final';
                end

                PV = app.fillPVyIfNeeded(PV);
                return;
            end

            % ============================================================
            % 2) 2nd-step: auto detection on filtered respiration
            %    (stored under PreprocessedData_GUI.Resp_secondStep)
            % ============================================================
            if isfield(app.PreprocessedData_GUI,'Resp_secondStep') && ~isempty(app.PreprocessedData_GUI.Resp_secondStep)

                R2 = app.PreprocessedData_GUI.Resp_secondStep;

                if isfield(R2,'peak_locs') && ~isempty(R2.peak_locs) && ...
                        isfield(R2,'valley_locs') && ~isempty(R2.valley_locs)

                    PV.peak_locs   = R2.peak_locs(:)';
                    PV.valley_locs = R2.valley_locs(:)';

                    if isfield(R2,'peaks') && ~isempty(R2.peaks) && numel(R2.peaks)==numel(PV.peak_locs)
                        PV.peaks = R2.peaks(:)';
                    end
                    if isfield(R2,'valleys') && ~isempty(R2.valleys) && numel(R2.valleys)==numel(PV.valley_locs)
                        PV.valleys = R2.valleys(:)';
                    end

                    PV.source = 'auto_2ndstep_filtered';
                    PV = app.fillPVyIfNeeded(PV);
                    return;
                end
            end

            % ============================================================
            % 3) 1st-step: initial auto detection (RespDetectResults)
            % ============================================================
            if isfield(app,'RespDetectResults') && ~isempty(app.RespDetectResults)
                R1 = app.RespDetectResults;
                if isfield(R1,'peak_locs') && ~isempty(R1.peak_locs) && ...
                        isfield(R1,'valley_locs') && ~isempty(R1.valley_locs)

                    PV.peak_locs   = R1.peak_locs(:)';
                    PV.valley_locs = R1.valley_locs(:)';

                    if isfield(R1,'peaks') && ~isempty(R1.peaks) && numel(R1.peaks)==numel(PV.peak_locs)
                        PV.peaks = R1.peaks(:)';
                    end
                    if isfield(R1,'valleys') && ~isempty(R1.valleys) && numel(R1.valleys)==numel(PV.valley_locs)
                        PV.valleys = R1.valleys(:)';
                    end

                    PV.source = 'auto_1ststep';
                    PV = app.fillPVyIfNeeded(PV);
                    return;
                end
            end

            % If none found, PV.source remains 'none'
        end


        function PV = fillPVyIfNeeded(app, PV)

            t = [];
            x = [];

            if isfield(app.PreprocessedData_GUI,'Time_synced_Physio')
                t = app.PreprocessedData_GUI.Time_synced_Physio(:);
            elseif isprop(app,'Resp_t') && ~isempty(app.Resp_t)
                t = app.Resp_t(:);
            end

            if isfield(app.PreprocessedData_GUI,'Resp_filtered') && ~isempty(app.PreprocessedData_GUI.Resp_filtered)
                x = app.PreprocessedData_GUI.Resp_filtered(:);
            elseif isfield(app.PreprocessedData_GUI,'Scalenorm_RESP_synced') && ~isempty(app.PreprocessedData_GUI.Scalenorm_RESP_synced)
                x = app.PreprocessedData_GUI.Scalenorm_RESP_synced(:);
            elseif isprop(app,'Resp_filt') && ~isempty(app.Resp_filt)
                x = app.Resp_filt(:);
            elseif isprop(app,'Resp_raw') && ~isempty(app.Resp_raw)
                x = app.Resp_raw(:);
            end

            canInterp = ~isempty(t) && ~isempty(x) && numel(t)==numel(x) && numel(t)>=2;

            if canInterp
                [tU, ia] = unique(t, 'stable');
                xU = x(ia);

                if isempty(PV.peaks) || numel(PV.peaks) ~= numel(PV.peak_locs)
                    PV.peaks = interp1(tU, xU, PV.peak_locs, 'linear', 'extrap');
                end
                if isempty(PV.valleys) || numel(PV.valleys) ~= numel(PV.valley_locs)
                    PV.valleys = interp1(tU, xU, PV.valley_locs, 'linear', 'extrap');
                end
            end
        end

        function [Time_CSF, CSF_Signal, csfSource, saveDirName] = getCSFSignalForRespProcessing(app)

            preprocessed_vars = app.PreprocessedData_GUI;

            % --- Time_CSF
            Time_CSF = [];
            if isfield(preprocessed_vars,'Time_range') && ~isempty(preprocessed_vars.Time_range)
                Time_CSF = preprocessed_vars.Time_range(:);
            end

            CSF_Signal = [];

            % ===== CALIBRATION =====
            [calData, saveDirName] = app.getCalibrationDataset();
            if ~isempty(calData)
                CSF_Signal = calData(:);
                csfSource  = 'CSF_calibrated_Dataset';
            end
        end


        function [Exhale, Inhale, Exhale_CSF, Exhale_CSF_idx, Inhale_CSF, Inhale_CSF_idx] = computeInhaleExhaleIntervals(app, peak_times, valley_times, start_time, end_time)
            % =========================================================
            % Input:
            %   peak_times   : respiration peak time points (sec)
            %   valley_times : respiration valley time points (sec)
            %   start_time   : analysis start time (sec)
            %   end_time     : analysis end time (sec)
            %
            % Output:
            %   Exhale / Inhale           : [start end] intervals in respiration time
            %   Exhale_CSF / Inhale_CSF   : [start end] intervals mapped to CSF time
            %   *_CSF_idx                 : corresponding CSF index ranges
            % =========================================================

            peak_times   = sort(peak_times(:));
            valley_times = sort(valley_times(:));
            preprocessed_vars = app.PreprocessedData_GUI;
            Time_CSF = preprocessed_vars.Time_range;
            Exhale = [];
            Inhale = [];

            if isempty(peak_times) || isempty(valley_times)
                return;
            end

            % =========================================================
            % Step 1. Construct Inhale / Exhale intervals (respiration time)
            % Rule:
            %   Exhale = Peak  → Valley
            %   Inhale = Valley → Peak
            % =========================================================

            % Determine which event occurs first
            if peak_times(1) < valley_times(1)
                % Case 1: Peak occurs first
                % → Start to first peak is Inhale
                Inhale = [Inhale; start_time, peak_times(1)];
                
                for i = 1:length(peak_times)
                    if i <= length(valley_times)
                        Exhale = [Exhale; peak_times(i), valley_times(i)]; 
                    end
                    if i < length(peak_times) 
                        Inhale = [Inhale; valley_times(i), peak_times(i+1)]; 
                    end
                end
            else
                % Case 2: Valley occurs first
                % → Start to first valley is Exhale
                Exhale = [Exhale; start_time, valley_times(1)];
                
                for i = 1:length(valley_times)
                    if i <= length(peak_times) 
                        Inhale = [Inhale; valley_times(i), peak_times(i)]; 
                    end
                    if i < length(valley_times)
                        Exhale = [Exhale; peak_times(i), valley_times(i+1)]; 
                    end
                end
            end

            % =========================================================
            % Step 2. Handle the tail segment (last event → end_time)
            % =========================================================
            if valley_times(end) > peak_times(end)
                % Last event is Valley → remaining segment is Inhale
                Inhale = [Inhale; valley_times(end), end_time];
            else
                % Last event is Peak → remaining segment is Exhale
                Exhale = [Exhale; peak_times(end), end_time];
            end

            % =========================================================
            % Step 3. Map Inhale / Exhale intervals to CSF time axis
            %   - Convert respiration time → nearest CSF index
            %   - Prevent overlap between Exhale and Inhale indices
            % =========================================================
            Exhale_CSF = zeros(size(Exhale)); 
            Exhale_CSF_idx = zeros(size(Exhale)); 

            Inhale_CSF = zeros(size(Inhale));
            Inhale_CSF_idx = zeros(size(Inhale));

            % Track already-used CSF indices to avoid overlap
            used_indices = false(length(Time_CSF), 1);

            % -------------------------
            % Exhale → CSF mapping
            % -------------------------
            for i = 1:size(Exhale,1)
                [~, idx1] = min(abs(Time_CSF - Exhale(i,1)));
                [~, idx2] = min(abs(Time_CSF - Exhale(i,2)));

                Exhale_CSF(i,:) = [Time_CSF(idx1), Time_CSF(idx2)];
                Exhale_CSF_idx(i,:) = [idx1, idx2];
                used_indices(idx1:idx2) = true;
            end

            % -------------------------
            % Inhale → CSF mapping
            %   (avoid overlap with Exhale intervals)
            % -------------------------
            for i = 1:size(Inhale,1)
                [~, idx1] = min(abs(Time_CSF - Inhale(i,1)));
                [~, idx2] = min(abs(Time_CSF - Inhale(i,2)));

                test_used_indices_idx1=used_indices(idx1);
                test_used_indices_idx2=used_indices(idx2);

                if test_used_indices_idx1==1  % start point overlapped! -> should be +1
                    idx1=idx1+1;
                else
                end

                if test_used_indices_idx2==1  % end point overlapped! -> should be -1
                    idx2=idx2-1;
                else
                end

                % 저장
                Inhale_CSF(i,:) = [Time_CSF(idx1), Time_CSF(idx2)];
                Inhale_CSF_idx(i,:) = [idx1, idx2];
            end


        end


        function [Area, SumVal] = computeIntervalIntegrals(app, Time_CSF, CSF_Signal, idxPairs)

            if isempty(idxPairs)
                Area = zeros(0,1);
                SumVal = zeros(0,1);
                return;
            end

            Area = zeros(size(idxPairs,1),1);
            SumVal = zeros(size(idxPairs,1),1);

            for i = 1:size(idxPairs,1)
                idx1 = idxPairs(i,1);
                idx2 = idxPairs(i,2);

                idx1 = max(1, idx1);
                idx2 = min(numel(Time_CSF), idx2);

                Area(i) = trapz(Time_CSF(idx1:idx2), CSF_Signal(idx1:idx2));
                SumVal(i) = sum(CSF_Signal(idx1:idx2));
            end
        end

        function logIntervalsDetail(app, name, Tpair, Ipair, Area, SumVal)

            app.MainApp.logMessage(sprintf('---- %s intervals (k | t1~t2 | idx1~idx2 | area | sum) ----', name));

            K = size(Ipair,1);
            for k = 1:K
                app.MainApp.logMessage(sprintf('%s%03d | %.4f~%.4f | %d~%d | A=%.6f | S=%.6f', ...
                    upper(name(1)), k, ...
                    Tpair(k,1), Tpair(k,2), ...
                    Ipair(k,1), Ipair(k,2), ...
                    Area(k), SumVal(k)));
            end
        end


        function plotSummaryOnUIAxes2(app)
            % =========================================================
            % Summary panel (4 axes) visualization
            %
            % This function draws a compact “QC/summary view” that aligns:
            %   (1) Respiration raw + filtered + peak/valley (backup + active)
            %   (2) CSF velocity signal (source-dependent: origin/cali)
            %   (3) Inhale/Exhale segment-wise integrated displacement (patch)
            %   (4) PPG time series
            %
            % Requirements:
            %   - app.PreprocessedData_GUI must contain synchronized Physio data
            %   - app.PreprocessedData_GUI.RespCsfSegments must be created first
            %     (e.g., via ProcessingCSFwithRESPButtonPushed pipeline)
            % =========================================================

            % =========================================================
            % (A) Load & prepare signals + peak/valley markers
            % =========================================================
            % Respiration (synced): raw / filtered
            t_resp   = app.PreprocessedData_GUI.Time_synced_Physio(:);
            x_raw    = app.PreprocessedData_GUI.Scalenorm_RESP_synced(:);
            x_filt   = app.PreprocessedData_GUI.Resp_filtered(:);
            x_ppg    = app.PreprocessedData_GUI.Scalenorm_Cardio_synced(:);

            % ---------------------------------------------------------
            % 1st-step backup PV (optional; only if exists)
            % ---------------------------------------------------------
            peak_locs_backup   = [];
            peaks_backup       = [];
            valley_locs_backup = [];
            valleys_backup     = [];
            if isfield(app,'RespDetectResults') && ~isempty(app.RespDetectResults)
                if isfield(app.RespDetectResults,'peak_locs');   peak_locs_backup = app.RespDetectResults.peak_locs(:); end
                if isfield(app.RespDetectResults,'peaks');       peaks_backup     = app.RespDetectResults.peaks(:); end
                if isfield(app.RespDetectResults,'valley_locs'); valley_locs_backup = app.RespDetectResults.valley_locs(:); end
                if isfield(app.RespDetectResults,'valleys');     valleys_backup     = app.RespDetectResults.valleys(:); end
            end

            % ---------------------------------------------------------
            % Active PV (edited_final → 2nd-step → 1st-step fallback)
            % ---------------------------------------------------------
            PV = app.getActivePV();
            peak_locs   = PV.peak_locs(:);
            valley_locs = PV.valley_locs(:);

            % If PV struct does not carry y-values, reconstruct from filtered signal
            peaks   = PV.peaks;
            valleys = PV.valleys;
            if isempty(peaks) || numel(peaks) ~= numel(peak_locs)
                peaks = interp1(t_resp, x_filt, peak_locs, 'linear', 'extrap');
            end
            if isempty(valleys) || numel(valleys) ~= numel(valley_locs)
                valleys = interp1(t_resp, x_filt, valley_locs, 'linear', 'extrap');
            end

            % =========================================================
            % (B) Load CSF + Resp-based segmentation results
            % =========================================================
            % RespCsfSegments is expected to be created earlier
            if ~isfield(app.PreprocessedData_GUI,'RespCsfSegments') || isempty(app.PreprocessedData_GUI.RespCsfSegments)
                app.MainApp.logMessage('[UIAxes2] RespCsfSegments is missing. Run ProcessingCSFwithRESP first.');
                return;
            end
            S = app.PreprocessedData_GUI.RespCsfSegments;

            Time_CSF    = app.PreprocessedData_GUI.Time_range(:);
            % CSF time axis + CSF signal (origin/cali selection handled inside helper)
            [~, CSF_Signal] = app.getCSFSignalForRespProcessing();

            Inhale_CSF     = S.Inhale_CSF;
            Exhale_CSF     = S.Exhale_CSF;
            Inhale_Area    = S.Inhale_Area(:);
            Exhale_Area    = S.Exhale_Area(:);

            % =========================================================
            % (C) Prepare 4 axes (create or reuse)
            % =========================================================
            % ax1: Resp (raw+filtered+PV)
            % ax2: CSF signal
            % ax3: Inhale/Exhale patch areas
            % ax4: PPG
            [ax1, ax2, ax3, ax4] = app.ensureSummaryAxes4();

            % ==========================================
            % (C) ax1: Resp raw + backup PV + filtered + PV
            % ==========================================
            cla(ax1); hold(ax1,'on');

            h_raw  = plot(ax1, t_resp, x_raw, 'k', 'LineWidth', 2);
            h_filt = plot(ax1, t_resp, x_filt, 'LineWidth', 2);

            h_raw.HitTest  = 'off';
            h_filt.HitTest = 'off';

            if ~isempty(peak_locs_backup)
                h_pb = plot(ax1, peak_locs_backup, peaks_backup, 'ko');
                h_pb.HitTest = 'off';
            end
            if ~isempty(valley_locs_backup)
                h_vb = plot(ax1, valley_locs_backup, valleys_backup, 'ks');
                h_vb.HitTest = 'off';
            end

            h_p = plot(ax1, peak_locs, peaks, 'ro', 'MarkerFaceColor','r', 'LineWidth',1.2);
            h_v = plot(ax1, valley_locs, valleys, 'bo', 'MarkerFaceColor','b', 'LineWidth',1.2);

            h_p.HitTest = 'off';
            h_v.HitTest = 'off';

            grid(ax1,'on'); axis(ax1,'tight');
            xlabel(ax1,'Time (s)'); ylabel(ax1,'Respiration');
            title(ax1,'Resp: raw + filtered + peaks/valleys');
            % legend(ax1, {'Resp raw','Resp filtered','Peaks(backup)','Valleys(backup)','Peaks','Valleys'}, ...
                % 'Location','northeastoutside');
            hold(ax1,'off');

            % ==========================================
            % (D) ax2: CSF signal
            % ==========================================
            cla(ax2); hold(ax2,'on');
            h_csf = plot(ax2, Time_CSF, CSF_Signal, 'k', 'LineWidth', 1.2);
            h_csf.HitTest = 'off';
            grid(ax2,'on'); axis(ax2,'tight');
            xlabel(ax2,'Time (s)'); ylabel(ax2,'CSF (cm/s)');
            title(ax2, sprintf('CSF Signal (%s)', S.CSF_source_used));
            % legend(ax2, {'CSF signal'}, 'Location','northeastoutside');
            hold(ax2,'off');

            % ==========================================
            % (E) ax3: Inhale/Exhale integrated area patch
            % ==========================================
            cla(ax3); hold(ax3,'on');

            % inhale patches (red)
            h1 = []; h2 = [];
            for i = 1:size(Inhale_CSF,1)
                x_patch = [Inhale_CSF(i,1), Inhale_CSF(i,2), Inhale_CSF(i,2), Inhale_CSF(i,1)];
                y_patch = [0, 0, Inhale_Area(i), Inhale_Area(i)];
                if i == 1
                    h1 = patch(ax3, x_patch, y_patch, 'r', 'EdgeColor','k', 'FaceAlpha',0.5);
                else
                    patch(ax3, x_patch, y_patch, 'r', 'EdgeColor','k', 'FaceAlpha',0.5);
                end
                text(ax3, mean(Inhale_CSF(i,:)), Inhale_Area(i) + 0.05, sprintf('%.2f', Inhale_Area(i)), ...
                    'HorizontalAlignment','center', 'FontSize',10, 'FontWeight','bold', 'Color','k');
            end

            % exhale patches (blue)
            for i = 1:size(Exhale_CSF,1)
                x_patch = [Exhale_CSF(i,1), Exhale_CSF(i,2), Exhale_CSF(i,2), Exhale_CSF(i,1)];
                y_patch = [0, 0, Exhale_Area(i), Exhale_Area(i)];
                if i == 1
                    h2 = patch(ax3, x_patch, y_patch, 'b', 'EdgeColor','k', 'FaceAlpha',0.5);
                else
                    patch(ax3, x_patch, y_patch, 'b', 'EdgeColor','k', 'FaceAlpha',0.5);
                end
                text(ax3, mean(Exhale_CSF(i,:)), Exhale_Area(i) + 0.05, sprintf('%.2f', Exhale_Area(i)), ...
                    'HorizontalAlignment','center', 'FontSize',10, 'FontWeight','bold', 'Color','k');
            end

            grid(ax3,'on');
            xlabel(ax3,'Time (s)');
            ylabel(ax3,'Integrated CSF Displacement (cm)');
            title(ax3,'Inhale(Redbox)/Exhale(BlueBox) Integrated Area');
            xlim(ax3, [Time_CSF(1), Time_CSF(end)]);
            % if ~isempty(h1) && ~isempty(h2)
            %     legend(ax3, [h1, h2], {'Inhale','Exhale'}, 'Location','northeastoutside');
            % end
            hold(ax3,'off');

            % ==========================================
            % (F) ax4: PPG
            % ==========================================
            cla(ax4); hold(ax4,'on');
            h_ppg = plot(ax4, t_resp, x_ppg, 'k', 'LineWidth', 1.2);
            h_ppg.HitTest = 'off';
            grid(ax4,'on'); axis(ax4,'tight');
            xlabel(ax4,'Time (s)'); ylabel(ax4,'PPG');
            title(ax4,'PPG Signal Over Time');
            % legend(ax4, {'PPG signal'}, 'Location','northeastoutside');
            hold(ax4,'off');
        end

        function makeAxesCompact(app, ax)

            % Disable the toolbar to avoid unnecessary space usage
            % and to prevent toolbar-related event interference
            if isprop(ax,'Toolbar') && ~isempty(ax.Toolbar)
                ax.Toolbar.Visible = 'off';
            end
            
            % Basic axes appearance settings
            ax.Box = 'on';
            ax.FontSize = 10;              % Smaller font reduces excess padding
            ax.TitleFontSizeMultiplier = 1.05; % Slight emphasis for the title
            ax.LabelFontSizeMultiplier = 1.0; % Keep axis labels consistent

            % Keep grid lines for readability while minimizing visual clutter
            ax.XGrid = 'on';
            ax.YGrid = 'on';

            % Note:
            % Long titles can significantly increase vertical spacing.
            % Keep titles concise (single-line) in the calling plotting functions.
            % ax.Title.String = "...";  % Avoid overly long titles
        end


        function [ax1, ax2, ax3, ax4] = ensureSummaryAxes4(app)
            % Create (or reuse) four stacked uiaxes inside app.UIAxes2Panel.
            % - If axes already exist and are valid, reuse them.
            % - Otherwise, clear the panel and rebuild a 4x1 uigridlayout with four uiaxes.

            % --- Safety check: UIAxes2Panel must exist and be valid

            if ~isprop(app,'UIAxes2Panel') || isempty(app.UIAxes2Panel) || ~isvalid(app.UIAxes2Panel)
                error('UIAxes2Panel does not exist. Please create a Panel in the UI and set its Name to "UIAxes2Panel".');
            end

            % --- Reuse existing axes if already created
            if isprop(app,'SummaryAxes') && ~isempty(app.SummaryAxes) && all(isvalid(app.SummaryAxes))
                ax1 = app.SummaryAxes(1);
                ax2 = app.SummaryAxes(2);
                ax3 = app.SummaryAxes(3);
                ax4 = app.SummaryAxes(4);
                return;
            end

            % --- Reset: remove any existing children (axes/layouts) from the panel
            delete(app.UIAxes2Panel.Children);

            % --- Create a 4-row x 1-column grid layout (stacked plots)
            gl = uigridlayout(app.UIAxes2Panel, [4 1]);
            %gl.RowHeight = {'1x','1x','1x','1x'};
            gl.RowHeight = {'1.25x','1.25x','1.25x','1.25x'};
            gl.ColumnWidth = {'1x'};
            gl.Padding = [2 2 2 2];
            gl.RowSpacing = 2;

            ax1 = uiaxes(gl); ax1.Layout.Row = 1;
            ax2 = uiaxes(gl); ax2.Layout.Row = 2;
            ax3 = uiaxes(gl); ax3.Layout.Row = 3;
            ax4 = uiaxes(gl); ax4.Layout.Row = 4;

            app.SummaryAxes = [ax1 ax2 ax3 ax4];

            % --- Apply consistent compact styling (helper function)
            app.makeAxesCompact(ax1);
            app.makeAxesCompact(ax2);
            app.makeAxesCompact(ax3);
            app.makeAxesCompact(ax4);

            % --- Hide x tick labels on the upper three axes to save vertical space
            % (Keep the bottom axis labels visible for time alignment)
            ax1.XTickLabel = [];
            ax2.XTickLabel = [];
            ax3.XTickLabel = [];
        end

        function plotInhaleExhaleBarsToUIAxes(app, Inhale_Area, Exhale_Area, Inhale_period_sum, Exhale_period_sum)
            %---------------------------------------------------------
            % Guard
            %---------------------------------------------------------
            if isempty(Inhale_Area) || isempty(Exhale_Area)
                app.MainApp.logMessage('[NETflow] Inhale/Exhale area is empty. Skip bar plots.');
                return;
            end
            if isempty(Inhale_period_sum) || isempty(Exhale_period_sum)
                app.MainApp.logMessage('[NETflow] Inhale/Exhale summation is empty. Skip bar plots.');
                return;
            end

            %---------------------------------------------------------
            % Step 7-1) Mean/Sum (Integrated area)
            %---------------------------------------------------------
            Inhale_mean = mean(Inhale_Area, 'omitnan');
            Inhale_sum  = sum(Inhale_Area,  'omitnan');
            Exhale_mean = mean(Exhale_Area, 'omitnan');
            Exhale_sum  = sum(Exhale_Area,  'omitnan');
            Total_mean  = mean([Inhale_Area(:); Exhale_Area(:)], 'omitnan');
            Total_sum   = sum([Inhale_Area(:); Exhale_Area(:)],  'omitnan');

            BarData_area = [Inhale_mean, Exhale_mean, Total_mean;
                Inhale_sum,  Exhale_sum,  Total_sum];

            %---------------------------------------------------------
            % Step 7-2) Mean/Sum (Summation)
            %---------------------------------------------------------
            Inhale_summation_mean = mean(Inhale_period_sum, 'omitnan');
            Inhale_summation_sum  = sum(Inhale_period_sum,  'omitnan');
            Exhale_summation_mean = mean(Exhale_period_sum, 'omitnan');
            Exhale_summation_sum  = sum(Exhale_period_sum,  'omitnan');
            Total_summation_mean  = mean([Inhale_period_sum(:); Exhale_period_sum(:)], 'omitnan');
            Total_summation_sum   = sum([Inhale_period_sum(:); Exhale_period_sum(:)],  'omitnan');

            BarData_sum = [Inhale_summation_mean, Exhale_summation_mean, Total_summation_mean;
                Inhale_summation_sum,  Exhale_summation_sum,  Total_summation_sum];

            BarLabels = {'Mean','Sum'};

            colorList = {[1 0 0], [0 0 1], [0.8 0.8 0.8]};  % Inhale/Exhale/Total
            faceAlpha = [0.5, 0.5, 1.0];

            %---------------------------------------------------------
            % (A) UIAxes2_4 : Integrated area barplot
            %---------------------------------------------------------
            ax = app.UIAxes2_4;
            cla(ax);  % clear
            ax.NextPlot = 'replace'; 

            b = bar(ax, BarData_area, 'grouped');
            grid(ax, 'on');

            % style
            for i = 1:numel(b)
                b(i).FaceColor = colorList{i};
                b(i).FaceAlpha = faceAlpha(i);
            end

            ax.XTick = 1:numel(BarLabels);
            ax.XTickLabel = BarLabels;
            ylabel(ax, 'Displacement (cm)');
            title(ax, 'Inhale/Exhale Mean and Sum of Integrated CSF Signal');

            yMax = max(BarData_area(:));
            if yMax <= 0 || isnan(yMax); yMax = 1; end
            yOffset = 0.02 * yMax;

            for i = 1:numel(b)
                x_vals = b(i).XEndPoints;
                y_vals = BarData_area(:, i);

                for j = 1:numel(y_vals)
                    text(ax, x_vals(j), y_vals(j) + yOffset, sprintf('%.2f', y_vals(j)), ...
                        'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom', ...
                        'FontSize', 10, 'FontWeight', 'bold', 'Color', 'k');
                end
            end

            legend(ax, {'Inhale','Exhale','Total'}, 'Location', 'northeastoutside');

            %---------------------------------------------------------
            % (B) UIAxes2_5 : Summation barplot
            %---------------------------------------------------------
            ax = app.UIAxes2_5;
            cla(ax);
            ax.NextPlot = 'replace';

            b2 = bar(ax, BarData_sum, 'grouped');
            grid(ax, 'on');

            for i = 1:numel(b2)
                b2(i).FaceColor = colorList{i};
                b2(i).FaceAlpha = faceAlpha(i);
            end

            ax.XTick = 1:numel(BarLabels);
            ax.XTickLabel = BarLabels;
            ylabel(ax, 'Summation (a.u. or cm/s·samples)');
            title(ax, 'Inhale/Exhale Mean and Sum of Summation CSF Signal');

            yMax2 = max(BarData_sum(:));
            if yMax2 <= 0 || isnan(yMax2); yMax2 = 1; end
            yOffset2 = 0.02 * yMax2;

            for i = 1:numel(b2)
                x_vals = b2(i).XEndPoints;
                y_vals = BarData_sum(:, i);

                for j = 1:numel(y_vals)
                    text(ax, x_vals(j), y_vals(j) + yOffset2, sprintf('%.2f', y_vals(j)), ...
                        'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom', ...
                        'FontSize', 10, 'FontWeight', 'bold', 'Color', 'k');
                end
            end

            legend(ax, {'Inhale','Exhale','Total'}, 'Location', 'northeastoutside');

            if ~isempty(app.MainApp)
                app.MainApp.logMessage('[NETflow] Bar plots updated on UIAxes2_4 / UIAxes2_5');
            end
        end


        function [flow_mls, geom] = computeFlow_mls_fromVelocity(app, velocity_cms)
            
            preprocessed_vars = app.PreprocessedData_GUI;

            % ---- prerequisite check
            if ~isfield(preprocessed_vars,'PCMRI_INFO') || isempty(preprocessed_vars.PCMRI_INFO)
                error('PCMRI_INFO is missing in app.PreprocessedData_GUI.');
            end
            if ~isfield(preprocessed_vars,'ROImaskSize_update') || isempty(preprocessed_vars.ROImaskSize_update)
                error('ROImaskSize_update is missing.');
            end

            info = preprocessed_vars.PCMRI_INFO;

            % PixelDimensions: [dx dy dz dt]
            dx = info.PixelDimensions(1);
            dy = info.PixelDimensions(2);
            dz = info.PixelDimensions(3);

            % unit conversion (mm -> cm)
            if isfield(info,'SpaceUnits') && (string(info.SpaceUnits) == "Millimeter")
                dx = dx * 0.1;
                dy = dy * 0.1;
                dz = dz * 0.1;
            end

            % geometry
            area_cm2   = dx * dy;
            thick_cm   = dz;
            nVox       = double(preprocessed_vars.ROImaskSize_update);

            % Q: flow (ml/s) = v(cm/s) * area(cm^2) * thickness(cm) * nVox
            % 1 cm^3 = 1 mL
            flow_mls = velocity_cms(:) * area_cm2 * thick_cm * nVox;

            geom = struct();
            geom.dx_cm = dx;
            geom.dy_cm = dy;
            geom.dz_cm = dz;
            geom.area_cm2 = area_cm2;
            geom.thick_cm = thick_cm;
            geom.nVox = nVox;
        end

        function net_mls = computeInhaleExhaleMetrics_mls(app, Exhale_CSF_idx, Inhale_CSF_idx, flow_mls)
            preprocessed_vars = app.PreprocessedData_GUI;

            if ~isfield(preprocessed_vars,'Time_range') || isempty(preprocessed_vars.Time_range)
                error('Time_range is missing in app.PreprocessedData_GUI.');
            end
            Time_CSF = preprocessed_vars.Time_range(:);

            if numel(flow_mls) ~= numel(Time_CSF)
                error('Length mismatch: flow_mls (%d) vs Time_range (%d).', numel(flow_mls), numel(Time_CSF));
            end

            CSF_Signal_mls = flow_mls(:);

            % ---- allocate
            Exhale_Area_mls       = zeros(size(Exhale_CSF_idx,1), 1);
            Inhale_Area_mls       = zeros(size(Inhale_CSF_idx,1), 1);
            Exhale_period_mls_sum = zeros(size(Exhale_CSF_idx,1), 1);
            Inhale_period_mls_sum = zeros(size(Inhale_CSF_idx,1), 1);

            % ---- Exhale
            for i = 1:size(Exhale_CSF_idx,1)
                idx1 = Exhale_CSF_idx(i,1);
                idx2 = Exhale_CSF_idx(i,2);

                idx1 = max(1, min(idx1, numel(Time_CSF)));
                idx2 = max(1, min(idx2, numel(Time_CSF)));
                if idx2 <= idx1
                    continue;
                end

                Exhale_Area_mls(i) = trapz(Time_CSF(idx1:idx2), CSF_Signal_mls(idx1:idx2));
                Exhale_period_mls_sum(i) = sum(CSF_Signal_mls(idx1:idx2));
            end

            % ---- Inhale
            for i = 1:size(Inhale_CSF_idx,1)
                idx1 = Inhale_CSF_idx(i,1);
                idx2 = Inhale_CSF_idx(i,2);

                idx1 = max(1, min(idx1, numel(Time_CSF)));
                idx2 = max(1, min(idx2, numel(Time_CSF)));
                if idx2 <= idx1
                    continue;
                end

                Inhale_Area_mls(i) = trapz(Time_CSF(idx1:idx2), CSF_Signal_mls(idx1:idx2));
                Inhale_period_mls_sum(i) = sum(CSF_Signal_mls(idx1:idx2));
            end

            net_mls = struct();
            net_mls.Time_CSF = Time_CSF;
            net_mls.CSF_Signal_mls = CSF_Signal_mls;

            net_mls.Exhale_Area_mls = Exhale_Area_mls;
            net_mls.Inhale_Area_mls = Inhale_Area_mls;

            net_mls.Exhale_period_mls_sum = Exhale_period_mls_sum;
            net_mls.Inhale_period_mls_sum = Inhale_period_mls_sum;

            net_mls.Exhale_Area_mls_mean = mean(Exhale_Area_mls,'omitnan'); net_mls.Exhale_Area_mls_sum = sum(Exhale_Area_mls,'omitnan');
            net_mls.Inhale_Area_mls_mean = mean(Inhale_Area_mls,'omitnan'); net_mls.Inhale_Area_mls_sum = sum(Inhale_Area_mls,'omitnan');
            net_mls.Total_Area_mls_mean = mean([Inhale_Area_mls; Exhale_Area_mls],'omitnan'); net_mls.Total_Area_mls_sum = sum([Inhale_Area_mls; Exhale_Area_mls],'omitnan');

            net_mls.Exhale_summation_mls_mean = mean(Exhale_period_mls_sum,'omitnan'); net_mls.Exhale_summation_mls_sum = sum(Exhale_period_mls_sum,'omitnan');
            net_mls.Inhale_summation_mls_mean = mean(Inhale_period_mls_sum,'omitnan'); net_mls.Inhale_summation_mls_sum = sum(Inhale_period_mls_sum,'omitnan');
            net_mls.Total_summation_mls_mean = mean([Inhale_period_mls_sum; Exhale_period_mls_sum],'omitnan'); net_mls.Total_summation_mls_sum = sum([Inhale_period_mls_sum; Exhale_period_mls_sum],'omitnan');


        end


        function mode = getSelectedUnitsMode(app)
            % mode: 'cms' or 'mls'
            sel = app.UnitsButtonGroup.SelectedObject;

            if sel == app.cmsButton
                mode = 'cms';
            elseif sel == app.mlsButton
                mode = 'mls';
            else
                mode = 'cms'; % fallback
            end
        end

        function D = getNetflowForPlot(app)
            mode = app.getSelectedUnitsMode();

            if strcmpi(mode,'mls')
                if isfield(app.PreprocessedData_GUI,'NETflow_mls') && ~isempty(app.PreprocessedData_GUI.NETflow_mls)
                    D = app.PreprocessedData_GUI.NETflow_mls;
                    D.units = 'ml/s';
                    return;
                end
            else
                if isfield(app.PreprocessedData_GUI,'NETflow_cms') && ~isempty(app.PreprocessedData_GUI.NETflow_cms)
                    D = app.PreprocessedData_GUI.NETflow_cms;
                    D.units = 'cm/s';
                    return;
                end
            end

            % fallback
            if isfield(app.PreprocessedData_GUI,'NETflow_cms') && ~isempty(app.PreprocessedData_GUI.NETflow_cms)
                D = app.PreprocessedData_GUI.NETflow_cms; D.units='cm/s'; return;
            end
            if isfield(app.PreprocessedData_GUI,'NETflow_mls') && ~isempty(app.PreprocessedData_GUI.NETflow_mls)
                D = app.PreprocessedData_GUI.NETflow_mls; D.units='ml/s'; return;
            end

            D = [];
        end

        function refreshUnitDependentPlots(app)

            D = app.getNetflowForPlot();
            if isempty(D)
                app.MainApp.logMessage('[PLOT] No NETflow results to plot. Run Processing first.');
                return;
            end

            % 1) UIAxes2Panel
            app.plotSummaryOnUIAxes2_byUnits(D);

            % 2) UIAxes2_4 / UIAxes2_5 (Mean/Sum barplot 2개)
            app.plotMeanSumBars_UIAxes2_4_5(D);

            app.MainApp.logMessage(sprintf('[PLOT] Updated plots for units = %s', D.units));
        end


        function plotSummaryOnUIAxes2_byUnits(app, D)
            % =========================================================
            % Summary visualization (4 stacked axes) with unit handling
            %
            % Inputs:
            %   D: struct containing CSF/segment results for plotting
            %      - D.Time_CSF
            %      - D.units                      (e.g., 'cm/s' or 'ml/s')
            %      - CSF_Signal_*                 (CSF_Signal_cms or CSF_Signal_mls)
            %      - Inhale_Area_* / Exhale_Area_*
            %      - Inhale_CSF_idx / Exhale_CSF_idx
            %
            % Behavior:
            %   ax1: Respiration (raw + filtered + PV markers)
            %   ax2: CSF signal (velocity or flow depending on D.units)
            %   ax3: Integrated area patches for inhale/exhale (unit-aware)
            %   ax4: PPG (if available)
            % =========================================================

            % ---- (0) Ensure 4 summary axes exist (create or reuse)
            [ax1, ax2, ax3, ax4] = app.ensureSummaryAxes4();

            % Resp plot might already be managed in another axes (e.g., app.UIAxes_4).
            % Here we redraw a compact "summary" version for consistency.

            pre = app.PreprocessedData_GUI;

            % =========================================================
            % (1) Respiration summary (ax1): raw + filtered + PV markers
            % =========================================================
            cla(ax1);

            % Prefer synced time vector from preprocessing; fallback to edit-session time
            if isfield(pre,'Time_synced_Physio')
                tResp = pre.Time_synced_Physio(:);
            else
                tResp = app.Resp_t(:);
            end

            xRaw  = pre.Scalenorm_RESP_synced(:);
            xFilt = pre.Resp_filtered(:);

            h1 = plot(ax1, tResp, xRaw, 'k', 'LineWidth', 1.5); hold(ax1,'on');
            h2 = plot(ax1, tResp, xFilt, 'LineWidth', 2);

            % Disable HitTest to avoid interfering with global click callbacks
            h1.HitTest='off'; h2.HitTest='off';

            % Plot the PV markers that were actually used downstream (final selection)
            if isfield(pre,'Peaks_used') && ~isempty(pre.Peaks_used)
                plot(ax1, pre.Peak_locs_used, pre.Peaks_used, 'ro', 'MarkerFaceColor','r');
            end
            if isfield(pre,'Valleys_used') && ~isempty(pre.Valleys_used)
                plot(ax1, pre.Valley_locs_used, pre.Valleys_used, 'bo', 'MarkerFaceColor','b');
            end

            grid(ax1,'on'); axis(ax1,'tight');
            xlabel(ax1,'Time (s)'); ylabel(ax1,'Resp (a.u.)');
            title(ax1,'Resp (raw + filtered + PV)');

            % =========================================================
            % (2) CSF signal summary (ax2): unit-aware (cm/s vs ml/s)
            % =========================================================
            cla(ax2);

            Time_CSF = D.Time_CSF(:);

            if isfield(D,'CSF_Signal_mls')
                csfSig = D.CSF_Signal_mls(:);
                ylab = 'Flow (ml/s)';
            else
                csfSig = D.CSF_Signal_cms(:);
                ylab = 'Velocity (cm/s)';
            end

            h = plot(ax2, Time_CSF, csfSig, 'k', 'LineWidth', 1.2);
            h.HitTest='off';
            grid(ax2,'on'); axis(ax2,'tight');
            xlabel(ax2,'Time (s)'); ylabel(ax2, ylab);
            title(ax2, sprintf('CSF signal (%s)', D.units));

            % =========================================================
            % (3) Integrated inhale/exhale patches (ax3): unit-aware area
            % =========================================================
            cla(ax3); hold(ax3,'on'); grid(ax3,'on');
            xlabel(ax3,'Time (s)');

            if isfield(D,'Inhale_Area_mls')
                InA = D.Inhale_Area_mls(:);
                ExA = D.Exhale_Area_mls(:);
                ylab3 = 'Integrated (ml)';
            else
                InA = D.Inhale_Area_cms(:);
                ExA = D.Exhale_Area_cms(:);
                ylab3 = 'Integrated (cm)';
            end
            ylabel(ax3, ylab3);
            title(ax3, sprintf('Inhale/Exhale integrated (%s)', D.units));

            % Interval indices are shared regardless of CSF units (same Time_CSF grid)
            ExIdx = D.Exhale_CSF_idx;
            InIdx = D.Inhale_CSF_idx;

            % inhale patches
            for i=1:size(InIdx,1)
                idx1 = InIdx(i,1); idx2 = InIdx(i,2);
                if idx2<=idx1; continue; end
                x1 = Time_CSF(idx1); x2 = Time_CSF(idx2);
                patch(ax3, [x1 x2 x2 x1], [0 0 InA(i) InA(i)], 'r', 'EdgeColor','k', 'FaceAlpha',0.35);
                text(ax3, mean([x1 x2]), InA(i), sprintf('%.2f', InA(i)), 'HorizontalAlignment','center', 'VerticalAlignment','bottom');
            end

            % exhale patches
            for i=1:size(ExIdx,1)
                idx1 = ExIdx(i,1); idx2 = ExIdx(i,2);
                if idx2<=idx1; continue; end
                x1 = Time_CSF(idx1); x2 = Time_CSF(idx2);
                patch(ax3, [x1 x2 x2 x1], [0 0 ExA(i) ExA(i)], 'b', 'EdgeColor','k', 'FaceAlpha',0.35);
                text(ax3, mean([x1 x2]), ExA(i), sprintf('%.2f', ExA(i)), 'HorizontalAlignment','center', 'VerticalAlignment','bottom');
            end
            xlim(ax3, [Time_CSF(1) Time_CSF(end)]);
            axis(ax3,'tight');
            hold(ax3,'off');

            % ----------------
            % (4) PPG (ax4)
            % ----------------
            cla(ax4);
            if isfield(pre,'Scalenorm_Cardio_synced_v2')
                xPPG = pre.Scalenorm_Cardio_synced_v2(:);
            elseif isfield(pre,'Scalenorm_Cardio_synced')
                xPPG = pre.Scalenorm_Cardio_synced(:);
            else
                xPPG = [];
            end

            if ~isempty(xPPG)
                hppg = plot(ax4, tResp, xPPG, 'k', 'LineWidth',1.2);
                hppg.HitTest='off';
                grid(ax4,'on'); axis(ax4,'tight');
                xlabel(ax4,'Time (s)'); ylabel(ax4,'PPG (a.u.)');
                title(ax4,'PPG signal');
            else
                title(ax4,'PPG signal (missing)');
            end
        end


        function plotMeanSumBars_UIAxes2_4_5(app, D)

            axA = app.UIAxes2_4;  % area mean/sum
            axS = app.UIAxes2_5;  % summation mean/sum

            % ---- pick arrays by units
            if isfield(D,'Inhale_Area_mls')
                InA = D.Inhale_Area_mls(:);
                ExA = D.Exhale_Area_mls(:);
                InS = D.Inhale_period_mls_sum(:);
                ExS = D.Exhale_period_mls_sum(:);
                ylabA = 'Integrated (ml)';
                ylabS = 'Summation (ml/s)';
                ttlA  = 'Mean/Sum of Integrated CSF (ml/s)';
                ttlS  = 'Mean/Sum of Summation CSF (ml/s)';
            else
                InA = D.Inhale_Area_cms(:);
                ExA = D.Exhale_Area_cms(:);
                InS = D.Inhale_Sum_cms(:);
                ExS = D.Exhale_Sum_cms(:);
                ylabA = 'Integrated (cm)';
                ylabS = 'Summation (cm/s)';
                ttlA  = 'Mean/Sum of Integrated CSF (cm/s)';
                ttlS  = 'Mean/Sum of Summation CSF (cm/s)';
            end

            % ---- compute stats
            Inhale_mean = mean(InA,'omitnan'); Inhale_sum = sum(InA,'omitnan');
            Exhale_mean = mean(ExA,'omitnan'); Exhale_sum = sum(ExA,'omitnan');
            Total_mean  = mean([InA; ExA],'omitnan'); Total_sum = sum([InA; ExA],'omitnan');

            Inhale_summation_mean = mean(InS,'omitnan'); Inhale_summation_sum = sum(InS,'omitnan');
            Exhale_summation_mean = mean(ExS,'omitnan'); Exhale_summation_sum = sum(ExS,'omitnan');
            Total_summation_mean  = mean([InS; ExS],'omitnan'); Total_summation_sum = sum([InS; ExS],'omitnan');

            % ---- (A) Area plot on UIAxes2_4
            cla(axA);
            BarDataA = [Inhale_mean, Exhale_mean, Total_mean;
                Inhale_sum,  Exhale_sum,  Total_sum];

            bA = bar(axA, BarDataA, 'grouped');
            set(axA,'XTickLabel',{'Mean','Sum'});
            ylabel(axA, ylabA);
            title(axA, ttlA);
            grid(axA,'on');

            % 값 텍스트
            for i=1:numel(bA)
                xvals = bA(i).XEndPoints;
                yvals = BarDataA(:,i);
                for j=1:numel(yvals)
                    text(axA, xvals(j), yvals(j) + 0.001*max(BarDataA(:)), sprintf('%.2f', yvals(j)), ...
                        'HorizontalAlignment','center','VerticalAlignment','bottom','FontWeight','bold');
                end
            end
            legend(axA, {'Inhale','Exhale','Total'}, 'Location','northeastoutside');

            % ---- (B) Summation plot on UIAxes2_5
            cla(axS);
            BarDataS = [Inhale_summation_mean, Exhale_summation_mean, Total_summation_mean;
                Inhale_summation_sum,  Exhale_summation_sum,  Total_summation_sum];

            bS = bar(axS, BarDataS, 'grouped');
            set(axS,'XTickLabel',{'Mean','Sum'});
            ylabel(axS, ylabS);
            title(axS, ttlS);
            grid(axS,'on');

            for i=1:numel(bS)
                xvals = bS(i).XEndPoints;
                yvals = BarDataS(:,i);
                for j=1:numel(yvals)
                    text(axS, xvals(j), yvals(j) + 0.001*max(BarDataS(:)), sprintf('%.2f', yvals(j)), ...
                        'HorizontalAlignment','center','VerticalAlignment','bottom','FontWeight','bold');
                end
            end
            legend(axS, {'Inhale','Exhale','Total'}, 'Location','northeastoutside');
        end

        function runCSFDynamicsFeatureCalcAndPlot(app)
            % ---------------- * Algorithm settings
            % This function implements a specific algorithm based on the method
            % described in Liu (2021, Thesis), Liu et al (2025).
            % -------------------------
            % (A) prerequisite
            % -------------------------
            if ~isfield(app.PreprocessedData_GUI,'Time_range') || isempty(app.PreprocessedData_GUI.Time_range)
                error('Time_range is missing in PreprocessedData_GUI.');
            end
            preprocessed_vars = app.PreprocessedData_GUI;

            % Time_CSF
            Time_CSF = preprocessed_vars.Time_range(:);

            % -------------------------
            % (B) Calibration dataset (cm/s) + saveDir
            % -------------------------
            [Calibration_dataset, saveDirName] = app.getCalibrationDataset();
            Calibration_dataset = Calibration_dataset(:);

            % -------------------------
            % (C) Convert velocity (cm/s) -> flow (mL/s) using ROI geometry
            % -------------------------
            [flow_mls, geom] = app.computeFlow_mls_fromVelocity(Calibration_dataset);
            flow_mls = flow_mls(:);

            % -------------------------
            % (D) Select display unit for plotting (features will be computed for both)
            % -------------------------
            selectedUnitBtn = app.UnitsButtonGroup.SelectedObject;

            use_mls = (selectedUnitBtn == app.mlsButton);
            if use_mls
                Flow_forPlot = flow_mls;   yLabelFlow = 'Flow [mL/s]';
                titlePrefix  = 'ml/s';
            else
                Flow_forPlot = Calibration_dataset; yLabelFlow = 'Velocity [cm/s]';
                titlePrefix  = 'cm/s';
            end

            % Length consistency checks
            if numel(Time_CSF) ~= numel(Calibration_dataset)
                error('Time_range and Calibration_dataset length mismatch.');
            end
            if numel(flow_mls) ~= numel(Time_CSF)
                error('Time_range and flow_mls length mismatch.');
            end

            % -------------------------
            % (E) Feature set #1: velocity-based metrics (cm/s)
            % -------------------------
            vel = Calibration_dataset;

            Mean_velocity_abs = mean(abs(vel), 'omitnan');
            Mean_velocity     = mean(vel,       'omitnan');

            SD_velocity       = std(vel, 'omitnan');
            SD_abs_velocity   = std(abs(vel), 'omitnan');

            CI_velocity       = SD_velocity / Mean_velocity;
            CI_abs_velocity   = SD_abs_velocity / Mean_velocity_abs;

            Cumsum_velocity   = cumsum(vel, 'omitnan');
            endpoint_Cumsum_velocity = Cumsum_velocity(end);

            endpoint_Cumsum_cali_area_velocity = NaN;
            if isfield(preprocessed_vars,'Mean_temporal_value_from_calibration_mask') && ...
                    ~isempty(preprocessed_vars.Mean_temporal_value_from_calibration_mask)
                tmp = preprocessed_vars.Mean_temporal_value_from_calibration_mask(:);
                Cumsum_cali_area_velocity = cumsum(tmp, 'omitnan');
                endpoint_Cumsum_cali_area_velocity = Cumsum_cali_area_velocity(end);
            end

            % -------------------------
            % (F) Feature 2: flow features (ml/s)
            % -------------------------
            flow = flow_mls;

            Mean_flow_mls_abs = mean(abs(flow), 'omitnan');
            Mean_flow_mls     = mean(flow,      'omitnan');

            SD_flow_mls       = std(flow, 'omitnan');
            SD_flow_mls_abs   = std(abs(flow), 'omitnan');

            CI_flow_mls       = SD_flow_mls / Mean_flow_mls;
            CI_abs_flow_mls   = SD_flow_mls_abs / Mean_flow_mls_abs;

            Cumsum_velocity_flow_mls = cumsum(flow, 'omitnan');
            endpoint_Cumsum_velocity_flow_mls = Cumsum_velocity_flow_mls(end);

            % -------------------------
            % (G) Volume displacement from flow (Boole's rule integration)
            %     - Up-sample time grid, spline interpolation, then integrate
            % -------------------------
            upsample_factor = 10;

            Time_CSF_interp = linspace(Time_CSF(1), Time_CSF(end), numel(Time_CSF)*upsample_factor).';
            flow_interp = spline(Time_CSF, flow, Time_CSF_interp);

            dt_flow_interp = mean(diff(Time_CSF_interp), 'omitnan');

            n_flow = numel(flow_interp);
            volume_displacement = zeros(n_flow,1);

            weights_for_volume_displacement = [7, 32, 12, 32, 7] / 90;

            for i = 3:n_flow-2
                f_window = flow_interp(i-2:i+2);
                deltaV = dt_flow_interp * sum(weights_for_volume_displacement .* f_window(:).');
                volume_displacement(i) = volume_displacement(i-1) + deltaV;
            end

            % Fill boundary samples (keep original behavior)
            volume_displacement(1)       = volume_displacement(3);
            volume_displacement(2)       = (volume_displacement(3) + volume_displacement(4)) / 2;
            volume_displacement(n_flow-1)= (volume_displacement(n_flow-2) + volume_displacement(n_flow-3)) / 2;
            volume_displacement(n_flow)  = volume_displacement(n_flow-2);

            % detrend + slope
            results_polyfit = polyfit(Time_CSF_interp, volume_displacement, 1);
            slope_before_detrend = results_polyfit(1);
            intercept_before_detrend = results_polyfit(2);

            volume_displacement_detrended = detrend(volume_displacement);

            % -------------------------
            % (H) Store outputs for downstream reuse (save both unit results)
            % -------------------------
            CSFdyn = struct();

            CSFdyn.saveDirName = saveDirName;
            CSFdyn.geom = geom;

            % velocity (cm/s)
            CSFdyn.velocity = struct();
            CSFdyn.velocity.Mean_abs = Mean_velocity_abs;
            CSFdyn.velocity.Mean     = Mean_velocity;
            CSFdyn.velocity.SD       = SD_velocity;
            CSFdyn.velocity.SD_abs   = SD_abs_velocity;
            CSFdyn.velocity.CI       = CI_velocity;
            CSFdyn.velocity.CI_abs   = CI_abs_velocity;
            CSFdyn.velocity.Cumsum_endpoint = endpoint_Cumsum_velocity;
            CSFdyn.velocity.Cumsum_cali_area_endpoint = endpoint_Cumsum_cali_area_velocity;

            % flow (ml/s)
            CSFdyn.flow = struct();
            CSFdyn.flow.Mean_abs = Mean_flow_mls_abs;
            CSFdyn.flow.Mean     = Mean_flow_mls;
            CSFdyn.flow.SD       = SD_flow_mls;
            CSFdyn.flow.SD_abs   = SD_flow_mls_abs;
            CSFdyn.flow.CI       = CI_flow_mls;
            CSFdyn.flow.CI_abs   = CI_abs_flow_mls;
            CSFdyn.flow.Cumsum_endpoint = endpoint_Cumsum_velocity_flow_mls;

            % displacement
            CSFdyn.displacement = struct();
            CSFdyn.displacement.upsample_factor = upsample_factor;
            CSFdyn.displacement.Time_interp = Time_CSF_interp;
            CSFdyn.displacement.flow_interp = flow_interp;
            CSFdyn.displacement.volume = volume_displacement;
            CSFdyn.displacement.volume_detrended = volume_displacement_detrended;
            CSFdyn.displacement.slope_before_detrend = slope_before_detrend;
            CSFdyn.displacement.intercept_before_detrend = intercept_before_detrend;

            % store raw series too (optional but handy)
            CSFdyn.series = struct();
            CSFdyn.series.Time_CSF = Time_CSF;
            CSFdyn.series.velocity_cms = vel;
            CSFdyn.series.flow_mls = flow;

            app.PreprocessedData_GUI.CSFDynamics = CSFdyn;

            % -------------------------
            % (I) UIAxes6 Plot (Flow + Volume)
            % -------------------------
            app.plotCSFDynamicsOnUIAxes6(Time_CSF, Flow_forPlot, yLabelFlow, ...
                Time_CSF_interp, flow_interp, volume_displacement, volume_displacement_detrended, ...
                titlePrefix, slope_before_detrend);

            % -------------------------
            % (J) Logging
            % -------------------------
            if ~isempty(app.MainApp)
                app.MainApp.logMessage(sprintf('[CSFdyn] saved → PreprocessedData_GUI.CSFDynamics | unit view=%s', titlePrefix));
                app.MainApp.logMessage(sprintf('[CSFdyn] slope(before detrend)=%.6f, intercept=%.6f', ...
                    slope_before_detrend, intercept_before_detrend));
            end
        end

        function plotCSFDynamicsOnUIAxes6(app, Time_CSF, Flow_forPlot, yLabelFlow, ...
                Time_CSF_interp, flow_interp, volume_displacement, volume_displacement_detrended, ...
                titlePrefix, slope_before_detrend)

            ax = app.UIAxes6;

            % ---- (1) Flow/Velocity plot ----
            plot(ax, Time_CSF, Flow_forPlot, 'k', 'LineWidth', 1.2);
            grid(ax,'on'); axis(ax,'tight');
            xlabel(ax, 'Time [s]');
            ylabel(ax, yLabelFlow);
            hold(ax,'on');
            % ---- (2) Volume displacement plot ----
            plot(ax, Time_CSF_interp, volume_displacement,'r', 'LineWidth', 2);
            plot(ax, Time_CSF_interp, volume_displacement_detrended, 'b--', 'LineWidth', 2);
            hold(ax,'off');
            grid(ax,'on'); axis(ax,'tight');
            xlabel(ax, 'Time [s]');
            ylabel(ax, 'Flow (black) [mL/s]; Volume [mL]');
            title(ax, sprintf('Volume displacement (r:original,b:detrend) | slope=%.4f', slope_before_detrend));
            %legend(ax, {'Integrated', 'Detrended'}, 'Location','northeastoutside');

        end


        function runTraditionalPVAnalysis(app)

            if ~isfield(app.PreprocessedData_GUI,'Time_range') || isempty(app.PreprocessedData_GUI.Time_range)
                error('PreprocessedData_GUI.Time_range is missing.');
            end

            % (1) cm/s dataset: Calibration_dataset
            [calData_cms, saveDirName] = app.getCalibrationDataset();
            calData_cms = calData_cms(:); % column

            % (2) ml/s dataset: cm/s -> ml/s
            [flow_mls, geom] = app.computeFlow_mls_fromVelocity(calData_cms);
            flow_mls = flow_mls(:);

            % (3) Traditional method analysis (cm/s)
            out_cms = app.computeTraditionalPVCore(calData_cms);
            out_cms.units = 'cm/s';

            % (4) Traditional method analysis 분석 (ml/s)
            out_mls = app.computeTraditionalPVCore(flow_mls);
            out_mls.units = 'mL/s';
            out_mls.geom  = geom; % 변환에 사용된 geometry 기록

            % (5) Save structure
            S = struct();
            S.saveDirName = saveDirName;
            S.time_csf    = app.PreprocessedData_GUI.Time_range(:);
            S.cms         = out_cms;
            S.mls         = out_mls;

            app.PreprocessedData_GUI.Traditional = S;

            % (6) Logging
            if ~isempty(app.MainApp)
                app.MainApp.logMessage(sprintf('[Traditional][cm/s] Max=%.4f Min=%.4f | MeanPeak=%.4f MeanValley=%.4f', ...
                    out_cms.Max_from_fit, out_cms.Min_from_fit, out_cms.Mean_peak, out_cms.Mean_valley));

                app.MainApp.logMessage(sprintf('[Traditional][ml/s] Max=%.4f Min=%.4f | MeanPeak=%.4f MeanValley=%.4f', ...
                    out_mls.Max_from_fit, out_mls.Min_from_fit, out_mls.Mean_peak, out_mls.Mean_valley));
            end
        end


        function out = computeTraditionalPVCore(app, sig, opts)
            % computeTraditionalPVCore
            % ------------------------------------------------------------
            % Implements the "traditional" approach:
            %   (1) Binning into a single cycle using peak-based shifting
            %   (2) Fourier (fourier1) fitting on the binned cycle
            %   (3) Peak/valley statistics using findpeaks on the raw signal
            %
            % INPUT
            %   sig  : 1D signal (cm/s or mL/s). Row/column orientation is OK.
            %   opts : (optional) struct
            %       .doPlot   (true/false)     : whether to plot binned result
            %       .axPlot   (uiaxes/axes)    : target axes for plotting
            %       .saveDir  (char/string)    : optional save folder for exported figures
            %       .maxShift (integer)        : max shift iterations for binning (default: nt-1)
            %
            % OUTPUT (out)
            %   out.bb                 : binned cycle values (Kx1)
            %   out.velocity           : interpolated cycle values (vector)
            %   out.xx                 : x-grid for interpolation
            %   out.tt_interp          : interpolated time grid
            %   out.vel_interp         : fitted/interpolated waveform on tt_interp
            %   out.Max_from_fit       : max of vel_interp
            %   out.Min_from_fit       : min of vel_interp
            %
            %   out.max_peaks_idx      : indices of positive peaks (raw signal)
            %   out.min_peaks_idx      : indices of negative peaks (raw signal)
            %   out.Mean_peak          : mean of positive peak values
            %   out.SD_peak            : std  of positive peak values
            %   out.Mean_valley        : mean of valley values (negative peaks)
            %   out.SD_valley          : std  of valley values
            %   out.CI_peak            : SD_peak / Mean_peak
            %   out.CI_valley          : SD_valley / Mean_valley
            %   out.Mean_diff_peak_valley : (Mean_peak + Mean_valley)  (legacy definition)
            %   out.SD_diff_peak_valley   : (SD_peak + SD_valley)      (legacy definition)
            %   out.CI_diff_peak_valley   : (SD_peak+SD_valley) / (Mean_peak+Mean_valley)
            %
            % Notes
            %   - This function intentionally preserves legacy behavior/definitions.
            %   - If 'fit' (curve fitting toolbox) fails, it falls back to pchip interpolation.
            % ------------------------------------------------------------

            if nargin < 3 || isempty(opts); opts = struct(); end
            if ~isfield(opts,'doPlot');   opts.doPlot = false; end
            if ~isfield(opts,'axPlot');   opts.axPlot = []; end
            if ~isfield(opts,'saveDir');  opts.saveDir = ''; end

            sig = sig(:);                         % Force column vector
            CSF_signal_Traditional_method = sig;      % Keep legacy variable naming
            nt = length(CSF_signal_Traditional_method);  % Signal length (time samples)

            if ~isfield(opts,'maxShift') || isempty(opts.maxShift)
                opts.maxShift = nt - 1;           
            end

            out = struct();
            out.nt = nt;

            % ============================================================
            % Method 1: "Binning" into a single cycle + sine-like fitting
            % ============================================================
            [~, peaks] = findpeaks(CSF_signal_Traditional_method);

            out.peaks_idx_first = peaks;

            % If no peaks exist, binning cannot proceed -> return NaNs for this branch
            if isempty(peaks)
                out.bb = [];
                out.velocity = [];
                out.xx = [];
                out.tt_interp = [];
                out.vel_interp = [];

                out.Max_from_fit = NaN;
                out.Min_from_fit = NaN;

            else
                % Create a peak-indicator vector, then build shifted versions (columns)
                in_a_bin = zeros(nt,1);
                in_a_bin(peaks) = 1;
                cnt = 1;

                % Legacy binning logic:
                % Keep adding shifted versions until every time point belongs to at least one bin,
                % i.e., min(sum(in_a_bin,2)) becomes > 0.
                % We also enforce an upper bound (opts.maxShift) for GUI stability.
                while min(sum(in_a_bin,2)) == 0 && cnt < opts.maxShift
                    in_a_bin(:,cnt+1) = circshift(in_a_bin(:,1), cnt);
                    cnt = cnt + 1;
                end

                % If still fails (due to maxShift cap), treat as binning failure
                if min(sum(in_a_bin,2)) == 0
                    out.bb = [];
                    out.velocity = [];
                    out.xx = [];
                    out.tt_interp = [];
                    out.vel_interp = [];
                    out.Max_from_fit = NaN;
                    out.Min_from_fit = NaN;
                else
                    shift_array = 1:size(in_a_bin,2);
                    bin_assignment = zeros(nt,1);
                    for tt = 1:nt
                        bin_assignment(tt) = min(shift_array(in_a_bin(tt,:) == 1));
                    end

                    % Regression target must be (nt x 1)
                    y = CSF_signal_Traditional_method;      % ✅ column (nt x 1)

                    bin_assignment(1:(peaks(1)-1)) = 0;

                    K = max(bin_assignment);
                    if K < 1
                        % No valid bins -> exit early with NaNs
                        out.bb = [];
                        out.vel_interp = [];
                        out.Max_from_fit = NaN;
                        out.Min_from_fit = NaN;
                        return;
                    end

                    x = zeros(nt, K);                   % Build design matrix for bin membership (nt x K)
                    for ii = 1:K
                        x(:,ii) = double(bin_assignment == ii);
                    end

                    bb = x \ y;                         % ✅ (K x 1)
                    out.bb = bb;

                    %%% Fitting a simple sine curve using interpolation %%%
                    xx = 0:1:size(bb)-1;
                    velocity = interpn(0:size(bb)-1, bb(1:end), xx);
                    vel = velocity.';
                    tt = xx.';

                    % Interpolation grid for smoother curve / fitting
                    t_interp = (0:0.2:size(bb)-1);
                    tt_interp = t_interp.';
                    out.xx = xx(:);
                    out.velocity = velocity(:);
                    out.tt_interp = tt_interp(:);

                    try
                        fit2 = fit(tt, vel, 'fourier1');
                        vel_interp = fit2(tt_interp);
                    catch
                        vel_interp = interp1(tt, vel, tt_interp, 'pchip', 'extrap');
                    end

                    out.vel_interp = vel_interp;

                    %%% Final min and max values from the interpolation sine curve %%%
                    out.Max_from_fit = max(vel_interp);
                    out.Min_from_fit = min(vel_interp);

                    
                end
            end

            % ============================================================
            % Method 2: Direct peak/valley statistics from raw signal
            % ============================================================

            [~, max_peaks] = findpeaks(CSF_signal_Traditional_method);
            max_peak_values = CSF_signal_Traditional_method(max_peaks);

            [~, min_peaks] = findpeaks(-CSF_signal_Traditional_method);
            min_peak_values = CSF_signal_Traditional_method(min_peaks);

            out.max_peaks_idx = max_peaks;
            out.min_peaks_idx = min_peaks;

            out.Mean_peak   = mean(max_peak_values, 'omitnan');
            out.SD_peak     = std(max_peak_values,  'omitnan');

            out.Mean_valley = mean(min_peak_values, 'omitnan');
            out.SD_valley   = std(min_peak_values,  'omitnan');

            % CI
            out.CI_peak   = out.SD_peak   / out.Mean_peak;
            out.CI_valley = out.SD_valley / out.Mean_valley;

            % difference
            out.Mean_diff_peak_valley = out.Mean_peak + out.Mean_valley;
            out.SD_diff_peak_valley   = out.SD_peak  + out.SD_valley;

            denom = (out.Mean_peak + out.Mean_valley);
            if denom == 0 || ~isfinite(denom)
                out.CI_diff_peak_valley = NaN;
            else
                out.CI_diff_peak_valley = (out.SD_peak + out.SD_valley) / denom;
            end

            
            out.mean_positive = out.Mean_peak;
            out.mean_negative = out.Mean_valley;
            out.sd_positive   = out.SD_peak;
            out.sd_negative   = out.SD_valley;

            out.Mean_Peak_points   = out.Mean_peak;
            out.Mean_Valley_points = out.Mean_valley;
        end

        function assertPSDPrerequisites(app)

            % assertPSDPrerequisites
            % ------------------------------------------------------------
            % Checks whether all required fields and signals are available
            % before running PSD / spectral analysis.
            %
            % This function throws an error immediately if any prerequisite
            % is missing or empty, preventing downstream failures.
            % ------------------------------------------------------------
            pv = app.PreprocessedData_GUI;

            % ------------------------------------------------------------
            % (1) Core required fields for PSD computation
            % ------------------------------------------------------------
            mustFields = {'Freq_range','Delta_time','Time_range', ...
                'Scalenorm_RESP_synced','Scalenorm_Cardio_synced'};
            for k = 1:numel(mustFields)
                if ~isfield(pv, mustFields{k}) || isempty(pv.(mustFields{k}))
                    error('PreprocessedData_GUI.%s is missing/empty.', mustFields{k});
                end
            end

            % ------------------------------------------------------------
            % (2) CSF signal in flow units (mL/s)
            % ------------------------------------------------------------
            % In the current pipeline, CSF flow (mL/s) is expected to be stored as:
            %   PreprocessedData_GUI.NETflow_mls.CSF_Signal_mls
            %
            % If this field is missing, it means that velocity-to-flow
            % conversion has not been performed yet.
            if isempty(pv.NETflow_mls.CSF_Signal_mls)
                error('PreprocessedData_GUI.NETflow_mls.CSF_Signal_mls is missing. Run ml/s conversion first.');
            end

            % ------------------------------------------------------------
            % (3) Physiological sampling rate
            % ------------------------------------------------------------
            % Resp_Fs / Physio_Fs is required for spectral analysis
            if isempty(pv.Physio_Fs)
                error('pv.Physio_Fs is missing. Set Resp_Fs (physio sampling rate).');
            end

            % ------------------------------------------------------------
            % (4) Filtered respiration signal (optional but recommended)
            % ------------------------------------------------------------
            % PSD can be computed using either raw or filtered respiration.
            % If Resp_filtered is missing, the pipeline may fall back to
            % Scalenorm_RESP_synced depending on implementation.
            if isempty(pv.Resp_filtered)
                % No hard error here: allowed fallback to raw respiration
                % (Scalenorm_RESP_synced).
            end
        end


        function P = getPSDFeatureParams_Default(app)

            % ---- CSF vs PPG ----
            P.Spectrum_range_for_CSF_PPG_upperlimit  = 0.15;
            P.Spectrum_range_for_CSF_PPG_lowerlimit  = 0.15;

            P.Spectrum_range_for_CSF_PPG_peak_found_lowerrange  = 0.8; % 48 BPM
            P.Spectrum_range_for_CSF_PPG_peak_found_upperrange  = 3.0; % 180 BPM

            P.Spectrum_range_for_CSF_PPG_SD_HPBW_Kurtosis_lowerrange = 0.8;
            P.Spectrum_range_for_CSF_PPG_SD_HPBW_Kurtosis_upperrange = 2.0;

            % ---- CSF vs RESP ----
            P.Spectrum_range_for_CSF_RESP_upperlimit = 0.1;
            P.Spectrum_range_for_CSF_RESP_lowerlimit = 0.1;

            P.Spectrum_range_for_CSF_RESP_peak_found_lowerrange = 0.01;
            P.Spectrum_range_for_CSF_RESP_peak_found_upperrange = 0.5;

            P.Spectrum_range_for_CSF_RESP_SD_HPBW_Kurtosis_lowerrange = 0.01;
            P.Spectrum_range_for_CSF_RESP_SD_HPBW_Kurtosis_upperrange = 0.5;

            % (선택) UI plot xlim
            P.PSD_XLIM = [0 2];
        end


        function runPSDAUCFeatures_CSF_RESP_PPG(app, P)

            pv = app.PreprocessedData_GUI;

            % ============================================================
            % Purpose
            %   Compute PSD-based features that quantify:
            %     (1) respiratory-related power in CSF spectrum
            %     (2) cardiac/PPG-related power in CSF spectrum
            %   Then compute AUC + HPBW around the target peaks and store
            %   all results into PreprocessedData_GUI.PSDFeatures.
            %
            % Notes
            %   - CSF PSD is computed from flow in mL/s (NETflow_mls.CSF_Signal_mls)
            %   - Resp/PPG PSDs are computed from synced physio signals
            %   - Resp peak frequency is detected in Resp PSD and mapped onto CSF PSD
            %   - Cardiac peak frequency is detected in PPG PSD and mapped onto CSF PSD
            % ============================================================

            % ================================
            % (A) CSF PSD (flow: mL/s)
            % ================================
            % freq_spec_CSF: expected frequency sampling/grid (from pipeline)
            % Fs_CSF: derived from CSF time resolution (Delta_time)
            freq_spec_CSF = pv.Freq_range(:);
            Fs_CSF = 1 / pv.Delta_time;

            csf_mls = pv.NETflow_mls.CSF_Signal_mls(:);  % Average_ml_s_within_ROI에 대응
            N_CSF   = numel(csf_mls);

            [freq_CSF, PSD_CSF] = app.computePSDfft_singleSided(csf_mls, Fs_CSF, freq_spec_CSF);

            % ================================
            % (B) Find dominant cardiac peak in CSF PSD (e.g., 0.8–3 Hz)
            % ================================
            % This captures the strongest cardiac-related component present in CSF spectrum.
            [peak_freq_cardiac, peak_psd_value_cardiac] = app.findPeakInRange(freq_CSF, PSD_CSF, ...
                P.Spectrum_range_for_CSF_PPG_peak_found_lowerrange, ...
                P.Spectrum_range_for_CSF_PPG_peak_found_upperrange);

            app.MainApp.logMessage(sprintf('Peak frequency [cardiac]: %.2f Hz', peak_freq_cardiac));
            app.MainApp.logMessage(sprintf('Peak PSD value [cardiac]: %.2e ml^2/s^2/Hz', peak_psd_value_cardiac));

            % ================================
            % (C) Find dominant respiratory peak in CSF PSD (e.g., 0.01–0.5 Hz)
            % ================================
            % This captures the strongest respiration-related component present in CSF spectrum.
            [peak_freq_CSF_RESP, peak_psd_value_CSF_RESP] = app.findPeakInRange(freq_CSF, PSD_CSF, ...
                P.Spectrum_range_for_CSF_RESP_peak_found_lowerrange, ...
                P.Spectrum_range_for_CSF_RESP_peak_found_upperrange);

            app.MainApp.logMessage(sprintf('Peak frequency [respiration in CSF]: %.2f Hz', peak_freq_CSF_RESP));
            app.MainApp.logMessage(sprintf('Peak PSD value [respiration in CSF]: %.2e ml^2/s^2/Hz', peak_psd_value_CSF_RESP));

            % ================================
            % (D) Resp PSD → detect resp peak → map onto CSF PSD (AUC/HPBW)
            % ================================
            % Step D-1: Compute Resp PSD from synced respiration signal
            Fs_RESP = pv.Physio_Fs;

            resp_sig = pv.Scalenorm_RESP_synced(:);
            [freq_RESP, PSD_RESP] = app.computePSDfft_singleSided(resp_sig, Fs_RESP, []); %#ok<ASGLU>

            [peak_freq_RESP, ~] = app.findPeakInRange(freq_RESP, PSD_RESP, ...
                P.Spectrum_range_for_CSF_RESP_peak_found_lowerrange, ...
                P.Spectrum_range_for_CSF_RESP_peak_found_upperrange);

            app.MainApp.logMessage(sprintf('Peak frequency [Resp]: %.2f Hz', peak_freq_RESP));

            % Step D-3: Quantify respiration-related power in CSF spectrum
            % Compute AUC/HPBW (and optional SD/HPBW/kurtosis) around peak_freq_RESP on CSF PSD
            RespInCSF = app.computeAUCandHPBW_onCSF(freq_CSF, PSD_CSF, peak_freq_RESP, ...
                P.Spectrum_range_for_CSF_RESP_lowerlimit, ...
                P.Spectrum_range_for_CSF_RESP_upperlimit, ...
                P.Spectrum_range_for_CSF_RESP_SD_HPBW_Kurtosis_lowerrange, ...
                P.Spectrum_range_for_CSF_RESP_SD_HPBW_Kurtosis_upperrange);

            app.MainApp.logMessage(sprintf('Calculated AUC [Resp in CSF]: %.2e', RespInCSF.AUC));
            app.MainApp.logMessage(sprintf('Half-Power Bandwidth (HPBW) inCSF for RESP: %.3f Hz', RespInCSF.HPBW));

            % ================================
            % (E) PPG PSD → detect cardiac peak → map onto CSF PSD (AUC/HPBW)
            % ================================
            % IMPORTANT:
            %   Here we assume PPG sampling rate equals pv.Physio_Fs.
            %   If PPG Fs differs from Resp Fs in your acquisition, separate Fs_PPG is needed.
            Fs_PPG = pv.Physio_Fs;
            ppg_sig = pv.Scalenorm_Cardio_synced(:);

            [freq_PPG, PSD_PPG] = app.computePSDfft_singleSided(ppg_sig, Fs_PPG, []); %#ok<ASGLU>

            [peak_freq_PPG, ~] = app.findPeakInRange(freq_PPG, PSD_PPG, ...
                P.Spectrum_range_for_CSF_PPG_peak_found_lowerrange, ...
                P.Spectrum_range_for_CSF_PPG_peak_found_upperrange);

            app.MainApp.logMessage(sprintf('Peak frequency [PPG]: %.2f Hz', peak_freq_PPG));

            % Quantify cardiac-related power in CSF spectrum (mapped using peak_freq_PPG)
            PPGInCSF = app.computeAUCandHPBW_onCSF(freq_CSF, PSD_CSF, peak_freq_PPG, ...
                P.Spectrum_range_for_CSF_PPG_lowerlimit, ...
                P.Spectrum_range_for_CSF_PPG_upperlimit, ...
                P.Spectrum_range_for_CSF_PPG_SD_HPBW_Kurtosis_lowerrange, ...
                P.Spectrum_range_for_CSF_PPG_SD_HPBW_Kurtosis_upperrange);

            app.MainApp.logMessage(sprintf('Calculated AUC [PPG in CSF]: %.2e', PPGInCSF.AUC));
            app.MainApp.logMessage(sprintf('Half-Power Bandwidth (HPBW) inCSF for PPG: %.3f Hz', PPGInCSF.HPBW));

            Ratio_AUC_RESP_PPG = RespInCSF.AUC / PPGInCSF.AUC;
            app.MainApp.logMessage(sprintf('Ratio AUC [Resp/PPG in CSF]: %.2f', Ratio_AUC_RESP_PPG));

            % ================================
            % (F) Plot on UIAxes (Fig13/Fig14 equivalents)
            % ================================
            % UIAxes6_2: CSF PSD with Resp-mapped band/AUC
            % UIAxes6_3: CSF PSD with PPG-mapped band/AUC
            app.plotCSF_PSD_withAUC(app.UIAxes6_2, freq_CSF, PSD_CSF, RespInCSF, ...
                'Spectrum RESP in CSF spectrum');

            app.plotCSF_PSD_withAUC(app.UIAxes6_3, freq_CSF, PSD_CSF, PPGInCSF, ...
                'Spectrum Cardio in CSF spectrum');

            if isfield(P,'PSD_XLIM') && ~isempty(P.PSD_XLIM)
                xlim(app.UIAxes6_2, P.PSD_XLIM);
                xlim(app.UIAxes6_3, P.PSD_XLIM);
            end

            % ================================
            % (G) Store results for downstream analysis / export
            % ================================
            F = struct();
            F.params = P;

            F.CSF.freq = freq_CSF;
            F.CSF.PSD  = PSD_CSF;

            F.CSF.peak_freq_cardiac = peak_freq_cardiac;
            F.CSF.peak_psd_cardiac  = peak_psd_value_cardiac;

            F.CSF.peak_freq_resp_inCSF = peak_freq_CSF_RESP;
            F.CSF.peak_psd_resp_inCSF  = peak_psd_value_CSF_RESP;

            F.Resp.peak_freq = peak_freq_RESP;
            F.PPG.peak_freq  = peak_freq_PPG;

            F.RespInCSF = RespInCSF;
            F.PPGInCSF  = PPGInCSF;

            F.Ratio_AUC_RESP_PPG = Ratio_AUC_RESP_PPG;

            app.PreprocessedData_GUI.PSDFeatures = F;
        end

        function [freq_out, PSD_out] = computePSDfft_singleSided(app, sig, Fs, freq_in)

            sig = sig(:);
            N   = numel(sig);

            spec = fft(sig);
            Mag  = abs(spec) / N;
            Pxx  = Mag.^2;

            % single-sided
            Pxx_single = Pxx(1:floor(N/2)+1);

            if isempty(freq_in)
                freq_spec = linspace(0, Fs, N).';
                freq_spec = freq_spec(1:floor(N/2)+1);
            else
                freq_spec = freq_in(:);
                freq_spec = freq_spec(1:floor(N/2)+1);
            end

            if numel(Pxx_single) >= 3
                Pxx_single(2:end-1) = 2 * Pxx_single(2:end-1); % energy compensation
            end

            PSD = Pxx_single / Fs;

            % remove 0 freq if you want
            freq_out = freq_spec;
            PSD_out  = PSD;
        end


        function [peak_freq, peak_psd] = findPeakInRange(app, freq, PSD, fLow, fHigh)

            freq = freq(:); PSD = PSD(:);

            idx = find(freq >= fLow & freq <= fHigh);

            if isempty(idx)
                peak_freq = NaN;
                peak_psd  = NaN;
                return;
            end

            psd_range = PSD(idx);
            [peak_psd, k] = max(psd_range);
            peak_freq = freq(idx(k));
        end

        function out = computeAUCandHPBW_onCSF(app, freq_CSF, PSD_CSF, peak_freq_fromPhysio, ...
                lowerLimit, upperLimit, sdRangeLow, sdRangeHigh)

            freq_CSF = freq_CSF(:);
            PSD_CSF  = PSD_CSF(:);

            out = struct();
            out.peak_freq_physio = peak_freq_fromPhysio;

            if ~isfinite(peak_freq_fromPhysio)
                out.closest_index = NaN;
                out.closest_freq  = NaN;
                out.lower_f = NaN; out.upper_f = NaN;
                out.freq_range = [];
                out.PSD_range  = [];
                out.AUC = NaN;
                out.SD_PSD_specific = NaN;
                out.HPBW = NaN;
                return;
            end

            [~, idx0] = min(abs(freq_CSF - peak_freq_fromPhysio));
            out.closest_index = idx0;
            out.closest_freq  = freq_CSF(idx0);

            lower_f = out.closest_freq - lowerLimit;
            upper_f = out.closest_freq + upperLimit;

            i1 = find(freq_CSF >= lower_f, 1, 'first');
            i2 = find(freq_CSF <= upper_f, 1, 'last');

            if isempty(i1) || isempty(i2) || i2 <= i1
                out.lower_f = lower_f; out.upper_f = upper_f;
                out.freq_range = [];
                out.PSD_range  = [];
                out.AUC = NaN;
                out.SD_PSD_specific = NaN;
                out.HPBW = NaN;
                return;
            end

            fseg = freq_CSF(i1:i2);
            pseg = PSD_CSF(i1:i2);

            keep = (fseg ~= 0);
            fseg = fseg(keep);
            pseg = pseg(keep);

            out.lower_f = lower_f;
            out.upper_f = upper_f;
            out.freq_range = fseg;
            out.PSD_range  = pseg;

            out.AUC = trapz(fseg, pseg);

            j1 = find(freq_CSF >= sdRangeLow, 1, 'first');
            j2 = find(freq_CSF <= sdRangeHigh, 1, 'last');
            if isempty(j1) || isempty(j2) || j2 <= j1
                out.SD_PSD_specific = NaN;
            else
                out.SD_PSD_specific = std(PSD_CSF(j1:j2), 'omitnan');
            end

            peak_power = PSD_CSF(idx0);
            half_level = peak_power / 2;

            if isempty(j1) || isempty(j2) || j2 <= j1
                out.HPBW = NaN;
            else
                f_spec = freq_CSF(j1:j2);
                p_spec = PSD_CSF(j1:j2);
                idxAbove = find(p_spec >= half_level);

                if isempty(idxAbove)
                    out.HPBW = NaN;
                else
                    out.HPBW = f_spec(idxAbove(end)) - f_spec(idxAbove(1));
                end
            end

            out.peak_power = peak_power;
            out.half_power = half_level;
        end


        function plotCSF_PSD_withAUC(app, ax, freq_CSF, PSD_CSF, aucStruct, plotTitle)

            cla(ax);

            freq_CSF = freq_CSF(:);
            PSD_CSF  = PSD_CSF(:);

            % base PSD
            hLine = plot(ax, freq_CSF, PSD_CSF, 'b', 'LineWidth', 1.5); hold(ax, 'on');
            hLine.HitTest = 'off';

            % AUC region fill
            if isfield(aucStruct,'freq_range') && ~isempty(aucStruct.freq_range)
                fseg = aucStruct.freq_range(:);
                pseg = aucStruct.PSD_range(:);

                % fill polygon
                hf = fill(ax, ...
                    [fseg; flipud(fseg)], ...
                    [zeros(size(fseg)); flipud(pseg)], ...
                    'b', 'FaceAlpha', 0.5, 'EdgeColor', 'none');
                hf.HitTest = 'off';

                % segment outline + marker
                hSeg = plot(ax, fseg, pseg, 'ro-', 'MarkerFaceColor','r', 'LineWidth', 1.0);
                hSeg.HitTest = 'off';
            end

            grid(ax, 'on');
            xlabel(ax, 'Spectrum (Hz)');
            ylabel(ax, 'PSD (unit^2/Hz)');
            title(ax, sprintf('%s | AUC=%.2e | HPBW=%.3f Hz', plotTitle, aucStruct.AUC, aucStruct.HPBW));

            hold(ax, 'off');
        end


        function params = getPPGDetectParamsFiltered(app)

            % defaults
            dPeakH = 0.5;
            dPeakD = 0.5;
            dValH  = 0.5;
            dValD  = 0.5;

            % EditField 값 읽기 (비어있거나 NaN이면 default로)
            v7  = app.Pkdetect_1_EditField_7.Value;   % Peak MinPeakHeight
            v8  = app.Pkdetect_1_EditField_8.Value;   % Peak MinPeakDistance
            v9  = app.Pkdetect_1_EditField_9.Value;   % Valley MinPeakHeight
            v10 = app.Pkdetect_1_EditField_10.Value;  % Valley MinPeakDistance

            if isempty(v7)  || ~isfinite(v7);  v7  = dPeakH; app.Pkdetect_1_EditField_7.Value  = v7;  end
            if isempty(v8)  || ~isfinite(v8);  v8  = dPeakD; app.Pkdetect_1_EditField_8.Value  = v8;  end
            if isempty(v9)  || ~isfinite(v9);  v9  = dValH;  app.Pkdetect_1_EditField_9.Value  = v9;  end
            if isempty(v10) || ~isfinite(v10); v10 = dValD;  app.Pkdetect_1_EditField_10.Value = v10; end


            params = struct();
            params.MinPeakHeight_peak     = v7;
            params.MinPeakDistance_peak   = v8;
            params.MinPeakHeight_valley   = v9;
            params.MinPeakDistance_valley = v10;

            params.PPG_frequency_filter_high = 10;   
            params.PPG_frequency_filter_low  = 0;    %#ok<NASGU> 

            % IBI band
            params.IBI_LF = [0.04 0.15];
            params.IBI_HF = [0.15 0.40];
        end

        function runPPGPeakDetectionCore(app)

            % -------------------------
            % (A) prerequisite
            % -------------------------
            if ~isfield(app.PreprocessedData_GUI,'Time_synced_Physio') || isempty(app.PreprocessedData_GUI.Time_synced_Physio)
                error('PreprocessedData_GUI.Time_synced_Physio is missing.');
            end
            if ~isfield(app.PreprocessedData_GUI,'Scalenorm_Cardio_synced') || isempty(app.PreprocessedData_GUI.Scalenorm_Cardio_synced)
                error('PreprocessedData_GUI.Scalenorm_Cardio_synced is missing.');
            end

            t = app.PreprocessedData_GUI.Time_synced_Physio(:);
            x_raw = app.PreprocessedData_GUI.Scalenorm_Cardio_synced(:);

            if numel(t) ~= numel(x_raw)
                error('PPG time and signal length mismatch.');
            end

            Fs = app.PreprocessedData_GUI.Physio_Fs;

            % -------------------------
            % (B) params
            % -------------------------
            params = app.getPPGDetectParamsFiltered();

            % -------------------------
            % (C) filtering (lowpass 10 Hz)
            % -------------------------
            order = 2;
            [b, a] = butter(order, [params.PPG_frequency_filter_high] / (Fs/2), 'low');
            x_filt = filtfilt(b, a, x_raw);

            % -------------------------
            % (D) peak/valley detection
            % -------------------------
            [peaks_PPG, peak_locs_PPG] = findpeaks(x_filt, t, ...
                'MinPeakHeight',   params.MinPeakHeight_peak, ...
                'MinPeakDistance', params.MinPeakDistance_peak);

            [valleys_PPG_neg, valley_locs_PPG] = findpeaks(-x_filt, t, ...
                'MinPeakHeight',   params.MinPeakHeight_valley, ...
                'MinPeakDistance', params.MinPeakDistance_valley);

            valleys_PPG = -valleys_PPG_neg;

            % -------------------------
            % (E) UIAxes_3, Figure16
            % -------------------------
            ax16 = app.UIAxes_3;
            cla(ax16);

            hraw = plot(ax16, t, x_raw, 'k', 'LineWidth', 2); hold(ax16,'on');
            hf   = plot(ax16, t, x_filt, 'LineWidth', 3);
            hp   = plot(ax16, peak_locs_PPG, peaks_PPG, 'ro', 'MarkerFaceColor','r');
            hv   = plot(ax16, valley_locs_PPG, valleys_PPG, 'bo', 'MarkerFaceColor','b');

            hraw.HitTest = 'off';
            hf.HitTest   = 'off';
            hp.HitTest   = 'off';
            hv.HitTest   = 'off';

            grid(ax16,'on');
            xlabel(ax16,'Time (s)');
            ylabel(ax16,'PPG Signal');
            title(ax16,'Peak and Valley Detection in PPG Signal');
            legend(ax16, {'Raw','Filtered','Peaks','Valleys'}, 'Location','northeastoutside');
            hold(ax16,'off');

            % -------------------------
            % (F) IBI calculation (.ibi)
            % -------------------------
            IBI = [];
            IBI_time = [];
            IBI_interp_t = [];
            IBI_interp = [];
            IBI_interp_trim_t = [];
            IBI_interp_trim = [];
            IBI_interp_trim_HR = [];

            if numel(peak_locs_PPG) >= 2
                Diff_Peaklocs_PPG = diff(peak_locs_PPG);  % beat-by-beat interval (sec)
                cumulative_time = [0; cumsum(Diff_Peaklocs_PPG(1:end-1))];

                IBI = [cumulative_time(:), Diff_Peaklocs_PPG(:)];
                IBI_time = IBI(:,1);

                % interpolation
                Interp_T = numel(x_filt)/Fs;
                IBI_interp_t = (0:1/Fs:Interp_T-1/Fs).';
                IBI_interp = interp1(IBI(:,1), IBI(:,2), IBI_interp_t, 'linear', 'extrap');

                IBI_last_end_time = IBI(end,1);
                valid_idx = IBI_interp_t <= IBI_last_end_time;

                IBI_interp_trim_t = IBI_interp_t(valid_idx);
                IBI_interp_trim   = IBI_interp(valid_idx);
                IBI_interp_trim_HR = 60 ./ IBI_interp_trim;
            end

            % -------------------------
            % (G) LF/HF filtering + UIAxes6_4, Figure19
            % -------------------------
            ax19 = app.UIAxes6_4;
            cla(ax19);

            LF_filt = [];
            HF_filt = [];

            if ~isempty(IBI_interp_trim_HR) && numel(IBI_interp_trim_HR) >= 10
                % LF
                [bLF, aLF] = butter(2, params.IBI_LF/(Fs/2), 'bandpass');
                LF_filt = filtfilt(bLF, aLF, IBI_interp_trim_HR);

                % HF
                [bHF, aHF] = butter(2, params.IBI_HF/(Fs/2), 'bandpass');
                HF_filt = filtfilt(bHF, aHF, IBI_interp_trim_HR);

                plot(ax19, IBI_interp_trim_t, IBI_interp_trim_HR, 'k', 'LineWidth', 3, 'DisplayName','HR'); hold(ax19,'on');
                plot(ax19, IBI_interp_trim_t, LF_filt+mean(IBI_interp_trim_HR, 'omitnan'), 'LineWidth', 1.5, 'DisplayName','LF');
                plot(ax19, IBI_interp_trim_t, HF_filt+mean(IBI_interp_trim_HR, 'omitnan'), 'LineWidth', 1.5, 'DisplayName','HF');
                hold(ax19,'off');

                legend(ax19,'Location','northeastoutside');
                xlabel(ax19,'Time (s)');
                ylabel(ax19,'Heart Rate-PPG BPM');
                title(ax19,'Heart Rate-PPG LF/HF filtering comparison');
                grid(ax19,'on');
            else
                plot(ax19, 0, 0, '.'); axis(ax19,'off');
                title(ax19,'IBI plot skipped (not enough PPG peaks)');
            end

            % -------------------------
            % (H) save data
            % -------------------------
            R = struct();
            R.params = params;

            R.Fs = Fs;
            R.t = t;
            R.raw = x_raw;
            R.filtered = x_filt;

            R.peaks = peaks_PPG;
            R.peak_locs = peak_locs_PPG;
            R.valleys = valleys_PPG;
            R.valley_locs = valley_locs_PPG;

            R.IBI = IBI;
            R.IBI_interp_t = IBI_interp_t;
            R.IBI_interp = IBI_interp;

            R.IBI_trim_t = IBI_interp_trim_t;
            R.IBI_trim = IBI_interp_trim;
            R.IBI_trim_HR = IBI_interp_trim_HR;

            R.LF_filtered = LF_filt;
            R.HF_filtered = HF_filt;

            app.PreprocessedData_GUI.PPG = R;

            % app level fields
            app.PreprocessedData_GUI.PPG.PPG_t      = t;
            app.PreprocessedData_GUI.PPG.PPG_raw    = x_raw;
            app.PreprocessedData_GUI.PPG.PPG_filt   = x_filt;
            app.PreprocessedData_GUI.PPG.PPG_peaks  = peaks_PPG;
            app.PreprocessedData_GUI.PPG.PPG_peak_locs = peak_locs_PPG;
            app.PreprocessedData_GUI.PPG.PPG_valleys   = valleys_PPG;
            app.PreprocessedData_GUI.PPG.PPG_valley_locs = valley_locs_PPG;

            if ~isempty(app.MainApp)
                app.MainApp.logMessage(sprintf('[PPG] peaks=%d, valleys=%d | Fs=%.3f', numel(peaks_PPG), numel(valleys_PPG), Fs));
                if ~isempty(IBI)
                    app.MainApp.logMessage(sprintf('[PPG] IBI rows=%d | IBI file saved', size(IBI,1)));
                end
            end
        end


        function ax = ensureTotalTemporalAxes10(app)
            % Ensure 10 stacked axes inside app.TotalTemporalSignalPanel
            % Store axes handles in app.TotalTemporalAxes10 (NOT in the panel property)

            if ~isprop(app,'TotalTemporalSignalPanel') || isempty(app.TotalTemporalSignalPanel) || ~isvalid(app.TotalTemporalSignalPanel)
                error('TotalTemporalSignalPanel is missing. Please create a Panel in the UI and name it "TotalTemporalSignalPanel".');
            end

            % Reuse existing axes if they already exist and are valid
            if isprop(app,'TotalTemporalAxes10') && ~isempty(app.TotalTemporalAxes10) ...
                    && numel(app.TotalTemporalAxes10)==10 && all(isvalid(app.TotalTemporalAxes10))
                ax = app.TotalTemporalAxes10;
                return;
            end

            % Remove existing children inside the panel (keep the panel itself)
            delete(app.TotalTemporalSignalPanel.Children);

            % Create a compact 10x1 grid layout
            gl = uigridlayout(app.TotalTemporalSignalPanel, [10 1]);
            gl.RowHeight   = repmat({'1x'}, 1, 10);
            gl.ColumnWidth = {'1x'};
            gl.Padding     = [1 1 1 1];
            gl.RowSpacing  = 1;

            ax = gobjects(10,1);
            for i = 1:10
                ax(i) = uiaxes(gl);
                ax(i).Layout.Row = i;

                ax(i).XGrid = 'on';
                ax(i).YGrid = 'on';
                ax(i).Box   = 'on';

                % Optional: minimize margins for a compact layout
                ax(i).LooseInset = max(ax(i).TightInset, [0.005 0.005 0.005 0.005]);
            end

            % Store axes handles in a dedicated app property
            app.TotalTemporalAxes10 = ax;
        end


        function plotTotalTemporalSignals10(app)

            % -------------------------
            % (A) required data check
            % -------------------------
            P = app.PreprocessedData_GUI;

            % time vectors
            Time_RESP   = P.Time_synced_Physio(:);
            Time_CSF    = P.Time_range(:);
            Time_Volume = P.CSFDynamics.displacement.Time_interp(:);
            Time_PPG    = P.PPG.IBI_trim_t(:);

            % signals
            resp_signal = P.Scalenorm_RESP_synced(:);
            csf_signal  = P.CSFDynamics.series.flow_mls(:);                 % ml/s
            vd_signal   = P.CSFDynamics.displacement.volume_detrended(:);   % ml
            ppg_signal  = P.PPG.IBI_trim_HR(:);                             % BPM

            if numel(Time_RESP)~=numel(resp_signal); error('RESP time/signal mismatch'); end
            if numel(Time_CSF)~=numel(csf_signal);  error('CSF time/signal mismatch'); end
            if numel(Time_Volume)~=numel(vd_signal); error('VD time/signal mismatch'); end
            if numel(Time_PPG)~=numel(ppg_signal); error('PPG time/signal mismatch'); end

            % Fs
            Fs_CSF = 1 / app.PreprocessedData_GUI.Delta_time;
            Diff_Time_Volume = diff(Time_Volume);
            Fs_Vol = 1/(Diff_Time_Volume(1));

            % -------------------------
            % (B) get band ranges (RESP band, PPG band) from saved PSD/AUC results
            % -------------------------
            
            CSF_filtering_RESP_low  = app.PreprocessedData_GUI.PSDFeatures.RespInCSF.freq_range(1);
            CSF_filtering_RESP_high = app.PreprocessedData_GUI.PSDFeatures.RespInCSF.freq_range(end);

            CSF_filtering_PPG_low   = app.PreprocessedData_GUI.PSDFeatures.PPGInCSF.freq_range(1);
            CSF_filtering_PPG_high  = app.PreprocessedData_GUI.PSDFeatures.PPGInCSF.freq_range(end);

            % -------------------------
            % (C) filtering CSF(flow) with padding
            % -------------------------
            padding_length_time_based = round(15 * Fs_CSF); padding_length= padding_length_time_based;
            % ✅ column padding (pad×1), and vertical concat
            padded_signal = [ ...
                repmat(csf_signal(1), padding_length, 1); ...
                csf_signal; ...
                repmat(csf_signal(end), padding_length, 1) ...
                ];
            %Step 3-1: Design Bandpass Filter
            order = 2; % Filter order
            [b, a] = butter(order, [CSF_filtering_RESP_low CSF_filtering_RESP_high] / (Fs_CSF/2), 'bandpass');

            % Step 3-2: Apply Filter Using filtfilt
            csf_signal_RESP_freq_filtered = filtfilt(b, a, padded_signal);
            csf_signal_RESP_freq_filtered = csf_signal_RESP_freq_filtered(padding_length+1:end-padding_length);

            % Step 3-1: Design Bandpass Filter
            order = 2; % Filter order
            [b, a] = butter(order, [CSF_filtering_PPG_low CSF_filtering_PPG_high] / (Fs_CSF/2), 'bandpass');

            % Step 3-2: Apply Filter Using filtfilt
            csf_signal_PPG_freq_filtered = filtfilt(b, a, padded_signal);
            csf_signal_PPG_freq_filtered = csf_signal_PPG_freq_filtered(padding_length+1:end-padding_length);

            % -------------------------
            % (D) filtering VD with same band limits (but Fs_Volume)
            % -------------------------
            % filtering IBI-PPG signal
            VD_filtering_RESP_frequency_low = app.PreprocessedData_GUI.PSDFeatures.RespInCSF.freq_range(1);
            VD_filtering_RESP_frequency_high = app.PreprocessedData_GUI.PSDFeatures.RespInCSF.freq_range(end);

            VD_filtering_PPG_frequency_low = app.PreprocessedData_GUI.PSDFeatures.PPGInCSF.freq_range(1);
            VD_filtering_PPG_frequency_high = app.PreprocessedData_GUI.PSDFeatures.PPGInCSF.freq_range(end);

            padding_length_time_based_VD = round(15 * Fs_Vol); padding_length_VD= padding_length_time_based_VD;
            padded_signal_VD = [ ...
                repmat(vd_signal(1), padding_length_VD, 1); ...
                vd_signal; ...
                repmat(vd_signal(end), padding_length_VD, 1) ...
                ];
            %Step 3-1: Design Bandpass Filter
            order = 2; % Filter order
            [b, a] = butter(order, [VD_filtering_RESP_frequency_low VD_filtering_RESP_frequency_high] / (Fs_Vol/2), 'bandpass');

            % Step 3-2: Apply Filter Using filtfilt
            VD_signal_RESP_freq_filtered = filtfilt(b, a, padded_signal_VD);
            VD_signal_RESP_freq_filtered = VD_signal_RESP_freq_filtered(padding_length_VD+1:end-padding_length_VD);

            % Step 3-1: Design Bandpass Filter
            order = 2; % Filter order
            [b, a] = butter(order, [VD_filtering_PPG_frequency_low VD_filtering_PPG_frequency_high] / (Fs_Vol/2), 'bandpass');

            % Step 3-2: Apply Filter Using filtfilt
            VD_signal_PPG_freq_filtered = filtfilt(b, a, padded_signal_VD);
            VD_signal_PPG_freq_filtered = VD_signal_PPG_freq_filtered(padding_length_VD+1:end-padding_length_VD);


            % -------------------------
            % (E) PPG LF/HF signals
            % -------------------------
            ppg_LF = P.PPG.LF_filtered(:) + mean(ppg_signal,'omitnan');
            ppg_HF = P.PPG.HF_filtered(:) + mean(ppg_signal,'omitnan');

            if numel(ppg_LF) ~= numel(Time_PPG)
                n = min(numel(ppg_LF), numel(Time_PPG));
                Time_PPG = Time_PPG(1:n);
                ppg_signal = ppg_signal(1:n);
                ppg_LF = ppg_LF(1:n);
                ppg_HF = ppg_HF(1:n);
            end

            % -------------------------
            % (F) save filtered results into app data
            % -------------------------
            if ~isfield(P,'TotalTemporal'); P.TotalTemporal = struct(); end

            P.TotalTemporal.CSF_RESP_filtered = csf_signal_RESP_freq_filtered(:);
            P.TotalTemporal.CSF_PPG_filtered  = csf_signal_PPG_freq_filtered(:);

            P.TotalTemporal.VD_RESP_filtered  = VD_signal_RESP_freq_filtered(:);
            P.TotalTemporal.VD_PPG_filtered   = VD_signal_PPG_freq_filtered(:);

            app.PreprocessedData_GUI.filteredCSF_VD = P;  % commit

            % -------------------------
            % (G) plot on TotalTemporalSignalPanel (10 axes)
            % -------------------------
            ax = app.ensureTotalTemporalAxes10();

            FS_TICK  = 7;
            FS_LABEL = 8;
            FS_TITLE = 9;


            tEnd = [Time_RESP(end), Time_CSF(end), Time_Volume(end), Time_PPG(end)];
            tEnd = min(tEnd);
            xLimCommon = [max(0, min([Time_RESP(1), Time_CSF(1), Time_Volume(1), Time_PPG(1)])), tEnd];

            % 1) Resp
            cla(ax(1));
            plot(ax(1), Time_RESP, resp_signal, 'k', 'LineWidth', 3);
            axis(ax(1),'tight'); xlim(ax(1), [0, tEnd]); 
            ylabel(ax(1), 'A.U.'); title(ax(1), 'Respiration');

            % 2) CSF flow raw
            cla(ax(2));
            plot(ax(2), Time_CSF, csf_signal, 'r', 'LineWidth', 1.5);
            axis(ax(2),'tight'); xlim(ax(2), [0, tEnd]); 
            ylabel(ax(2), 'mL/s'); title(ax(2), 'CSF flow');

            % 3) CSF flow filtered RESP
            cla(ax(3));
            plot(ax(3), Time_CSF, csf_signal_RESP_freq_filtered, 'r', 'LineWidth', 1.5);
            axis(ax(3),'tight'); xlim(ax(3), [0, tEnd]); 
            ylabel(ax(3), 'mL/s'); title(ax(3), 'CSF flow filtered RESP band');

            % 4) CSF flow filtered PPG
            cla(ax(4));
            plot(ax(4), Time_CSF, csf_signal_PPG_freq_filtered, 'r', 'LineWidth', 1.5);
            axis(ax(4),'tight'); xlim(ax(4), [0, tEnd]); 
            ylabel(ax(4), 'mL/s'); title(ax(4), 'CSF flow filtered PPG band');

            % 5) Volume displacement raw
            cla(ax(5));
            plot(ax(5), Time_Volume, vd_signal, 'r', 'LineWidth', 1.5);
            axis(ax(5),'tight'); xlim(ax(5), [0, tEnd]); 
            ylabel(ax(5), 'mL'); title(ax(5), 'Volume displacement');

            % 6) Volume displacement filtered RESP
            cla(ax(6));
            plot(ax(6), Time_Volume, VD_signal_RESP_freq_filtered, 'r', 'LineWidth', 1.5);
            axis(ax(6),'tight');  xlim(ax(6), [0, tEnd]); 
            ylabel(ax(6), 'mL'); title(ax(6), 'VD filtered RESP band');

            % 7) Volume displacement filtered PPG
            cla(ax(7));
            plot(ax(7), Time_Volume, VD_signal_PPG_freq_filtered, 'r', 'LineWidth', 1.5);
            axis(ax(7),'tight'); xlim(ax(7), [0, tEnd]); 
            ylabel(ax(7), 'mL'); title(ax(7), 'VD filtered PPG band');

            % 8) Heart rate (BPM)
            cla(ax(8));
            plot(ax(8), Time_PPG, ppg_signal, 'b', 'LineWidth', 1.5);
            axis(ax(8),'tight'); xlim(ax(8), [0, tEnd]); 
            ylabel(ax(8), 'BPM'); title(ax(8), 'Heart rate');

            % 9) HR LF
            cla(ax(9));
            plot(ax(9), Time_PPG, ppg_LF, 'b', 'LineWidth', 1.5);
            axis(ax(9),'tight'); xlim(ax(9), [0, tEnd]); 
            ylabel(ax(9), 'BPM'); title(ax(9), 'Heart rate filtered LF');

            % 10) HR HF
            cla(ax(10));
            plot(ax(10), Time_PPG, ppg_HF, 'b', 'LineWidth', 1.5);
             axis(ax(10),'tight'); xlim(ax(10), [0, tEnd]);
            ylabel(ax(10), 'BPM'); title(ax(10), 'Heart rate filtered HF');

            
            xlabel(ax(10), 'Time (s)');

            for i = 1:10
                ax(i).FontSize = FS_TICK;           % tick font
                ax(i).Title.FontSize = FS_TITLE;    % title font
                ax(i).XLabel.FontSize = FS_LABEL;   % xlabel
                ax(i).YLabel.FontSize = FS_LABEL;   % ylabel

                ax(i).XAxis.TickLength = [0.003 0.003];
                ax(i).YAxis.TickLength = [0.003 0.003];
            end

        end

        function setOneBreathResults(app, oneBreathStruct)
            if ~isstruct(oneBreathStruct)
                error('oneBreathStruct must be a struct.');
            end
            app.PreprocessedData_GUI.OneBreath = oneBreathStruct;
        end

        function computeBasicVelocityFlowFeatures(app)
            % computeBasicVelocityFlowFeatures
            % - Velocity features from getCalibrationDataset()
            % - Flow features from PreprocessedData_GUI.CSFDynamics.series.flow_mls
            % - Save to app.PreprocessedData_GUI.Features.*

            P = app.PreprocessedData_GUI;

            % -------------------------
            % (A) Velocity dataset (cm/s)
            % -------------------------
            [calData, saveDirName] = app.getCalibrationDataset();
            calData = calData(:); % column

            if isempty(calData) || ~isnumeric(calData)
                error('Calibration_dataset is empty or not numeric.');
            end

            % -------------------------
            % (B) Flow dataset (mL/s)
            % -------------------------
            if ~isfield(P,'CSFDynamics') || ~isfield(P.CSFDynamics,'series') || ~isfield(P.CSFDynamics.series,'flow_mls')
                error('PreprocessedData_GUI.CSFDynamics.series.flow_mls is missing.');
            end
            flow_mls = P.CSFDynamics.series.flow_mls(:);

            if isempty(flow_mls) || ~isnumeric(flow_mls)
                error('Average_ml_s_within_ROI (flow_mls) is empty or not numeric.');
            end

            % -------------------------
            % (C) Velocity features
            % -------------------------
            Mean_velocity_abs = mean(abs(calData), 'omitnan');
            Mean_velocity     = mean(calData, 'omitnan');

            SD_velocity       = std(calData, 0, 'omitnan');
            SD_abs_velocity   = std(abs(calData), 0, 'omitnan');

            CI_velocity       = app.local_safe_div(SD_velocity,     Mean_velocity);
            CI_abs_velocity   = app.local_safe_div(SD_abs_velocity, Mean_velocity_abs);

            Cumsum_velocity              = cumsum(calData, 'omitnan');
            endpoint_Cumsum_velocity     = Cumsum_velocity(end);

            % (optional) calibration mask area cumsum endpoint
            endpoint_Cumsum_cali_area_velocity = NaN;
            if isfield(P,'Mean_temporal_value_from_calibration_mask') && ~isempty(P.Mean_temporal_value_from_calibration_mask)
                tmp = P.Mean_temporal_value_from_calibration_mask(:);
                tmp_cumsum = cumsum(tmp, 'omitnan');
                endpoint_Cumsum_cali_area_velocity = tmp_cumsum(end);
            end

            % -------------------------
            % (D) Flow features
            % -------------------------
            Mean_flow_mls_abs = mean(abs(flow_mls), 'omitnan');
            Mean_flow_mls     = mean(flow_mls, 'omitnan');

            SD_flow_mls       = std(flow_mls, 0, 'omitnan');
            SD_flow_mls_abs   = std(abs(flow_mls), 0, 'omitnan');

            CI_flow_mls       = app.local_safe_div(SD_flow_mls,     Mean_flow_mls);
            CI_abs_flow_mls   = app.local_safe_div(SD_flow_mls_abs, Mean_flow_mls_abs);

            Cumsum_flow_mls          = cumsum(flow_mls, 'omitnan');
            endpoint_Cumsum_flow_mls = Cumsum_flow_mls(end);

            % -------------------------
            % (E) Save into PreprocessedData_GUI
            % -------------------------
            P.Features = app.local_ensureField(P, 'Features');

            % Velocity block
            P.Features.Velocity = struct();
            P.Features.Velocity.saveDirName = saveDirName;

            P.Features.Velocity.Mean        = Mean_velocity;
            P.Features.Velocity.MeanAbs     = Mean_velocity_abs;
            P.Features.Velocity.SD          = SD_velocity;
            P.Features.Velocity.SDAbs       = SD_abs_velocity;
            P.Features.Velocity.CI          = CI_velocity;
            P.Features.Velocity.CIAbs       = CI_abs_velocity;

            P.Features.Velocity.CumsumEnd   = endpoint_Cumsum_velocity;
            P.Features.Velocity.CumsumCalMaskEnd = endpoint_Cumsum_cali_area_velocity;

            % Flow block
            P.Features.Flow = struct();
            P.Features.Flow.Mean        = Mean_flow_mls;
            P.Features.Flow.MeanAbs     = Mean_flow_mls_abs;
            P.Features.Flow.SD          = SD_flow_mls;
            P.Features.Flow.SDAbs       = SD_flow_mls_abs;
            P.Features.Flow.CI          = CI_flow_mls;
            P.Features.Flow.CIAbs       = CI_abs_flow_mls;

            P.Features.Flow.CumsumEnd   = endpoint_Cumsum_flow_mls;

            % write back
            app.PreprocessedData_GUI.Basic_Features = P;

            % -------------------------
            % (F) Log
            % -------------------------
            if ~isempty(app.MainApp)
                app.MainApp.logMessage(sprintf('[Features] Velocity: Mean=%.4f SD=%.4f CI=%.4f | Flow: Mean=%.4f SD=%.4f CI=%.4f', ...
                    Mean_velocity, SD_velocity, CI_velocity, Mean_flow_mls, SD_flow_mls, CI_flow_mls));
            end
        end

        % ===== helper: safe division (avoid Inf/NaN blow-up) =====
        function y = local_safe_div(app, a, b)
            if ~isfinite(b) || b == 0
                y = NaN;
            else
                y = a / b;
            end
        end

        % ===== helper: ensure a field exists =====
        function S = local_ensureField(app, S, fname)
            if ~isfield(S, fname) || isempty(S.(fname))
                S.(fname) = struct();
            end
        end


        function columnNames = getFeatureColumnNames(app)
            columnNames = { ...
                'Mean_velocity','Sum_velocity','Mean_absolute_velocity','SD_of_velocity','SD_of_absolute_velocity','CI_with_velocity','CI_with_absolute_velocity', ...
                'Mean_Peak_points_of_CSF_velocity','Maximum_velocity_from_Traditionalcode','SD_of_Peak_points_of_CSF_velocity','CI_with_Peak_points_of_CSF_velocity', ...
                'Mean_Valley_points_of_CSF_velocity','Minimum_velocity_from_Traditionalcode','SD_of_Valley_points_of_CSF_velocity','CI_with_Valley_points_of_CSF_velocity', ...
                'Mean_Difference_between_Peak_Valley','SD_Difference_between_Peak_Valley','CI_with_Difference_between_Peak_Valley', ...
                'Mean_Flow_rate','Sum_Flow_rate','Mean_absolute_Flow_rate','SD_of_Flow_rate','SD_of_absolute_Flow_rate','CI_with_Flow_rate','CI_with_absolute_Flow_rate', ...
                'Mean_Peak_points_of_CSF_Flow_rate','Maximum_Flow_rate_from_Traditionalcode','SD_of_Peak_points_of_CSF_Flow_rate','CI_with_Peak_points_of_CSF_Flow_rate', ...
                'Mean_Valley_points_of_CSF_Flow_rate','Minimum_flow_rate_from_Traditionalcode','SD_of_Valley_points_of_CSF_Flow_rate','CI_with_Valley_points_of_CSF_Flow_rate', ...
                'Mean_Diff_between_Peak_valley_Flow_rate','SD_Diff_between_Peak_valley_Flow_rate','CI_with_Diff_between_Peak_valley_flow_rate', ...
                'Inhale_mean_of_Integrated_CSF_Flow_rate','Exhale_mean_of_Integrated_CSF_Flow_rate','Total_mean_of_Integrated_CSF_Flow_rate', ...
                'Inhale_sum_of_Integrated_CSF_Flow_rate','Exhale_sum_of_Integrated_CSF_Flow_rate','Total_sum_of_Integrated_CSF_Flow_rate', ...
                'Slope_before_detrend_of_volume_displacement', ...
                'AUC_of_RESP','SD_of_predefine_RESP_spectrum','HPBW_range_of_predefine_RESP_spectrum', ...
                'AUC_of_Cardiac','SD_of_predefine_Cardiac_spectrum','HPBW_range_of_predefine_Cardiac_spectrum', ...
                'Ratio_AUC_RESP_PPG', ...
                'Mean_Peak_time_interval','Mean_Valley_time_interval','SD_Peak_time_interval','SD_Valley_time_interval', ...
                'Max_norm_TimeP_RESP','Max_norm_TimeP_Flow_rate','Max_norm_TimeP_value_of_Flow_rate', ...
                'Max_norm_TimeP_Flow_rate_f_RESP','Max_norm_TimeP_value_of_Flow_rate_f_RESP', ...
                'Max_norm_TimeP_Flow_rate_f_Cardiac','Max_norm_TimeP_value_of_Flow_rate_f_Cardiac', ...
                'Max_norm_TimeP_volume_displacement','Max_norm_TimeP_value_of_volume_displacement', ...
                'Max_norm_TimeP_volume_displacement_f_RESP','Max_norm_TimeP_value_of_volume_displacement_f_RESP', ...
                'Max_norm_TimeP_volume_displacement_f_Cardiac','Max_norm_TimeP_value_of_volume_displacement_f_Cardiac', ...
                'Max_norm_TimeP_HR','Max_norm_TimeP_value_of_HR', ...
                'Max_norm_TimeP_HR_f_LF_band','Max_norm_TimeP_value_of_HR_f_LF_band', ...
                'Max_norm_TimeP_HR_f_HF_band','Max_norm_TimeP_value_of_HR_f_HF_band', ...
                'Min_norm_TimeP_RESP','Min_norm_TimeP_Flow_rate','Min_norm_TimeP_value_of_Flow_rate', ...
                'Min_norm_TimeP_Flow_rate_f_RESP','Min_norm_TimeP_value_of_Flow_rate_f_RESP', ...
                'Min_norm_TimeP_Flow_rate_f_Cardiac','Min_norm_TimeP_value_of_Flow_rate_f_Cardiac', ...
                'Min_norm_TimeP_volume_displacement','Min_norm_TimeP_value_of_volume_displacement', ...
                'Min_norm_TimeP_volume_displacement_f_RESP','Min_norm_TimeP_value_of_volume_displacement_f_RESP', ...
                'Min_norm_TimeP_volume_displacement_f_Cardiac','Min_norm_TimeP_value_of_volume_displacement_f_Cardiac', ...
                'Min_norm_TimeP_HR','Min_norm_TimeP_value_of_HR', ...
                'Min_norm_TimeP_HR_f_LF_band','Min_norm_TimeP_value_of_HR_f_LF_band', ...
                'Min_norm_TimeP_HR_f_HF_band','Min_norm_TimeP_value_of_HR_f_HF_band' ...
                };
        end


        function T = buildFeatureTableFromPreprocessed(app, P, columnNames)

            vals = nan(1, numel(columnNames));

            for k = 1:numel(columnNames)
                cn = columnNames{k};

                switch cn
                    % ---------------- Velocity basic ----------------
                    case 'Mean_velocity'
                        vals(k) = gf(P, {'Basic_Features','Features','Velocity','Mean'});
                    case 'Sum_velocity'
                        vals(k) = gf(P, {'Basic_Features','Features','Velocity','CumsumEnd'});
                    case 'Mean_absolute_velocity'
                        vals(k) = gf(P, {'Basic_Features','Features','Velocity','MeanAbs'});
                    case 'SD_of_velocity'
                        vals(k) = gf(P, {'Basic_Features','Features','Velocity','SD'});
                    case 'SD_of_absolute_velocity'
                        vals(k) = gf(P, {'Basic_Features','Features','Velocity','SDAbs'});
                    case 'CI_with_velocity'
                        vals(k) = gf(P, {'Basic_Features','Features','Velocity','CI'});
                    case 'CI_with_absolute_velocity'
                        vals(k) = gf(P, {'Basic_Features','Features','Velocity','CIAbs'});

                        % ---------------- Traditional cms ----------------
                    case 'Mean_Peak_points_of_CSF_velocity'
                        vals(k) = gf(P, {'Traditional','cms','Mean_peak'});
                    case 'Maximum_velocity_from_Traditionalcode'
                        vals(k) = gf(P, {'Traditional','cms','Max_from_fit'});
                    case 'SD_of_Peak_points_of_CSF_velocity'
                        vals(k) = gf(P, {'Traditional','cms','SD_peak'});
                    case 'CI_with_Peak_points_of_CSF_velocity'
                        vals(k) = gf(P, {'Traditional','cms','CI_peak'});

                    case 'Mean_Valley_points_of_CSF_velocity'
                        vals(k) = gf(P, {'Traditional','cms','Mean_valley'});
                    case 'Minimum_velocity_from_Traditionalcode'
                        vals(k) = gf(P, {'Traditional','cms','Min_from_fit'});
                    case 'SD_of_Valley_points_of_CSF_velocity'
                        vals(k) = gf(P, {'Traditional','cms','SD_valley'});
                    case 'CI_with_Valley_points_of_CSF_velocity'
                        vals(k) = gf(P, {'Traditional','cms','CI_valley'});

                    case 'Mean_Difference_between_Peak_Valley'
                        vals(k) = gf(P, {'Traditional','cms','Mean_diff_peak_valley'});
                    case 'SD_Difference_between_Peak_Valley'
                        vals(k) = gf(P, {'Traditional','cms','SD_diff_peak_valley'});
                    case 'CI_with_Difference_between_Peak_Valley'
                        vals(k) = gf(P, {'Traditional','cms','CI_diff_peak_valley'});

                        % ---------------- Flow basic ----------------
                    case 'Mean_Flow_rate'
                        vals(k) = gf(P, {'Basic_Features','Features','Flow','Mean'});
                    case 'Sum_Flow_rate'
                        vals(k) = gf(P, {'Basic_Features','Features','Flow','CumsumEnd'});
                    case 'Mean_absolute_Flow_rate'
                        vals(k) = gf(P, {'Basic_Features','Features','Flow','MeanAbs'});
                    case 'SD_of_Flow_rate'
                        vals(k) = gf(P, {'Basic_Features','Features','Flow','SD'});
                    case 'SD_of_absolute_Flow_rate'
                        vals(k) = gf(P, {'Basic_Features','Features','Flow','SDAbs'});
                    case 'CI_with_Flow_rate'
                        vals(k) = gf(P, {'Basic_Features','Features','Flow','CI'});
                    case 'CI_with_absolute_Flow_rate'
                        vals(k) = gf(P, {'Basic_Features','Features','Flow','CIAbs'});

                        % ---------------- Traditional mls ----------------
                    case 'Mean_Peak_points_of_CSF_Flow_rate'
                        vals(k) = gf(P, {'Traditional','mls','Mean_peak'});
                    case 'Maximum_Flow_rate_from_Traditionalcode'
                        vals(k) = gf(P, {'Traditional','mls','Max_from_fit'});
                    case 'SD_of_Peak_points_of_CSF_Flow_rate'
                        vals(k) = gf(P, {'Traditional','mls','SD_peak'});
                    case 'CI_with_Peak_points_of_CSF_Flow_rate'
                        vals(k) = gf(P, {'Traditional','mls','CI_peak'});

                    case 'Mean_Valley_points_of_CSF_Flow_rate'
                        vals(k) = gf(P, {'Traditional','mls','Mean_valley'});
                    case 'Minimum_flow_rate_from_Traditionalcode'
                        vals(k) = gf(P, {'Traditional','mls','Min_from_fit'});
                    case 'SD_of_Valley_points_of_CSF_Flow_rate'
                        vals(k) = gf(P, {'Traditional','mls','SD_valley'});
                    case 'CI_with_Valley_points_of_CSF_Flow_rate'
                        vals(k) = gf(P, {'Traditional','mls','CI_valley'});

                    case 'Mean_Diff_between_Peak_valley_Flow_rate'
                        vals(k) = gf(P, {'Traditional','mls','Mean_diff_peak_valley'});
                    case 'SD_Diff_between_Peak_valley_Flow_rate'
                        vals(k) = gf(P, {'Traditional','mls','SD_diff_peak_valley'});
                    case 'CI_with_Diff_between_Peak_valley_flow_rate'
                        vals(k) = gf(P, {'Traditional','mls','CI_diff_peak_valley'});

                        % ---------------- Integrated flow ----------------
                    case 'Inhale_mean_of_Integrated_CSF_Flow_rate'
                        vals(k) = gf(P, {'NETflow_mls', 'Inhale_Area_mls_mean'});
                    case 'Exhale_mean_of_Integrated_CSF_Flow_rate'
                        vals(k) = gf(P, {'NETflow_mls', 'Exhale_Area_mls_mean'});
                    case 'Total_mean_of_Integrated_CSF_Flow_rate'
                        vals(k) = gf(P, {'NETflow_mls', 'Total_Area_mls_mean'});
                    case 'Inhale_sum_of_Integrated_CSF_Flow_rate'
                        vals(k) = gf(P, {'NETflow_mls', 'Inhale_Area_mls_sum'});
                    case 'Exhale_sum_of_Integrated_CSF_Flow_rate'
                        vals(k) = gf(P, {'NETflow_mls', 'Exhale_Area_mls_sum'});
                    case 'Total_sum_of_Integrated_CSF_Flow_rate'
                        vals(k) = gf(P, {'NETflow_mls', 'Total_Area_mls_sum'});

                        % ---------------- VD slope ----------------
                    case 'Slope_before_detrend_of_volume_displacement'
                        vals(k) = gf(P, {'CSFDynamics','displacement','slope_before_detrend'});

                        % ---------------- PSD features ----------------
                    case 'AUC_of_RESP'
                        vals(k) = gf(P, {'PSDFeatures','RespInCSF','AUC'});
                    case 'SD_of_predefine_RESP_spectrum'
                        vals(k) = gf(P, {'PSDFeatures','RespInCSF','SD_PSD_specific'});
                    case 'HPBW_range_of_predefine_RESP_spectrum'
                        vals(k) = gf(P, {'PSDFeatures','RespInCSF','HPBW'});
                    % case 'Kurtosis_of_predefine_RESP_spectrum'
                    %     vals(k) = gf(P, {'PSDFeatures','RespInCSF','Kurtosis'});

                    case 'AUC_of_Cardiac'
                        vals(k) = gf(P, {'PSDFeatures','PPGInCSF','AUC'});
                    case 'SD_of_predefine_Cardiac_spectrum'
                        vals(k) = gf(P, {'PSDFeatures','PPGInCSF','SD_PSD_specific'});
                    case 'HPBW_range_of_predefine_Cardiac_spectrum'
                        vals(k) = gf(P, {'PSDFeatures','PPGInCSF','HPBW'});
                    % case 'Kurtosis_of_predefine_Cardiac_spectrum'
                    %     vals(k) = gf(P, {'PSDFeatures','PPGInCSF','Kurtosis'});

                    case 'Ratio_AUC_RESP_PPG'
                        vals(k) = gf(P, {'PSDFeatures','Ratio_AUC_RESP_PPG'});

                        % ---------------- PPG interval stats ----------------
                    case 'Mean_Peak_time_interval'
                        vals(k) = gf(P, {'Resp_secondStep','stats','mean_peak_period'});
                    case 'Mean_Valley_time_interval'
                        vals(k) = gf(P, {'Resp_secondStep','stats','mean_valley_period'});
                    case 'SD_Peak_time_interval'
                        vals(k) = gf(P, {'Resp_secondStep','stats','std_peak_period'});
                    case 'SD_Valley_time_interval'
                        vals(k) = gf(P, {'Resp_secondStep','stats','std_valley_period'});

                        % ---------------- OneBreath Max/Min ----------------

                    case 'Max_norm_TimeP_RESP'
                        vals(k) = gf(P, {'OneBreath','F','max_mean_resp_t'});
                    case 'Min_norm_TimeP_RESP'
                        vals(k) = gf(P, {'OneBreath','F','min_mean_resp_t'});

                        % ---- Flow rate (raw CSF) ----
                    case 'Max_norm_TimeP_Flow_rate'
                        vals(k) = gf(P, {'OneBreath','F','max_mean_csf_t'});
                    case 'Max_norm_TimeP_value_of_Flow_rate'
                        vals(k) = gf(P, {'OneBreath','F','max_mean_csf'});
                    case 'Min_norm_TimeP_Flow_rate'
                        vals(k) = gf(P, {'OneBreath','F','min_mean_csf_t'});
                    case 'Min_norm_TimeP_value_of_Flow_rate'
                        vals(k) = gf(P, {'OneBreath','F','min_mean_csf'});

                        % ---- Flow rate filtered RESP ----
                    case 'Max_norm_TimeP_Flow_rate_f_RESP'
                        vals(k) = gf(P, {'OneBreath','F','max_mean_csf_resp_t'});
                    case 'Max_norm_TimeP_value_of_Flow_rate_f_RESP'
                        vals(k) = gf(P, {'OneBreath','F','max_mean_csf_resp'});
                    case 'Min_norm_TimeP_Flow_rate_f_RESP'
                        vals(k) = gf(P, {'OneBreath','F','min_mean_csf_resp_t'});
                    case 'Min_norm_TimeP_value_of_Flow_rate_f_RESP'
                        vals(k) = gf(P, {'OneBreath','F','min_mean_csf_resp'});

                        % ---- Flow rate filtered Cardiac(PPG) ----
                    case 'Max_norm_TimeP_Flow_rate_f_Cardiac'
                        vals(k) = gf(P, {'OneBreath','F','max_mean_csf_ppg_t'});
                    case 'Max_norm_TimeP_value_of_Flow_rate_f_Cardiac'
                        vals(k) = gf(P, {'OneBreath','F','max_mean_csf_ppg'});
                    case 'Min_norm_TimeP_Flow_rate_f_Cardiac'
                        vals(k) = gf(P, {'OneBreath','F','min_mean_csf_ppg_t'});
                    case 'Min_norm_TimeP_value_of_Flow_rate_f_Cardiac'
                        vals(k) = gf(P, {'OneBreath','F','min_mean_csf_ppg'});

                        % ---- Volume displacement (raw) ----
                    case 'Max_norm_TimeP_volume_displacement'
                        vals(k) = gf(P, {'OneBreath','F','max_mean_vd_t'});
                    case 'Max_norm_TimeP_value_of_volume_displacement'
                        vals(k) = gf(P, {'OneBreath','F','max_mean_vd'});
                    case 'Min_norm_TimeP_volume_displacement'
                        vals(k) = gf(P, {'OneBreath','F','min_mean_vd_t'});
                    case 'Min_norm_TimeP_value_of_volume_displacement'
                        vals(k) = gf(P, {'OneBreath','F','min_mean_vd'});

                        % ---- Volume displacement filtered RESP ----
                    case 'Max_norm_TimeP_volume_displacement_f_RESP'
                        vals(k) = gf(P, {'OneBreath','F','max_mean_vd_resp_t'});
                    case 'Max_norm_TimeP_value_of_volume_displacement_f_RESP'
                        vals(k) = gf(P, {'OneBreath','F','max_mean_vd_resp'});
                    case 'Min_norm_TimeP_volume_displacement_f_RESP'
                        vals(k) = gf(P, {'OneBreath','F','min_mean_vd_resp_t'});
                    case 'Min_norm_TimeP_value_of_volume_displacement_f_RESP'
                        vals(k) = gf(P, {'OneBreath','F','min_mean_vd_resp'});

                        % ---- Volume displacement filtered Cardiac(PPG) ----
                    case 'Max_norm_TimeP_volume_displacement_f_Cardiac'
                        vals(k) = gf(P, {'OneBreath','F','max_mean_vd_ppg_t'});
                    case 'Max_norm_TimeP_value_of_volume_displacement_f_Cardiac'
                        vals(k) = gf(P, {'OneBreath','F','max_mean_vd_ppg'});
                    case 'Min_norm_TimeP_volume_displacement_f_Cardiac'
                        vals(k) = gf(P, {'OneBreath','F','min_mean_vd_ppg_t'});
                    case 'Min_norm_TimeP_value_of_volume_displacement_f_Cardiac'
                        vals(k) = gf(P, {'OneBreath','F','min_mean_vd_ppg'});

                        % ---- HR (raw) ----
                    case 'Max_norm_TimeP_HR'
                        vals(k) = gf(P, {'OneBreath','F','max_mean_hr_t'});
                    case 'Max_norm_TimeP_value_of_HR'
                        vals(k) = gf(P, {'OneBreath','F','max_mean_hr'});
                    case 'Min_norm_TimeP_HR'
                        vals(k) = gf(P, {'OneBreath','F','min_mean_hr_t'});
                    case 'Min_norm_TimeP_value_of_HR'
                        vals(k) = gf(P, {'OneBreath','F','min_mean_hr'});

                        % ---- HR filtered LF ----
                    case 'Max_norm_TimeP_HR_f_LF_band'
                        vals(k) = gf(P, {'OneBreath','F','max_mean_hr_lf_t'});
                    case 'Max_norm_TimeP_value_of_HR_f_LF_band'
                        vals(k) = gf(P, {'OneBreath','F','max_mean_hr_lf'});
                    case 'Min_norm_TimeP_HR_f_LF_band'
                        vals(k) = gf(P, {'OneBreath','F','min_mean_hr_lf_t'});
                    case 'Min_norm_TimeP_value_of_HR_f_LF_band'
                        vals(k) = gf(P, {'OneBreath','F','min_mean_hr_lf'});

                        % ---- HR filtered HF ----
                    case 'Max_norm_TimeP_HR_f_HF_band'
                        vals(k) = gf(P, {'OneBreath','F','max_mean_hr_hf_t'});
                    case 'Max_norm_TimeP_value_of_HR_f_HF_band'
                        vals(k) = gf(P, {'OneBreath','F','max_mean_hr_hf'});
                    case 'Min_norm_TimeP_HR_f_HF_band'
                        vals(k) = gf(P, {'OneBreath','F','min_mean_hr_hf_t'});
                    case 'Min_norm_TimeP_value_of_HR_f_HF_band'
                        vals(k) = gf(P, {'OneBreath','F','min_mean_hr_hf'});


                    otherwise
                        vals(k) = NaN;
                end
            end

            T = array2table(vals, 'VariableNames', columnNames);

            % ===== local safe getter =====
            function v = gf(S, pathCell)
                v = NaN;
                try
                    tmp = S;
                    for ii = 1:numel(pathCell)
                        if ~isstruct(tmp) || ~isfield(tmp, pathCell{ii})
                            return;
                        end
                        tmp = tmp.(pathCell{ii});
                    end
                    if isnumeric(tmp) && isscalar(tmp) && isfinite(tmp)
                        v = tmp;
                    elseif isnumeric(tmp) && isscalar(tmp)
                        v = tmp;
                    end
                catch
                    v = NaN;
                end
            end
        end


    end

    methods (Access = private)
        function plotCSF(app)

            selectedBtn = app.CSFsignaltypeButtonGroup.SelectedObject;

            if selectedBtn == app.OriginButton
                y = app.PreprocessedData_GUI.Mean_temporal_signal_ROI_origin;
                ttl = 'CSF velocity signal (Origin ROI)';
            elseif selectedBtn == app.CalibrationButton
                y = app.PreprocessedData_GUI.Mean_temporal_signal_ROI;
                ttl = 'CSF velocity signal (Calibrated ROI)';
            else
                y = app.PreprocessedData_GUI.Mean_temporal_signal_ROI_origin;
                ttl = 'CSF velocity signal';
            end

            t = app.PreprocessedData_GUI.Time_range;
            if isempty(t) || isempty(y)
                cla(app.UIAxes_Temporal);
                title(app.UIAxes_Temporal, 'No data to plot');
                return;
            end

            % Plot
            cla(app.UIAxes_Temporal);
            plot(app.UIAxes_Temporal, t, y);
            axis(app.UIAxes_Temporal, 'tight');
            xlabel(app.UIAxes_Temporal, 'Time Points [sec]');
            ylabel(app.UIAxes_Temporal, 'cm/s');
            title(app.UIAxes_Temporal, ttl);

            
        end

        function plotRESP(app)
            
            % Plot signals for each pixel in the mask at Respiration signal part axes
            plot(app.UIAxes_Temporal_Resp, app.PreprocessedData_GUI.Time_synced_Physio, app.PreprocessedData_GUI.Scalenorm_RESP_synced);axis(app.UIAxes_Temporal_Resp ,'tight');
            xlabel(app.UIAxes_Temporal_Resp, 'Time Points [sec]');
            ylabel(app.UIAxes_Temporal_Resp, 'A.U.');
            title(app.UIAxes_Temporal_Resp, 'Respiratory belt signal');

            
        end

        function plotPPG(app)

            % Plot signals for each pixel in the mask at Respiration signal part axes
            plot(app.UIAxes_Temporal_Cardio, app.PreprocessedData_GUI.Time_synced_Physio, app.PreprocessedData_GUI.Scalenorm_Cardio_synced);axis(app.UIAxes_Temporal_Cardio ,'tight');
            xlabel(app.UIAxes_Temporal_Cardio, 'Time Points [sec]');
            ylabel(app.UIAxes_Temporal_Cardio, 'A.U.');
            title(app.UIAxes_Temporal_Cardio, 'PPG signal');

        end

        % Callback for parent subwindow close
        function closeRequest(app, event)
            % % Check if the subwindow of the subwindow exists
            if ~isempty(app.popupApp_csfone) && isvalid(app.popupApp_csfone)
                delete(app.popupApp_csfone); % Close the subwindow of the subwindow
            end
            % 
            % if ~isempty(app.MagnitudeWindow) && isvalid(app.MagnitudeWindow)
            %     delete(app.MagnitudeWindow); % Close the subwindow of the subwindow
            % end

            % Close the parent subwindow
            delete(app);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadPreprocessddatasetButton
        function LoadPreprocessddatasetButtonPushed(app, event)
            app.LoadPreprocessddatasetButton.Enable = 'off';
            cleanup = onCleanup(@() set(app.LoadPreprocessddatasetButton,'Enable','on'));
            % ------* Data load.
            [file, path] = uigetfile('*.mat', 'Pick a MR PCMRI Single mat format file');
            if isequal(file, 0)
                uialert(app.UIFigure, 'No file selected', 'Error');
                app.MainApp.logMessage('[Error] No file selected');
                return;
            end
            if ~contains(file, 'Process_data_2')
                error('[System error] Please bring process data .mat files version 3')
            end

            app.file_name_loaded = file;
            app.file_path_name_loaded = path;
            cd(path);

            app.MainApp.logMessage(strcat('[System info]... Success bring file :', file));
            app.PreprocessedData_GUI=load(strcat(path, file));
            %PCMRI_data = PCMRI_data';

            app.MainApp.logMessage(strcat('[Data info] ', 'Path: ', path, ' Name: ', file));
            app.MainApp.logMessage('[System info] Data load success!');

            app.plotCSF();
            app.plotRESP();
            app.plotPPG();

            app.HasUserEditedPV = false;


        end

        % Selection changed function: CSFsignaltypeButtonGroup
        function CSFsignaltypeButtonGroupSelectionChanged(app, event)
            app.plotCSF();           
        end

        % Button pushed function: PeakValleydetectionRespButton
        function PeakValleydetectionRespButtonPushed(app, event)
            app.RedopeakvalleydetectionRESPButton.Enable = 'on';
            app.SecondsteppeakvalleydetectionRESPButton.Enable = 'on';
            app.EditpeakvalleypointButton.Enable = 'on';
            try
                app.runPeakValleyDetectionResp();
                app.MainApp.logMessage('[Analysis info] Conduct peak valley detection (First step)');
            catch ME
                % disp(ME.message);
                app.MainApp.logMessage(ME.message);
            end
        end

        % Size changed function: ProcessingpipelinePanel
        function ProcessingpipelinePanelSizeChanged(app, event)
            position = app.ProcessingpipelinePanel.Position;
        end

        % Button pushed function: RedopeakvalleydetectionRESPButton
        function RedopeakvalleydetectionRESPButtonPushed(app, event)
            try
                app.runPeakValleyDetectionResp();
                app.MainApp.logMessage('[Analysis info] Conduct peak valley detection (First step)');
            catch ME
                % disp(ME.message);
                app.MainApp.logMessage(ME.message);
            end
        end

        % Button pushed function: SecondsteppeakvalleydetectionRESPButton
        function SecondsteppeakvalleydetectionRESPButtonPushed(app, event)
            try
                app.ProcessingCSFwithRESPButton.Enable = 'on';
                app.HasUserEditedPV = false;
                app.runSecondStepPeakValleyDetectionResp();                
            catch ME
                %disp(ME.message);
                app.MainApp.logMessage(ME.message);
            end
        end

        % Value changed function: EditpeakSwitch
        function EditpeakSwitchValueChanged(app, event)
            if ~app.IsEditPVMode; return; end
            app.updatePVEditTargetFromSwitches();
            
        end

        % Value changed function: EditvalleySwitch
        function EditvalleySwitchValueChanged(app, event)
            if ~app.IsEditPVMode; return; end
            app.updatePVEditTargetFromSwitches();
        end

        % Button pushed function: EditpeakvalleypointButton
        function EditpeakvalleypointButtonPushed2(app, event)
            app.IsEditPVMode = ~app.IsEditPVMode; 
            app.EditpeakSwitch.Enable   = app.IsEditPVMode;
            app.EditvalleySwitch.Enable = app.IsEditPVMode;

            fig = ancestor(app.UIAxes_4, 'figure');

            if app.IsEditPVMode
                app.preparePVEditSession();

                % set(fig, 'WindowButtonDownFcn', @(src, evt) app.onPVEditClick());
                set(fig, 'WindowButtonDownFcn', @(src, event) app.onPVEditClick(src, event));

                app.updatePVEditTargetFromSwitches();

                app.refreshPVMarkersOnly();
            else
                set(fig, 'WindowButtonDownFcn', []);

                app.EditpeakSwitch.Value = 'Off';
                app.EditvalleySwitch.Value = 'Off';
                app.PVEditTarget = '';

                app.commitFinalPVToData();

                app.refreshPVMarkersOnly();
            end
        end

        % Button pushed function: ProcessingCSFwithRESPButton
        function ProcessingCSFwithRESPButtonPushed(app, event)
            % =========================================================
            % (0) Update UI state
            %   - Enable the button for single-breathing-cycle analysis
            % =========================================================

            app.ProcessingCSFwithinonebreathingcycleButton.Enable = 'on';

            yP = [];
            yV = [];

            % =========================================================
            % (1) Obtain active Peak/Valley (PV) set
            %   - Priority order:
            %       edited final → 2nd-step automatic → 1st-step automatic
            % =========================================================

            PV = app.getActivePV();

            if isempty(PV) || ~isfield(PV,'source') || strcmpi(PV.source,'none')
                app.MainApp.logMessage('[PV] No peak/valley data available.');
                app.MainApp.logMessage('→ Run 1st/2nd detection (or load PV arrays) before Processing.');
                return;
            end

            nP = numel(PV.peak_locs);
            nV = numel(PV.valley_locs);

            app.MainApp.logMessage(sprintf('[PV] source   = %s', PV.source));
            app.MainApp.logMessage(sprintf('[PV] #peaks   = %d', nP));
            app.MainApp.logMessage(sprintf('[PV] #valleys = %d', nV));
            % =========================================================
            % (2) Prepare respiration signal for PV value interpolation
            %   - Prefer filtered signal, fallback to raw if unavailable
            %   - Ensure time vector is suitable for interp1
            % =========================================================
            t = [];
            x = [];

            if isprop(app,'Resp_t') && ~isempty(app.Resp_t)
                t = app.Resp_t(:);
            end

            if isprop(app,'Resp_filt') && ~isempty(app.Resp_filt)
                x = app.Resp_filt(:);
            elseif isprop(app,'Resp_raw') && ~isempty(app.Resp_raw)
                x = app.Resp_raw(:);
            end

            canInterp = ~isempty(t) && ~isempty(x) && numel(t)==numel(x) && all(isfinite(t)) && all(isfinite(x));

            % Ensure strictly increasing time for interp1 stability
            if canInterp
                [tU, ia] = unique(t, 'stable');
                xU = x(ia);
                canInterp = numel(tU) >= 2;
            end
            % =========================================================
            % (3) Finalize PV amplitudes (y-values)
            %   - Use stored PV values if available
            %   - Otherwise interpolate from respiration signal
            % =========================================================

            % --- Peaks ---
            if nP > 0
                yP = [];
                if isfield(PV,'peaks') && ~isempty(PV.peaks) && numel(PV.peaks)==nP
                    yP = PV.peaks(:);
                elseif canInterp
                    yP = interp1(tU, xU, PV.peak_locs(:), 'linear', 'extrap');
                end

                app.MainApp.logMessage('---- Peaks (index | time | value) ----');
                for i = 1:nP
                    if isempty(yP)
                        app.MainApp.logMessage(sprintf('P%03d | t=%.4f | y=(NA)', i, PV.peak_locs(i)));
                    else
                        app.MainApp.logMessage(sprintf('P%03d | t=%.4f | y=%.6f', i, PV.peak_locs(i), yP(i)));
                    end
                end
            else
                app.MainApp.logMessage('---- Peaks: none ----');
            end

            % --- Valleys ---
            if nV > 0
                yV = [];
                if isfield(PV,'valleys') && ~isempty(PV.valleys) && numel(PV.valleys)==nV
                    yV = PV.valleys(:);
                elseif canInterp
                    yV = interp1(tU, xU, PV.valley_locs(:), 'linear', 'extrap');
                end

                app.MainApp.logMessage('---- Valleys (index | time | value) ----');
                for i = 1:nV
                    if isempty(yV)
                        app.MainApp.logMessage(sprintf('V%03d | t=%.4f | y=(NA)', i, PV.valley_locs(i)));
                    else
                        app.MainApp.logMessage(sprintf('V%03d | t=%.4f | y=%.6f', i, PV.valley_locs(i), yV(i)));
                    end
                end
            else
                app.MainApp.logMessage('---- Valleys: none ----');
            end

            % =========================================================
            % (4) Store finalized PV data for downstream processing
            %   - Unified format regardless of manual editing
            % =========================================================
            app.PreprocessedData_GUI.Peaks_used       = yP;                 
            app.PreprocessedData_GUI.Peak_locs_used   = PV.peak_locs(:);    
            app.PreprocessedData_GUI.Valleys_used     = yV;                 
            app.PreprocessedData_GUI.Valley_locs_used = PV.valley_locs(:);  
            app.PreprocessedData_GUI.PV_source_used   = PV.source;

            % Save whether PVs were manually edited
            if isprop(app,'HasUserEditedPV')
                app.PreprocessedData_GUI.HasUserEditedPV = logical(app.HasUserEditedPV);
                
            end

            peak_times   = PV.peak_locs(:);
            valley_times = PV.valley_locs(:);

            nP = numel(peak_times);
            nV = numel(valley_times);

            % =========================
            % (2) Select CSF time series and signal
            %     - Origin vs. calibrated dataset
            % =========================
            [Time_CSF, CSF_Signal, csfSource, saveDirName_csf] = app.getCSFSignalForRespProcessing();

            if isempty(Time_CSF) || isempty(CSF_Signal) || numel(Time_CSF) ~= numel(CSF_Signal)
                app.MainApp.logMessage('[CSF] Time_range / CSF signal invalid or mismatched length.');
                return;
            end

            app.MainApp.logMessage(sprintf('[CSF] source=%s | N=%d | saveDir=%s', csfSource, numel(Time_CSF), saveDirName_csf));

            % =========================
            % (3) Define inhale/exhale intervals
            %     - Based on respiration-domain peak/valley timing
            % =========================

            % =========================
            % (4) Map respiration intervals onto the CSF time grid
            %     - Includes handling of overlapping indices
            % =========================
            start_time = Time_CSF(1);
            end_time   = Time_CSF(end);

            [Exhale, Inhale, Exhale_CSF, Exhale_CSF_idx, Inhale_CSF, Inhale_CSF_idx] = app.computeInhaleExhaleIntervals(peak_times, valley_times, start_time, end_time);


            % =========================
            % (5) Compute interval integrals
            %     - Area (trapz-based) and summation
            % =========================
            [Exhale_Area, Exhale_Sum] = app.computeIntervalIntegrals(Time_CSF, CSF_Signal, Exhale_CSF_idx);
            [Inhale_Area, Inhale_Sum] = app.computeIntervalIntegrals(Time_CSF, CSF_Signal, Inhale_CSF_idx);

            % =========================
            % (6) Store results for reuse in downstream analyses
            % =========================
            S = struct();
            S.PV_source_used   = PV.source;
            S.CSF_source_used  = csfSource;
            S.saveDirName      = saveDirName_csf;

            S.peak_locs_used   = peak_times;
            S.valley_locs_used = valley_times;

            S.Exhale_resp      = Exhale;
            S.Inhale_resp      = Inhale;

            S.Exhale_CSF       = Exhale_CSF;
            S.Exhale_CSF_idx   = Exhale_CSF_idx;
            S.Inhale_CSF       = Inhale_CSF;
            S.Inhale_CSF_idx   = Inhale_CSF_idx;

            S.Exhale_Area      = Exhale_Area;
            S.Inhale_Area      = Inhale_Area;
            S.Exhale_Sum       = Exhale_Sum;
            S.Inhale_Sum       = Inhale_Sum;

            app.PreprocessedData_GUI.RespCsfSegments = S;

            % ===== (6-1) Also store cm/s results in NETflow_cms structure
            %       - Standardized format for plotting and summary statistics
            % =====
            net_cm = struct();
            net_cm.Time_CSF = Time_CSF(:);
            net_cm.CSF_Signal_cms = CSF_Signal(:);

            net_cm.Exhale_CSF_idx = Exhale_CSF_idx;
            net_cm.Inhale_CSF_idx = Inhale_CSF_idx;

            net_cm.Exhale_Area_cms = Exhale_Area(:);
            net_cm.Inhale_Area_cms = Inhale_Area(:);

            net_cm.Exhale_Sum_cms = Exhale_Sum(:);
            net_cm.Inhale_Sum_cms = Inhale_Sum(:);

            net_cm.saveDirName = saveDirName_csf;
            net_cm.CSF_source_used = csfSource;

            net_cm.Exhale_Area_cm_mean = mean(Exhale_Area,'omitnan'); net_cm.Exhale_Area_cm_sum = sum(Exhale_Area,'omitnan');
            net_cm.Inhale_Area_cm_mean = mean(Inhale_Area,'omitnan'); net_cm.Inhale_Area_cm_sum = sum(Inhale_Area,'omitnan');
            net_cm.Total_Area_cm_mean = mean([Inhale_Area; Exhale_Area],'omitnan'); net_cm.Total_Area_cm_sum = sum([Inhale_Area; Exhale_Area],'omitnan');

            net_cm.Exhale_summation_cm_mean = mean(Exhale_Sum,'omitnan'); net_cm.Exhale_summation_cm_sum = sum(Exhale_Sum,'omitnan');
            net_cm.Inhale_summation_cm_mean = mean(Inhale_Sum,'omitnan'); net_cm.Inhale_summation_cm_sum = sum(Inhale_Sum,'omitnan');
            net_cm.Total_summation_cm_mean = mean([Inhale_Sum; Exhale_Sum],'omitnan'); net_cm.Total_summation_cm_sum = sum([Inhale_Sum; Exhale_Sum],'omitnan');

            app.PreprocessedData_GUI.NETflow_cms = net_cm;

            % =========================
            % (7) Logging: summary and detailed interval information
            % =========================
            app.MainApp.logMessage(sprintf('[SEG] Exhale=%d | Inhale=%d', size(Exhale,1), size(Inhale,1)));
            app.MainApp.logMessage(sprintf('[AREA mean] Exhale=%.6f | Inhale=%.6f', mean(Exhale_Area,'omitnan'), mean(Inhale_Area,'omitnan')));
            app.MainApp.logMessage(sprintf('[SUM  mean] Exhale=%.6f | Inhale=%.6f', mean(Exhale_Sum,'omitnan'),  mean(Inhale_Sum,'omitnan')));

            % Detailed per-interval logging (optional)
            app.logIntervalsDetail('Exhale', Exhale_CSF, Exhale_CSF_idx, Exhale_Area, Exhale_Sum);
            app.logIntervalsDetail('Inhale', Inhale_CSF, Inhale_CSF_idx, Inhale_Area, Inhale_Sum);

            
            % plotting summary figure
            %app.plotSummaryOnUIAxes2();

            %app.plotInhaleExhaleBarsToUIAxes(Inhale_Area, Exhale_Area, Inhale_Sum, Exhale_Sum);


            % =========================
            % (8) Convert velocity (cm/s) to flow (ml/s) and repeat analysis
            % =========================

            % Retrieve calibration dataset
            [Calibration_dataset, saveDirName_cal] = app.getCalibrationDataset();

            % Convert cm/s to ml/s using geometric information
            [flow_mls, geom] = app.computeFlow_mls_fromVelocity(Calibration_dataset);

            % Compute inhale/exhale metrics in ml/s domain
            % (Assumes interval indices already computed)
            net_mls = app.computeInhaleExhaleMetrics_mls(Exhale_CSF_idx, Inhale_CSF_idx, flow_mls);

            % Store ml/s results for plotting and downstream use
            app.PreprocessedData_GUI.NETflow_mls = net_mls;
            app.PreprocessedData_GUI.NETflow_mls.geom = geom;
            app.PreprocessedData_GUI.NETflow_mls.saveDirName = saveDirName_cal;
            app.PreprocessedData_GUI.NETflow_mls.Time_CSF       = Time_CSF(:);
            app.PreprocessedData_GUI.NETflow_mls.CSF_Signal_mls = flow_mls(:);
            app.PreprocessedData_GUI.NETflow_mls.Exhale_CSF_idx = Exhale_CSF_idx;
            app.PreprocessedData_GUI.NETflow_mls.Inhale_CSF_idx = Inhale_CSF_idx;

            % Logging (ml/s domain)
            app.MainApp.logMessage(sprintf('[ml/s] area_cm2=%.6f, thick_cm=%.4f, nVox=%d', ...
                geom.area_cm2, geom.thick_cm, geom.nVox));
            app.MainApp.logMessage(sprintf('[ml/s] Exhale intervals=%d, Inhale intervals=%d', ...
                numel(net_mls.Exhale_Area_mls), numel(net_mls.Inhale_Area_mls)));

            % =========================
            % (9) Plotting and feature extraction
            % =========================

            try

                app.refreshUnitDependentPlots();
                app.MainApp.logMessage('[CSF] Conducted CSF plotting');
            catch ME
                app.MainApp.logMessage(['[CSF][ERROR] ' ME.message]);
            end

            % =========================
            % (10) Traditional PV analysis and PSD-based features
            % =========================


            try
                app.runCSFDynamicsFeatureCalcAndPlot();
                app.MainApp.logMessage('[CSF] CSF dynamics features calculated and saved.');
            catch ME
                app.MainApp.logMessage(['[CSF][ERROR] ' ME.message]);
            end


            % ===== Traditional PV analysis (cm/s & ml/s) =====
            app.runTraditionalPVAnalysis();
            app.MainApp.logMessage('[Traditional] PV analysis saved to PreprocessedData_GUI.Traditional');
            
            % calculate velocity and flow features
            app.computeBasicVelocityFlowFeatures();

            % Check prerequisites for PSD analysis
            app.assertPSDPrerequisites();

            % Load default PSD/AUC/HPBW parameters
            P = app.getPSDFeatureParams_Default();

            % Run PSD, AUC, and HPBW analysis for CSF–RESP–PPG
            app.runPSDAUCFeatures_CSF_RESP_PPG(P);


        end

        % Selection changed function: UnitsButtonGroup
        function UnitsButtonGroupSelectionChanged(app, event)
            app.refreshUnitDependentPlots();
            
        end

        % Button pushed function: PeakdetectionPPGButton
        function PeakdetectionPPGButtonPushed(app, event)
            % Activate Redo button
            app.RedopeakdetectionPPGButton.Enable = 'on';

            % Run code
            app.runPPGPeakDetectionCore();
            
            % plot
            app.plotTotalTemporalSignals10();
        end

        % Button pushed function: RedopeakdetectionPPGButton
        function RedopeakdetectionPPGButtonPushed(app, event)
            app.runPPGPeakDetectionCore();
            
            % plot
            app.plotTotalTemporalSignals10();
        end

        % Button pushed function: 
        % ProcessingCSFwithinonebreathingcycleButton
        function ProcessingCSFwithinonebreathingcycleButtonPushed(app, event)
            app.MainApp.logMessage('------* Open Window CSF_within_onebreathing cycle');
                        

            % =========================================================
            % Place popup on the SAME monitor as caller window,
            % anchored to TOP-RIGHT of that monitor, keeping popup width.
            % =========================================================
            monitors = get(groot, 'MonitorPositions');  % [x y w h] each row

            % (1) Find monitor that contains the caller window center
            mainPos = app.UIFigure.Position;  % [x y w h]
            mainCenter = [mainPos(1) + mainPos(3)/2, mainPos(2) + mainPos(4)/2];

            monIdx = find( ...
                mainCenter(1) >= monitors(:,1) & mainCenter(1) <= monitors(:,1) + monitors(:,3) & ...
                mainCenter(2) >= monitors(:,2) & mainCenter(2) <= monitors(:,2) + monitors(:,4), ...
                1);

            if isempty(monIdx)
                monIdx = 1; % fallback
            end

            monPos = monitors(monIdx,:); % [monX monY monW monH]

            app.popupApp_csfone = CSF_onecycle_Window;

            app.popupApp_csfone.UIFigure.AutoResizeChildren = 'on';

            popupPos0 = app.popupApp_csfone.UIFigure.Position; % [x y w h]
            popupW0   = popupPos0(3);
            popupH0   = popupPos0(4);

            marginRight = 20;
            marginTop   = 40;
            marginLeft  = 20;
            marginBottom= 20;

            maxW = monPos(3) - (marginLeft + marginRight);
            newW = min(popupW0, maxW);
            newW = max(300, newW);  % safety

            maxH = monPos(4) - (marginTop + marginBottom);
            newH = min(popupH0, maxH);
            newH = max(300, newH);

            % right edge = monX + monW - marginRight
            newLeft   = (monPos(1) + monPos(3) - marginRight) - newW;
            % top edge  = monY + monH - marginTop
            newBottom = (monPos(2) + monPos(4) - marginTop) - newH;

            newLeft   = max(monPos(1) + marginLeft, newLeft);
            newBottom = max(monPos(2) + marginBottom, newBottom);

            app.popupApp_csfone.UIFigure.Position = [newLeft, newBottom, newW, newH];

            % Pass the Main App reference to OpenImageSlide
            app.popupApp_csfone.setMainApp(app); % Pass 'app' (MainApp) as a reference;
            app.popupApp_csfone.Data_transmit(app.PreprocessedData_GUI); % Pass 'app' (MainApp) as a reference;
            app.MainApp.logMessage('[System info] Open Window CSF_within_onebreathing cycle!');

        end

        % Button pushed function: SaveAllDatasetButton
        function SaveAllDatasetButtonPushed(app, event)

            % -------------------------
            % (A) sanity check: loaded path/file
            % -------------------------
            if isempty(app.file_path_name_loaded) || ~isfolder(app.file_path_name_loaded)
                error('file_path_name_loaded is empty or invalid. (uigetfile - check file status)');
            end
            if isempty(app.file_name_loaded)
                error('file_name_loaded is empty. (uigetfile - check file status)');
            end

            % -------------------------
            % (B) Create output folder
            % -------------------------
            [~, baseName, ~] = fileparts(app.file_name_loaded);

            outFolder = fullfile(app.file_path_name_loaded, [baseName '_CSF_GUI_Output']);
            if ~exist(outFolder, 'dir')
                mkdir(outFolder);
            end

            ts = datestr(now, 'yyyymmdd_HHMMSS');
            outFolderTS = fullfile(outFolder, ts);
            mkdir(outFolderTS);


            % -------------------------
            % (D) 1) Save struct PreprocessedData_GUI
            % -------------------------
            Processed_dataset = app.PreprocessedData_GUI; %#ok<NASGU>
            matStructFile = fullfile(outFolderTS, [baseName '_PreprocessedData_GUI.mat']);
            save(matStructFile, 'Processed_dataset', '-v7.3');

            % -------------------------
            % (E) 2) Create Feature table
            % -------------------------
            columnNames = app.getFeatureColumnNames(); 
            T = app.buildFeatureTableFromPreprocessed(Processed_dataset, columnNames); 

            % -------------------------
            % (F) 3) Save table (.mat + .xlsx)
            % -------------------------
            matTableFile = fullfile(outFolderTS, [baseName '_FeatureTable.mat']);
            save(matTableFile, 'T');

            xlsxFile = fullfile(outFolderTS, [baseName '_FeatureTable.xlsx']);
            writetable(T, xlsxFile);

            % -------------------------
            % (G) log
            % -------------------------
            if ~isempty(app.MainApp)
                app.MainApp.logMessage(['[SaveAllDataset] Saved to: ' outFolderTS]);
                app.MainApp.logMessage([' - ' matStructFile]);
                app.MainApp.logMessage([' - ' matTableFile]);
                app.MainApp.logMessage([' - ' xlsxFile]);
            else
                disp(['[SaveAllDataset] Saved to: ' outFolderTS]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_Temporal = uiaxes(app.UIFigure);
            title(app.UIAxes_Temporal, 'Title')
            xlabel(app.UIAxes_Temporal, 'X')
            ylabel(app.UIAxes_Temporal, 'Y')
            zlabel(app.UIAxes_Temporal, 'Z')
            app.UIAxes_Temporal_Resp = uiaxes(app.UIFigure);
            title(app.UIAxes_Temporal_Resp, 'Title')
            xlabel(app.UIAxes_Temporal_Resp, 'X')
            ylabel(app.UIAxes_Temporal_Resp, 'Y')
            zlabel(app.UIAxes_Temporal_Resp, 'Z')
            app.UIAxes_Temporal_Cardio = uiaxes(app.UIFigure);
            title(app.UIAxes_Temporal_Cardio, 'Title')
            xlabel(app.UIAxes_Temporal_Cardio, 'X')
            ylabel(app.UIAxes_Temporal_Cardio, 'Y')
            zlabel(app.UIAxes_Temporal_Cardio, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes2_4 = uiaxes(app.UIFigure);
            title(app.UIAxes2_4, 'Title')
            xlabel(app.UIAxes2_4, 'X')
            ylabel(app.UIAxes2_4, 'Y')
            zlabel(app.UIAxes2_4, 'Z')
            app.UIAxes2_5 = uiaxes(app.UIFigure);
            title(app.UIAxes2_5, 'Title')
            xlabel(app.UIAxes2_5, 'X')
            ylabel(app.UIAxes2_5, 'Y')
            zlabel(app.UIAxes2_5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes6_2 = uiaxes(app.UIFigure);
            title(app.UIAxes6_2, 'Title')
            xlabel(app.UIAxes6_2, 'X')
            ylabel(app.UIAxes6_2, 'Y')
            zlabel(app.UIAxes6_2, 'Z')
            app.UIAxes6_3 = uiaxes(app.UIFigure);
            title(app.UIAxes6_3, 'Title')
            xlabel(app.UIAxes6_3, 'X')
            ylabel(app.UIAxes6_3, 'Y')
            zlabel(app.UIAxes6_3, 'Z')
            app.UIAxes6_4 = uiaxes(app.UIFigure);
            title(app.UIAxes6_4, 'Title')
            xlabel(app.UIAxes6_4, 'X')
            ylabel(app.UIAxes6_4, 'Y')
            zlabel(app.UIAxes6_4, 'Z')
            app.LoadPreprocessddatasetButton = uibutton(app.UIFigure, 'push');
            app.LoadPreprocessddatasetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPreprocessddatasetButtonPushed, true);
            app.LoadPreprocessddatasetButton.Text = 'Load Preprocessd dataset';
            app.TemporalPCMRIsignalLabel = uilabel(app.UIFigure);
            app.TemporalPCMRIsignalLabel.Text = 'Temporal PCMRI signal';
            app.RespirationbeltsignalLabel = uilabel(app.UIFigure);
            app.RespirationbeltsignalLabel.Text = 'Respiration belt signal';
            app.PPGsignalLabel = uilabel(app.UIFigure);
            app.PPGsignalLabel.Text = 'PPG signal';
            app.ProcessingpipelinePanel = uipanel(app.UIFigure);
            app.ProcessingpipelinePanel.Title = 'Processing pipeline';
            app.ProcessingpipelinePanel.SizeChangedFcn = createCallbackFcn(app, @ProcessingpipelinePanelSizeChanged, true);
            app.PeakValleydetectionRespButton = uibutton(app.ProcessingpipelinePanel, 'push');
            app.PeakValleydetectionRespButton.ButtonPushedFcn = createCallbackFcn(app, @PeakValleydetectionRespButtonPushed, true);
            app.PeakValleydetectionRespButton.Text = {'Peak & Valley'; 'detection (Resp)'};
            app.PeakdetectionPPGButton = uibutton(app.ProcessingpipelinePanel, 'push');
            app.PeakdetectionPPGButton.ButtonPushedFcn = createCallbackFcn(app, @PeakdetectionPPGButtonPushed, true);
            app.PeakdetectionPPGButton.Text = 'Peak detection (PPG)';
            app.ProcessingCSFwithRESPButton = uibutton(app.ProcessingpipelinePanel, 'push');
            app.ProcessingCSFwithRESPButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessingCSFwithRESPButtonPushed, true);
            app.ProcessingCSFwithRESPButton.Text = 'Processing CSF with RESP';
            app.ProcessingCSFwithinonebreathingcycleButton = uibutton(app.ProcessingpipelinePanel, 'push');
            app.ProcessingCSFwithinonebreathingcycleButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessingCSFwithinonebreathingcycleButtonPushed, true);
            app.ProcessingCSFwithinonebreathingcycleButton.Text = {'Processing CSF '; 'within one breathing cycle'};
            app.ChoosecalibrationmethodLabel = uilabel(app.ProcessingpipelinePanel);
            app.ChoosecalibrationmethodLabel.Text = '--- Choose calibration method --- ';
            app.CalibrationmethodButtonGroup = uibuttongroup(app.ProcessingpipelinePanel);
            app.CalibrationmethodButtonGroup.Title = 'Calibration method';
            app.CalibrationButton_2 = uiradiobutton(app.CalibrationmethodButtonGroup);
            app.CalibrationButton_2.Text = 'Calibration';
            app.DetrendButton = uiradiobutton(app.CalibrationmethodButtonGroup);
            app.DetrendButton.Text = 'Detrend';
            app.DetrendButton.Value = true;
            app.BothButton = uiradiobutton(app.CalibrationmethodButtonGroup);
            app.BothButton.Text = 'Both';
            app.SaveAllDatasetButton = uibutton(app.ProcessingpipelinePanel, 'push');
            app.SaveAllDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAllDatasetButtonPushed, true);
            app.SaveAllDatasetButton.Text = 'SaveAllDataset';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '-------------------------------------------------------------------------------------------';
            app.RedopeakvalleydetectionRESPButton = uibutton(app.UIFigure, 'push');
            app.RedopeakvalleydetectionRESPButton.ButtonPushedFcn = createCallbackFcn(app, @RedopeakvalleydetectionRESPButtonPushed, true);
            app.RedopeakvalleydetectionRESPButton.Text = {'Re-do '; 'peak & valley detection '; '(RESP)'};
            app.PeakdetectionparametersLabel = uilabel(app.UIFigure);
            app.PeakdetectionparametersLabel.Text = 'Peak detection parameters';
            app.MinPeakHeightLabel = uilabel(app.UIFigure);
            app.MinPeakHeightLabel.Text = 'MinPeakHeight';
            app.Pkdetect_1_EditField = uieditfield(app.UIFigure, 'numeric');
            app.Pkdetect_1_EditField.Value = 0.5;
            app.MinPeakHeightLabel_2 = uilabel(app.UIFigure);
            app.MinPeakHeightLabel_2.Text = 'MinPeakDistance';
            app.Pkdetect_1_EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Pkdetect_1_EditField_2.Value = 7;
            app.MinPeakHeightLabel_3 = uilabel(app.UIFigure);
            app.MinPeakHeightLabel_3.Text = 'MinPeakHeight';
            app.Pkdetect_1_EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.Pkdetect_1_EditField_3.Value = 0.5;
            app.MinPeakHeightLabel_4 = uilabel(app.UIFigure);
            app.MinPeakHeightLabel_4.Text = 'MinPeakDistance';
            app.Pkdetect_1_EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.Pkdetect_1_EditField_4.Value = 7;
            app.SpectrumfilterLabel = uilabel(app.UIFigure);
            app.SpectrumfilterLabel.Text = 'Spectrum (filter):';
            app.ValleyLabel = uilabel(app.UIFigure);
            app.ValleyLabel.Text = 'Valley:';
            app.MinPeakHeightLabel_5 = uilabel(app.UIFigure);
            app.MinPeakHeightLabel_5.Text = 'Up_Limit';
            app.Pkdetect_1_EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.Pkdetect_1_EditField_5.Value = 3;
            app.MinPeakHeightLabel_6 = uilabel(app.UIFigure);
            app.MinPeakHeightLabel_6.Text = 'Lo_Limit';
            app.Pkdetect_1_EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.Pkdetect_1_EditField_6.Value = 0.5;
            app.PeakLabel = uilabel(app.UIFigure);
            app.PeakLabel.Text = 'Peak:';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '-------------------------------------------------------------------------------------------';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '-------------------------------------------------------------------------------------------------';
            app.RedopeakdetectionPPGButton = uibutton(app.UIFigure, 'push');
            app.RedopeakdetectionPPGButton.ButtonPushedFcn = createCallbackFcn(app, @RedopeakdetectionPPGButtonPushed, true);
            app.RedopeakdetectionPPGButton.Text = {'Re-do '; 'peak detection '; '(PPG)'};
            app.PeakdetectionparametersLabel_2 = uilabel(app.UIFigure);
            app.PeakdetectionparametersLabel_2.Text = 'Peak detection parameters';
            app.ValleyLabel_2 = uilabel(app.UIFigure);
            app.ValleyLabel_2.Text = 'Valley:';
            app.PeakLabel_2 = uilabel(app.UIFigure);
            app.PeakLabel_2.Text = 'Peak:';
            app.MinPeakHeightLabel_7 = uilabel(app.UIFigure);
            app.MinPeakHeightLabel_7.Text = 'MinPeakHeight';
            app.Pkdetect_1_EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.Pkdetect_1_EditField_7.Value = 0.5;
            app.MinPeakHeightLabel_8 = uilabel(app.UIFigure);
            app.MinPeakHeightLabel_8.Text = 'MinPeakDistance';
            app.Pkdetect_1_EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.Pkdetect_1_EditField_8.Value = 0.5;
            app.MinPeakHeightLabel_9 = uilabel(app.UIFigure);
            app.MinPeakHeightLabel_9.Text = 'MinPeakHeight';
            app.Pkdetect_1_EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.Pkdetect_1_EditField_9.Value = 0.5;
            app.MinPeakHeightLabel_10 = uilabel(app.UIFigure);
            app.MinPeakHeightLabel_10.Text = 'MinPeakDistance';
            app.Pkdetect_1_EditField_10 = uieditfield(app.UIFigure, 'numeric');
            app.Pkdetect_1_EditField_10.Value = 0.5;
            app.FirststepSetfilteredrespsignalLabel = uilabel(app.UIFigure);
            app.FirststepSetfilteredrespsignalLabel.Text = 'First step: Set filtered resp signal';
            app.SecondstepSettingPeakValleypointbasedonthefilteredsignalLabel = uilabel(app.UIFigure);
            app.SecondstepSettingPeakValleypointbasedonthefilteredsignalLabel.Text = 'Second step: Setting Peak & Valley point based on the filtered signal';
            app.SecondsteppeakvalleydetectionRESPButton = uibutton(app.UIFigure, 'push');
            app.SecondsteppeakvalleydetectionRESPButton.ButtonPushedFcn = createCallbackFcn(app, @SecondsteppeakvalleydetectionRESPButtonPushed, true);
            app.SecondsteppeakvalleydetectionRESPButton.Text = {'Second step '; 'peak & valley detection '; '(RESP)'};
            app.PeakdetectionparametersLabel_3 = uilabel(app.UIFigure);
            app.PeakdetectionparametersLabel_3.Text = 'Peak detection parameters';
            app.ValleyLabel_3 = uilabel(app.UIFigure);
            app.ValleyLabel_3.Text = 'Valley:';
            app.PeakLabel_3 = uilabel(app.UIFigure);
            app.PeakLabel_3.Text = 'Peak:';
            app.MinPeakHeightLabel_11 = uilabel(app.UIFigure);
            app.MinPeakHeightLabel_11.Text = 'MinPeakHeight';
            app.Pkdetect_1_EditField_11 = uieditfield(app.UIFigure, 'numeric');
            app.Pkdetect_1_EditField_11.Value = 0.5;
            app.MinPeakHeightLabel_12 = uilabel(app.UIFigure);
            app.MinPeakHeightLabel_12.Text = 'MinPeakDistance';
            app.Pkdetect_1_EditField_12 = uieditfield(app.UIFigure, 'numeric');
            app.Pkdetect_1_EditField_12.Value = 7;
            app.MinPeakHeightLabel_13 = uilabel(app.UIFigure);
            app.MinPeakHeightLabel_13.Text = 'MinPeakHeight';
            app.Pkdetect_1_EditField_13 = uieditfield(app.UIFigure, 'numeric');
            app.Pkdetect_1_EditField_13.Value = 0.5;
            app.MinPeakHeightLabel_14 = uilabel(app.UIFigure);
            app.MinPeakHeightLabel_14.Text = 'MinPeakDistance';
            app.Pkdetect_1_EditField_14 = uieditfield(app.UIFigure, 'numeric');
            app.Pkdetect_1_EditField_14.Value = 7;
            app.EditpeakvalleypointButton = uibutton(app.UIFigure, 'push');
            app.EditpeakvalleypointButton.ButtonPushedFcn = createCallbackFcn(app, @EditpeakvalleypointButtonPushed2, true);
            app.EditpeakvalleypointButton.Text = {'Edit '; 'peak & valley '; 'point'};
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '-------------------------------------------------------------------------------------------------';
            app.CSFsignaltypeButtonGroup = uibuttongroup(app.UIFigure);
            app.CSFsignaltypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CSFsignaltypeButtonGroupSelectionChanged, true);
            app.CSFsignaltypeButtonGroup.Title = 'CSF signal type';
            app.OriginButton = uiradiobutton(app.CSFsignaltypeButtonGroup);
            app.OriginButton.Text = 'Origin';
            app.OriginButton.Value = true;
            app.CalibrationButton = uiradiobutton(app.CSFsignaltypeButtonGroup);
            app.CalibrationButton.Text = 'Calibration';
            app.EditpeakLabel = uilabel(app.UIFigure);
            app.EditpeakLabel.Text = 'Edit peak';
            app.EditpeakSwitch = uiswitch(app.UIFigure, 'slider');
            app.EditpeakSwitch.ValueChangedFcn = createCallbackFcn(app, @EditpeakSwitchValueChanged, true);
            app.EditvalleySwitchLabel = uilabel(app.UIFigure);
            app.EditvalleySwitchLabel.Text = 'Edit valley';
            app.EditvalleySwitch = uiswitch(app.UIFigure, 'slider');
            app.EditvalleySwitch.ValueChangedFcn = createCallbackFcn(app, @EditvalleySwitchValueChanged, true);
            app.UIAxes2Panel = uipanel(app.UIFigure);
            app.UIAxes2Panel.Title = 'MultiFigures';
            app.UnitsButtonGroup = uibuttongroup(app.UIFigure);
            app.UnitsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UnitsButtonGroupSelectionChanged, true);
            app.UnitsButtonGroup.Title = 'Units';
            app.cmsButton = uiradiobutton(app.UnitsButtonGroup);
            app.cmsButton.Text = 'cm/s';
            app.cmsButton.Value = true;
            app.mlsButton = uiradiobutton(app.UnitsButtonGroup);
            app.mlsButton.Text = 'ml/s';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '-------------------------------------------------------------------------------------------------';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '-------------------------------------------------------------------------------------------';
            app.TotalTemporalSignalPanel = uipanel(app.UIFigure);
            app.TotalTemporalSignalPanel.Title = 'MultiFigures';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Analysis_signals_window

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end