classdef Breathing_PCMRI_GUI_Ver1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ImageProcessMenu     matlab.ui.container.Menu
        ROIsegmentMenu       matlab.ui.container.Menu
        AnalysisDataMenu     matlab.ui.container.Menu
        Anat_withPhysioMenu  matlab.ui.container.Menu
        LogoImage            matlab.ui.control.Image
        LogTextArea          matlab.ui.control.TextArea
        LogTextAreaLabel     matlab.ui.control.Label
    end

    
    properties (Access = public)
        PCMRI_Data 
        PCMRI_Data_Mag
        PCMRI_Info
        Delta_time
        MultiplicativeScaling_Factor
        AdditiveOffset_Factor
        Load_Data_info_filename
        Load_Data_info_path


        Save_folder = 'Processed_data'; % Default output folder name for processed results
    end
    
    properties (Access = private)
        LogFilePath % Path to the log file
        popupApp % Reference to the Open_image_slide window
        popupApp_anat % Reference to the Analysis_window
    end
    
    methods (Access = public)
        % Append a message to the in-app log (TextArea) with a timestamp and write it to a log file
        function logMessage(app, message)
            % Read the current content from the log TextArea
            currentLog = app.LogTextArea.Value;

             % Convert TextArea content into a single string if it is stored as a cell array
            if iscell(currentLog)
                currentLog = strjoin(currentLog, '\n'); % Concatenate cell elements with newline separators
            end

            % Generate a timestamp for the new log entry
            timestamp = datestr(now, 'yyyy-mm-dd HH:MM:SS');
            formattedMessage = sprintf('[%s] %s', timestamp, message);

            % Append the new message to the existing log content
            newLog = sprintf('%s\n%s', currentLog, message);

            % Update the TextArea with the appended log content
            app.LogTextArea.Value = newLog;

            % Save only the new entry to the external log file
            app.saveLogToFile(formattedMessage);
        end

        % Handle the close request for the main UI figure (ensures child windows are closed first)
        function UIFigureCloseRequest(app, event)
             % Close any active child windows owned by the main application
            closeAllChildWindows(app);

            % Terminate the main application UI
            delete(app.UIFigure);
        end


    end
    
    methods (Access = private)
       % Append a single log entry to the external log file on disk
        function saveLogToFile(app, logContent)
            try
                % Open the log file in append mode
                fid = fopen(app.LogFilePath, 'a');

                % Write the new log entry
                fprintf(fid, '%s\n', logContent);

                % Close the file handle
                fclose(fid);
            catch
                warning('Failed to save log to file.');
            end
        end


        % Close all active child windows and any additional figures created by the app
        function closeAllChildWindows(app)
            % Close the Open_image_slide pop-up window if it exists
            if ~isempty(app.popupApp) && isvalid(app.popupApp)
                delete(app.popupApp);
                app.popupApp = []; % Clear the handle after closing
            end

            % Close the Analysis_window pop-up window if it exists
            if ~isempty(app.popupApp_anat) && isvalid(app.popupApp_anat)
                delete(app.popupApp_anat);
                app.popupApp_anat = []; % Clear the reference after closing
            end

            % Close any other MATLAB figure windows except the main app UI
            figs = findall(0, 'Type', 'figure');
            for i = 1:length(figs)
                if figs(i) ~= app.UIFigure
                    close(figs(i));
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % -----------------------------------* Log file initialization
            % ------------------------------------------------------
            % (1) Locate the currently running App Designer (.mlapp) file
            % ------------------------------------------------------
            appFullPath = mfilename('fullpath');       % e.g. /Users/.../MyApp.mlapp
            appDir      = fileparts(appFullPath);      % Directory containing the app file

            % ------------------------------------------------------
            % (2) Define the log file path (saved next to the .mlapp file)
            % ------------------------------------------------------
            app.LogFilePath = fullfile(appDir, 'AppLog.txt');

            % Remove any previous log file to start a clean session
            if exist(app.LogFilePath, 'file')
                delete(app.LogFilePath);
            end

            % Write the startup entry to the log
            app.logMessage('App started and log file reset.');
            % ------------------------------------------------------
            
            % Get the directory of the current main app file
            appFolderPath = fileparts(mfilename('fullpath'));

            % Add the app directory to the MATLAB search path
            addpath(appFolderPath);

            % Add all subfolders under the app directory (recursive)
            addpath(genpath(appFolderPath)); % Adds all subfolders recursively

            disp('App folder path added to MATLAB search path.');
            app.logMessage('App folder path added to MATLAB search path.');

            % Position the main window on the screen
            % Options: 'Center', 'northwest', 'northeast', etc.
            movegui(app.UIFigure, 'northwest');

            % MATLAB figures support WindowStyle options such as 'normal' and 'modal'
            %app.UIFigure.WindowStyle = 'normal';
            %app.UIFigure.WindowStyle = 'modal';

            % Assign a unified close-request handler (ensures all child windows are closed)
            app.UIFigure.CloseRequestFcn = @(src, event) app.UIFigureCloseRequest(event);
        end

        % Menu selected function: ROIsegmentMenu
        function ROIsegmentMenuSelected(app, event)
            app.logMessage('------* Open Image start');
            
            
            % ------* Load PCMRI dataset (NIfTI)
            [file, path] = uigetfile('*.nii', 'Select PCMRI Data File');
            if isequal(file, 0)
                uialert(app.UIFigure, 'No file selected', 'Error');
                app.logMessage('[Error] No file selected');
                return;
            end

            % ----- Store selected file information (for later reference/export)
            
            app.Load_Data_info_filename = file;
            app.Load_Data_info_path = path;
            
            % -----
            
            cd(path); mkdir(app.Save_folder);
            Data_PC = niftiread(strcat(path, file));
            Data_PC_info = niftiinfo(strcat(path, file));
            if isequal(erase(file, '_ph'), 0)
                uialert(app.UIFigure, 'No Magnitude file existed', 'Error');
                app.logMessage('[Error] No Magnitude file existed; Please check phase data name _ph or magnitude data exist');
                return;
            end
            Data_PC_magnitude = niftiread(strcat(path, erase(file, '_ph')));
            Data_PC_magnitude_info = niftiinfo(strcat(path, erase(file, '_ph')));
            Data_PC_magnitude=Data_PC_magnitude(:,:,:,1:size(Data_PC, 4));

            % Extract temporal resolution (Delta time) from NIfTI header
            delta_time = Data_PC_info.PixelDimensions;
            delta_time = delta_time(4);

            % Retrieve scaling parameters for phase/velocity data
            MultiplicativeScaling_factor = Data_PC_info.MultiplicativeScaling;
            AdditiveOffset_factor = Data_PC_info.AdditiveOffset;

            % Retrieve scaling parameters for magnitude data
            MultiplicativeScaling_factor_Mag = Data_PC_magnitude_info.MultiplicativeScaling;
            AdditiveOffset_factor_Mag = Data_PC_magnitude_info.AdditiveOffset;

            % Convert to double and apply header scaling (scaled = raw * multiplicative + offset)
            Data_PC_adjusted_scaling = double(Data_PC)*MultiplicativeScaling_factor + AdditiveOffset_factor;
            % Convert to double and apply scaling for magnitude data
            Data_PC_magnitude_adjusted_scaling = double(Data_PC_magnitude)*MultiplicativeScaling_factor_Mag + AdditiveOffset_factor_Mag;
            %Data_PC_magnitude_adjusted_scaling = double(Data_PC_magnitude);

            % Reorient images for display consistency (rotate left 90° and flip left-right)
            % Apply the same transform to all frames/slices
            for i = 1:size(Data_PC_adjusted_scaling, 4)
                % Rotate 90° counterclockwise
                rotatedData = rot90(Data_PC_adjusted_scaling(:,:,1,i));
                % Flip left-right
                rotatedFlippedData(:,:,1,i) = flip(rotatedData, 2);
            end

            % Reorient magnitude images using the same convention (rotate + flip)
            for i = 1:size(Data_PC_magnitude_adjusted_scaling, 4)
                % Rotate 90° counterclockwise
                rotatedData_Magnitude = rot90(Data_PC_magnitude_adjusted_scaling(:,:,1,i));
                % Flip left-right
                rotatedFlippedData_Magnitude(:,:,1,i) = flip(rotatedData_Magnitude, 2);
            end

            %----- Cache key variables in the main app (shared across child windows)
            app.PCMRI_Data = squeeze(rotatedFlippedData);
            app.PCMRI_Data_Mag = squeeze(rotatedFlippedData_Magnitude);
            app.PCMRI_Info = Data_PC_info;
            app.Delta_time = delta_time;
            app.MultiplicativeScaling_Factor = MultiplicativeScaling_factor;
            app.AdditiveOffset_Factor = AdditiveOffset_factor;
            %------------------------
            
            app.logMessage(strcat('[Data info] ', 'Path: ', path, ' Name: ', file));
            app.logMessage('[System info] Data load success!');

            % ----- Main window geometry (used to position the child window nearby)
            % Get the position of the main window (MainApp)
            mainPosition = app.UIFigure.Position;
            mainWidth = mainPosition(3); % Width of MainApp window

            % Compute an x-offset to place a new window to the right (with a small gap)
            newWindowLeft = mainPosition(1) + mainWidth + 10; % 10-pixel gap to the right of MainApp
            newWindowBottom = mainPosition(2); % Align vertically with MainApp

            % ----- Child window geometry and monitor-aware placement
            % Open the ROI segmentation popup after data loading completes
            % Previous approach: app.popupApp = Open_image_slide; (replaced by monitor-aware placement logic)

            % Instantiate the child window
            app.popupApp = Open_image_slide;

            % Enable auto-resize so internal UI components scale with the window
            app.popupApp.UIFigure.AutoResizeChildren = 'on';

            % Read the default popup size
            popupPos = app.popupApp.UIFigure.Position;  % [x y w h]
            popupW   = popupPos(3);
            popupH   = popupPos(4);

            % Identify which monitor contains the main window (multi-monitor support)
            mainPos  = app.UIFigure.Position;           % [x y w h]
            monitors = get(groot, 'MonitorPositions');  % [x y w h] (각 행 = 모니터)

            mainCenter = [mainPos(1) + mainPos(3)/2, ...
                mainPos(2) + mainPos(4)/2];

            monIdx = find( ...
                mainCenter(1) >= monitors(:,1) & mainCenter(1) <= monitors(:,1) + monitors(:,3) & ...
                mainCenter(2) >= monitors(:,2) & mainCenter(2) <= monitors(:,2) + monitors(:,4), ...
                1);

            if isempty(monIdx)
                monIdx = 1;   % Fall back to the primary monitor if no match is found
            end

            monPos = monitors(monIdx,:);   % [monX, monY, monW, monH]

            % Compute the maximum usable window size within the monitor bounds (with optional margins)
            marginLeft   = 0;
            marginTop    = 0;
            marginRight  = 0;
            marginBottom = 0;

            maxW = monPos(3) - (marginLeft + marginRight);
            maxH = monPos(4) - (marginTop  + marginBottom);

            % Downscale only if the popup exceeds the available monitor space (preserve aspect ratio)
            scale = min(1, min(maxW / popupW, maxH / popupH));
            newW  = popupW * scale;
            newH  = popupH * scale;

            % Place the popup anchored at the top-left of the target monitor
            newLeft   = monPos(1) + marginLeft;
            newBottom = monPos(2) + monPos(4) - marginTop - newH;

            % Ensure the popup remains within the bottom edge of the monitor
            newBottom = max(monPos(2) + marginBottom, newBottom);

            % Apply final size/position
            app.popupApp.UIFigure.Position = [newLeft, newBottom, newW, newH];


            % Pass the main app handle to the popup (enables shared data access and callbacks)
            app.popupApp.setMainApp(app); % Pass 'app' (MainApp) as a reference
            app.logMessage('[System info] Open window for analyze ROI segmentation!');

            % Send PCMRI data to the popup and initialize display components
            app.popupApp.initializeDisplay(app.PCMRI_Data); % Initialize display with PCMRI data
            app.popupApp.initializeDisplay_average(app.PCMRI_Data); % Initialize average display with PCMRI data
            app.popupApp.Initialize_VENC_param();
            app.logMessage('[System info] Initial display success!');
            %popupApp.displayData(app.PCMRIData); % Pass data to the Popup App

            % Initialize UI controls required for further analysis in the popup window
            app.popupApp.initializeButtons_forfurtheranalysis();

        end

        % Menu selected function: Anat_withPhysioMenu
        function Anat_withPhysioMenuSelected(app, event)
            app.logMessage('------* Open AnslysisData with physiological data');
            
            % ----- Main window geometry (used to position the child window nearby)
            % Get the position of the main window (MainApp)
            mainPosition = app.UIFigure.Position;
            mainWidth = mainPosition(3); % Width of MainApp window

            % Compute an x-offset to place a new window to the right (with a small gap)
            newWindowLeft = mainPosition(1) + mainWidth + 10; % 10-pixel gap to the right of MainApp
            newWindowBottom = mainPosition(2); % Align vertically with MainApp

            % ----- Child window geometry and monitor-aware placement
            % Open the analysis popup window
            % Previous approach: app.popupApp = Open_image_slide; (replaced by monitor-aware placement logic)

            % Instantiate the analysis window
            app.popupApp_anat = Analysis_signals_window;

            % Enable auto-resize so internal UI components scale with the window
            app.popupApp_anat.UIFigure.AutoResizeChildren = 'on';

            % Read the default popup size
            popupPos = app.popupApp_anat.UIFigure.Position;  % [x y w h]
            popupW   = popupPos(3);
            popupH   = popupPos(4);

            % Identify which monitor contains the main window (multi-monitor support)
            mainPos  = app.UIFigure.Position;           % [x y w h]
            monitors = get(groot, 'MonitorPositions');  % [x y w h] (one row per monitor)

            mainCenter = [mainPos(1) + mainPos(3)/2, ...
                mainPos(2) + mainPos(4)/2];

            monIdx = find( ...
                mainCenter(1) >= monitors(:,1) & mainCenter(1) <= monitors(:,1) + monitors(:,3) & ...
                mainCenter(2) >= monitors(:,2) & mainCenter(2) <= monitors(:,2) + monitors(:,4), ...
                1);

            if isempty(monIdx)
                monIdx = 1;   % Fall back to the primary monitor if no match is found
            end

            monPos = monitors(monIdx,:);   % [monX, monY, monW, monH]

            % -----------------------------
            % Anchor the popup to the top-left of the monitor and constrain its size to fit on-screen
            % -----------------------------
            marginLeft   = 10;
            marginRight  = 10;
            marginTop    = 10;
            marginBottom = 10;

            % Preserve the original popup dimensions as the baseline
            popupPos = app.popupApp_anat.UIFigure.Position;  % [x y w h]
            popupW0  = popupPos(3);
            popupH0  = popupPos(4);

            % Top-left anchor (left)
            newLeft = monPos(1) + marginLeft;

            % Use the default popup width; shrink only if it exceeds the monitor bounds
            maxW = monPos(3)+500 - (marginLeft + marginRight);
            %newW = min(popupW0, maxW);
            newW = popupW0+20;

            % Use the default popup height; shrink only if it exceeds the monitor bounds
            maxH = monPos(4) - (marginTop + marginBottom);
            newH = min(popupH0, maxH);

            % Top-left alignment (compute bottom coordinate from top edge)
            newBottom = monPos(2) + monPos(4) - marginTop - newH;
            newBottom = max(monPos(2) + marginBottom, newBottom);


            % Apply final size/position
            app.popupApp_anat.UIFigure.Position = [newLeft, newBottom, newW, newH];


            % Pass the Main App reference to OpenImageSlide
            app.popupApp_anat.setMainApp(app); % Pass 'app' (MainApp) as a reference
            app.popupApp_anat.startupFcn();
            app.logMessage('[System info] Open window for analyze ROI segmentation!');

            % Initialize UI controls required for further analysis in the analysis window
            app.popupApp_anat.initializeButtons_forfurtheranalysis();


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageProcessMenu = uimenu(app.UIFigure);
            app.ImageProcessMenu.Text = 'ImageProcess';
            app.ROIsegmentMenu = uimenu(app.ImageProcessMenu);
            app.ROIsegmentMenu.MenuSelectedFcn = createCallbackFcn(app, @ROIsegmentMenuSelected, true);
            app.ROIsegmentMenu.Text = 'ROIsegment';
            app.AnalysisDataMenu = uimenu(app.UIFigure);
            app.AnalysisDataMenu.Text = 'AnalysisData';
            app.Anat_withPhysioMenu = uimenu(app.AnalysisDataMenu);
            app.Anat_withPhysioMenu.MenuSelectedFcn = createCallbackFcn(app, @Anat_withPhysioMenuSelected, true);
            app.Anat_withPhysioMenu.Text = 'Anat_withPhysio';
            app.LogTextAreaLabel = uilabel(app.UIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.UIFigure);
            app.LogoImage = uiimage(app.UIFigure);
            app.LogoImage.ImageSource = fullfile(pathToMLAPP, 'src', 'Logo_for_GUI_window.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Breathing_PCMRI_GUI_Ver1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end