classdef CSF_onecycle_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        MultiFiguresPanel  matlab.ui.container.Panel
    end

    
    properties (Access = public)
        MainApp % Reference to the main application instance
        P   % Parameter structure used for analysis
    end

    properties (Access = private)
        Fixed_length = 200        % Default fixed length (as in the original code)
        Results                   % Structure for storing computed results
        Ax matlab.ui.control.UIAxes  % UIAxes handle for plotting
        TotalTemporalAxes10   % Handles for the 10 stacked temporal axes
    end
    
    methods (Access = public)
        
        function setMainApp(app, mainAppReference)
            app.MainApp = mainAppReference;
            % Set the close request callback
            app.UIFigure.CloseRequestFcn = @(src, event) app.closeRequest(event);
        end

        function Data_transmit(app, transmit_data)
            app.P = transmit_data;
            app.CSF_onebreathing_cycle_analysis();
        end

        function ax = ensureAxes10(app)
            % Create (or reuse) 10 vertically stacked UIAxes inside app.MultiFiguresPanel.
            % The axes handles are stored in app.TotalTemporalAxes10 (not inside the panel).

            % Validate that the target panel exists and is valid

            if ~isprop(app,'MultiFiguresPanel') || isempty(app.MultiFiguresPanel) || ~isvalid(app.MultiFiguresPanel)
                error('MultiFiguresPanel is missing. Please create a Panel in the UI and name it "MultiFiguresPanel".');
            end

            % Reuse existing axes if they were already created and are still valid
            if isprop(app,'TotalTemporalAxes10') && ~isempty(app.TotalTemporalAxes10) ...
                    && numel(app.TotalTemporalAxes10)==10 && all(isvalid(app.TotalTemporalAxes10))
                ax = app.TotalTemporalAxes10;
                return;
            end

            % Remove existing children inside the panel (keep the panel itself)
            delete(app.MultiFiguresPanel.Children);

            % Create a tight 10x1 grid layout within the panel
            gl = uigridlayout(app.MultiFiguresPanel, [10 1]);
            gl.RowHeight   = repmat({'1x'}, 1, 10);
            gl.ColumnWidth = {'1x'};
            gl.Padding     = [1 1 1 1];
            gl.RowSpacing  = 1;

            ax = gobjects(10,1);
            for i = 1:10
                ax(i) = uiaxes(gl);
                ax(i).Layout.Row = i;

                ax(i).XGrid = 'on';
                ax(i).YGrid = 'on';
                ax(i).Box   = 'on';

                % Optional: minimize margins so the stacked axes look compact
                ax(i).LooseInset = max(ax(i).TightInset, [0.005 0.005 0.005 0.005]);
            end

            % Store axes handles in a dedicated app property for later reuse
            app.TotalTemporalAxes10 = ax;
        end

        function app = CSF_onebreathing_cycle_analysis(app)

            % (1) Create (or reuse) 10 stacked axes in MultiFiguresPanel
            %     - ensureAxes10() returns a 10x1 array of UIAxes handles
            app.Ax = app.ensureAxes10();

            % (2) Update Fixed_length from UI (if an edit field exists).
            %     - If the UI control does not exist, keep the default value (e.g., 200).
            % if isprop(app,'FixedLengthEditField') && ~isempty(app.FixedLengthEditField) && isvalid(app.FixedLengthEditField)
            %     app.Fixed_length = app.FixedLengthEditField.Value;
            % end

            % (3) Run the one-breathing-cycle analysis pipeline
            app.runOneBreathingCycleAnalysis();
        end

        function [S] = fetchRequiredSignals(app)

            % fetchRequiredSignals
            % Collect and validate all signals/time vectors required for
            % one-breathing-cycle analysis (Resp, CSF flow, volume displacement, PPG/HR),
            % including band-filtered versions and respiration valley timestamps.

            P = app.P;
            % =========================
            % (1) Time vectors
            % =========================
            S.Time_RESP   = P.Time_synced_Physio(:);
            S.Time_CSF    = P.Time_range(:);
            S.Time_Volume = P.CSFDynamics.displacement.Time_interp(:);
            S.Time_PPG    = P.PPG.IBI_trim_t(:);
            % =========================
            % (2) Signals (main series)
            % =========================
            S.resp_signal = P.Scalenorm_RESP_synced(:);
            S.csf_signal  = P.CSFDynamics.series.flow_mls(:);                 % ml/s
            S.vd_signal   = P.CSFDynamics.displacement.volume_detrended(:);   % ml
            S.ppg_signal  = P.PPG.IBI_trim_HR(:);                             % BPM

            % =========================
            % (3) HRV components (LF/HF)
            %   - Add back mean HR to show LF/HF around the HR baseline
            %   - Field names must match what you stored in P.PPG
            % =========================
            S.ppg_LF = P.PPG.LF_filtered(:) + mean(S.ppg_signal,'omitnan');
            S.ppg_HF = P.PPG.HF_filtered(:) + mean(S.ppg_signal,'omitnan');

            % =========================
            % (4) Resp cycle anchors (valley times)
            %   - Breath cycle is defined valley -> valley
            % =========================
            if isfield(P,'RespCsfSegments') && isfield(P.RespCsfSegments,'valley_locs_used')
                S.valley_times = P.RespCsfSegments.valley_locs_used(:);
            elseif isfield(P,'Valley_locs_used')
                S.valley_times = P.Valley_locs_used(:);
            else
                error('valley_times not found. Save valley_locs_used into PreprocessedData_GUI first.');
            end

            % =========================
            % (5) Band-filtered signals (RESP-band / PPG-band)
            %   - Prefer to standardize storage under P.filteredCSF_VD.TotalTemporal
            % =========================
            if isfield(P,'filteredCSF_VD') && isfield(P.filteredCSF_VD,'TotalTemporal')
                S.csf_signal_RESP = P.filteredCSF_VD.TotalTemporal.CSF_RESP_filtered(:);
                S.csf_signal_PPG  = P.filteredCSF_VD.TotalTemporal.CSF_PPG_filtered(:);
                S.vd_signal_RESP  = P.filteredCSF_VD.TotalTemporal.VD_RESP_filtered(:);
                S.vd_signal_PPG   = P.filteredCSF_VD.TotalTemporal.VD_PPG_filtered(:);
            else
                error('Filtered signals not found. Create and store CSFDynamics.filtered.(flow_RESP/flow_PPG/vd_RESP/vd_PPG) first.');
            end
            % =========================
            % (6) Length / consistency checks
            % =========================
            if numel(S.Time_RESP)~=numel(S.resp_signal); error('RESP time/signal mismatch'); end
            if numel(S.Time_CSF)~=numel(S.csf_signal);   error('CSF time/signal mismatch'); end
            if numel(S.Time_Volume)~=numel(S.vd_signal); error('VD time/signal mismatch'); end
            if numel(S.Time_PPG)~=numel(S.ppg_signal);   error('PPG time/signal mismatch'); end

        end

        function R = computeNormalizedCycles(app, S)

            Fixed_length = app.Fixed_length;

            normalized = struct();
            normalized.resp = [];
            normalized.csf  = [];
            normalized.csf_resp = [];
            normalized.csf_ppg  = [];
            normalized.vd   = [];
            normalized.vd_resp = [];
            normalized.vd_ppg  = [];
            normalized.hr   = [];
            normalized.hr_lf = [];
            normalized.hr_hf = [];

            valley_times = S.valley_times;
            t_percent = linspace(0,100,Fixed_length);

            for i = 1:numel(valley_times)-1

                t_start = valley_times(i);
                t_end   = valley_times(i+1);

                % target time grid
                t_target = linspace(t_start, t_end, Fixed_length);

                % ---------- RESP ----------
                idx = (S.Time_RESP >= t_start) & (S.Time_RESP <= t_end);
                if nnz(idx) >= 2
                    normalized.resp = [normalized.resp; interp1(S.Time_RESP(idx), S.resp_signal(idx), t_target, 'linear')];
                else
                    normalized.resp = [normalized.resp; nan(1,Fixed_length)];
                end

                % ---------- CSF raw / RESP / PPG ----------
                idx = (S.Time_CSF >= t_start) & (S.Time_CSF <= t_end);
                if nnz(idx) >= 2
                    tseg = S.Time_CSF(idx);
                    normalized.csf      = [normalized.csf;      interp1(tseg, S.csf_signal(idx),      t_target, 'linear')];
                    normalized.csf_resp = [normalized.csf_resp; interp1(tseg, S.csf_signal_RESP(idx), t_target, 'linear')];
                    normalized.csf_ppg  = [normalized.csf_ppg;  interp1(tseg, S.csf_signal_PPG(idx),  t_target, 'linear')];
                else
                    normalized.csf      = [normalized.csf;      nan(1,Fixed_length)];
                    normalized.csf_resp = [normalized.csf_resp; nan(1,Fixed_length)];
                    normalized.csf_ppg  = [normalized.csf_ppg;  nan(1,Fixed_length)];
                end

                % ---------- VD raw / RESP / PPG ----------
                idx = (S.Time_Volume >= t_start) & (S.Time_Volume <= t_end);
                if nnz(idx) >= 2
                    tseg = S.Time_Volume(idx);
                    normalized.vd      = [normalized.vd;      interp1(tseg, S.vd_signal(idx),      t_target, 'linear')];
                    normalized.vd_resp = [normalized.vd_resp; interp1(tseg, S.vd_signal_RESP(idx), t_target, 'linear')];
                    normalized.vd_ppg  = [normalized.vd_ppg;  interp1(tseg, S.vd_signal_PPG(idx),  t_target, 'linear')];
                else
                    normalized.vd      = [normalized.vd;      nan(1,Fixed_length)];
                    normalized.vd_resp = [normalized.vd_resp; nan(1,Fixed_length)];
                    normalized.vd_ppg  = [normalized.vd_ppg;  nan(1,Fixed_length)];
                end

                % ---------- HR / HR-LF / HR-HF ----------
                idx = (S.Time_PPG >= t_start) & (S.Time_PPG <= t_end);
                if nnz(idx) >= 2
                    tseg = S.Time_PPG(idx);
                    normalized.hr    = [normalized.hr;    interp1(tseg, S.ppg_signal(idx), t_target, 'linear')];
                    normalized.hr_lf = [normalized.hr_lf; interp1(tseg, S.ppg_LF(idx),     t_target, 'linear')];
                    normalized.hr_hf = [normalized.hr_hf; interp1(tseg, S.ppg_HF(idx),     t_target, 'linear')];
                else
                    normalized.hr    = [normalized.hr;    nan(1,Fixed_length)];
                    normalized.hr_lf = [normalized.hr_lf; nan(1,Fixed_length)];
                    normalized.hr_hf = [normalized.hr_hf; nan(1,Fixed_length)];
                end
            end

            % mean/std
            R = struct();
            R.Fixed_length = Fixed_length;
            R.t_percent = t_percent;

            R.normalized = normalized;

            % R.mean_resp = mean(normalized.resp, 1, 'omitnan');
            % R.std_resp  = std (normalized.resp, 0, 1, 'omitnan');
            % 
            % R.mean_csf = mean(normalized.csf, 1, 'omitnan');
            % R.std_csf  = std (normalized.csf, 0, 1, 'omitnan');
            % 
            % R.mean_csf_resp = mean(normalized.csf_resp, 1, 'omitnan');
            % R.std_csf_resp  = std (normalized.csf_resp, 0, 1, 'omitnan');
            % % 
            % R.mean_csf_ppg = mean(normalized.csf_ppg, 1, 'omitnan');
            % R.std_csf_ppg  = std (normalized.csf_ppg, 0, 1, 'omitnan');
            % 
            % R.mean_vd = mean(normalized.vd, 1, 'omitnan');
            % R.std_vd  = std (normalized.vd, 0, 1, 'omitnan');
            % 
            % R.mean_vd_resp = mean(normalized.vd_resp, 1, 'omitnan');
            % R.std_vd_resp  = std (normalized.vd_resp, 0, 1, 'omitnan');
            % 
            % R.mean_vd_ppg = mean(normalized.vd_ppg, 1, 'omitnan');
            % R.std_vd_ppg  = std (normalized.vd_ppg, 0, 1, 'omitnan');
            % 
            % R.mean_hr = mean(normalized.hr, 1, 'omitnan');
            % R.std_hr  = std (normalized.hr, 0, 1, 'omitnan');
            % 
            % R.mean_hr_lf = mean(normalized.hr_lf, 1, 'omitnan');
            % R.std_hr_lf  = std (normalized.hr_lf, 0, 1, 'omitnan');
            % 
            % R.mean_hr_hf = mean(normalized.hr_hf, 1, 'omitnan');
            % R.std_hr_hf  = std (normalized.hr_hf, 0, 1, 'omitnan');

            
            % We used mean without 'omitnan' option, because averaging without nan 
            % might be overestimate in terms of signal (Especially, edge of the signal)

            R.mean_resp = mean(normalized.resp, 1);
            R.std_resp  = std (normalized.resp, 0, 1);

            R.mean_csf = mean(normalized.csf, 1);
            R.std_csf  = std (normalized.csf, 0, 1);

            R.mean_csf_resp = mean(normalized.csf_resp, 1);
            R.std_csf_resp  = std (normalized.csf_resp, 0, 1);
            % 
            R.mean_csf_ppg = mean(normalized.csf_ppg, 1);
            R.std_csf_ppg  = std (normalized.csf_ppg, 0, 1);

            % R.mean_csf_ppg = mean(normalized.csf_ppg, 1);
            % R.std_csf_ppg  = std (normalized.csf_ppg, 0, 1);

            R.mean_vd = mean(normalized.vd, 1);
            R.std_vd  = std (normalized.vd, 0, 1);

            R.mean_vd_resp = mean(normalized.vd_resp, 1);
            R.std_vd_resp  = std (normalized.vd_resp, 0, 1);

            R.mean_vd_ppg = mean(normalized.vd_ppg, 1);
            R.std_vd_ppg  = std (normalized.vd_ppg, 0, 1);

            R.mean_hr = mean(normalized.hr, 1);
            R.std_hr  = std (normalized.hr, 0, 1);

            R.mean_hr_lf = mean(normalized.hr_lf, 1);
            R.std_hr_lf  = std (normalized.hr_lf, 0, 1);

            R.mean_hr_hf = mean(normalized.hr_hf, 1);
            R.std_hr_hf  = std (normalized.hr_hf, 0, 1);

        end


        function F = computeMaxMinFeatures(app, R)

            t_percent = R.t_percent;

            getOne = @(idx) idx(1);

            F = struct();

            % resp
            [F.max_mean_resp, idx] = max(R.mean_resp);  idx = getOne(idx); F.max_mean_resp_t = t_percent(idx);
            [F.min_mean_resp, idx] = min(R.mean_resp);  idx = getOne(idx); F.min_mean_resp_t = t_percent(idx);

            % csf
            [F.max_mean_csf, idx] = max(R.mean_csf); idx=getOne(idx); F.max_mean_csf_t=t_percent(idx);
            [F.min_mean_csf, idx] = min(R.mean_csf); idx=getOne(idx); F.min_mean_csf_t=t_percent(idx);

            % csf_resp
            [F.max_mean_csf_resp, idx] = max(R.mean_csf_resp); idx=getOne(idx); F.max_mean_csf_resp_t=t_percent(idx);
            [F.min_mean_csf_resp, idx] = min(R.mean_csf_resp); idx=getOne(idx); F.min_mean_csf_resp_t=t_percent(idx);

            % csf_ppg
            [F.max_mean_csf_ppg, idx] = max(R.mean_csf_ppg); idx=getOne(idx); F.max_mean_csf_ppg_t=t_percent(idx);
            [F.min_mean_csf_ppg, idx] = min(R.mean_csf_ppg); idx=getOne(idx); F.min_mean_csf_ppg_t=t_percent(idx);

            % vd
            [F.max_mean_vd, idx] = max(R.mean_vd); idx=getOne(idx); F.max_mean_vd_t=t_percent(idx);
            [F.min_mean_vd, idx] = min(R.mean_vd); idx=getOne(idx); F.min_mean_vd_t=t_percent(idx);

            % vd_resp
            [F.max_mean_vd_resp, idx] = max(R.mean_vd_resp); idx=getOne(idx); F.max_mean_vd_resp_t=t_percent(idx);
            [F.min_mean_vd_resp, idx] = min(R.mean_vd_resp); idx=getOne(idx); F.min_mean_vd_resp_t=t_percent(idx);

            % vd_ppg
            [F.max_mean_vd_ppg, idx] = max(R.mean_vd_ppg); idx=getOne(idx); F.max_mean_vd_ppg_t=t_percent(idx);
            [F.min_mean_vd_ppg, idx] = min(R.mean_vd_ppg); idx=getOne(idx); F.min_mean_vd_ppg_t=t_percent(idx);

            % hr
            [F.max_mean_hr, idx] = max(R.mean_hr); idx=getOne(idx); F.max_mean_hr_t=t_percent(idx);
            [F.min_mean_hr, idx] = min(R.mean_hr); idx=getOne(idx); F.min_mean_hr_t=t_percent(idx);

            % hr_lf
            [F.max_mean_hr_lf, idx] = max(R.mean_hr_lf); idx=getOne(idx); F.max_mean_hr_lf_t=t_percent(idx);
            [F.min_mean_hr_lf, idx] = min(R.mean_hr_lf); idx=getOne(idx); F.min_mean_hr_lf_t=t_percent(idx);

            % hr_hf
            [F.max_mean_hr_hf, idx] = max(R.mean_hr_hf); idx=getOne(idx); F.max_mean_hr_hf_t=t_percent(idx);
            [F.min_mean_hr_hf, idx] = min(R.mean_hr_hf); idx=getOne(idx); F.min_mean_hr_hf_t=t_percent(idx);

        end


        function plotMeanSD10(app, R, F)

            ax = app.Ax;
            t  = R.t_percent;

            FS_TICK=8; FS_LABEL=9; FS_TITLE=10;

            % helper
            plotBand = @(axh, meanSig, stdSig, faceRGB) ...
                app.localPlotBand(axh, t, meanSig, stdSig, faceRGB);

            % 1 Resp
            cla(ax(1));
            plotBand(ax(1), R.mean_resp, R.std_resp, [0.8 0.8 1]);
            yline(ax(1), 0, '--k', 'LineWidth', 1.0);
            yline(ax(1), F.max_mean_resp, '--m', 'LineWidth', 1);
            xline(ax(1), F.max_mean_resp_t, '--m', 'LineWidth', 1);
            yline(ax(1), F.min_mean_resp, '--m', 'LineWidth', 1);
            xline(ax(1), F.min_mean_resp_t, '--m', 'LineWidth', 1);
            title(ax(1), 'Normalized Respiratory Signal (Mean ± SD)');
            ylabel(ax(1), 'A.U.');

            % 2 CSF
            cla(ax(2));
            plotBand(ax(2), R.mean_csf, R.std_csf, [1 0.8 0.8]);
            yline(ax(2), 0, '--k', 'LineWidth', 1.0);
            yline(ax(2), F.max_mean_csf, '--m', 'LineWidth', 1);
            xline(ax(2), F.max_mean_csf_t, '--m', 'LineWidth', 1);
            yline(ax(2), F.min_mean_csf, '--m', 'LineWidth', 1);
            xline(ax(2), F.min_mean_csf_t, '--m', 'LineWidth', 1);
            title(ax(2), 'Normalized CSF Signal (Mean ± SD)');
            ylabel(ax(2), 'mL/s');

            % 3 CSF RESP
            cla(ax(3));
            plotBand(ax(3), R.mean_csf_resp, R.std_csf_resp, [1 0.8 0.8]);
            yline(ax(3), 0, '--k', 'LineWidth', 1.0);
            yline(ax(3), F.max_mean_csf_resp, '--m', 'LineWidth', 1);
            xline(ax(3), F.max_mean_csf_resp_t, '--m', 'LineWidth', 1);
            yline(ax(3), F.min_mean_csf_resp, '--m', 'LineWidth', 1);
            xline(ax(3), F.min_mean_csf_resp_t, '--m', 'LineWidth', 1);
            title(ax(3), 'Normalized CSF filtered RESP (Mean ± SD)');
            ylabel(ax(3), 'mL/s');

            % 4 CSF PPG
            cla(ax(4));
            plotBand(ax(4), R.mean_csf_ppg, R.std_csf_ppg, [1 0.8 0.8]);
            yline(ax(4), 0, '--k', 'LineWidth', 1.0);
            yline(ax(4), F.max_mean_csf_ppg, '--m', 'LineWidth', 1);
            xline(ax(4), F.max_mean_csf_ppg_t, '--m', 'LineWidth', 1);
            yline(ax(4), F.min_mean_csf_ppg, '--m', 'LineWidth', 1);
            xline(ax(4), F.min_mean_csf_ppg_t, '--m', 'LineWidth', 1);
            title(ax(4), 'Normalized CSF filtered PPG (Mean ± SD)');
            ylabel(ax(4), 'mL/s');

            % 5 VD
            cla(ax(5));
            plotBand(ax(5), R.mean_vd, R.std_vd, [1 0.8 0.8]);
            yline(ax(5), 0, '--k', 'LineWidth', 1.0);
            yline(ax(5), F.max_mean_vd, '--m', 'LineWidth', 1);
            xline(ax(5), F.max_mean_vd_t, '--m', 'LineWidth', 1);
            yline(ax(5), F.min_mean_vd, '--m', 'LineWidth', 1);
            xline(ax(5), F.min_mean_vd_t, '--m', 'LineWidth', 1);
            title(ax(5), 'Normalized Volume displacement (Mean ± SD)');
            ylabel(ax(5), 'mL');

            % 6 VD RESP
            cla(ax(6));
            plotBand(ax(6), R.mean_vd_resp, R.std_vd_resp, [1 0.8 0.8]);
            yline(ax(6), 0, '--k', 'LineWidth', 1.0);
            yline(ax(6), F.max_mean_vd_resp, '--m', 'LineWidth', 1);
            xline(ax(6), F.max_mean_vd_resp_t, '--m', 'LineWidth', 1);
            yline(ax(6), F.min_mean_vd_resp, '--m', 'LineWidth', 1);
            xline(ax(6), F.min_mean_vd_resp_t, '--m', 'LineWidth', 1);
            title(ax(6), 'Normalized VD filtered RESP (Mean ± SD)');
            ylabel(ax(6), 'mL');

            % 7 VD PPG
            cla(ax(7));
            plotBand(ax(7), R.mean_vd_ppg, R.std_vd_ppg, [1 0.8 0.8]);
            yline(ax(7), 0, '--k', 'LineWidth', 1.0);
            yline(ax(7), F.max_mean_vd_ppg, '--m', 'LineWidth', 1);
            xline(ax(7), F.max_mean_vd_ppg_t, '--m', 'LineWidth', 1);
            yline(ax(7), F.min_mean_vd_ppg, '--m', 'LineWidth', 1);
            xline(ax(7), F.min_mean_vd_ppg_t, '--m', 'LineWidth', 1);
            title(ax(7), 'Normalized VD filtered PPG (Mean ± SD)');
            ylabel(ax(7), 'mL');

            % 8 HR
            cla(ax(8));
            plotBand(ax(8), R.mean_hr, R.std_hr, [0.8 0.8 1]);
            yline(ax(8), F.max_mean_hr, '--m', 'LineWidth', 1);
            xline(ax(8), F.max_mean_hr_t, '--m', 'LineWidth', 1);
            yline(ax(8), F.min_mean_hr, '--m', 'LineWidth', 1);
            xline(ax(8), F.min_mean_hr_t, '--m', 'LineWidth', 1);
            title(ax(8), 'Normalized Heart rate (Mean ± SD)');
            ylabel(ax(8), 'BPM');

            % 9 HR LF
            cla(ax(9));
            plotBand(ax(9), R.mean_hr_lf, R.std_hr_lf, [0.8 0.8 1]);
            yline(ax(9), F.max_mean_hr_lf, '--m', 'LineWidth', 1);
            xline(ax(9), F.max_mean_hr_lf_t, '--m', 'LineWidth', 1);
            yline(ax(9), F.min_mean_hr_lf, '--m', 'LineWidth', 1);
            xline(ax(9), F.min_mean_hr_lf_t, '--m', 'LineWidth', 1);
            title(ax(9), 'Normalized HR filtered LF (Mean ± SD)');
            ylabel(ax(9), 'BPM');

            % 10 HR HF
            cla(ax(10));
            plotBand(ax(10), R.mean_hr_hf, R.std_hr_hf, [0.8 0.8 1]);
            yline(ax(10), F.max_mean_hr_hf, '--m', 'LineWidth', 1);
            xline(ax(10), F.max_mean_hr_hf_t, '--m', 'LineWidth', 1);
            yline(ax(10), F.min_mean_hr_hf, '--m', 'LineWidth', 1);
            xline(ax(10), F.min_mean_hr_hf_t, '--m', 'LineWidth', 1);
            title(ax(10), 'Normalized HR filtered HF (Mean ± SD)');
            ylabel(ax(10), 'BPM');
            xlabel(ax(10), 'Normalized Time (%)');

            for i = 1:10
                ax(i).XLim = [0 100];
                ax(i).FontSize = FS_TICK;
                ax(i).Title.FontSize = FS_TITLE;
                ax(i).Title.FontWeight = 'normal';
                ax(i).XLabel.FontSize = FS_LABEL;
                ax(i).YLabel.FontSize = FS_LABEL;
                ax(i).XGrid = 'on'; ax(i).YGrid='on'; ax(i).Box='on';
                if i < 10
                    ax(i).XTickLabel = {};
                end
            end

        end

        function localPlotBand(app, axh, t, meanSig, stdSig, faceRGB)

            t       = t(:).';
            meanSig = meanSig(:).';
            stdSig  = stdSig(:).';

            upper = meanSig + stdSig;
            lower = meanSig - stdSig;

            valid = isfinite(t) & isfinite(upper) & isfinite(lower);

            hold(axh,'on');
            if any(valid)
                fill(axh, [t(valid) fliplr(t(valid))], ...
                    [upper(valid) fliplr(lower(valid))], ...
                    faceRGB, 'EdgeColor','none', 'FaceAlpha', 0.5);
            end

            plot(axh, t, meanSig, 'k', 'LineWidth', 2);
        end


        function runOneBreathingCycleAnalysis(app)

            % runOneBreathingCycleAnalysis
            % Top-level pipeline for "one breathing cycle" analysis:
            %   1) Load and validate required signals/time vectors
            %   2) Normalize each valley-to-valley cycle to a fixed length and compute mean/SD
            %   3) Extract max/min features (magnitude + location in normalized cycle)
            %   4) Store results into this app (and optionally propagate to MainApp)
            %   5) Plot the 10 stacked summary panels

            % ---------------------------------------------------------
            % (1) Load + validate all prerequisite signals
            % ---------------------------------------------------------
            S = app.fetchRequiredSignals();

            % ---------------------------------------------------------
            % (2) Valley-to-valley cycle normalization (fixed-length) and summary stats
            % ---------------------------------------------------------
            R = app.computeNormalizedCycles(S);

            % ---------------------------------------------------------
            % (3) Feature extraction: max/min value and its timing within the normalized cycle
            % ---------------------------------------------------------
            F = app.computeMaxMinFeatures(R);

            % ---------------------------------------------------------
            % (4) Save results into this analysis app
            % ---------------------------------------------------------
            app.Results = struct();
            app.Results.S = S;
            app.Results.R = R;
            app.Results.F = F;

            % ---------------------------------------------------------
            % (5) Plot (10 stacked axes)
            % ---------------------------------------------------------
            app.plotMeanSD10(R, F);

            % ---------------------------------------------------------
            % (6) Optional: send results back to MainApp for persistence/downstream analysis
            % ---------------------------------------------------------
            %app.MainApp.PreprocessedData_GUI.OneBreath = app.Results;
            if ~isempty(app.MainApp) && isvalid(app.MainApp)
                app.MainApp.setOneBreathResults(app.Results);
            end

        end


    end
    
    methods (Access = private)
        
        % Callback for parent subwindow close
        function closeRequest(app, event)
            % % Check if the subwindow of the subwindow exists
            % if ~isempty(app.AnalysisWindow) && isvalid(app.AnalysisWindow)
            %     delete(app.AnalysisWindow); % Close the subwindow of the subwindow
            % end
            % 
            % if ~isempty(app.MagnitudeWindow) && isvalid(app.MagnitudeWindow)
            %     delete(app.MagnitudeWindow); % Close the subwindow of the subwindow
            % end

            % Close the parent subwindow
            delete(app);
        end
    end
    

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MultiFiguresPanel = uipanel(app.UIFigure);
            app.MultiFiguresPanel.Title = 'MultiFigures';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CSF_onecycle_Window

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end