classdef Calibration_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ColorbarlowEditField_2        matlab.ui.control.NumericEditField
        ColorbarlowEditFieldLabel_2   matlab.ui.control.Label
        ColorbarhighEditField_2       matlab.ui.control.NumericEditField
        ColorbarhighEditFieldLabel_2  matlab.ui.control.Label
        TemporalsignalfororiginmaskLabel  matlab.ui.control.Label
        CancelButton                  matlab.ui.control.Button
        ROImaskpixelcountEditField    matlab.ui.control.NumericEditField
        ROImaskpixelcountEditFieldLabel  matlab.ui.control.Label
        AdjustcalibrationButton       matlab.ui.control.Button
        ROIedit_remove                matlab.ui.control.Button
        ROIedit_include               matlab.ui.control.Button
        SudoROIeditLabel              matlab.ui.control.Label
        SpectralsignalLabel           matlab.ui.control.Label
        TemporalsignalforcalibrationLabel  matlab.ui.control.Label
        AnalysistypeButtonGroup       matlab.ui.container.ButtonGroup
        AveragesignalButton           matlab.ui.control.RadioButton
        OverallmasksignalButton       matlab.ui.control.RadioButton
        SinglesignaloverlayButton     matlab.ui.control.RadioButton
        ColorbarlowEditField          matlab.ui.control.NumericEditField
        ColorbarlowEditFieldLabel     matlab.ui.control.Label
        ColorbarhighEditField         matlab.ui.control.NumericEditField
        ColorbarhighEditFieldLabel    matlab.ui.control.Label
        SignaloverlaynumberSpinner    matlab.ui.control.Spinner
        SignaloverlaynumberSpinnerLabel  matlab.ui.control.Label
        UIAxes_selectedROI_std        matlab.ui.control.UIAxes
        UIAxes_Temporal_origin        matlab.ui.control.UIAxes
        UIAxes_Spectral               matlab.ui.control.UIAxes
        UIAxes_Temporal               matlab.ui.control.UIAxes
        UIAxes_maskROI                matlab.ui.control.UIAxes
        UIAxes_selectedROI            matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        MainApp % Handle to the main GUI/app (shared data, logging, save folder, etc.)
        ParentSubWindow % Handle to the parent subwindow (ROI selection/segmentation stage)
        ParentAnalysisWindow % Handle to the parent analysis window (for cross-window state/IO)
        % PCMRI % PCMRI data
        % ---- ROI-cropped PCMRI data (working set for this window)
        Selected_region_PCMRI % Cropped PCMRI stack (X × Y × T), typically rotated/flipped to match display
        Selected_region_averaged_image % Mean image used for visualization/ROI selection (X × Y)
        Selected_region_std_image % Standard deviation image for QC/feature use (X × Y)
        % IsROIActive % Flag to indicate if ROI selection is active
        
        % ---- Spectral / per-voxel features (precomputed in parent window)
        Selected_P_f % Per-voxel spectrum magnitude (X × Y × F or T depending on implementation)
        % I_Pi_forapp % Matrix storing the I_Pi values
        % M_ROI_forapp % ROI matrix after noise filtering
        % M_ROI_mask_forapp % Binary mask after thresholding
        % freq_range % Frequency range used for FFT
        % deltafreq_for_CF_Auto % Frequency step size for CF_Auto algorithm
        % time_range % Time range of the PCMRI data
        % AnalysisWindow % Description
        %Selected_region_PCMRI
        %PCMRI
        M_ROI_mask_forapp % Binary ROI mask (X × Y), 1 = in-ROI, 0 = out
        ROI_P_f_max
        ROI_dc
        ROI_P_f_real
        ROI_f_max_mat_forapp

        % ---- Physiology signals (raw and normalized)
        Resp_forapp
        Cardio_forapp
        Mean_value_Resp_forapp
        STD_value_Resp_forapp
        Mean_value_Cardio_forapp
        STD_value_Cardio_forapp
        Scalenorm_Resp_forapp
        Scalenorm_Cardio_forapp
        Resp_Fs_forapp
        Time_Resp_forapp
        Time_Resp_new_forapp
        Time_Setpoint_forapp
        Resp_new_forapp
        Scalenorm_Resp_new_forapp
        Cardio_new_forapp
        Scalenorm_Cardio_new_forapp
        
        
        % ---- ROI pixel statistics (for QC and tracking manual edits)
        ROI_Base_pixel_count
        ROI_last_pixel_count

        % ---- Single-voxel overlay plots (clicked points)
        Signal_single_overlay_temporal
        Signal_single_overlay_spectral
        
        % ---- “Overall mask” extracted voxel-wise collections
        Signal_Overall_temporal
        Signal_Overall_spectral
        Signal_Overall_ROI_P_f_max
        Signal_Overall_ROI_f_max
        Signal_Overall_dc
        Signal_Overall_M_ROI_real
        Signal_Overall_M_ROI_real_firstsample

        % ---- ROI-mean summary signals
        Signal_Mean_temporal
        Signal_Mean_spectral
    end
    
    properties (Access = private)
        % ---- Interactive point selection state (click-to-plot and ROI editing)
        ClickedPoints = []; % Selected points on ROI image [x, y]
        ClickedPoints_1 = []; % Selected points on mask image [x, y]
        ClickedPoints_2 = []; % Additional point set (e.g., calibration/secondary mode), if used
        PlottedSignals = {}; % Line handles for temporal plots
        PlottedSpectra = {}; % Line handles for spectral plots
        
        %PlottedSignals_2 = {}; % Stroes plotted PCMRI signals at Respiration signal part.

        RedDots = []; % Marker handles on mask axes (set 1)
        RedDots_1 = []; % Marker handles on ROI axes (set 2)
        RedDots_2 = []; % Marker handles for third selection set (optional)
        CurrentPlotMode % Current visualization mode (e.g., OverlaySignal / OverallMaskSignal / AverageSignal)
        OverlaidSignals = []; % Handles or cached data for overlaid temporal plots (if used)
        OverlaidSpectra = []; % Handles or cached data for overlaid spectral plots (if used)

        LastSelectedPoint = []; % Last point picked (for operations like delete/add around a point)
        LastSelectedPoint_1 = []; % Secondary “last point” (if multiple axes/contexts are used)

        ROIImageHandle % Handle to ROI image object (for hit-testing or refresh)

        DragData % Struct for click-drag ROI selection rectangle state
        ImageHandles % Stored image handles for callback routing across axes

        % ---- Mask overlay handles (e.g., calibration masks or multi-layer overlays)
        MaskImageHandle1
        MaskImageHandle2
        MaskImageHandle3
    end
    
    methods (Access = public)
        
        function setMainApp(app, mainAppReference, ParentAppReference, ParentAnalysisAppReference)
            app.MainApp = mainAppReference;
            app.ParentSubWindow = ParentAppReference;
            app.ParentAnalysisWindow = ParentAnalysisAppReference;
        end

        function Adjusting_baseline_mask(app)
            [rows, cols] = size(app.ParentAnalysisWindow.M_ROI_mask_forapp);
            RGBMask = zeros(rows, cols, 3); % Initialize RGB mask
            maskcolor = 'Red';
            switch maskcolor
                case 'Red'
                    RGBMask(:, :, 1) = app.ParentAnalysisWindow.M_ROI_mask_forapp; % Set red channel where mask is 1
                case 'Blue'
                    RGBMask(:, :, 3) = app.ParentAnalysisWindow.M_ROI_mask_forapp; % Set red channel where mask is 1
                case 'Green'
                    RGBMask(:, :, 2) = app.ParentAnalysisWindow.M_ROI_mask_forapp; % Set red channel where mask is 1
                otherwise
                    RGBMask(:, :, 1) = app.ParentAnalysisWindow.M_ROI_mask_forapp; % Set red channel where mask is 1
            end

            % Overlay the mask image
            hold(app.UIAxes_maskROI, 'on');
            app.MaskImageHandle1 = imagesc(app.UIAxes_maskROI, RGBMask, 'AlphaData', 0.35);

            % Overlay the mask image
            hold(app.UIAxes_selectedROI, 'on');
            app.MaskImageHandle2 = imagesc(app.UIAxes_selectedROI, RGBMask, 'AlphaData', 0.35);

            % Overlay the mask image
            hold(app.UIAxes_selectedROI_std, 'on');
            app.MaskImageHandle3 = imagesc(app.UIAxes_selectedROI_std, RGBMask, 'AlphaData', 0.35);

        end

        function initializeColorbarLimits(app, imageData)
            % Get the min and max values from the current image data
            minValue = min(imageData(:));
            maxValue = max(imageData(:));

            % Set the initial values of the colorbar edit fields
            app.ColorbarlowEditField.Value = minValue;
            app.ColorbarhighEditField.Value = maxValue;

            % Set the color axis limits based on the initialized values
            caxis(app.UIAxes_selectedROI, [minValue, maxValue]);
            %caxis(app.UIAxes_fusionimg, [minValue, maxValue]);
        end

        function initializeColorbarLimits_std(app, imageData)
            % Get the min and max values from the current image data
            minValue = min(imageData(:));
            maxValue = max(imageData(:));

            % Set the initial values of the colorbar edit fields
            app.ColorbarlowEditField_2.Value = minValue;
            app.ColorbarhighEditField_2.Value = maxValue;

            % Set the color axis limits based on the initialized values
            caxis(app.UIAxes_selectedROI_std, [minValue, maxValue]);
            %caxis(app.UIAxes_fusionimg, [minValue, maxValue]);
        end

        function initializeROIcount(app, roiMask)
            % Check if the input ROI mask is valid
            if isempty(roiMask)
                disp('ROI mask is empty.');
                return;
            end

            % Count the number of pixels where the ROI mask equals 1
            roiPixelCount = nnz(roiMask == 1);

            % Display the count or store it in the app
            app.MainApp.logMessage(['Number of Base ROI mask pixels: ', num2str(roiPixelCount)]);
            app.ROImaskpixelcountEditField.Value = roiPixelCount;

            % (Optional) Store the count in an app property
            app.ROI_Base_pixel_count = roiPixelCount;
        end

        function updateROIcount(app, roiMask)
            % Check if the input ROI mask is valid
            if isempty(roiMask)
                disp('ROI mask is empty.');
                return;
            end

            % Count the number of pixels where the ROI mask equals 1
            roiPixelCount = nnz(roiMask == 1);

            % Display the count or store it in the app
            app.MainApp.logMessage(['Number of update ROI mask pixels: ', num2str(roiPixelCount)]);
            app.ROImaskpixelcountEditField.Value = roiPixelCount;

            % (Optional) Store the count in an app property
            %app.ROI_Base_pixel_count = roiPixelCount;
        end

        function LastROIcount(app, roiMask)
            % Check if the input ROI mask is valid
            if isempty(roiMask)
                disp('ROI mask is empty.');
                return;
            end

            % Count the number of pixels where the ROI mask equals 1
            roiPixelCount = nnz(roiMask == 1);

            % Display the count or store it in the app
            app.MainApp.logMessage(['Number of Last ROI mask pixels: ', num2str(roiPixelCount)]);

            % (Optional) Store the count in an app property
            app.ROI_last_pixel_count = roiPixelCount;
        end
        

        function Initial_dataset(app, PCMRI_Data, PCMRI_std_Data, ROI_MASK)
            %-----Flag confirmation.
            if app.ParentAnalysisWindow.Flag_calibration_state_withinROI==1 % flag has set on.
                app.AdjustcalibrationButton.BackgroundColor = [0.2, 0.8, 0.2]; % Green color.
                app.AdjustcalibrationButton.Enable = 'off'; % Disable button after clicking.
            end

            app.Selected_region_PCMRI=app.ParentAnalysisWindow.Selected_region_PCMRI;
            app.Selected_region_averaged_image = PCMRI_Data;
            app.Selected_region_std_image = PCMRI_std_Data;
            app.M_ROI_mask_forapp = ROI_MASK;
            app.Selected_P_f = app.ParentAnalysisWindow.Selected_P_f;
            app.ROI_P_f_max = app.ParentAnalysisWindow.ROI_P_f_max;
            app.ROI_dc = app.ParentAnalysisWindow.ROI_dc;
            app.ROI_P_f_real = app.ParentAnalysisWindow.ROI_P_f_real;
            app.ROI_f_max_mat_forapp = app.ParentAnalysisWindow.ROI_f_max_mat_forapp;
            time_range = app.ParentSubWindow.time_range_forapp;
            freq_range = app.ParentSubWindow.freq_range_forapp;

            
            % initialization color limits
            app.initializeColorbarLimits(app.Selected_region_PCMRI(:, :, 1));
            % initialization color limits _std
            app.initializeColorbarLimits_std(app.Selected_region_std_image);

            % Initialization ROI mask pixels count
            app.initializeROIcount(ROI_MASK);

            % For color range.
            lowLimit = app.ColorbarlowEditField.Value;
            highLimit = app.ColorbarhighEditField.Value;

            % For color range.
            lowLimit_std = app.ColorbarlowEditField_2.Value;
            highLimit_std = app.ColorbarhighEditField_2.Value;

            % Display the std image in UIAxes_AveragedImage
            cla(app.UIAxes_selectedROI_std);
            imgHandle2 = imagesc(app.UIAxes_selectedROI_std, PCMRI_std_Data);
            colormap(app.UIAxes_selectedROI_std, 'gray');
            axis(app.UIAxes_selectedROI_std, 'image');
            axis(app.UIAxes_selectedROI_std, 'tight');
            colorbar(app.UIAxes_selectedROI_std);clim(app.UIAxes_selectedROI_std, [lowLimit_std, highLimit_std]);
            xlabel(app.UIAxes_selectedROI_std, 'Pixels');
            ylabel(app.UIAxes_selectedROI_std, 'Pixels');
            title(app.UIAxes_selectedROI_std, 'Std Image - Select ROI');

            % Display the averaged image in UIAxes_AveragedImage
            cla(app.UIAxes_selectedROI);
            imgHandle = imagesc(app.UIAxes_selectedROI, PCMRI_Data);
            colormap(app.UIAxes_selectedROI, 'gray');
            axis(app.UIAxes_selectedROI, 'image');
            axis(app.UIAxes_selectedROI, 'tight');
            colorbar(app.UIAxes_selectedROI);clim(app.UIAxes_selectedROI, [lowLimit, highLimit]);
            xlabel(app.UIAxes_selectedROI, 'Pixels');
            ylabel(app.UIAxes_selectedROI, 'Pixels');
            title(app.UIAxes_selectedROI, 'Averaged Image - Select ROI');

            % Display the mask image in UIAxes_AveragedImage
            cla(app.UIAxes_maskROI);
            imgHandle1 = imagesc(app.UIAxes_maskROI, ROI_MASK);
            colormap(app.UIAxes_maskROI, 'gray');
            axis(app.UIAxes_maskROI, 'image');
            axis(app.UIAxes_maskROI, 'tight');
            colorbar(app.UIAxes_maskROI);clim(app.UIAxes_maskROI, [0, 1]);
            xlabel(app.UIAxes_maskROI, 'Pixels');
            ylabel(app.UIAxes_maskROI, 'Pixels');
            title(app.UIAxes_maskROI, 'Averaged Image - Select ROI');

            app.Adjusting_baseline_mask();
            
            % Save param.
            app.ParentSubWindow.saveData()

            % ✅ Initialize DragData to prevent errors
            app.DragData = struct('isDragging', false, 'startPoint', [], 'rectHandle', []);
            %Get image dimensions
            app.DragData.imgSize = size(get(imgHandle, 'CData')); % Get image data size

            %set(imgHandle, 'ButtonDownFcn', @(src, event) mousePress(app, src, event, app.UIAxes_selectedROI));
            %set(imgHandle1, 'ButtonDownFcn', @(src, event) mousePress(app, src, event, app.UIAxes_maskROI));

            % % Set up mouse event callbacks for both axes
            % fig = ancestor(app.UIAxes_selectedROI, 'figure');
            % 
            % % Attach callbacks to handle both click and drag
            % set(fig, 'WindowButtonDownFcn', @(src, event) mousePress(app, src, event));
            % set(fig, 'WindowButtonMotionFcn', @(src, event) mouseDrag(app, src, event));
            % set(fig, 'WindowButtonUpFcn', @(src, event) mouseRelease(app, imgHandle, app.DragData.imgSize, time_range, freq_range));

            % Set up mouse event callbacks for both axes
            fig1 = ancestor(app.UIAxes_selectedROI, 'figure');

            % Attach callbacks to handle both click and drag
            set(fig1, 'WindowButtonDownFcn', @(src, event) mousePress(app, src, event));
            set(fig1, 'WindowButtonMotionFcn', @(src, event) mouseDrag(app, src, event));
            set(fig1, 'WindowButtonUpFcn', @(src, event) mouseRelease(app, imgHandle, app.DragData.imgSize, time_range, freq_range));

            % Store image handles for reference
            app.ImageHandles = {imgHandle, imgHandle1, imgHandle2};


        end

        function mousePress(app, ~, ~)
            if isempty(app.DragData)
                app.DragData = struct('isDragging', false, 'startPoint', [], 'rectHandle', []);
            end

            % Get the current mouse position within the UIAxes
            cp = get(app.UIAxes_selectedROI, 'CurrentPoint');
            clickX = round(cp(1, 1));  % ✅ Ensure integer values
            clickY = round(cp(1, 2));

            % Get the current axes limits (boundaries)
            xLimits = xlim(app.UIAxes_selectedROI);
            yLimits = ylim(app.UIAxes_selectedROI);

            % ✅ Check if the click is within the axes boundaries
            if clickX < xLimits(1) || clickX > xLimits(2) || ...
                    clickY < yLimits(1) || clickY > yLimits(2)
                % Click is outside the axes - Do nothing
                app.DragData.isValidClick = false;  % ✅ Mark click as invalid
                app.DragData.startPoint = [];  % ✅ Reset stored point
                disp('Click ignored: Outside the axes boundaries.');
                return;
            end

            % ✅ Proceed if the click is within the axes boundaries
            app.DragData.startPoint = [clickX, clickY];
            app.DragData.isDragging = false;
            app.DragData.isValidClick = true;  % ✅ Mark click as valid

            % Optional Debug Output
            disp(['Click registered at: (', num2str(clickX), ', ', num2str(clickY), ')']);

        end

        function mouseDrag(app, ~, ~)
            if isempty(app.DragData) || isempty(app.DragData.startPoint)
                return; % Skip if DragData is not initialized
            end

            cp = get(app.UIAxes_selectedROI, 'CurrentPoint');
            currentPoint = clampPoint(app, cp(1, 1:2), app.DragData.imgSize);
            startPoint = app.DragData.startPoint;
            movement = norm(currentPoint - startPoint);

            % Trigger drag if movement exceeds threshold
            if movement > 2
                app.DragData.isDragging = true;

                % Ensure rectangle stays within image bounds
                xMin = max(1, min(startPoint(1), currentPoint(1)));
                yMin = max(1, min(startPoint(2), currentPoint(2)));
                xMax = min(app.DragData.imgSize(2), max(startPoint(1), currentPoint(1)));
                yMax = min(app.DragData.imgSize(1), max(startPoint(2), currentPoint(2)));

                rectPos = [xMin, yMin, xMax - xMin, yMax - yMin];

                % Draw or update the selection rectangle
                if isempty(app.DragData.rectHandle) || ~isvalid(app.DragData.rectHandle)
                    hold(app.UIAxes_selectedROI, 'on');
                    app.DragData.rectHandle = rectangle(app.UIAxes_selectedROI, ...
                        'Position', rectPos, 'EdgeColor', 'r', 'LineStyle', '--', 'LineWidth', 1);
                    hold(app.UIAxes_selectedROI, 'off');
                else
                    set(app.DragData.rectHandle, 'Position', rectPos);
                end
            end
        end

        function mouseRelease(app, imgHandle, imgSize, time_range, freq_range)
            if isempty(app.DragData)
                return;
            end

            % ✅ Skip if the initial click was invalid
            if ~app.DragData.isValidClick
                return;
            end

            cp = get(app.UIAxes_selectedROI, 'CurrentPoint');
            cp = clampPoint(app, round(cp(1, 1:2)), app.DragData.imgSize);

            % ✅ Get current axes limits
            xLimits = xlim(app.UIAxes_selectedROI);
            yLimits = ylim(app.UIAxes_selectedROI);

            % ✅ Check if the release is within the axes boundaries
            if cp(1) < xLimits(1) || cp(1) > xLimits(2) || ...
                    cp(2) < yLimits(1) || cp(2) > yLimits(2)
                disp('Mouse release ignored: Outside the axes boundaries.');
                return;  % Do nothing if outside the axes
            end

            if app.DragData.isDragging
                % Handle Drag Selection
                rectPos = get(app.DragData.rectHandle, 'Position');
                xRange = max(1, round(rectPos(1))) : min(imgSize(2), round(rectPos(1) + rectPos(3)));
                yRange = max(1, round(rectPos(2))) : min(imgSize(1), round(rectPos(2) + rectPos(4)));

                for x = xRange
                    for y = yRange
                        plotRedDotOnImage(app, [x, y], imgHandle, time_range, freq_range);
                    end
                end
                delete(app.DragData.rectHandle);
            else
                % Handle Single Click
                if cp(1) >= 1 && cp(1) <= imgSize(2) && cp(2) >= 1 && cp(2) <= imgSize(1)
                    plotRedDotOnImage(app, cp, imgHandle, time_range, freq_range);
                else
                    title(app.UIAxes_selectedROI, 'Click is outside the image bounds');
                end
            end

            % Reset DragData
            app.DragData.isDragging = false;
            app.DragData.startPoint = [];
            app.DragData.rectHandle = [];
        end

        % Plotting function for a single point
        function plotRedDotOnImage(app, point, imgHandle, time_range, freq_range)
            x = point(1);
            y = point(2);

            imgSize = size(get(imgHandle, 'CData'));
            if x >= 1 && x <= imgSize(2) && y >= 1 && y <= imgSize(1)
                % Store the clicked/selected point
                app.ClickedPoints_1 = [app.ClickedPoints_1; x, y];
                app.LastSelectedPoint = [x, y];

                % Plot a red dot
                hold(app.UIAxes_maskROI, 'on');
                redDot = plot(app.UIAxes_maskROI, x, y, 'ro', 'MarkerSize', 4, 'LineWidth', 2);
                hold(app.UIAxes_maskROI, 'off');

                app.RedDots = [app.RedDots, redDot];

                app.SignaloverlaynumberSpinner.Limits = [0, size(app.ClickedPoints_1, 1)];
                app.SignaloverlaynumberSpinner.Value = size(app.ClickedPoints_1, 1);

                title(app.UIAxes_maskROI, sprintf('Selected at X: %d, Y: %d', x, y));

                % Plot corresponding signal and spectrum
                %signal = squeeze(app.Selected_region_PCMRI(y, x, :));
                %spectrum = squeeze(app.Selected_P_f(y, x, :));

                %plotSignal(app, time_range, signal, x, y);
                %plotSpectrum(app, freq_range, spectrum, x, y);
            end

            if x >= 1 && x <= imgSize(2) && y >= 1 && y <= imgSize(1)
                % Store the clicked/selected point
                app.ClickedPoints = [app.ClickedPoints; x, y];
                app.LastSelectedPoint = [x, y];

                % Plot a red dot
                hold(app.UIAxes_selectedROI, 'on');
                redDot = plot(app.UIAxes_selectedROI, x, y, 'ro', 'MarkerSize', 4, 'LineWidth', 2);
                hold(app.UIAxes_selectedROI, 'off');

                app.RedDots_1 = [app.RedDots_1, redDot];
                title(app.UIAxes_selectedROI, sprintf('Selected at X: %d, Y: %d', x, y));

                % Plot corresponding signal and spectrum
                %signal = squeeze(app.Selected_region_PCMRI(y, x, :));
                %spectrum = squeeze(app.Selected_P_f(y, x, :));

                %plotSignal(app, time_range, signal, x, y);
                %plotSpectrum(app, freq_range, spectrum, x, y);
            end

            if x >= 1 && x <= imgSize(2) && y >= 1 && y <= imgSize(1)
                % Store the clicked/selected point
                app.ClickedPoints_2 = [app.ClickedPoints_2; x, y];
                app.LastSelectedPoint = [x, y];

                % Plot a red dot
                hold(app.UIAxes_selectedROI_std, 'on');
                redDot = plot(app.UIAxes_selectedROI_std, x, y, 'ro', 'MarkerSize', 4, 'LineWidth', 2);
                hold(app.UIAxes_selectedROI_std, 'off');

                app.RedDots_2 = [app.RedDots_2, redDot];
                title(app.UIAxes_selectedROI_std, sprintf('Selected at X: %d, Y: %d', x, y));

                % Plot corresponding signal and spectrum
                %signal = squeeze(app.Selected_region_PCMRI(y, x, :));
                %spectrum = squeeze(app.Selected_P_f(y, x, :));

                %plotSignal(app, time_range, signal, x, y);
                %plotSpectrum(app, freq_range, spectrum, x, y);
            end

            %    -------------- plot dataset.

            % Check if the clicked point is within image bounds
            if x >= 1 && x <= imgSize(2) && y >= 1 && y <= imgSize(1)
                signal = squeeze(app.Selected_region_PCMRI(y, x, :));
                spectrum = squeeze(app.Selected_P_f(y, x, :));

                % Plot signal
                hold(app.UIAxes_Temporal, 'on');
                signalPlot = plot(app.UIAxes_Temporal,time_range,  signal, 'DisplayName', sprintf('Point (%d, %d)', x, y));axis(app.UIAxes_Temporal ,'tight');
                hold(app.UIAxes_Temporal, 'off');
                app.PlottedSignals{end + 1} = signalPlot;
                xlabel(app.UIAxes_Temporal, 'Time Points [sec]');
                ylabel(app.UIAxes_Temporal, 'Signal Intensity (cm/s)');
                title(app.UIAxes_Temporal, 'Single Time-Series Data of specific voxels in ROI');
               

                % Plot spectrum
                hold(app.UIAxes_Spectral, 'on');
                spectrumPlot = plot(app.UIAxes_Spectral,freq_range,  spectrum, 'DisplayName', sprintf('Point (%d, %d)', x, y));axis(app.UIAxes_Spectral ,'tight');
                hold(app.UIAxes_Spectral, 'off');
                app.PlottedSpectra{end + 1} = spectrumPlot;
                xlim(app.UIAxes_Spectral,[0 freq_range(length(freq_range)/2)]);
                xlabel(app.UIAxes_Spectral,'Spectrum (Hz)');
                ylabel(app.UIAxes_Spectral,'Magnitude');
                title(app.UIAxes_Spectral,'Single Spectrum Data of specific voxels in ROI');

            else
                % should be logmessage here.

            end
            
        end

        % Helper Function to Clamp Point within Image Boundaries
        function clampedPoint = clampPoint(app, point, imgSize)
            clampedPoint(1) = min(max(1, point(1)), imgSize(2)); % Clamp X within [1, width]
            clampedPoint(2) = min(max(1, point(2)), imgSize(1)); % Clamp Y within [1, height]
        end

        
        % Update the ROI mask visualization
        function updateROIMaskVisualization(app)
            % Remove all red dots
            app.clearRedDots();

            % variable update.
            time_range = app.ParentSubWindow.time_range_forapp;
            freq_range = app.ParentSubWindow.freq_range_forapp;
            
            % Display the mask image in UIAxes_AveragedImage
            cla(app.UIAxes_maskROI);
            imgHandle1 = imagesc(app.UIAxes_maskROI, app.M_ROI_mask_forapp);
            colormap(app.UIAxes_maskROI, 'gray');
            axis(app.UIAxes_maskROI, 'image');
            axis(app.UIAxes_maskROI, 'tight');
            colorbar(app.UIAxes_maskROI);clim(app.UIAxes_maskROI, [0, 1]);
            xlabel(app.UIAxes_maskROI, 'Pixels');
            ylabel(app.UIAxes_maskROI, 'Pixels');
            title(app.UIAxes_maskROI, 'Averaged Image - Select ROI');
            
            % For color range.
            lowLimit = app.ColorbarlowEditField.Value;
            highLimit = app.ColorbarhighEditField.Value;

            % Display the selectedROI image in UIAxes_AveragedImage
            cla(app.UIAxes_selectedROI);
            imgHandle = imagesc(app.UIAxes_selectedROI, app.Selected_region_averaged_image);
            colormap(app.UIAxes_selectedROI, 'gray');
            axis(app.UIAxes_selectedROI, 'image');
            axis(app.UIAxes_selectedROI, 'tight');
            colorbar(app.UIAxes_selectedROI);clim(app.UIAxes_selectedROI, [lowLimit, highLimit]);
            xlabel(app.UIAxes_selectedROI, 'Pixels');
            ylabel(app.UIAxes_selectedROI, 'Pixels');
            title(app.UIAxes_selectedROI, 'Averaged Image - Select ROI');

            % For color range.
            lowLimit_std = app.ColorbarlowEditField_2.Value;
            highLimit_std = app.ColorbarhighEditField_2.Value;

            % Display the selectedROI image in UIAxes_AveragedImage
            cla(app.UIAxes_selectedROI_std);
            imgHandle2 = imagesc(app.UIAxes_selectedROI_std, app.Selected_region_std_image);
            colormap(app.UIAxes_selectedROI_std, 'gray');
            axis(app.UIAxes_selectedROI_std, 'image');
            axis(app.UIAxes_selectedROI_std, 'tight');
            colorbar(app.UIAxes_selectedROI_std);clim(app.UIAxes_selectedROI_std, [lowLimit_std, highLimit_std]);
            xlabel(app.UIAxes_selectedROI_std, 'Pixels');
            ylabel(app.UIAxes_selectedROI_std, 'Pixels');
            title(app.UIAxes_selectedROI_std, 'Averaged Image - Select ROI');

            app.Adjusting_baseline_mask();
            
            % update ROI mask pixels count
            app.updateROIcount(app.M_ROI_mask_forapp)

            % Clear any existing plots
            cla(app.UIAxes_Temporal);
            cla(app.UIAxes_Spectral);
            % Clear any existing plots at respiration signal part.
            %cla(app.UIAxes_Temporal_origin);
            
            % Set up mouse click callback on the axes
            % imgHandle1.ButtonDownFcn = @(src, event) app.plotRedDotOnImage(event.IntersectionPoint, imgHandle1);

            % app.ParentSubWindow.saveData()

            % ✅ Initialize DragData to prevent errors
            app.DragData = struct('isDragging', false, 'startPoint', [], 'rectHandle', []);
            %Get image dimensions
            app.DragData.imgSize = size(get(imgHandle1, 'CData')); % Get image data size
            %disp(app.DragData.imgSize)

            %set(imgHandle, 'ButtonDownFcn', @(src, event) mousePress(app, src, event, app.UIAxes_selectedROI));
            %set(imgHandle1, 'ButtonDownFcn', @(src, event) mousePress(app, src, event, app.UIAxes_maskROI));

            % % Set up mouse event callbacks for both axes
            % fig = ancestor(app.UIAxes_selectedROI, 'figure');
            % 
            % % Attach callbacks to handle both click and drag
            % set(fig, 'WindowButtonDownFcn', @(src, event) mousePress(app, src, event));
            % set(fig, 'WindowButtonMotionFcn', @(src, event) mouseDrag(app, src, event));
            % set(fig, 'WindowButtonUpFcn', @(src, event) mouseRelease(app, imgHandle, app.DragData.imgSize, time_range, freq_range));

            % Set up mouse event callbacks for both axes
            fig1 = ancestor(app.UIAxes_maskROI, 'figure');

            % Attach callbacks to handle both click and drag
            set(fig1, 'WindowButtonDownFcn', @(src, event) mousePress(app, src, event));
            set(fig1, 'WindowButtonMotionFcn', @(src, event) mouseDrag(app, src, event));
            set(fig1, 'WindowButtonUpFcn', @(src, event) mouseRelease(app, imgHandle1, app.DragData.imgSize, time_range, freq_range));

            % Store image handles for reference
            %app.ImageHandles = {imgHandle, imgHandle1};

        end


        function plotOverallMaskSignal(app)
            time_range = app.ParentSubWindow.time_range_forapp;
            freq_range = app.ParentSubWindow.freq_range_forapp;
            %------------ step7. extract single voxel data from ROI mask.
            PCMRI_data = app.Selected_region_PCMRI;
            M_ROI_mask = app.M_ROI_mask_forapp;
            % Step 7-1: Apply the Mask
            % Replicate the mask across the third dimension to match the size of PCMRI_data
            ROI_mask_3D = repmat(M_ROI_mask, [1, 1, size(PCMRI_data, 3)]);
            masked_M_ROI_data = PCMRI_data .* ROI_mask_3D; % Element-wise multiplication to apply mask

            % Step 7-2: Extract Time-Series Data for Masked Voxels
            % Find the linear indices of the ROI pixels (i.e., where ROI_mask == 1)
            [mask_x, mask_y] = find(M_ROI_mask == 1);
            num_voxels = numel(mask_x); % Number of voxels in the mask

            % Preallocate for storing the time-series data of masked voxels
            masked_M_ROI_signals = zeros(num_voxels, size(PCMRI_data, 3)); % (num_voxels x 720)
            masked_M_ROI_spectrum = zeros(num_voxels, size(PCMRI_data, 3)); % (num_voxels x 720)
            masked_M_ROI_P_f_max = zeros(num_voxels,1); % (num_voxels x 1)
            masked_M_ROI_f_max = zeros(num_voxels,1); % (num_voxels x 1)
            masked_M_ROI_dc = zeros(num_voxels,1); % (num_voxels x 1)
            masked_M_ROI_real = zeros(num_voxels, size(PCMRI_data, 3)); % (num_voxels x 720)
            masked_M_ROI_real_firstsample = zeros(num_voxels,1); % (num_voxels x 1)

            % Extract each voxel's time-series data
            for i = 1:num_voxels
                x = mask_x(i);
                y = mask_y(i);
                masked_M_ROI_signals(i, :) = squeeze(PCMRI_data(x, y, :)); % Extract and store time-series
                masked_M_ROI_spectrum(i, :) = squeeze(app.Selected_P_f(x, y, :)); % Extract and store time-series
                masked_M_ROI_P_f_max(i) = squeeze(app.ROI_P_f_max(x, y)); % Extract and store time-series
                masked_M_ROI_f_max(i) = squeeze(app.ROI_f_max_mat_forapp(x, y)); % Extract and store time-series
                masked_M_ROI_dc(i) = squeeze(app.ROI_dc(x, y)); % Extract and store time-series
                masked_M_ROI_real(i, :) = squeeze(app.ROI_P_f_real(x, y, :)); % Extract and store time-series
                masked_M_ROI_real_firstsample(i) = squeeze(app.ROI_P_f_real(x, y, 1)); % Extract and store time-series
            end

            % Plot signals for each pixel in the mask
            hold(app.UIAxes_Temporal, 'on');
            for i = 1:num_voxels
                signal = masked_M_ROI_signals(i,:);
                plot(app.UIAxes_Temporal, time_range, signal, 'DisplayName', sprintf('Pixel (%d, %d)', mask_x(i), mask_y(i)));axis(app.UIAxes_Temporal ,'tight');
            end
            hold(app.UIAxes_Temporal, 'off');
            xlabel(app.UIAxes_Temporal, 'Time Points [sec]');
            ylabel(app.UIAxes_Temporal, 'Signal Intensity (cm/s)');
            title(app.UIAxes_Temporal, 'Time-Series Data of Masked Voxels in ROI');

            % Plot signals for each pixel in the mask
            hold(app.UIAxes_Spectral, 'on');
            for i = 1:num_voxels
                signal = masked_M_ROI_spectrum(i,:);
                plot(app.UIAxes_Spectral, freq_range, signal, 'DisplayName', sprintf('Pixel (%d, %d)', mask_x(i), mask_y(i)));axis(app.UIAxes_Spectral ,'tight');
            end
            hold(app.UIAxes_Spectral, 'off');
            xlim(app.UIAxes_Spectral,[0 freq_range(length(freq_range)/2)]);
            xlabel(app.UIAxes_Spectral,'Spectrum (Hz)');
            ylabel(app.UIAxes_Spectral,'Magnitude');
            title(app.UIAxes_Spectral,'Spectrum Data of Masked Voxels in ROI');

            % Save signal to app variables.
            app.Signal_Overall_temporal = masked_M_ROI_signals;
            app.Signal_Overall_spectral = masked_M_ROI_spectrum;
            app.Signal_Overall_ROI_P_f_max = masked_M_ROI_P_f_max;
            app.Signal_Overall_ROI_f_max = masked_M_ROI_f_max;
            app.Signal_Overall_dc = masked_M_ROI_dc;
            app.Signal_Overall_M_ROI_real = masked_M_ROI_real;
            app.Signal_Overall_M_ROI_real_firstsample = masked_M_ROI_real_firstsample;

        end

        function plotAverageSignal(app)
            time_range = app.ParentSubWindow.time_range_forapp;
            freq_range = app.ParentSubWindow.freq_range_forapp;
            %------------ step7. extract single voxel data from ROI mask.
            PCMRI_data = app.Selected_region_PCMRI;
            M_ROI_mask = app.M_ROI_mask_forapp;
            % Step 7-1: Apply the Mask
            % Replicate the mask across the third dimension to match the size of PCMRI_data
            ROI_mask_3D = repmat(M_ROI_mask, [1, 1, size(PCMRI_data, 3)]);
            masked_M_ROI_data = PCMRI_data .* ROI_mask_3D; % Element-wise multiplication to apply mask

            % Step 7-2: Extract Time-Series Data for Masked Voxels
            % Find the linear indices of the ROI pixels (i.e., where ROI_mask == 1)
            [mask_x, mask_y] = find(M_ROI_mask == 1);
            num_voxels = numel(mask_x); % Number of voxels in the mask

            % Preallocate for storing the time-series data of masked voxels
            masked_M_ROI_signals = zeros(num_voxels, size(PCMRI_data, 3)); % (num_voxels x 720)
            masked_M_ROI_spectrum = zeros(num_voxels, size(PCMRI_data, 3)); % (num_voxels x 720)
            masked_M_ROI_P_f_max = zeros(num_voxels,1); % (num_voxels x 1)
            masked_M_ROI_f_max = zeros(num_voxels,1); % (num_voxels x 1)
            masked_M_ROI_dc = zeros(num_voxels,1); % (num_voxels x 1)
            masked_M_ROI_real = zeros(num_voxels, size(PCMRI_data, 3)); % (num_voxels x 720)
            masked_M_ROI_real_firstsample = zeros(num_voxels,1); % (num_voxels x 1)

            % Extract each voxel's time-series data
            for i = 1:num_voxels
                x = mask_x(i);
                y = mask_y(i);
                masked_M_ROI_signals(i, :) = squeeze(PCMRI_data(x, y, :)); % Extract and store time-series
                masked_M_ROI_spectrum(i, :) = squeeze(app.Selected_P_f(x, y, :)); % Extract and store time-series
                masked_M_ROI_P_f_max(i) = squeeze(app.ROI_P_f_max(x, y)); % Extract and store time-series
                masked_M_ROI_f_max(i) = squeeze(app.ROI_f_max_mat_forapp(x, y)); % Extract and store time-series
                masked_M_ROI_dc(i) = squeeze(app.ROI_dc(x, y)); % Extract and store time-series
                masked_M_ROI_real(i, :) = squeeze(app.ROI_P_f_real(x, y, :)); % Extract and store time-series
                masked_M_ROI_real_firstsample(i) = squeeze(app.ROI_P_f_real(x, y, 1)); % Extract and store time-series
            end

            % Mean data
            Mean_masked_M_ROI_signals = mean(masked_M_ROI_signals, 1);
            Mean_masked_M_ROI_spectrals = mean(masked_M_ROI_spectrum, 1);


            %-------------->  For origin mask plotting
            M_ROI_mask_origin = app.ParentAnalysisWindow.M_ROI_mask_forapp;
            % Step 7-1: Apply the Mask
            % Replicate the mask across the third dimension to match the size of PCMRI_data
            ROI_mask_3D = repmat(M_ROI_mask_origin, [1, 1, size(PCMRI_data, 3)]);
            masked_M_ROI_data_origin = PCMRI_data .* ROI_mask_3D; % Element-wise multiplication to apply mask

            % Step 7-2: Extract Time-Series Data for Masked Voxels
            % Find the linear indices of the ROI pixels (i.e., where ROI_mask == 1)
            [mask_x_origin, mask_y_origin] = find(M_ROI_mask_origin == 1);
            num_voxels_origin = numel(mask_x_origin); % Number of voxels in the mask

            % Preallocate for storing the time-series data of masked voxels
            masked_M_ROI_signals_origin = zeros(num_voxels_origin, size(PCMRI_data, 3)); % (num_voxels x 720)
            masked_M_ROI_spectrum_origin = zeros(num_voxels_origin, size(PCMRI_data, 3)); % (num_voxels x 720)
            masked_M_ROI_P_f_max_origin = zeros(num_voxels_origin,1); % (num_voxels x 1)
            masked_M_ROI_f_max_origin = zeros(num_voxels_origin,1); % (num_voxels x 1)
            masked_M_ROI_dc_origin = zeros(num_voxels_origin,1); % (num_voxels x 1)
            masked_M_ROI_real_origin = zeros(num_voxels_origin, size(PCMRI_data, 3)); % (num_voxels x 720)
            masked_M_ROI_real_firstsample_origin = zeros(num_voxels_origin,1); % (num_voxels x 1)

            % Extract each voxel's time-series data
            for ii = 1:num_voxels_origin
                x = mask_x_origin(ii);
                y = mask_y_origin(ii);
                masked_M_ROI_signals_origin(ii, :) = squeeze(PCMRI_data(x, y, :)); % Extract and store time-series
                masked_M_ROI_spectrum_origin(ii, :) = squeeze(app.Selected_P_f(x, y, :)); % Extract and store time-series
                masked_M_ROI_P_f_max_origin(ii) = squeeze(app.ROI_P_f_max(x, y)); % Extract and store time-series
                masked_M_ROI_f_max_origin(ii) = squeeze(app.ROI_f_max_mat_forapp(x, y)); % Extract and store time-series
                masked_M_ROI_dc_origin(ii) = squeeze(app.ROI_dc(x, y)); % Extract and store time-series
                masked_M_ROI_real_origin(ii, :) = squeeze(app.ROI_P_f_real(x, y, :)); % Extract and store time-series
                masked_M_ROI_real_firstsample_origin(ii) = squeeze(app.ROI_P_f_real(x, y, 1)); % Extract and store time-series
            end

            % Mean data
            Mean_masked_M_ROI_signals_origin = mean(masked_M_ROI_signals_origin, 1);
            Mean_masked_M_ROI_spectrals_origin = mean(masked_M_ROI_spectrum_origin, 1);
            
            % Plot signals for each pixel in the mask
            plot(app.UIAxes_Temporal_origin, time_range, Mean_masked_M_ROI_signals_origin, 'r', 'DisplayName', sprintf('Pixel (%d, %d)', mask_x_origin(ii), mask_y_origin(ii)));axis(app.UIAxes_Temporal_origin ,'tight');
            xlabel(app.UIAxes_Temporal_origin, 'Time Points [sec]');
            ylabel(app.UIAxes_Temporal_origin, 'Signal Intensity (cm/s)');
            title(app.UIAxes_Temporal_origin, 'Averaged Time-Series Data of Masked Voxels in ROI');

            % Plot signals for each pixel in the mask
            plot(app.UIAxes_Temporal, time_range, Mean_masked_M_ROI_signals, 'DisplayName', sprintf('Pixel (%d, %d)', mask_x(i), mask_y(i)));axis(app.UIAxes_Temporal ,'tight');
            xlabel(app.UIAxes_Temporal, 'Time Points [sec]');
            ylabel(app.UIAxes_Temporal, 'Signal Intensity (cm/s)');
            title(app.UIAxes_Temporal, 'Averaged Time-Series Data of Masked Voxels in ROI');

            % Plot signals for each pixel in the mask
            plot(app.UIAxes_Spectral, freq_range, Mean_masked_M_ROI_spectrals, 'DisplayName', sprintf('Pixel (%d, %d)', mask_x(i), mask_y(i)));axis(app.UIAxes_Spectral ,'tight');
            hold(app.UIAxes_Spectral, 'on');
            plot(app.UIAxes_Spectral, freq_range, Mean_masked_M_ROI_spectrals_origin, 'r', 'DisplayName', sprintf('Pixel (%d, %d)', mask_x_origin(ii), mask_y_origin(ii)));axis(app.UIAxes_Spectral ,'tight');
            xlim(app.UIAxes_Spectral,[0 freq_range(length(freq_range)/2)]);
            xlabel(app.UIAxes_Spectral, 'Spectrum [Hz]');
            ylabel(app.UIAxes_Spectral, 'Signal Intensity (cm/s)');
            title(app.UIAxes_Spectral, 'Averaged Spectrum Data of Masked Voxels in ROI');

            % Save signal to app variables.
            app.Signal_Mean_temporal = Mean_masked_M_ROI_signals;
            app.Signal_Mean_spectral = Mean_masked_M_ROI_spectrals;

            % Save signal to app variables.
            app.Signal_Overall_temporal = masked_M_ROI_signals;
            app.Signal_Overall_spectral = masked_M_ROI_spectrum;
            app.Signal_Overall_ROI_P_f_max = masked_M_ROI_P_f_max;
            app.Signal_Overall_ROI_f_max = masked_M_ROI_f_max;
            app.Signal_Overall_dc = masked_M_ROI_dc;
            app.Signal_Overall_M_ROI_real = masked_M_ROI_real;
            app.Signal_Overall_M_ROI_real_firstsample = masked_M_ROI_real_firstsample;
        end


        function updatePlot(app)
            % Remove all red dots
            app.clearRedDots();

            switch app.CurrentPlotMode
                case 'OverlaySignal'
                    % action needed (Possible to conduct ROImask working here.)
                    app.updateROIMaskVisualization();
                case 'OverallMaskSignal'
                    app.plotOverallMaskSignal();
                case 'AverageSignal'
                    app.plotAverageSignal();
            end
        end

        % Function to remove all red dots
        function clearRedDots(app)
            % Loop through and delete each red dot
            for i = 1:numel(app.RedDots)
                delete(app.RedDots(i));
            end
            % Clear the RedDots array
            app.RedDots = [];

            for i = 1:numel(app.RedDots_1)
                delete(app.RedDots_1(i));
            end
            % Clear the RedDots array
            app.RedDots_1 = [];

            for i = 1:numel(app.RedDots_2)
                delete(app.RedDots_2(i));
            end
            % Clear the RedDots array
            app.RedDots_2 = [];


            % Reset the spinner value to 0
            app.SignaloverlaynumberSpinner.Value = 0;
            app.SignaloverlaynumberSpinner.Limits = [0, 0];

            % Reset saved point at all.
            app.ClickedPoints = []; % Stores clicked points [x, y]
            app.ClickedPoints_1 = [];
            app.ClickedPoints_2 = [];
            app.PlottedSignals = {}; % Stores plotted PCMRI signals
            app.PlottedSpectra = {}; % Stores plotted spectral data

        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ColorbarhighEditField
        function ColorbarhighEditFieldValueChanged(app, event)
            % Update the colorbar limits
            lowLimit = app.ColorbarlowEditField.Value;
            highLimit = app.ColorbarhighEditField.Value;
            clim(app.UIAxes_selectedROI, [lowLimit, highLimit]);
            %clim(app.UIAxes_fusionimg, [lowLimit, highLimit]);
        end

        % Value changed function: ColorbarlowEditField
        function ColorbarlowEditFieldValueChanged(app, event)
            % Update the colorbar limits
            lowLimit = app.ColorbarlowEditField.Value;
            highLimit = app.ColorbarhighEditField.Value;
            clim(app.UIAxes_selectedROI, [lowLimit, highLimit]);
            %clim(app.UIAxes_fusionimg, [lowLimit, highLimit]);
        end

        % Value changed function: SignaloverlaynumberSpinner
        function SignaloverlaynumberSpinnerValueChanged(app, event)
            newValue = app.SignaloverlaynumberSpinner.Value;

            % Remove excess points if the spinner value decreases
            while size(app.ClickedPoints, 1) > newValue
                % Remove the last red dot
                delete(app.RedDots(end));
                app.RedDots(end) = [];

                % Remove the last red dot
                delete(app.RedDots_1(end));
                app.RedDots_1(end) = [];

                % Remove the last red dot
                delete(app.RedDots_2(end));
                app.RedDots_2(end) = [];


                % Remove the last plotted signal and spectrum
                delete(app.PlottedSignals{end});
                delete(app.PlottedSpectra{end});
                app.PlottedSignals(end) = [];
                app.PlottedSpectra(end) = [];
                
                % Remove the last plotted signal and spectrum part in respiration part.
                %delete(app.PlottedSignals_2{end});
                %app.PlottedSignals_2(end) = [];

                % Remove the last clicked point
                app.ClickedPoints(end, :) = [];
                app.ClickedPoints_1(end,:) = [];
                app.ClickedPoints_2(end,:) = [];
            end

            % Update spinner limits
            app.SignaloverlaynumberSpinner.Limits = [0, size(app.ClickedPoints, 1)];
        end

        % Selection changed function: AnalysistypeButtonGroup
        function AnalysistypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.AnalysistypeButtonGroup.SelectedObject;
            if selectedButton == app.SinglesignaloverlayButton
                app.CurrentPlotMode = 'OverlaySignal';
            elseif selectedButton == app.OverallmasksignalButton
                app.CurrentPlotMode = 'OverallMaskSignal';
            elseif selectedButton == app.AveragesignalButton
                app.CurrentPlotMode = 'AverageSignal';
            end

            % Clear any existing plots
            cla(app.UIAxes_Temporal);
            cla(app.UIAxes_Spectral);
            
            % Clear any existing plots in respiration part axes
            %cla(app.UIAxes_Temporal_origin);

            % Execute the selected function
            app.updatePlot();
        end

        % Button pushed function: ROIedit_remove
        function ROIedit_removeButtonPushed(app, event)
            if isempty(app.LastSelectedPoint)
                app.MainApp.logMessage('[System error] No point selected. Click on the image first.');
                return;
            end
            
            % Update the ROI mask for all selected pixels
            for i = 1:size(app.ClickedPoints_1, 1)
                x = app.ClickedPoints_1(i, 1);
                y = app.ClickedPoints_1(i, 2);
                app.M_ROI_mask_forapp(y, x) = 0; % Include the pixel in the ROI mask
            end

            % Update the ROI visualization
            app.updateROIMaskVisualization();
        end

        % Button pushed function: ROIedit_include
        function ROIedit_includeButtonPushed(app, event)
            if isempty(app.LastSelectedPoint)
                app.MainApp.logMessage('[System error] No point selected. Click on the image first.');
                return;
            end
            
            % Update the ROI mask for all selected pixels
            for i = 1:size(app.ClickedPoints_1, 1)
                x = app.ClickedPoints_1(i, 1);
                y = app.ClickedPoints_1(i, 2);
                app.M_ROI_mask_forapp(y, x) = 1; % Include the pixel in the ROI mask
            end

            % Update the ROI visualization
            app.updateROIMaskVisualization();
        end

        % Button pushed function: AdjustcalibrationButton
        function AdjustcalibrationButtonPushed(app, event)
            selectOption = app.AnalysistypeButtonGroup.SelectedObject;

            if ~isempty(app.ParentAnalysisWindow) && isvalid(app.ParentAnalysisWindow)
                if selectOption == app.AveragesignalButton
                    app.ParentAnalysisWindow.Calibration_withinROIEditField.Value = 'adjusted';
                    %--- update values to parentwindow to adjust Calibration.
                    % app.M_ROI_mask_forapp -> updated mask value
                    % app.Signal_Mean_temporal -> Average value from mask
                    % app.Signal_Mean_spectral -> Average value from mask
                    % Transmit Flag
                    app.ParentAnalysisWindow.Flag_calibration_state_withinROI = 1; % Flag on;
                    app.ParentAnalysisWindow.Mask_calibration_work = app.M_ROI_mask_forapp;
                    app.ParentAnalysisWindow.Mean_temporal_value_from_calibration_mask = app.Signal_Mean_temporal;
                    app.ParentAnalysisWindow.Mean_spectrum_value_from_calibration_mask = app.Signal_Mean_spectral;
                    app.ParentAnalysisWindow.roi_mask_count_cali = app.ROImaskpixelcountEditField.Value;
                    
                    % Change button color after clicking.
                    app.AdjustcalibrationButton.BackgroundColor = [0.2, 0.8, 0.2]; % Green color.
                    app.AdjustcalibrationButton.Enable = 'off'; % Disable button after clicking.

                    % activate function in parent window
                    app.ParentAnalysisWindow.Adjusting_Calibration_to_Variables();
                    % activate function in parent window
                    app.ParentAnalysisWindow.Adjusting_Calibration_mask();

                    disp('[Calibration system info] Successfully adjusting Calibration results..!!');

                else
                    disp('[Calibration system info] Please choose analaysis type button as "Average" ');
                end

            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            Flaginformation_check = app.ParentAnalysisWindow.Flag_calibration_state_withinROI;
            if Flaginformation_check==0

            elseif Flaginformation_check==1
                if ~isempty(app.ParentAnalysisWindow) && isvalid(app.ParentAnalysisWindow)
                    app.ParentAnalysisWindow.Calibration_withinROIEditField.Value = 'None';
                    %--- update values to parentwindow to adjust Calibration.
                    % app.M_ROI_mask_forapp -> updated mask value
                    % app.Signal_Mean_temporal -> Average value from mask
                    % app.Signal_Mean_spectral -> Average value from mask
                    % Transmit Flag
                    app.ParentAnalysisWindow.Flag_calibration_state_withinROI = 0; % Flag on;
                    app.ParentAnalysisWindow.Mask_calibration_work = [];
                    app.ParentAnalysisWindow.Mean_temporal_value_from_calibration_mask = [];
                    app.ParentAnalysisWindow.Mean_spectrum_value_from_calibration_mask = [];
                    app.ParentAnalysisWindow.roi_mask_count_cali = [];

                    % Change button color after clicking.
                    app.AdjustcalibrationButton.BackgroundColor = [0.8, 0.8, 0.8]; % grey color.
                    app.AdjustcalibrationButton.Enable = 'on'; % Disable button after clicking.

                    % deactivate function in parent window
                    app.ParentAnalysisWindow.Canceling_Calibration_to_Variables();
                    app.ParentAnalysisWindow.Canceling_Calibration_mask();

                    disp('[Calibration system info] Cancelled Calibration results..!!');
                end

            end
        end

        % Value changed function: ColorbarlowEditField_2
        function ColorbarlowEditField_2ValueChanged(app, event)
            % Update the colorbar limits
            lowLimit = app.ColorbarlowEditField_2.Value;
            highLimit = app.ColorbarhighEditField_2.Value;
            clim(app.UIAxes_selectedROI_std, [lowLimit, highLimit]);
            %clim(app.UIAxes_fusionimg, [lowLimit, highLimit]);
            
        end

        % Value changed function: ColorbarhighEditField_2
        function ColorbarhighEditField_2ValueChanged(app, event)
            % Update the colorbar limits
            lowLimit = app.ColorbarlowEditField_2.Value;
            highLimit = app.ColorbarhighEditField_2.Value;
            clim(app.UIAxes_selectedROI_std, [lowLimit, highLimit]);
            %clim(app.UIAxes_fusionimg, [lowLimit, highLimit]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_selectedROI = uiaxes(app.UIFigure);
            title(app.UIAxes_selectedROI, 'Title')
            xlabel(app.UIAxes_selectedROI, 'X')
            ylabel(app.UIAxes_selectedROI, 'Y')
            zlabel(app.UIAxes_selectedROI, 'Z')
            app.UIAxes_maskROI = uiaxes(app.UIFigure);
            title(app.UIAxes_maskROI, 'Title')
            xlabel(app.UIAxes_maskROI, 'X')
            ylabel(app.UIAxes_maskROI, 'Y')
            zlabel(app.UIAxes_maskROI, 'Z')
            app.UIAxes_Temporal = uiaxes(app.UIFigure);
            title(app.UIAxes_Temporal, 'Title')
            xlabel(app.UIAxes_Temporal, 'X')
            ylabel(app.UIAxes_Temporal, 'Y')
            zlabel(app.UIAxes_Temporal, 'Z')
            app.UIAxes_Spectral = uiaxes(app.UIFigure);
            title(app.UIAxes_Spectral, 'Title')
            xlabel(app.UIAxes_Spectral, 'X')
            ylabel(app.UIAxes_Spectral, 'Y')
            zlabel(app.UIAxes_Spectral, 'Z')
            app.UIAxes_Temporal_origin = uiaxes(app.UIFigure);
            title(app.UIAxes_Temporal_origin, 'Title')
            xlabel(app.UIAxes_Temporal_origin, 'X')
            ylabel(app.UIAxes_Temporal_origin, 'Y')
            zlabel(app.UIAxes_Temporal_origin, 'Z')
            app.UIAxes_selectedROI_std = uiaxes(app.UIFigure);
            title(app.UIAxes_selectedROI_std, 'Title')
            xlabel(app.UIAxes_selectedROI_std, 'X')
            ylabel(app.UIAxes_selectedROI_std, 'Y')
            zlabel(app.UIAxes_selectedROI_std, 'Z')
            app.SignaloverlaynumberSpinnerLabel = uilabel(app.UIFigure);
            app.SignaloverlaynumberSpinnerLabel.Text = 'Signal overlay number';
            app.SignaloverlaynumberSpinner = uispinner(app.UIFigure);
            app.SignaloverlaynumberSpinner.ValueChangedFcn = createCallbackFcn(app, @SignaloverlaynumberSpinnerValueChanged, true);
            app.ColorbarhighEditFieldLabel = uilabel(app.UIFigure);
            app.ColorbarhighEditFieldLabel.Text = 'Colorbar high';
            app.ColorbarhighEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColorbarhighEditField.ValueChangedFcn = createCallbackFcn(app, @ColorbarhighEditFieldValueChanged, true);
            app.ColorbarlowEditFieldLabel = uilabel(app.UIFigure);
            app.ColorbarlowEditFieldLabel.Text = 'Colorbar low';
            app.ColorbarlowEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColorbarlowEditField.ValueChangedFcn = createCallbackFcn(app, @ColorbarlowEditFieldValueChanged, true);
            app.AnalysistypeButtonGroup = uibuttongroup(app.UIFigure);
            app.AnalysistypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AnalysistypeButtonGroupSelectionChanged, true);
            app.AnalysistypeButtonGroup.Title = 'Analysis type';
            app.SinglesignaloverlayButton = uiradiobutton(app.AnalysistypeButtonGroup);
            app.SinglesignaloverlayButton.Text = 'Single signal overlay';
            app.SinglesignaloverlayButton.Value = true;
            app.OverallmasksignalButton = uiradiobutton(app.AnalysistypeButtonGroup);
            app.OverallmasksignalButton.Text = 'Overall mask signal';
            app.AveragesignalButton = uiradiobutton(app.AnalysistypeButtonGroup);
            app.AveragesignalButton.Text = 'Average signal';
            app.TemporalsignalforcalibrationLabel = uilabel(app.UIFigure);
            app.TemporalsignalforcalibrationLabel.Text = 'Temporal signal for calibration';
            app.SpectralsignalLabel = uilabel(app.UIFigure);
            app.SpectralsignalLabel.Text = 'Spectral signal';
            app.SudoROIeditLabel = uilabel(app.UIFigure);
            app.SudoROIeditLabel.Text = 'Sudo ROI edit';
            app.ROIedit_include = uibutton(app.UIFigure, 'push');
            app.ROIedit_include.ButtonPushedFcn = createCallbackFcn(app, @ROIedit_includeButtonPushed, true);
            app.ROIedit_include.Text = '+';
            app.ROIedit_remove = uibutton(app.UIFigure, 'push');
            app.ROIedit_remove.ButtonPushedFcn = createCallbackFcn(app, @ROIedit_removeButtonPushed, true);
            app.ROIedit_remove.Text = '-';
            app.AdjustcalibrationButton = uibutton(app.UIFigure, 'push');
            app.AdjustcalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustcalibrationButtonPushed, true);
            app.AdjustcalibrationButton.Text = 'Adjust calibration';
            app.ROImaskpixelcountEditFieldLabel = uilabel(app.UIFigure);
            app.ROImaskpixelcountEditFieldLabel.Text = 'ROI mask pixel count';
            app.ROImaskpixelcountEditField = uieditfield(app.UIFigure, 'numeric');
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.TemporalsignalfororiginmaskLabel = uilabel(app.UIFigure);
            app.TemporalsignalfororiginmaskLabel.Text = 'Temporal signal for origin mask';
            app.ColorbarhighEditFieldLabel_2 = uilabel(app.UIFigure);
            app.ColorbarhighEditFieldLabel_2.Text = 'Colorbar high';
            app.ColorbarhighEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ColorbarhighEditField_2.ValueChangedFcn = createCallbackFcn(app, @ColorbarhighEditField_2ValueChanged, true);
            app.ColorbarlowEditFieldLabel_2 = uilabel(app.UIFigure);
            app.ColorbarlowEditFieldLabel_2.Text = 'Colorbar low';
            app.ColorbarlowEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ColorbarlowEditField_2.ValueChangedFcn = createCallbackFcn(app, @ColorbarlowEditField_2ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calibration_window

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end