classdef MagnitudeImgPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ColorbarhighEditField       matlab.ui.control.NumericEditField
        ColorbarhighEditFieldLabel  matlab.ui.control.Label
        ColorbarlowEditField        matlab.ui.control.NumericEditField
        ColorbarlowEditFieldLabel   matlab.ui.control.Label
        UIAxes_rawimg               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        ParentSubWindow % Handle to the parent window/app (used to access shared UI/state)
        PCMRI_Data_MAG % Magnitude PCMRI image stack (X × Y × T)
        
    end
    
    methods (Access = public)
        
        function setMainApp(app, ParentAppReference)
            % Store a reference to the parent subwindow so we can read shared UI values
            % (e.g., the current frame index from the parent's slider).
            app.ParentSubWindow = ParentAppReference;
        end

        function setMagnitudeImage(app, magnitudeImage)
            % Load the magnitude image stack into this window and refresh the display.
            %
            % Input:
            %   magnitudeImage : 3D array (X × Y × T), where T is the frame/time dimension
            %
            % Workflow:
            %   1) Store the input stack
            %   2) Initialize colorbar limits based on the first frame
            %   3) Display the frame currently selected in the parent window

            app.PCMRI_Data_MAG = magnitudeImage;

            % Number of frames in the magnitude stack (assumes 3rd dim is time)
            numFrames = size(app.PCMRI_Data_MAG, 3); % Assume data is (x, y, time)
            % (Optional) If this window has its own slider/spinner, initialize them here
            %app.TimerSlider.Limits = [1, numFrames]; % Set slider limits
            %app.TimerSlider.Value = 1; % Start at the first frame
            %app.FramenumberSpinner.Limits = [1, numFrames]; % Set FramenumberSpinner limits
            %app.FramenumberSpinner.Value = 1; % Start at the first frame

            % Initialize color scaling from the first frame (prevents washed-out display)
            app.initializeColorbarLimits(app.PCMRI_Data_MAG(:, :, 1));
            %app.initializeColorbarLimits_fusion(app.PCMRI(:, :, 1));
            % Draw the current frame based on the parent window's frame selection
            app.updateImage(app.ParentSubWindow.TimerSlider.Value);

        end

        function initializeColorbarLimits(app, imageData)
            % Set the colorbar edit fields and axes scaling based on image min/max.
            %
            % Input:
            %   imageData : 2D array (single frame)
            minValue = min(imageData(:));
            maxValue = max(imageData(:));

            % Update UI edit fields
            app.ColorbarlowEditField.Value = minValue;
            app.ColorbarhighEditField.Value = maxValue;

            % Apply color scaling to the display axes
            %caxis(app.UIAxes_rawimg, [minValue, maxValue]);
            caxis(app.UIAxes_rawimg, [minValue, maxValue]);
        end
        
        function updateImage(app, frameIndex)
            % Render a single magnitude frame in UIAxes_rawimg.
            %
            % Input:
            %   frameIndex : integer frame index (1..T)
            if isempty(app.PCMRI_Data_MAG)
                return;
            end
            %disp(num2str(size(app.PCMRI_Data_MAG)));
            % Clear the axes and show the requested frame
            cla(app.UIAxes_rawimg);
            imagesc(app.UIAxes_rawimg, app.PCMRI_Data_MAG(:, :, frameIndex));
            colormap(app.UIAxes_rawimg, 'gray'); colorbar(app.UIAxes_rawimg);
            % Fit the image to the axes
            % Axes formatting
            xlabel(app.UIAxes_rawimg, 'Pixels');
            ylabel(app.UIAxes_rawimg, 'Pixels');
            axis(app.UIAxes_rawimg, 'image'); % Maintain aspect ratio
            axis(app.UIAxes_rawimg, 'tight'); % Fit the image to the axes
            title(app.UIAxes_rawimg, sprintf('Frame %d', frameIndex));
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ColorbarlowEditField
        function ColorbarlowEditFieldValueChanged(app, event)
            % Update the colorbar limits
            lowLimit = app.ColorbarlowEditField.Value;
            highLimit = app.ColorbarhighEditField.Value;
            clim(app.UIAxes_rawimg, [lowLimit, highLimit]);
            %clim(app.UIAxes_fusionimg, [lowLimit, highLimit]);
        end

        % Value changed function: ColorbarhighEditField
        function ColorbarhighEditFieldValueChanged(app, event)
            % Update the colorbar limits
            lowLimit = app.ColorbarlowEditField.Value;
            highLimit = app.ColorbarhighEditField.Value;
            clim(app.UIAxes_rawimg, [lowLimit, highLimit]);
            %clim(app.UIAxes_fusionimg, [lowLimit, highLimit]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_rawimg = uiaxes(app.UIFigure);
            title(app.UIAxes_rawimg, 'Title')
            xlabel(app.UIAxes_rawimg, 'X')
            ylabel(app.UIAxes_rawimg, 'Y')
            zlabel(app.UIAxes_rawimg, 'Z')
            app.ColorbarlowEditFieldLabel = uilabel(app.UIFigure);
            app.ColorbarlowEditFieldLabel.Text = 'Colorbar low';
            app.ColorbarlowEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColorbarlowEditField.ValueChangedFcn = createCallbackFcn(app, @ColorbarlowEditFieldValueChanged, true);
            app.ColorbarhighEditFieldLabel = uilabel(app.UIFigure);
            app.ColorbarhighEditFieldLabel.Text = 'Colorbar high';
            app.ColorbarhighEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColorbarhighEditField.ValueChangedFcn = createCallbackFcn(app, @ColorbarhighEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MagnitudeImgPlot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end