classdef Open_image_slide < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        VisualizeROImaskwithSelectedROIPCMRILabel  matlab.ui.control.Label
        SaveROIimageLabel               matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        MaskloadtypeButtonGroup         matlab.ui.container.ButtonGroup
        LoadpredefinedmaskButton        matlab.ui.control.RadioButton
        CalculatemaskButton             matlab.ui.control.RadioButton
        VENCEditField                   matlab.ui.control.NumericEditField
        ColorbarhighEditField_3Label_2  matlab.ui.control.Label
        MagnitudeImageBtn               matlab.ui.control.Button
        PleasedoanalyzingmasksignalLabel  matlab.ui.control.Label
        AnlaysismaskedsignalButton      matlab.ui.control.Button
        SaveROIImgButton                matlab.ui.control.Button
        ColorbarlowEditField_Overlay    matlab.ui.control.NumericEditField
        ColorbarlowEditField_3Label     matlab.ui.control.Label
        ColorbarhighEditField_Overlay   matlab.ui.control.NumericEditField
        ColorbarhighEditField_3Label    matlab.ui.control.Label
        MaskcolorDropDown               matlab.ui.control.DropDown
        MaskcolorDropDownLabel          matlab.ui.control.Label
        TransparencycontrolpanelLabel   matlab.ui.control.Label
        ROIMaskSlider                   matlab.ui.control.Slider
        ROIMaskSliderLabel              matlab.ui.control.Label
        SelectedROIPCMRISlider          matlab.ui.control.Slider
        SelectedROIPCMRISliderLabel     matlab.ui.control.Label
        ExtractedROImaskLabel           matlab.ui.control.Label
        ExtractedROI_afternoisecorrectionLabel  matlab.ui.control.Label
        ExtractedROII_PtLabel           matlab.ui.control.Label
        ThresholdtypesDropDown          matlab.ui.control.DropDown
        ThresholdtypesDropDownLabel     matlab.ui.control.Label
        Analysis_RegionDropDown         matlab.ui.control.DropDown
        Analysis_RegionDropDownLabel    matlab.ui.control.Label
        ROIsizeDropDown                 matlab.ui.control.DropDown
        ROIsizeDropDownLabel            matlab.ui.control.Label
        ROIprocessstartButton           matlab.ui.control.Button
        ColorbarlowEditField_2          matlab.ui.control.NumericEditField
        ColorbarlowEditField_2Label     matlab.ui.control.Label
        ColorbarhighEditField_2         matlab.ui.control.NumericEditField
        ColorbarhighEditField_2Label    matlab.ui.control.Label
        ColorbarlowEditField            matlab.ui.control.NumericEditField
        ColorbarlowEditFieldLabel       matlab.ui.control.Label
        ColorbarhighEditField           matlab.ui.control.NumericEditField
        ColorbarhighEditFieldLabel      matlab.ui.control.Label
        FusionSlider                    matlab.ui.control.RangeSlider
        FusionrangeLabel                matlab.ui.control.Label
        FramenumberSpinner              matlab.ui.control.Spinner
        TimerSlider                     matlab.ui.control.Slider
        FramenumberSpinnerLabel         matlab.ui.control.Label
        FrameLabel                      matlab.ui.control.Label
        UIAxes_ROI_analysis_Mask_2      matlab.ui.control.UIAxes
        UIAxes_ROI_analysis_Mask        matlab.ui.control.UIAxes
        UIAxes_ROI_analysis_noiseremove  matlab.ui.control.UIAxes
        UIAxes_ROI_analysis_Ipt         matlab.ui.control.UIAxes
        UIAxes_rawimg_ROInew            matlab.ui.control.UIAxes
        UIAxes_rawimg_2                 matlab.ui.control.UIAxes
        UIAxes_rawimg                   matlab.ui.control.UIAxes
        UIAxes_fusionimg                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        MainApp % Reference to the main application (used to access shared properties/data)
        AnalysisWindow % Handle/reference to the analysis child window
        MagnitudeWindow % Handle/reference to the magnitude display window
        PCMRI % PCMRI phase/velocity image series
        PCMRI_Magnitude % PCMRI magnitude image series
        Selected_region_PCMRI % Selected ROI time-series image data (rotated/flipped; selected region only)
        Selected_region_averaged_image % Mean image within the selected ROI range
        Selected_region_std_image % Standard deviation image within the selected ROI range
        Overall_PCMRI % Full-field PCMRI image series (entire image extent)
        Overall_PCMRI_Std % Standard deviation images computed from the full-field PCMRI series
        Overall_averagedImage % Mean image computed from the full-field PCMRI series

        IsROIActive % Flag indicating whether interactive ROI selection is currently enabled
        I_Pi_forapp % Matrix storing I_Pi values (internal intermediate variable)
        M_ROI_forapp % ROI matrix after noise filtering (intermediate result)
        M_ROI_mask_forapp % Binary ROI mask after thresholding
        freq_range_forapp % Frequency range used for FFT-based analysis
        deltafreq_for_CF_Auto_forapp % Frequency step size used in the CF_Auto algorithm
        time_range_forapp % Time range corresponding to the PCMRI time series
        P_F_forapp;
        P_f_max_forapp;
        dc_forapp;
        P_f_real_forapp;
        f_max_mat_forapp;
        M_ROI_th_forapp;
        P_angle_forapp;
        P_complex_forapp;
        ROI_labels_forapp;
        Auto_CF_Image_forapp;
        VENC_forapp;
        CurrentROIprocessmode;

        %-------------- load predefined mask
        Load_Data_info_filename_predefinedmask;
        Load_Data_info_path_predefinedmask;
        Predefined_mask_fortheapp;
        Segmented_predefined_mask_fortheapp;
        x_seg
        y_seg
        averagedImage_seg


        %-------------- boundary box information
        x_pointing_fortheapp_boundarybox
        y_pointing_fortheapp_boundarybox
        selected_box_size
        selected_halfSize
        xMin_fortheapp;
        xMax_fortheapp;
        yMin_fortheapp;
        yMax_fortheapp;

        P_F_overall_forapp
        P_angle_overall_forapp
        P_complex_overall_forapp
        P_real_overall_forapp
        P_f_max_overall_forapp
        dc_overall_forapp
        f_max_mat_overall_forapp

    end
    
    properties (Access = private)
        PCMRIImageHandle % Graphics handle for the PCMRI image (used for overlay updates)
        MaskImageHandle % Graphics handle for the ROI mask image (used for overlay updates)
    end
    
    methods (Access = public)
        
        function setMainApp(app, mainAppReference)
            app.MainApp = mainAppReference;
            % Register a custom close-request handler for this child window
            app.UIFigure.CloseRequestFcn = @(src, event) app.closeRequest(event);
        end

        function initializeButtons_forfurtheranalysis(app)
            % Disable analysis-related controls until required inputs/steps are completed
            app.AnlaysismaskedsignalButton.Enable = 'off';
            app.SaveROIImgButton.Enable = 'off';
        end


        function initializeTransparencySliders(app)
            % Configure alpha (transparency) sliders for PCMRI and ROI mask overlays
            % Both sliders are normalized to [0, 1]
            app.SelectedROIPCMRISlider.Limits = [0, 1];
            app.ROIMaskSlider.Limits = [0, 1];

            % Set default transparency values
            app.SelectedROIPCMRISlider.Value = 1; % Fully opaque for PCMRI image
            app.ROIMaskSlider.Value = 0.5; % Semi-transparent mask overlay

            % Optional: Provide tick marks for easier adjustment
            app.SelectedROIPCMRISlider.MajorTicks = 0:0.2:1;
            app.ROIMaskSlider.MajorTicks = 0:0.2:1;
        end

        function initializeColorbarLimits(app, imageData)
            % Initialize colorbar limits for the raw image display based on image min/max
            minValue = min(imageData(:));
            maxValue = max(imageData(:));

            % Populate colorbar limit edit fields with the data-driven defaults
            app.ColorbarlowEditField.Value = minValue;
            app.ColorbarhighEditField.Value = maxValue;

            % Apply initial color axis limits to the raw image axes
            caxis(app.UIAxes_rawimg, [minValue, maxValue]);
            %caxis(app.UIAxes_fusionimg, [minValue, maxValue]);
        end

        function initializeColorbarLimits_fusion(app, imageData)
            % Initialize colorbar limits for the fusion/averaged image display based on image min/max
            minValue = min(imageData(:));
            maxValue = max(imageData(:));

            % Populate fusion colorbar limit edit fields with the data-driven defaults
            app.ColorbarlowEditField_2.Value = minValue;
            app.ColorbarhighEditField_2.Value = maxValue;

            % Apply initial color axis limits to the fusion image axes
            %caxis(app.UIAxes_rawimg, [minValue, maxValue]);
            caxis(app.UIAxes_fusionimg, [minValue, maxValue]);
        end

        function initializeColorbarLimits_overlay(app, imageData)
            % Initialize colorbar limits for the overlay view (if a dedicated overlay axes is used)
            minValue = min(imageData(:));
            maxValue = max(imageData(:));

            % Populate overlay colorbar limit edit fields with the data-driven defaults
            app.ColorbarlowEditField_Overlay.Value = minValue;
            app.ColorbarhighEditField_Overlay.Value = maxValue;

            % Note: color axis limits can be applied to specific axes depending on overlay implementation
            %caxis(app.UIAxes_rawimg, [minValue, maxValue]);
            %caxis(app.UIAxes_fusionimg, [minValue, maxValue]);
        end

        function initializeDisplay(app, pcmridata)
            % Cache PCMRI data in the child window and retrieve magnitude data from the main app
            app.PCMRI = pcmridata;
            app.PCMRI_Magnitude = app.MainApp.PCMRI_Data_Mag;
            
            % Configure time/frame navigation controls based on the number of frames
            numFrames = size(app.PCMRI, 3); % % Expected data layout: (x, y, time)
            app.TimerSlider.Limits = [1, numFrames]; % Slider bounds
            app.TimerSlider.Value = 1; % Default to the first frame
            app.FramenumberSpinner.Limits = [1, numFrames]; % Spinner bounds
            app.FramenumberSpinner.Value = 1; % Default to the first frame

            % Initialize color scaling and render the first frame
            app.initializeColorbarLimits(app.PCMRI(:, :, 1));
            app.initializeColorbarLimits_fusion(app.PCMRI(:, :, 1));
            app.updateImage(1);
        end
        
        function updateImage(app, frameIndex)
            % Guard clause: do nothing if no PCMRI data has been loaded
            if isempty(app.PCMRI)
                return;
            end

            % Render the selected frame in the raw image axes
            cla(app.UIAxes_rawimg);
            imagesc(app.UIAxes_rawimg, app.PCMRI(:, :, frameIndex));
            colormap(app.UIAxes_rawimg, 'gray'); colorbar(app.UIAxes_rawimg);
            % Configure axes appearance
            xlabel(app.UIAxes_rawimg, 'Pixels');
            ylabel(app.UIAxes_rawimg, 'Pixels');
            axis(app.UIAxes_rawimg, 'image'); % Preserve aspect ratio
            axis(app.UIAxes_rawimg, 'tight'); % Fit image bounds to axes
            title(app.UIAxes_rawimg, sprintf('Frame %d', frameIndex));
        end

        % Method to initialize display and set up the range slider
        function initializeDisplay_average(app, pcmriData)
            app.PCMRI = pcmriData; % Cache PCMRI data for range-based averaging

            % Configure the range slider based on the total number of frames
            numFrames = size(app.PCMRI, 3);
            app.FusionSlider.Limits = [1, numFrames];
            app.FusionSlider.Value = [1, numFrames]; % Default to the full frame range

            % Compute and display the averaged image for the initial slider range
            startFrame = app.FusionSlider.Value(1);
            endFrame = app.FusionSlider.Value(2);
            

            % Render the averaged image for the current range
            app.updateAveragedImage(startFrame, endFrame);
            
            
        end

        % Method to update the averaged image based on the selected range
        function updateAveragedImage(app, startFrame, endFrame)
            % Guard clause: do nothing if no PCMRI data has been loaded
            if isempty(app.PCMRI)
                return;
            end

            % Compute the mean image across the selected frame range
            averagedImage = mean(app.PCMRI(:, :, startFrame:endFrame), 3);

            % Clear the axes before drawing the updated averaged image
            cla(app.UIAxes_fusionimg);

            % Render the averaged image
            imagesc(app.UIAxes_fusionimg, averagedImage);
            colormap(app.UIAxes_fusionimg, 'gray');
            colorbar(app.UIAxes_fusionimg);
            % Configure axes appearance
            xlabel(app.UIAxes_fusionimg, 'Pixels');
            ylabel(app.UIAxes_fusionimg, 'Pixels');
            axis(app.UIAxes_fusionimg, 'image');
            axis(app.UIAxes_fusionimg, 'tight');

            % Title indicates the frame range used for averaging
            title(app.UIAxes_fusionimg, sprintf('Averaged Image: Frames %d to %d', startFrame, endFrame));
        end

        % Method to initialize the colorbar limits based on the image data
        function updateColorbarLimits(app, imageData)
            % Update color scaling based on current image data and user-defined limits (edit fields)
            minValue = min(imageData(:));
            maxValue = max(imageData(:));

            % Initialize edit fields only if they are not yet set (NaN)
            if isnan(app.ColorbarlowEditField.Value)
                app.ColorbarlowEditField.Value = minValue;
            end
            if isnan(app.ColorbarhighEditField.Value)
                app.ColorbarhighEditField.Value = maxValue;
            end

            % Apply the current color limits to both raw and fusion axes
            clim(app.UIAxes_rawimg, [app.ColorbarlowEditField.Value, app.ColorbarhighEditField.Value]);
            clim(app.UIAxes_fusionimg, [app.ColorbarlowEditField.Value, app.ColorbarhighEditField.Value]);
        end


        % Method to capture an ROI based on a mouse click in UIAxes_AveragedImage
        function captureROI(app, averagedImage, standarddeviationImage, imgHandle)
             % Exit if ROI selection mode is not enabled
            if ~app.IsROIActive
                return;
            end

            % Disable ROI selection after a valid click (single-shot selection)
            app.IsROIActive = false;

            % Read current colorbar limits for consistent ROI visualization
            lowLimit = app.ColorbarlowEditField_2.Value;
            highLimit = app.ColorbarhighEditField_2.Value;

            % Read mouse-click coordinates from the axes (rounded to nearest pixel)
            point = app.UIAxes_rawimg_2.CurrentPoint;
            x = round(point(1, 1));
            y = round(point(1, 2));
            app.x_seg=x;
            app.y_seg=y;

            % Determine the ROI bounding-box size from the dropdown selection

            % Log the selected pixel coordinate
            app.MainApp.logMessage(strcat('[System info] Selected pixel coordinates: x = ', num2str(x), ' y = ', num2str(y)));
            
            selectedSize = app.ROIsizeDropDown.Value;
            switch selectedSize
                case '8x8'
                    halfSize = 4;
                case '16x16'
                    halfSize = 8;
                case '32x32'
                    halfSize = 16;
                case '64x64'
                    halfSize = 32;
                case '128x128'
                    halfSize = 64;
                otherwise
                    halfSize = 16; % Default ROI size (half-width)
            end

            % Compute ROI bounding-box indices (clamped to image boundaries)
            % halfSize = ROI_Box_limit; % Half of the ROI window to be extracted
            xMin = max(1, x - halfSize);
            xMax = min(size(averagedImage, 2), x + halfSize - 1);
            yMin = max(1, y - halfSize);
            yMax = min(size(averagedImage, 1), y + halfSize - 1);

            % Extract the averaged ROI patch from the selected window
            rotatedFlippedData_fusion_selectedRegion = averagedImage(yMin:yMax, xMin:xMax);
            app.Selected_region_averaged_image=rotatedFlippedData_fusion_selectedRegion;
            app.averagedImage_seg=averagedImage;
            
            % Extract and store the corresponding ROI patch from the standard-deviation image
            rotatedFlippedData_fusion_selectedRegion_std = standarddeviationImage(yMin:yMax, xMin:xMax);
            app.Selected_region_std_image=rotatedFlippedData_fusion_selectedRegion_std;

            % Log the extracted ROI size
            app.MainApp.logMessage(strcat('[System info] Extracted region size: ', num2str(size(rotatedFlippedData_fusion_selectedRegion, 1)), ' x ', num2str(size(rotatedFlippedData_fusion_selectedRegion, 2))));
            %selectedRegion = rotatedFlippedData(yMin:yMax, xMin:xMax, 1, 1);

            % Draw the ROI bounding box on the averaged image display
            hold(app.UIAxes_rawimg_2, 'on');
            rectangle(app.UIAxes_rawimg_2, 'Position', [xMin, yMin, xMax - xMin + 1, yMax - yMin + 1], ...
                'EdgeColor', 'r', 'LineWidth', 2);
            hold(app.UIAxes_rawimg_2, 'off');


            % Render the extracted ROI patch in the ROI preview axes
            cla(app.UIAxes_rawimg_ROInew);
            imagesc(app.UIAxes_rawimg_ROInew, rotatedFlippedData_fusion_selectedRegion);
            colormap(app.UIAxes_rawimg_ROInew, 'gray');
            axis(app.UIAxes_rawimg_ROInew, 'image');
            axis(app.UIAxes_rawimg_ROInew, 'tight');
            colorbar(app.UIAxes_rawimg_ROInew);clim(app.UIAxes_rawimg_ROInew, [lowLimit, highLimit]);
            xlabel(app.UIAxes_rawimg_ROInew, 'Pixels');
            ylabel(app.UIAxes_rawimg_ROInew, 'Pixels');
            title(app.UIAxes_rawimg_ROInew, sprintf('Extracted ROI (%dx%d)',  xMax - xMin + 1,  xMax - xMin + 1));
            % Extract the corresponding PCMRI time series for the selected ROI window
            app.Selected_region_PCMRI=squeeze(app.MainApp.PCMRI_Data(yMin:yMax, xMin:xMax, :));

            % Cache full-field datasets and ROI bounding-box parameters for downstream processing
            app.Overall_PCMRI = app.MainApp.PCMRI_Data;
            app.Overall_averagedImage = averagedImage;
            app.x_pointing_fortheapp_boundarybox = x;
            app.y_pointing_fortheapp_boundarybox = y;
            app.selected_box_size = selectedSize;
            app.selected_halfSize = halfSize;
            app.xMin_fortheapp = xMin; 
            app.xMax_fortheapp = xMax;
            app.yMin_fortheapp = yMin;
            app.yMax_fortheapp = yMax;
        

            % Disable the click callback after ROI extraction (prevents additional selections)
            imgHandle.ButtonDownFcn = [];
            

            % Select ROI mask source (predefined mask vs. auto-segmentation)
            selectedButton = app.MaskloadtypeButtonGroup.SelectedObject;
            if selectedButton == app.LoadpredefinedmaskButton
                % Load and apply a predefined ROI mask
                app.loadmaskROI();
            elseif selectedButton == app.CalculatemaskButton
                % Run automatic ROI segmentation
                app.segmentROI();
            end
            

        end


        % Function to overlay the PCMRI image and the ROI mask
        function plotOverlaidImages(app, PCMRIImage, MaskImage)
            % Guard clause: PCMRI image must be available
            if isempty(PCMRIImage)
                uialert(app.UIFigure, 'No PCMRI data loaded.', 'Error');
                return;
            end

            % Guard clause: ROI mask must be available
            if isempty(MaskImage)
                uialert(app.UIFigure, 'No Mask data loaded.', 'Error');
                return;
            end

            % Validate that PCMRI image and mask have matching dimensions
            if (size(PCMRIImage, 1) ~= size(MaskImage, 1)) || (size(PCMRIImage, 2) ~= size(MaskImage, 2))
                uialert(app.UIFigure, 'Images must be the same size!', 'Error');
                return;
            end

            % Render the PCMRI base image in the overlay axes
            app.PCMRIImageHandle = imagesc(app.UIAxes_ROI_analysis_Mask_2, PCMRIImage);
            hold(app.UIAxes_ROI_analysis_Mask_2, 'on');
            % Apply initial transparency to the PCMRI layer
            alpha(app.PCMRIImageHandle, app.SelectedROIPCMRISlider.Value);

            % Convert the binary mask to an RGB overlay using the selected mask color
            [rows, cols] = size(MaskImage);
            RGBMask = zeros(rows, cols, 3); % Initialize RGB mask
            maskcolor = app.MaskcolorDropDown.Value;
            switch maskcolor
                case 'Red'
                    RGBMask(:, :, 1) = MaskImage; % Apply mask to red channel
                case 'Blue'
                    RGBMask(:, :, 3) = MaskImage; % Apply mask to blue channel
                case 'Green'
                    RGBMask(:, :, 2) = MaskImage; % Apply mask to green channel
                otherwise
                    RGBMask(:, :, 1) = MaskImage; % Default: red channel
            end


            % Render the ROI mask as a semi-transparent RGB overlay
            app.MaskImageHandle = imagesc(app.UIAxes_ROI_analysis_Mask_2, RGBMask, 'AlphaData', app.ROIMaskSlider.Value);
            % Set initial transparency for the PCMRI image
            %alpha(app.MaskImageHandle, app.ROIMaskSlider.Value);

            % Read current overlay color scaling limits
            lowLimit = app.ColorbarlowEditField_Overlay.Value;
            highLimit = app.ColorbarhighEditField_Overlay.Value;

            % Configure axes appearance
            colormap(app.UIAxes_ROI_analysis_Mask_2, 'gray'); % Use grayscale for the PCMRI image
            axis(app.UIAxes_ROI_analysis_Mask_2, 'image');
            colorbar(app.UIAxes_ROI_analysis_Mask_2);
            clim(app.UIAxes_ROI_analysis_Mask_2, [lowLimit, highLimit]);
            xlabel(app.UIAxes_ROI_analysis_Mask_2, 'Pixels');
            ylabel(app.UIAxes_ROI_analysis_Mask_2, 'Pixels');
            title(app.UIAxes_ROI_analysis_Mask_2, 'Overlay of PCMRI and Mask');

            % Release hold after overlay rendering
            hold(app.UIAxes_ROI_analysis_Mask_2, 'off');
        end
        
        % Segment the ROI using a predefined mask (loaded externally)
        function loadmaskROI(app)
            % Guard clause: PCMRI data must be available from the main app
            if isempty(app.MainApp.PCMRI_Data)
                uialert(app.UIFigure, 'No PCMRI data loaded.', 'Error');
                return;
            end

            % Read ROI data dimensions (selected window only)
            [x_size, y_size, t_size] = size(app.Selected_region_PCMRI);
            PCMRI_INFO=app.MainApp.PCMRI_Info;
            Delta_time=app.MainApp.Delta_time;

            % Select VENC based on the target anatomical/physiological region
            selected_Region = app.Analysis_RegionDropDown.Value;
            switch selected_Region
                case 'Foramen magnum'
                    VENC = app.VENCEditField.Value;
                case 'Superior Sagital Sinus'
                    VENC = app.VENCEditField.Value;
                case 'Aqueduct'
                    VENC = app.VENCEditField.Value;
                case 'C2/C3_blood'
                    VENC = app.VENCEditField.Value;
                case 'C2/C3_CSF'
                    VENC = app.VENCEditField.Value;
                case 'Internal Carotid Artery'
                    VENC = app.VENCEditField.Value;
                case 'Lateral ventricle'
                    VENC = app.VENCEditField.Value;
                case 'Forth ventricle'
                    VENC = app.VENCEditField.Value;
                case 'Lumbar spine (L3)'
                    VENC = app.VENCEditField.Value;
                otherwise
                    VENC = 5; % Default VENC fallback
            end

            app.VENC_forapp = VENC;

            selectedSize = app.ROIsizeDropDown.Value;
            switch selectedSize
                case '8x8'
                    halfSize = 4;
                case '16x16'
                    halfSize = 8;
                case '32x32'
                    halfSize = 16;
                case '64x64'
                    halfSize = 32;
                case '128x128'
                    halfSize = 64;
                otherwise
                    halfSize = 16; % Default ROI size (half-width)
            end

             % Read delta time from the main app (used for frequency axis definition)
            delta_time = app.MainApp.Delta_time;
            %Type = selected_Thresholdtype;

            % Define time and frequency axes for FFT analysis
            delta_freq = 1/delta_time;
            total_acquisitiontime = delta_time*t_size;
            time_range = linspace(0, total_acquisitiontime, t_size);
            deltafreq_for_CF_Auto=1/total_acquisitiontime;
            freq_range = linspace(0, delta_freq, t_size);

            % Preallocate FFT-derived matrices and intermediate outputs
            P_F = zeros(x_size, y_size, length(freq_range));
            P_angle = zeros(x_size, y_size, length(freq_range));
            P_complex = zeros(x_size, y_size, length(freq_range));
            P_real = zeros(x_size, y_size, length(freq_range));
            P_F_dc=zeros(x_size, y_size);
            P_f_max = zeros(x_size, y_size);
            I_Pi = zeros(x_size, y_size);
            f_max_mat = zeros(x_size, y_size);
            ROI_labels = cell(x_size, y_size); % Labels for artery, vein, LCS
            Auto_CF_Image = struct('v_f0', [], 'fx', [], 'PI', []);
            M_ROI = zeros(x_size, y_size); % Initialize the PI/score matrix
            
            % ------------* Data analysis workflow

             % Guard clause: ROI PCMRI time series must be available
            if isempty(app.Selected_region_PCMRI)
                uialert(app.UIFigure, 'No selected region PCMRI data loaded.', 'Error');
                return;
            end


            % Recompute ROI window bounds based on the stored click coordinate and ROI size
            % halfSize = ROI_Box_limit; % Half of the ROI window to be extracted
            xMin = max(1, app.x_seg - halfSize);
            xMax = min(size(app.averagedImage_seg, 2), app.x_seg + halfSize - 1);
            yMin = max(1, app.y_seg - halfSize);
            yMax = min(size(app.averagedImage_seg, 1), app.y_seg + halfSize - 1);

            % Extract the predefined mask region aligned with the selected ROI window
            rotatedFlippedData_fusion_selectedRegion = app.Predefined_mask_fortheapp(yMin:yMax, xMin:xMax);
            rotatedFlippedData_fusion_selectedRegion=double(rotatedFlippedData_fusion_selectedRegion);
            app.Segmented_predefined_mask_fortheapp=rotatedFlippedData_fusion_selectedRegion;


            PCMRI_data=app.Selected_region_PCMRI;

            % Compute FFT features for each pixel time series within the ROI window
            for x = 1:x_size
                for y = 1:y_size
                    %----------- *  Step 1: Compute FFT for each pixel time-series signal
                    pi_t = squeeze(PCMRI_data(x, y, :)); % Extract time-series data for pixel (x, y)
                    P_F(x, y, :) = abs(fft(pi_t)) / t_size; % Scale normalization.
                    P_angle(x, y, :) = angle(fft(pi_t));
                    P_complex(x, y, :) = fft(pi_t)/ t_size;
                    P_real(x, y, :) = real(fft(pi_t))/ t_size;

                end
            end

            % Compute FFT features for the full-field PCMRI data (for global/overall context)
            Overall_PCMRI_data_forspec = app.MainApp.PCMRI_Data;
            % Read full-field dimensions
            [x_size_overall, y_size_overall, t_size_overall] = size(Overall_PCMRI_data_forspec);
            freq_range_overall = linspace(0, delta_freq, t_size_overall);
            
            % Preallocate full-field FFT outputs
            P_F_overall = zeros(x_size_overall, y_size_overall, length(freq_range_overall));
            P_angle_overall = zeros(x_size_overall, y_size_overall, length(freq_range_overall));
            P_complex_overall = zeros(x_size_overall, y_size_overall, length(freq_range_overall));
            P_real_overall = zeros(x_size_overall, y_size_overall, length(freq_range_overall));
            
            % Compute FFT for each pixel time series in the full-field data
            for x = 1:x_size_overall
                for y = 1:y_size_overall
                     %----------- *  Step 1: Compute FFT for each pixel time-series signal
                    pi_t_overall = squeeze(Overall_PCMRI_data_forspec(x, y, :)); % Extract time-series data for pixel (x, y)
                    P_F_overall(x, y, :) = abs(fft(pi_t_overall)) / t_size_overall; % Scale normalization.
                    P_angle_overall(x, y, :) = angle(fft(pi_t_overall));
                    P_complex_overall(x, y, :) = fft(pi_t_overall)/ t_size_overall;
                    P_real_overall(x, y, :) = real(fft(pi_t_overall))/ t_size_overall;

                end
            end

            
            %app.M_ROI_mask_forapp=rotatedFlippedData_fusion_selectedRegion;

            M_ROI_mask=rotatedFlippedData_fusion_selectedRegion;
            % ------ * Initialize overlay visualization controls
            app.initializeTransparencySliders(); % For the visualized evaluation
            app.initializeColorbarLimits_overlay(M_ROI_mask);


            %------------------------------------------------------------
            % Cache computed outputs and intermediate variables for downstream steps and plotting
            app.I_Pi_forapp = I_Pi;
            app.M_ROI_forapp = M_ROI;
            app.M_ROI_mask_forapp=M_ROI_mask;
            app.P_F_forapp=P_F;
            app.P_f_max_forapp = P_f_max;
            app.dc_forapp = P_F_dc;
            app.P_f_real_forapp = P_real;
            app.f_max_mat_forapp = f_max_mat;
            app.time_range_forapp = time_range;
            app.freq_range_forapp = freq_range;
            app.deltafreq_for_CF_Auto_forapp = deltafreq_for_CF_Auto;
            %app.M_ROI_th_forapp = M_ROI_th;
            app.P_angle_forapp = P_angle;
            app.P_complex_forapp = P_complex;
            app.ROI_labels_forapp = ROI_labels;
            app.Auto_CF_Image_forapp = Auto_CF_Image;

            app.P_F_overall_forapp = P_F_overall;
            app.P_angle_overall_forapp = P_angle_overall;
            app.P_complex_overall_forapp = P_complex_overall;
            app.P_real_overall_forapp = P_real_overall;
            %------------------------------------------------------------


            % Display the Segmented ROI image.
            % cla(app.UIAxes_ROI_analysis_Ipt);
            % imagesc(app.UIAxes_ROI_analysis_Ipt, I_Pi);
            % colormap(app.UIAxes_ROI_analysis_Ipt, 'gray');
            % axis(app.UIAxes_ROI_analysis_Ipt, 'image');
            % axis(app.UIAxes_ROI_analysis_Ipt, 'tight');
            % colorbar(app.UIAxes_ROI_analysis_Ipt);
            % %clim(app.UIAxes_ROI_analysis_Ipt, [lowLimit, highLimit]);
            % xlabel(app.UIAxes_ROI_analysis_Ipt, 'Pixels');
            % ylabel(app.UIAxes_ROI_analysis_Ipt, 'Pixels');
            % title(app.UIAxes_ROI_analysis_Ipt, 'Intensity for new methods I-Pi before noisefilter');
            
            
            % Display the Segmented ROI image.
            % cla(app.UIAxes_ROI_analysis_noiseremove);
            % imagesc(app.UIAxes_ROI_analysis_noiseremove, M_ROI_th);
            % colormap(app.UIAxes_ROI_analysis_noiseremove, 'gray');
            % axis(app.UIAxes_ROI_analysis_noiseremove, 'image');
            % axis(app.UIAxes_ROI_analysis_noiseremove, 'tight');
            % colorbar(app.UIAxes_ROI_analysis_noiseremove);
            % %clim(app.UIAxes_ROI_analysis_Ipt, [lowLimit, highLimit]);
            % xlabel(app.UIAxes_ROI_analysis_noiseremove, 'Pixels');
            % ylabel(app.UIAxes_ROI_analysis_noiseremove, 'Pixels');
            % title(app.UIAxes_ROI_analysis_noiseremove, 'Intensity for new methods I-Pi after noisefilter, sortingThreshold');

            % Display the Segmented ROI image.
            cla(app.UIAxes_ROI_analysis_Mask);
            imagesc(app.UIAxes_ROI_analysis_Mask, M_ROI_mask);
            colormap(app.UIAxes_ROI_analysis_Mask, 'gray');
            axis(app.UIAxes_ROI_analysis_Mask, 'image');
            axis(app.UIAxes_ROI_analysis_Mask, 'tight');
            colorbar(app.UIAxes_ROI_analysis_Mask);
            %clim(app.UIAxes_ROI_analysis_Ipt, [lowLimit, highLimit]);
            xlabel(app.UIAxes_ROI_analysis_Mask, 'Pixels');
            ylabel(app.UIAxes_ROI_analysis_Mask, 'Pixels');
            title(app.UIAxes_ROI_analysis_Mask, 'ROI Mask');


            % ---------------------------------- Cache key parameters/datasets and generate overlay visualization
            % Overlay the ROI mask on top of the averaged PCMRI image for visual validation

            app.plotOverlaidImages(app.Selected_region_averaged_image, M_ROI_mask);

            % Enable analysis/export controls once the ROI mask is ready
            app.AnlaysismaskedsignalButton.Enable = 'on';
            app.SaveROIImgButton.Enable = 'on';


        end

        % Segment ROI using I_Pi-based feature extraction (FFT-driven metric)
        function segmentROI(app)
             % ---------------- * Algorithm settings
             % This function implements a specific algorithm based on the method
             % described in Liu (2021, Thesis), Liu et al (2025).
            Noise_filter = true;
            
            % Guard clause: PCMRI data must be available from the main app
            if isempty(app.MainApp.PCMRI_Data)
                uialert(app.UIFigure, 'No PCMRI data loaded.', 'Error');
                return;
            end

            % Read ROI window dimensions (x, y, time)
            [x_size, y_size, t_size] = size(app.Selected_region_PCMRI);
            PCMRI_INFO=app.MainApp.PCMRI_Info;
            Delta_time=app.MainApp.Delta_time;
            %disp(num2str(Delta_time)); %-> Can access MainApp dataset.
            
            % Read analysis parameters from UI controls (target region → VENC)
            selected_Region = app.Analysis_RegionDropDown.Value;
            switch selected_Region
                case 'Foramen magnum'
                    VENC = app.VENCEditField.Value;
                case 'Superior Sagital Sinus'
                    VENC = app.VENCEditField.Value;
                case 'Aqueduct'
                    VENC = app.VENCEditField.Value;
                case 'C2/C3_blood'
                    VENC = app.VENCEditField.Value;
                case 'C2/C3_CSF'
                    VENC = app.VENCEditField.Value;
                case 'Internal Carotid Artery'
                    VENC = app.VENCEditField.Value;
                case 'Lateral ventricle'
                    VENC = app.VENCEditField.Value;
                case 'Forth ventricle'
                    VENC = app.VENCEditField.Value;
                case 'Lumbar spine (L3)'
                    VENC = app.VENCEditField.Value;
                otherwise
                    VENC = 5; % Default VENC fallback
            end

            app.VENC_forapp = VENC;

            selected_Thresholdtype = app.ThresholdtypesDropDown.Value;
            switch selected_Thresholdtype
                case 'All'
                    Threshold = [-1, 1];
                case 'Artery'
                    Threshold = [0.2, 1];
                case 'Vein'
                    Threshold = [-1, -0.05];
                case 'LCS'
                    Threshold = [-0.2, 0.2];
                case 'LCS_Breathing'
                    Threshold = [-0.2, 0.2];
                otherwise
                    Threshold = [-0.2, 0.2]; % Default classification threshold
            end

            % Read delta time and set the current segmentation mode/type
            delta_time = app.MainApp.Delta_time;
            Type = selected_Thresholdtype;

            % Define time and frequency axes for FFT-based feature extraction
            delta_freq = 1/delta_time;
            total_acquisitiontime = delta_time*t_size;
            time_range = linspace(0, total_acquisitiontime, t_size);
            deltafreq_for_CF_Auto=1/total_acquisitiontime;
            freq_range = linspace(0, delta_freq, t_size);

            % Preallocate FFT outputs and intermediate variables
            P_F = zeros(x_size, y_size, length(freq_range));
            P_angle = zeros(x_size, y_size, length(freq_range));
            P_complex = zeros(x_size, y_size, length(freq_range));
            P_real = zeros(x_size, y_size, length(freq_range));
            P_F_dc=zeros(x_size, y_size);
            P_f_max = zeros(x_size, y_size);
            I_Pi = zeros(x_size, y_size);
            f_max_mat = zeros(x_size, y_size);
            ROI_labels = cell(x_size, y_size); % Labels for artery, vein, LCS
            Auto_CF_Image = struct('v_f0', [], 'fx', [], 'PI', []);
            M_ROI = zeros(x_size, y_size); % 2D ROI matrix initialization
            
            % ------------* FFT-based ROI analysis workflow

             % Guard clause: selected ROI PCMRI time series must be available
            if isempty(app.Selected_region_PCMRI)
                uialert(app.UIFigure, 'No selected region PCMRI data loaded.', 'Error');
                return;
            end

            PCMRI_data=app.Selected_region_PCMRI;

            % Loop over all pixels and process each pixel-wise time series
            for x = 1:x_size
                for y = 1:y_size
                    %----------- * Step 1: Compute the FFT for the pixel-wise time-series signal
                    pi_t = squeeze(PCMRI_data(x, y, :)); % Extract the time series at pixel (x, y)
                    P_F(x, y, :) = abs(fft(pi_t)) / t_size; % Normalized FFT magnitude spectrum
                    P_angle(x, y, :) = angle(fft(pi_t)); % FFT phase spectrum
                    P_complex(x, y, :) = fft(pi_t)/ t_size; % Normalized complex FFT
                    P_real(x, y, :) = real(fft(pi_t))/ t_size; % Normalized real component of the FFT
                    %-------------------------------------------------------------
                    if strcmp(Type, 'Vein')
                        %----------- * Step 2: Compute the DC component and identify the dominant respiratory-band frequency
                        Auto_CF_Image(x, y).v_f0 = P_F(x, y, 1); % DC term (mean/offset proxy in the spectrum)
                        P_F_dc(x,y)=P_F(x, y, 1);
                        [P_f_max(x, y), idx] = max(P_F(x, y, freq_range >= 0.01 & freq_range <= 0.5));
                        f_max_idx = find(freq_range >= 0.01 & freq_range <= 0.5);
                        f_max_i = freq_range(f_max_idx(idx));
                        Auto_CF_Image(x, y).fx = f_max_i; % Dominant frequency (respiration-band)
                        f_max_mat(x, y) = f_max_i;
                        %-------------------------------------------------------------

                        %----------- * Step 3: Compute I_Pi (Eq. 5-10) as band-power concentration around the dominant peak
                        numerator = sum(P_F(x, y, (freq_range >= (f_max_i - deltafreq_for_CF_Auto)) & (freq_range <= (f_max_i + deltafreq_for_CF_Auto)))); % delta freq 0.2?
                        denominator = sum(P_F(x, y, freq_range >= 0.01 & freq_range <= 0.5));
                        I_Pi(x, y) = numerator / denominator;
                        Auto_CF_Image(x, y).PI = I_Pi(x, y);
                        %-------------------------------------------------------------
                    elseif strcmp(Type, 'LCS_Breathing')
                        %----------- * Step 2: Compute the DC component and identify the dominant respiratory-band frequency (breathing-driven LCS)
                        Auto_CF_Image(x, y).v_f0 = P_F(x, y, 1); % DC term (mean/offset proxy in the spectrum)
                        P_F_dc(x,y)=P_F(x, y, 1);
                        [P_f_max(x, y), idx] = max(P_F(x, y, freq_range >= 0.01 & freq_range <= 0.5));
                        f_max_idx = find(freq_range >= 0.01 & freq_range <= 0.5);
                        f_max_i = freq_range(f_max_idx(idx));
                        Auto_CF_Image(x, y).fx = f_max_i; % Dominant frequency (respiration-band)
                        f_max_mat(x, y) = f_max_i;
                        %-------------------------------------------------------------

                        %----------- * Step 3: Compute I_Pi (Eq. 5-10) within the respiration band
                        numerator = sum(P_F(x, y, (freq_range >= (f_max_i - deltafreq_for_CF_Auto)) & (freq_range <= (f_max_i + deltafreq_for_CF_Auto)))); % Local band around f_max
                        denominator = sum(P_F(x, y, freq_range >= 0.01 & freq_range <= 0.5)); % Total power within respiration band
                        I_Pi(x, y) = numerator / denominator;
                        Auto_CF_Image(x, y).PI = I_Pi(x, y);
                        %-------------------------------------------------------------
                    else
                        %----------- * Step 2: Compute the DC component and identify the dominant cardiac-band frequency
                        Auto_CF_Image(x, y).v_f0 = P_F(x, y, 1); % DC term (mean/offset proxy in the spectrum)
                        P_F_dc(x,y)=P_F(x, y, 1);
                        %[P_f_max(x, y), idx] = max(P_F(x, y, freq_range >= 0.8 & freq_range <= 2.0));
                        [P_f_max(x, y), idx] = max(P_F(x, y, freq_range >= 0.8 & freq_range <= 3.0));
                        %[P_f_max(x, y), idx] = max(P_F(x, y, freq_range >= 0.6 & freq_range <= 3.0));%for flow phantom
                        %f_max_idx = find(freq_range >= 0.8 & freq_range <= 2.0);
                        f_max_idx = find(freq_range >= 0.8 & freq_range <= 3.0);
                        %f_max_idx = find(freq_range >= 0.6 & freq_range <= 3.0);%for flow phantom
                        f_max_i = freq_range(f_max_idx(idx));
                        Auto_CF_Image(x, y).fx = f_max_i; % Dominant frequency (cardiac-band)
                        f_max_mat(x, y) = f_max_i;
                        %-------------------------------------------------------------

                        %----------- * Step 3: Compute I_Pi (Eq. 5-10) within the cardiac band
                        numerator = sum(P_F(x, y, (freq_range >= (f_max_i - deltafreq_for_CF_Auto)) & (freq_range <= (f_max_i + deltafreq_for_CF_Auto)))); % delta freq 0.2?
                        %denominator = sum(P_F(x, y, freq_range >= 0.8 & freq_range <= 2.0));
                        denominator = sum(P_F(x, y, freq_range >= 0.8 & freq_range <= 3.0));
                        %denominator = sum(P_F(x, y, freq_range >= 0.6 & freq_range <= 3.0));%for flow phantom
                        I_Pi(x, y) = numerator / denominator;
                        Auto_CF_Image(x, y).PI = I_Pi(x, y);
                        %-------------------------------------------------------------
                    end

                end
            end

            % Compute FFT-derived spectral features for the full-field PCMRI data (overall reference)
            Overall_PCMRI_data_forspec = app.MainApp.PCMRI_Data;
            % Read full-field data dimensions
            [x_size_overall, y_size_overall, t_size_overall] = size(Overall_PCMRI_data_forspec);
            % Define frequency axis for the full-field FFT
            freq_range_overall = linspace(0, delta_freq, t_size_overall);
            % Preallocate full-field FFT outputs
            P_F_overall = zeros(x_size_overall, y_size_overall, length(freq_range_overall));
            P_angle_overall = zeros(x_size_overall, y_size_overall, length(freq_range_overall));
            P_complex_overall = zeros(x_size_overall, y_size_overall, length(freq_range_overall));
            P_real_overall = zeros(x_size_overall, y_size_overall, length(freq_range_overall));
            for x = 1:x_size_overall
                for y = 1:y_size_overall
                    %----------- * Step 1: Compute FFT for each pixel-wise time series (full-field)
                    pi_t_overall = squeeze(Overall_PCMRI_data_forspec(x, y, :)); % Extract the time series at pixel (x, y)
                    P_F_overall(x, y, :) = abs(fft(pi_t_overall)) / t_size_overall; % Normalized FFT magnitude spectrum
                    P_angle_overall(x, y, :) = angle(fft(pi_t_overall)); % FFT phase spectrum
                    P_complex_overall(x, y, :) = fft(pi_t_overall)/ t_size_overall; % Normalized complex FFT
                    P_real_overall(x, y, :) = real(fft(pi_t_overall))/ t_size_overall; % Normalized real component of the FFT
                    %-------------------------------------------------------------
                    if strcmp(Type, 'Vein')
                        %----------- * Step 2: Compute DC component and dominant respiratory-band frequency (full-field)
                        Auto_CF_Image_overall(x, y).v_f0 = P_F_overall(x, y, 1); % DC term
                        P_F_dc_overall(x,y)=P_F_overall(x, y, 1);
                        [P_f_max_overall(x, y), idx] = max(P_F_overall(x, y, freq_range_overall >= 0.01 & freq_range_overall <= 0.5));
                        f_max_idx_overall = find(freq_range_overall >= 0.01 & freq_range_overall <= 0.5);
                        f_max_i_overall = freq_range_overall(f_max_idx_overall(idx));
                        Auto_CF_Image_overall(x, y).fx = f_max_i_overall; % Dominant frequency (respiration-band)
                        f_max_mat_overall(x, y) = f_max_i_overall;
                        %-------------------------------------------------------------
                    elseif strcmp(Type, 'LCS_Breathing')
                        %----------- * Step 2: Compute DC component and dominant respiratory-band frequency (full-field; breathing-driven LCS)
                        Auto_CF_Image_overall(x, y).v_f0 = P_F_overall(x, y, 1); % DC term
                        P_F_dc_overall(x,y)=P_F_overall(x, y, 1);
                        [P_f_max_overall(x, y), idx] = max(P_F_overall(x, y, freq_range_overall >= 0.01 & freq_range_overall <= 0.5));
                        f_max_idx_overall = find(freq_range_overall >= 0.01 & freq_range_overall <= 0.5);
                        f_max_i_overall = freq_range_overall(f_max_idx_overall(idx));
                        Auto_CF_Image_overall(x, y).fx = f_max_i_overall; % Dominant frequency (respiration-band)
                        f_max_mat_overall(x, y) = f_max_i_overall;
                        %-------------------------------------------------------------
                    else
                        %----------- * Step 2: Compute DC component and dominant cardiac-band frequency (full-field)
                        Auto_CF_Image_overall(x, y).v_f0 = P_F_overall(x, y, 1); % DC term
                        P_F_dc_overall(x,y)=P_F_overall(x, y, 1);
                        %[P_f_max(x, y), idx] = max(P_F(x, y, freq_range >= 0.8 & freq_range <= 2.0));
                        [P_f_max_overall(x, y), idx] = max(P_F_overall(x, y, freq_range_overall >= 0.8 & freq_range_overall <= 3.0));
                        %[P_f_max(x, y), idx] = max(P_F(x, y, freq_range >= 0.6 & freq_range <= 3.0));%for flow phantom
                        %f_max_idx = find(freq_range >= 0.8 & freq_range <= 2.0);
                        f_max_idx_overall = find(freq_range_overall >= 0.8 & freq_range_overall <= 3.0);
                        %f_max_idx = find(freq_range >= 0.6 & freq_range <= 3.0);%for flow phantom
                        f_max_i_overall = freq_range_overall(f_max_idx_overall(idx));
                        Auto_CF_Image_overall(x, y).fx = f_max_i_overall; % Dominant frequency (cardiac-band)
                        f_max_mat_overall(x, y) = f_max_i_overall;
                        %-------------------------------------------------------------

                    end
                end
            end


            %----------- * Step 4: Noise filtering (frequency-consistency filter)
            if strcmp(Type, 'Vein')
                if Noise_filter
                    [p_x, p_y] = find(I_Pi == max(I_Pi, [], 'all')); % Identify the pixel with the maximum PI (reference peak)
                    Fre_resp = Auto_CF_Image(p_x, p_y).fx; % Use its dominant frequency as the estimated respiration rate
                    Resp_Interval = [Fre_resp - 0.025, Fre_resp + 0.025];

                    % Suppress PI values whose dominant frequency falls outside the respiration interval
                    [idx_suppress_x, idx_suppress_y]= find(f_max_mat < Resp_Interval(1) | f_max_mat > Resp_Interval(2));
                    for i=1:length(idx_suppress_x)
                        Auto_CF_Image(idx_suppress_x(i), idx_suppress_y(i)).PI = 0;
                    end
                end
            elseif strcmp(Type, 'LCS_Breathing')
                if Noise_filter
                    [p_x, p_y] = find(I_Pi == max(I_Pi, [], 'all')); % Identify the pixel with the maximum PI (reference peak)
                    Fre_resp = Auto_CF_Image(p_x, p_y).fx; % Use its dominant frequency as the estimated respiration rate
                    Resp_Interval = [Fre_resp - 0.025, Fre_resp + 0.025];

                    % Suppress PI values whose dominant frequency falls outside the respiration interval
                    [idx_suppress_x, idx_suppress_y]= find(f_max_mat < Resp_Interval(1) | f_max_mat > Resp_Interval(2));
                    for i=1:length(idx_suppress_x)
                        Auto_CF_Image(idx_suppress_x(i), idx_suppress_y(i)).PI = 0;
                    end
                end
            else
                if Noise_filter
                    [p_x, p_y] = find(I_Pi == max(I_Pi, [], 'all')); % Identify the pixel with the maximum PI (reference peak)
                    Fre_cardiac = Auto_CF_Image(p_x, p_y).fx; % Use its dominant frequency as the estimated heart rate
                    Cardiac_Interval = [Fre_cardiac - 0.2, Fre_cardiac + 0.2];

                    % Suppress PI values whose dominant frequency falls outside the cardiac interval
                    [idx_suppress_x, idx_suppress_y]= find(f_max_mat < Cardiac_Interval(1) | f_max_mat > Cardiac_Interval(2));
                    for i=1:length(idx_suppress_x)
                        Auto_CF_Image(idx_suppress_x(i), idx_suppress_y(i)).PI = 0;
                    end
                end
            end
            %-------------------------------------------------------------

            %----------- * Step 5: Pixel classification logic (Eq. 5-13)
            for x = 1:x_size
                for y = 1:y_size
                    % Apply class-specific decision rules based on the selected segmentation Type
                    switch Type
                        case 'All'
                            % Multi-class mode: assign Artery / Vein / LCS using DC-level and sign-based criteria (scaled by VENC)
                            if (P_F(x, y, 1) > Threshold(1) * -0.2 * VENC) && (P_F(x, y, 1) <= Threshold(2) * VENC)
                                ROI_labels{x, y} = 'Artery';
                            elseif (P_real(x, y, 1) >= Threshold(1) * VENC) && (P_real(x, y, 1) < Threshold(2) * -0.2 * VENC)
                                ROI_labels{x, y} = 'Vein';
                            elseif (P_F(x, y, 1) >= Threshold(1) * 0.2 * VENC) && (P_F(x, y, 1) <= Threshold(2) * 0.2 * VENC)
                                ROI_labels{x, y} = 'LCS';
                            else
                                % Reject pixel if it does not satisfy any class rule
                                ROI_labels{x, y} = '';
                                Auto_CF_Image(x, y).PI = 0;
                            end
                        case 'Artery'
                            % Single-class mode: retain only pixels meeting the Artery criterion; suppress all others
                            if (P_F(x, y, 1) >= Threshold(1) * VENC) && (P_F(x, y, 1) <= Threshold(2) * VENC)
                                ROI_labels{x, y} = 'Artery';
                            else
                                ROI_labels{x, y} = '';
                                Auto_CF_Image(x, y).PI = 0;
                            end
                        case 'Vein'
                            % Single-class mode: retain only pixels meeting the Vein criterion (uses real/DC sign); suppress all others
                            if (P_real(x, y, 1) >= Threshold(1) * VENC) && (P_real(x, y, 1) <= Threshold(2) * VENC)
                                ROI_labels{x, y} = 'Vein';
                                %Auto_CF_Image(x, y).PI = 1;
                            else
                                ROI_labels{x, y} = '';
                                Auto_CF_Image(x, y).PI = 0;
                            end

                        case 'LCS'
                            % Single-class mode: retain only pixels meeting the LCS criterion; suppress all others
                            if (P_F(x, y, 1) >= Threshold(1) * VENC) && (P_F(x, y, 1) <= Threshold(2) * VENC)
                                ROI_labels{x, y} = 'LCS';
                            else
                                ROI_labels{x, y} = '';
                                Auto_CF_Image(x, y).PI = 0;
                            end
                        case 'LCS_Breathing'
                            % Single-class mode: retain only pixels meeting the breathing-driven LCS criterion; suppress all others
                            if (P_F(x, y, 1) >= Threshold(1) * VENC) && (P_F(x, y, 1) <= Threshold(2) * VENC)
                                ROI_labels{x, y} = 'LCS_Breathing';
                            else
                                ROI_labels{x, y} = '';
                                Auto_CF_Image(x, y).PI = 0;
                            end
                        otherwise
                            error('Invalid Type Selected');
                    end

                end
            end

            if strcmp(Type, 'Vein')
                % (Placeholder) Optional: suppress labels outside a predefined frequency/threshold interval for Vein mode
            else
                % Remove labels for pixels suppressed during frequency-consistency filtering (Step 4)
                for i=1:length(idx_suppress_x)
                    ROI_labels{idx_suppress_x(i), idx_suppress_y(i)}= {};
                end
            end
            %-------------------------------------------------------------

            % Display and copy ROI results
            for x = 1:x_size
                for y = 1:y_size
                    % Print classification output for non-empty labels (debug/trace)
                    if ~isempty(ROI_labels{x, y})
                        fprintf('Pixel (%d, %d) classified as: %s\n', x, y, ROI_labels{x, y});
                    end
                    % Populate the ROI score map using PI values from Auto_CF_Image
                    M_ROI(x, y) = Auto_CF_Image(x, y).PI; % Copy PI values to ROI matrix
                end
            end


            %------------------------------------------------------------
            %----------- * Step 6: ROI extraction via sorting/thresholding of the PI map
            if strcmp(Type, 'Vein') | strcmp(Type, 'Artery')
                % For Vein/Artery: compute the mean PI over non-zero pixels and keep pixels above the mean
                M_ROI_without_0 = M_ROI(:);
                M_ROI_without_0=M_ROI_without_0(M_ROI_without_0>0);
                ROI_Threshold = mean(M_ROI_without_0);
                M_ROI_th=M_ROI;
                M_ROI_th(M_ROI_th<ROI_Threshold)=0;

                % Convert the thresholded PI map into a binary ROI mask
                M_ROI_mask=M_ROI_th;
                M_ROI_mask(M_ROI_mask>0)=1;
            else
                % For LCS/LCS_Breathing/All: keep the dominant pixels using a percentile-based cutoff (e.g., top 5%)
                ROI_Threshold = prctile(M_ROI(:), 95); % Percentile threshold to retain dominant ROI candidates
                M_ROI_th=M_ROI;
                M_ROI_th(M_ROI_th<ROI_Threshold)=0;

                % Convert the thresholded PI map into a binary ROI mask
                M_ROI_mask=M_ROI_th;
                M_ROI_mask(M_ROI_mask>0)=1;
            end
            %------------------------------------------------------------
            % Cache key intermediate outputs to app properties for downstream analysis/plotting/export
            app.I_Pi_forapp = I_Pi;
            app.M_ROI_forapp = M_ROI;
            app.M_ROI_mask_forapp=M_ROI_mask;
            app.P_F_forapp=P_F;
            app.P_f_max_forapp = P_f_max;
            app.dc_forapp = P_F_dc;
            app.P_f_real_forapp = P_real;
            app.f_max_mat_forapp = f_max_mat;
            app.time_range_forapp = time_range;
            app.freq_range_forapp = freq_range;
            app.deltafreq_for_CF_Auto_forapp = deltafreq_for_CF_Auto;
            app.M_ROI_th_forapp = M_ROI_th;
            app.P_angle_forapp = P_angle;
            app.P_complex_forapp = P_complex;
            app.ROI_labels_forapp = ROI_labels;
            app.Auto_CF_Image_forapp = Auto_CF_Image;


            % Cache full-field (overall) spectral features for global reference/visualization
            app.P_F_overall_forapp = P_F_overall;
            app.P_angle_overall_forapp = P_angle_overall;
            app.P_complex_overall_forapp = P_complex_overall;
            app.P_real_overall_forapp = P_real_overall;
            app.P_f_max_overall_forapp = P_f_max_overall;
            app.dc_overall_forapp = P_F_dc_overall;
            app.f_max_mat_overall_forapp = f_max_mat_overall;
            

            % ------ * Initialize visualization controls (overlay sliders and color scaling)
            app.initializeTransparencySliders(); % For the visualized evaluation
            app.initializeColorbarLimits_overlay(M_ROI_mask);

            % Display the I_Pi map (before noise suppression and ROI thresholding)
            cla(app.UIAxes_ROI_analysis_Ipt);
            imagesc(app.UIAxes_ROI_analysis_Ipt, I_Pi);
            colormap(app.UIAxes_ROI_analysis_Ipt, 'gray');
            axis(app.UIAxes_ROI_analysis_Ipt, 'image');
            axis(app.UIAxes_ROI_analysis_Ipt, 'tight');
            colorbar(app.UIAxes_ROI_analysis_Ipt);
            %clim(app.UIAxes_ROI_analysis_Ipt, [lowLimit, highLimit]);
            xlabel(app.UIAxes_ROI_analysis_Ipt, 'Pixels');
            ylabel(app.UIAxes_ROI_analysis_Ipt, 'Pixels');
            title(app.UIAxes_ROI_analysis_Ipt, 'Intensity for new methods I-Pi before noisefilter');
            
            
            % Display the thresholded ROI score map (after noise filtering and sorting/thresholding)
            cla(app.UIAxes_ROI_analysis_noiseremove);
            imagesc(app.UIAxes_ROI_analysis_noiseremove, M_ROI_th);
            colormap(app.UIAxes_ROI_analysis_noiseremove, 'gray');
            axis(app.UIAxes_ROI_analysis_noiseremove, 'image');
            axis(app.UIAxes_ROI_analysis_noiseremove, 'tight');
            colorbar(app.UIAxes_ROI_analysis_noiseremove);
            %clim(app.UIAxes_ROI_analysis_Ipt, [lowLimit, highLimit]);
            xlabel(app.UIAxes_ROI_analysis_noiseremove, 'Pixels');
            ylabel(app.UIAxes_ROI_analysis_noiseremove, 'Pixels');
            title(app.UIAxes_ROI_analysis_noiseremove, 'Intensity for new methods I-Pi after noisefilter, sortingThreshold');

            % Display the final binary ROI mask
            cla(app.UIAxes_ROI_analysis_Mask);
            imagesc(app.UIAxes_ROI_analysis_Mask, M_ROI_mask);
            colormap(app.UIAxes_ROI_analysis_Mask, 'gray');
            axis(app.UIAxes_ROI_analysis_Mask, 'image');
            axis(app.UIAxes_ROI_analysis_Mask, 'tight');
            colorbar(app.UIAxes_ROI_analysis_Mask);
            %clim(app.UIAxes_ROI_analysis_Ipt, [lowLimit, highLimit]);
            xlabel(app.UIAxes_ROI_analysis_Mask, 'Pixels');
            ylabel(app.UIAxes_ROI_analysis_Mask, 'Pixels');
            title(app.UIAxes_ROI_analysis_Mask, 'ROI Mask');


            % ---------------------------------- Overlay visualization for quality control
            % Overlay the ROI mask on the averaged PCMRI image for visual validation
            
            app.plotOverlaidImages(app.Selected_region_averaged_image, M_ROI_mask);

            % Enable analysis/export buttons once the ROI segmentation is complete
            app.AnlaysismaskedsignalButton.Enable = 'on';
            app.SaveROIImgButton.Enable = 'on';


        end

        % Save all relevant outputs (raw/ROI data, spectral features, masks, and metadata) to a MAT file
        function saveData(app)
            save_folderpath = app.MainApp.Save_folder;
            
            % --------- Package outputs into a struct for reproducible saving
            data.PCMRI_rawData = app.MainApp.PCMRI_Data; 
            data.PCMRI_INFO = app.MainApp.PCMRI_Info;
            data.PCMRIData = app.Selected_region_PCMRI;
            data.PCMRIData_averageimage = app.Selected_region_averaged_image;
            
            data.Delta_time = app.MainApp.Delta_time;
            data.MultiplicativeScalingFactor = app.MainApp.MultiplicativeScaling_Factor;
            data.AdditiveOffsetFactor = app.MainApp.AdditiveOffset_Factor;

            data.Freq_range=app.freq_range_forapp;
            data.deltafreq_for_CF_Auto=app.deltafreq_for_CF_Auto_forapp;
            data.Time_range=app.time_range_forapp;

            data.I_Pi=app.I_Pi_forapp;
            data.P_F=app.P_F_forapp;
            data.P_f_max=app.P_f_max_forapp;
            data.dc=app.dc_forapp;
            data.P_f_real=app.P_f_real_forapp;
            data.f_max_mat=app.f_max_mat_forapp;
            data.M_ROI_th = app.M_ROI_th_forapp;
            data.P_angle = app.P_angle_forapp;
            data.P_complex = app.P_complex_forapp;
            data.ROI_labels = app.ROI_labels_forapp;
            data.Auto_CF_Image = app.Auto_CF_Image_forapp;

            data.M_ROI=app.M_ROI_forapp;
            data.M_ROI_mask=app.M_ROI_mask_forapp;

            data.SaveTime = datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss'); % Add save time

            % Save as a MAT file (struct fields become variables via '-struct')
            save(strcat(save_folderpath, '/Process_data_1_SegROIPart.mat'), '-struct', 'data'); % Save as a MAT file
            app.MainApp.logMessage('[System info] Data saved successfully. "Processed data segmenation ROI"');
        end


        % Save all images generated in the subwindow
        function saveAllImages(app)
            % Create an output folder for exported figures
            saveFolder = fullfile(app.MainApp.Save_folder, 'SavedImages_Process_data_1'); % Save in save directory
            if ~exist(saveFolder, 'dir')
                mkdir(saveFolder);
            end

            % List of target UIAxes to export and their corresponding filenames
            axesList = {app.UIAxes_rawimg_2, app.UIAxes_rawimg_ROInew, app.UIAxes_ROI_analysis_Ipt, app.UIAxes_ROI_analysis_noiseremove, app.UIAxes_ROI_analysis_Mask, app.UIAxes_ROI_analysis_Mask_2};
            axesNames = {'Raw_Img_forseg', 'SegmentedWindow_ROI', 'ROI_anal_Ipt', 'ROI_anal_Ipt_noiseremove', 'ROI_anal_mask', 'ROI_anal_overlay_img'}; % Corresponding names for saved files

            % Iterate through each UIAxes and export its current contents as a PNG image
            for i = 1:numel(axesList)
                ax = axesList{i};
                if isempty(ax) || ~isvalid(ax)
                    continue; % Skip missing/invalid axes handles
                end

                % Render the axes into a temporary hidden figure for clean export
                fig = figure('Visible', 'off'); % Create a non-displayed figure
                copyobj(ax, fig); % Copy the axes (and its children) into the new figure
                set(gca, 'Position', [0, 0, 600, 400]); % Resize/reposition axes for consistent output framing
                % Save the figure as PNG
                saveFileName = fullfile(saveFolder, [axesNames{i}, '.png']);
                saveas(fig, saveFileName); % Save as PNG
                close(fig); % Close the temporary figure
            end

            % Report export completion via log + console
            app.MainApp.logMessage(['All images saved in folder: ', saveFolder]);
            disp(['All images saved in folder: ', saveFolder]);
        end


        function Initialize_VENC_param(app)
            % Set a default VENC value based on the selected anatomical region
            selected_Region = app.Analysis_RegionDropDown.Value;
            switch selected_Region
                case 'Foramen magnum'
                    VENC = 5;
                case 'Superior Sagital Sinus'
                    VENC = 30;
                case 'Aqueduct'
                    VENC = 10;
                case 'C2/C3_blood'
                    VENC = 30;
                case 'C2/C3_CSF'
                    VENC = 5;
                case 'Internal Carotid Artery'
                    VENC = 90;
                case 'Lateral ventricle'
                    VENC = 5;
                case 'Forth ventricle'
                    VENC = 5;
                case 'Lumbar spine (L3)'
                    VENC = 60;
                otherwise
                    VENC = 5;
            end
            % Apply the selected default VENC to the UI and cache it for later use
            app.VENCEditField.Value=VENC;
            app.VENC_forapp = VENC;
        end

        function Load_predefine_mask(app)
            % ------* Data load.
            % Load a user-selected predefined ROI mask (NIfTI format)
            [file, path] = uigetfile('*.nii', 'Select Predefine mask Data File');
            if isequal(file, 0)
                uialert(app.UIFigure, 'No file selected', 'Error');
                app.logMessage('[Error] No file selected');
                return;
            end

            % ----- Data information
            % Store selected file metadata for provenance/reproducibility
            app.Load_Data_info_filename_predefinedmask = file;
            app.Load_Data_info_path_predefinedmask = path;
            
            % -----
            % Read the predefined mask volume and its header info
            Predefined_mask = niftiread(strcat(path, file));
            Predefined_mask_info = niftiinfo(strcat(path, file));
            
            % Re-orient the mask to match the PCMRI display orientation (rotate + left-right flip)
            rotatedmaskData = rot90(Predefined_mask); % Rotate 90 degrees counterclockwise
            rotatedFlippedData = flip(rotatedmaskData,2); % Flip left-right
            
            % Cache the aligned predefined mask for ROI segmentation workflows
            app.Predefined_mask_fortheapp=rotatedFlippedData;            

        end
    end
    
    methods (Access = private)
        % Close-request handler for the parent subwindow
        function closeRequest(app, event)
             % If child windows exist, close them first to avoid orphaned UI instances
            if ~isempty(app.AnalysisWindow) && isvalid(app.AnalysisWindow)
                delete(app.AnalysisWindow); % Close the nested analysis window
            end

            if ~isempty(app.MagnitudeWindow) && isvalid(app.MagnitudeWindow)
                delete(app.MagnitudeWindow); % Close the nested magnitude window
            end

            % Close the current subwindow instance
            delete(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FramenumberSpinner, TimerSlider
        function TimerSliderValueChanged(app, event)

        end

        % Callback function: TimerSlider, UIAxes_rawimg
        function TimerSliderValueChanging(app, event)
            % Get the slider value as the current frame index
            frameIndex = round(event.Value); % Ensure it's an integer
            app.FramenumberSpinner.Value = frameIndex; % Update Spinner
            % Update the displayed image
            app.updateImage(frameIndex);

            if isempty(app.MagnitudeWindow) || ~isvalid(app.MagnitudeWindow)

            else
                app.MagnitudeWindow.updateImage(frameIndex);
            end
        end

        % Value changing function: FramenumberSpinner
        function FramenumberSpinnerValueChanging(app, event)
            frameIndex = round(event.Value);
            app.TimerSlider.Value = frameIndex; % Update Slider
            app.updateImage(frameIndex); % Update displayed image

            if isempty(app.MagnitudeWindow) || ~isvalid(app.MagnitudeWindow)

            else
                app.MagnitudeWindow.updateImage(frameIndex);
            end

        end

        % Value changing function: FusionSlider
        function FusionSliderValueChanging(app, event)
            % Get the range selected on the slider
            rangeValues = round(event.Value); % Ensure integer values
            startFrame = rangeValues(1);
            endFrame = rangeValues(2);

            % Update the displayed averaged image
            app.updateAveragedImage(startFrame, endFrame);
        end

        % Value changed function: ColorbarlowEditField
        function ColorbarlowEditFieldValueChanged(app, event)
            % Update the colorbar limits
            lowLimit = app.ColorbarlowEditField.Value;
            highLimit = app.ColorbarhighEditField.Value;
            clim(app.UIAxes_rawimg, [lowLimit, highLimit]);
            %clim(app.UIAxes_fusionimg, [lowLimit, highLimit]);
        end

        % Value changed function: ColorbarhighEditField
        function ColorbarhighEditFieldValueChanged(app, event)
            % Update the colorbar limits
            lowLimit = app.ColorbarlowEditField.Value;
            highLimit = app.ColorbarhighEditField.Value;
            clim(app.UIAxes_rawimg, [lowLimit, highLimit]);
            %clim(app.UIAxes_fusionimg, [lowLimit, highLimit]);
        end

        % Value changed function: ColorbarlowEditField_2
        function ColorbarlowEditField_2ValueChanged(app, event)
            % Update the colorbar limits
            lowLimit = app.ColorbarlowEditField_2.Value;
            highLimit = app.ColorbarhighEditField_2.Value;
            %clim(app.UIAxes_rawimg, [lowLimit, highLimit]);
            clim(app.UIAxes_fusionimg, [lowLimit, highLimit]);
            
        end

        % Value changed function: ColorbarhighEditField_2
        function ColorbarhighEditField_2ValueChanged(app, event)
            % Update the colorbar limits
            lowLimit = app.ColorbarlowEditField_2.Value;
            highLimit = app.ColorbarhighEditField_2.Value;
            %clim(app.UIAxes_rawimg, [lowLimit, highLimit]);
            clim(app.UIAxes_fusionimg, [lowLimit, highLimit]);
        end

        % Button pushed function: ROIprocessstartButton
        function ROIprocessstartButtonPushed(app, event)
            % Check whether PCMRI data has been loaded before allowing ROI extraction
            if isempty(app.PCMRI)
                uialert(app.UIFigure, 'No PCMRI data loaded.', 'Error');
                return;
            end

            % Log the ROI extraction action
            app.MainApp.logMessage('[System info] ROI extract button pressed..!');
            
            % If the user selected the "load predefined mask" option, prompt and load the mask file
            selectedButton = app.MaskloadtypeButtonGroup.SelectedObject;
            if selectedButton == app.LoadpredefinedmaskButton
                app.Load_predefine_mask();
            end

            % Enable ROI selection mode (mouse click will be accepted once)
            app.IsROIActive = true;

            % Read the current frame range and display settings from the UI controls
            startFrame = app.FusionSlider.Value(1);
            endFrame = app.FusionSlider.Value(2);
            lowLimit = app.ColorbarlowEditField_2.Value;
            highLimit = app.ColorbarhighEditField_2.Value;
            % Compute an averaged image over the selected time/frame range for robust ROI targeting
            averagedImage = mean(app.PCMRI(:, :, startFrame:endFrame), 3);
            % Compute a standard-deviation map across time (overall) for downstream QC/feature use
            %standarddeviationImage = std(app.PCMRI(:, :, startFrame:endFrame), 0, 3);
            standarddeviationImage = std(app.PCMRI(:,:,:), 0, 3); % Overall std images.
            app.Overall_PCMRI_Std = standarddeviationImage;

            % Render the averaged image and prepare the UIAxes for ROI selection
            cla(app.UIAxes_rawimg_2);
            imgHandle = imagesc(app.UIAxes_rawimg_2, averagedImage);
            colormap(app.UIAxes_rawimg_2, 'gray');
            axis(app.UIAxes_rawimg_2, 'image');
            axis(app.UIAxes_rawimg_2, 'tight');
            colorbar(app.UIAxes_rawimg_2);clim(app.UIAxes_rawimg_2, [lowLimit, highLimit]);
            xlabel(app.UIAxes_rawimg_2, 'Pixels');
            ylabel(app.UIAxes_rawimg_2, 'Pixels');
            title(app.UIAxes_rawimg_2, 'Averaged Image - Select ROI');

            % Prompt the user to click a target location for ROI extraction
            app.MainApp.logMessage('[System info] Please Click coordinates that you want to extract for ROI..!');

           
            % Register a mouse-click callback: clicking the image triggers ROI capture and downstream processing
            imgHandle.ButtonDownFcn = @(src, event) app.captureROI(averagedImage, standarddeviationImage, imgHandle);

            
            % Alternative (disabled): use ginput to capture one click from the user
            %[x, y] = ginput(1); % Get coordinates from user click

            % Notes (disabled): convert the click coordinates to integer pixel indices
            %x = round(x);
            %y = round(y);

            
        end

        % Value changed function: ColorbarhighEditField_Overlay
        function ColorbarhighEditField_OverlayValueChanged(app, event)
            % Update the colorbar limits
            lowLimit = app.ColorbarlowEditField_Overlay.Value;
            highLimit = app.ColorbarhighEditField_Overlay.Value;
            %clim(app.UIAxes_rawimg, [lowLimit, highLimit]);
            clim(app.UIAxes_ROI_analysis_Mask_2, [lowLimit, highLimit]);
            
            
        end

        % Value changed function: ColorbarlowEditField_Overlay
        function ColorbarlowEditField_OverlayValueChanged(app, event)
            % Update the colorbar limits
            lowLimit = app.ColorbarlowEditField_Overlay.Value;
            highLimit = app.ColorbarhighEditField_Overlay.Value;
            %clim(app.UIAxes_rawimg, [lowLimit, highLimit]);
            clim(app.UIAxes_ROI_analysis_Mask_2, [lowLimit, highLimit]);
            
        end

        % Value changed function: SelectedROIPCMRISlider
        function SelectedROIPCMRISliderValueChanged(app, event)
            value = app.SelectedROIPCMRISlider.Value;
            
        end

        % Value changing function: SelectedROIPCMRISlider
        function SelectedROIPCMRISliderValueChanging(app, event)
            % Get the slider value as the current frame index
            alphaIndex = round(event.Value); % Ensure it's an integer
            %app.FramenumberSpinner.Value = alphaIndex; % Update Spinner
            % Update the displayed image
            app.plotOverlaidImages(app.Selected_region_averaged_image, app.M_ROI_mask_forapp);
            
        end

        % Value changing function: ROIMaskSlider
        function ROIMaskSliderValueChanging(app, event)
            % Get the slider value as the current frame index
            alphaIndex = round(event.Value); % Ensure it's an integer
            %app.FramenumberSpinner.Value = alphaIndex; % Update Spinner
            % Update the displayed image
            app.plotOverlaidImages(app.Selected_region_averaged_image, app.M_ROI_mask_forapp);
            
        end

        % Button pushed function: AnlaysismaskedsignalButton
        function AnlaysismaskedsignalButtonPushed(app, event)
            
            % Get the position of the main window (MainApp)
            mainPosition = app.UIFigure.Position;
            mainWidth = mainPosition(3); % Width of MainApp window

            % Calculate the left position for the new window to place it to the right
            newWindowLeft = mainPosition(1) + mainWidth + 10; % 10-pixel gap to the right of MainApp
            newWindowBottom = mainPosition(2); % Align vertically with MainApp

            % Open the Popup window after data is loaded
            % Create a new window
            app.AnalysisWindow = Analysis_further_window;

            popupPosition = app.AnalysisWindow.UIFigure.Position; % Get the default position of PopupApp

            % Set only the left and bottom position to place it next to MainApp
            app.AnalysisWindow.UIFigure.Position = [newWindowLeft, newWindowBottom, popupPosition(3), popupPosition(4)];

            % Pass the Main App reference to OpenImageSlide
            app.AnalysisWindow.setMainApp(app.MainApp, app); % Pass 'app' (MainApp) as a reference
            app.AnalysisWindow.Initial_dataset(app.Selected_region_averaged_image, app.M_ROI_mask_forapp);
            
            % app.saveData(); % Save Data for analysis.
        end

        % Button pushed function: MagnitudeImageBtn
        function MagnitudeImageBtnButtonPushed(app, event)
            if isempty(app.MagnitudeWindow) || ~isvalid(app.MagnitudeWindow)
                % Pass the PCMRI magnitude data and reference to this subwindow
                magnitudeImage = app.PCMRI_Magnitude; % Example for magnitude calculation
               
                % Open the Popup window after data is loaded
                app.MagnitudeWindow = MagnitudeImgPlot;
                MagnitudeWindowPositionPosition = app.MagnitudeWindow.UIFigure.Position; % Get the default position of PopupApp

                 % Get the position of the main window (MainApp)
                mainPosition = app.UIFigure.Position;
                mainWidth = mainPosition(3); % Width of MainApp window

                % Get the default size of the new MagnitudeWindow
                defaultMagnitudeWindowPosition = app.MagnitudeWindow.UIFigure.Position;
                magnitudeWindowWidth = defaultMagnitudeWindowPosition(3);
                magnitudeWindowHeight = defaultMagnitudeWindowPosition(4);

                % Calculate the left position for the new window to place it to the right
                newWindowLeft = mainPosition(1) - magnitudeWindowWidth - 10; % 10-pixel gap to the right of MainApp
                newWindowBottom = mainPosition(2)*2; % Align vertically with MainApp

                % Set only the left and bottom position to place it next to MainApp
                app.MagnitudeWindow.UIFigure.Position = [newWindowLeft, newWindowBottom, magnitudeWindowWidth, magnitudeWindowHeight];

                % Pass the Main App reference to OpenImageSlide
                app.MagnitudeWindow.setMainApp(app); % Pass current 'app' as a reference.
                app.MagnitudeWindow.setMagnitudeImage(magnitudeImage);
                app.MainApp.logMessage('[System info] Open window for analyze ROI segmentation!');
            else
                disp('Magnitude window is already open.');
            end
        end

        % Button pushed function: SaveROIImgButton
        function SaveROIImgButtonPushed(app, event)
            app.saveAllImages();
        end

        % Value changed function: Analysis_RegionDropDown
        function Analysis_RegionDropDownValueChanged(app, event)
            %value = app.Analysis_RegionDropDown.Value;
            % Get parameter from objects.
            selected_Region = app.Analysis_RegionDropDown.Value;
            switch selected_Region
                case 'Foramen magnum'
                    VENC = 5;
                case 'Superior Sagital Sinus'
                    VENC = 30;
                case 'Aqueduct'
                    VENC = 10;
                case 'C2/C3_blood'
                    VENC = 30;
                case 'C2/C3_CSF'
                    VENC = 5;
                case 'Internal Carotid Artery'
                    VENC = 90;
                case 'Lateral ventricle'
                    VENC = 5;
                case 'Forth ventricle'
                    VENC = 5;
                case 'Lumbar spine (L3)'
                    VENC = 60;
                otherwise
                    VENC = 5;
            end
            app.VENCEditField.Value=VENC;            
            app.VENC_forapp = VENC;
        end

        % Selection changed function: MaskloadtypeButtonGroup
        function MaskloadtypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.MaskloadtypeButtonGroup.SelectedObject;
            if selectedButton == app.LoadpredefinedmaskButton
                app.CurrentROIprocessmode = 'Loadpredefinedmask';
                %app.ROIsizeDropDown.Enable = 'off'; % Disable button after clicking.
                %app.Analysis_RegionDropDown.Enable = 'off'; % Disable button after clicking.
                %app.VENCEditField.Enable = 'off'; % Disable button after clicking.
                app.ThresholdtypesDropDown.Enable = 'off'; % Disable button after clicking.
            elseif selectedButton == app.CalculatemaskButton
                app.CurrentROIprocessmode = 'Calculatemask';
                app.ROIsizeDropDown.Enable = 'on'; % Enable button after clicking.
                app.Analysis_RegionDropDown.Enable = 'on'; % Enable button after clicking.
                app.VENCEditField.Enable = 'on'; % Enable button after clicking.
                app.ThresholdtypesDropDown.Enable = 'on'; % Enable button after clicking.

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_fusionimg = uiaxes(app.UIFigure);
            title(app.UIAxes_fusionimg, 'Title')
            xlabel(app.UIAxes_fusionimg, 'X')
            ylabel(app.UIAxes_fusionimg, 'Y')
            zlabel(app.UIAxes_fusionimg, 'Z')
            app.UIAxes_rawimg = uiaxes(app.UIFigure);
            title(app.UIAxes_rawimg, 'Title')
            xlabel(app.UIAxes_rawimg, 'X')
            ylabel(app.UIAxes_rawimg, 'Y')
            zlabel(app.UIAxes_rawimg, 'Z')
            app.UIAxes_rawimg.ButtonDownFcn = createCallbackFcn(app, @TimerSliderValueChanging, true);
            app.UIAxes_rawimg_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_rawimg_2, 'Title')
            xlabel(app.UIAxes_rawimg_2, 'X')
            ylabel(app.UIAxes_rawimg_2, 'Y')
            zlabel(app.UIAxes_rawimg_2, 'Z')
            app.UIAxes_rawimg_ROInew = uiaxes(app.UIFigure);
            title(app.UIAxes_rawimg_ROInew, 'Title')
            xlabel(app.UIAxes_rawimg_ROInew, 'X')
            ylabel(app.UIAxes_rawimg_ROInew, 'Y')
            zlabel(app.UIAxes_rawimg_ROInew, 'Z')
            app.UIAxes_ROI_analysis_Ipt = uiaxes(app.UIFigure);
            title(app.UIAxes_ROI_analysis_Ipt, 'Title')
            xlabel(app.UIAxes_ROI_analysis_Ipt, 'X')
            ylabel(app.UIAxes_ROI_analysis_Ipt, 'Y')
            zlabel(app.UIAxes_ROI_analysis_Ipt, 'Z')
            app.UIAxes_ROI_analysis_noiseremove = uiaxes(app.UIFigure);
            title(app.UIAxes_ROI_analysis_noiseremove, 'Title')
            xlabel(app.UIAxes_ROI_analysis_noiseremove, 'X')
            ylabel(app.UIAxes_ROI_analysis_noiseremove, 'Y')
            zlabel(app.UIAxes_ROI_analysis_noiseremove, 'Z')
            app.UIAxes_ROI_analysis_Mask = uiaxes(app.UIFigure);
            title(app.UIAxes_ROI_analysis_Mask, 'Title')
            xlabel(app.UIAxes_ROI_analysis_Mask, 'X')
            ylabel(app.UIAxes_ROI_analysis_Mask, 'Y')
            zlabel(app.UIAxes_ROI_analysis_Mask, 'Z')
            app.UIAxes_ROI_analysis_Mask_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_ROI_analysis_Mask_2, 'Title')
            xlabel(app.UIAxes_ROI_analysis_Mask_2, 'X')
            ylabel(app.UIAxes_ROI_analysis_Mask_2, 'Y')
            zlabel(app.UIAxes_ROI_analysis_Mask_2, 'Z')
            app.FrameLabel = uilabel(app.UIFigure);
            app.FrameLabel.Text = 'Frame';
            app.FramenumberSpinnerLabel = uilabel(app.UIFigure);
            app.FramenumberSpinnerLabel.Text = 'Frame number';
            app.TimerSlider = uislider(app.UIFigure);
            app.TimerSlider.ValueChangedFcn = createCallbackFcn(app, @TimerSliderValueChanged, true);
            app.TimerSlider.ValueChangingFcn = createCallbackFcn(app, @TimerSliderValueChanging, true);
            app.FramenumberSpinner = uispinner(app.UIFigure);
            app.FramenumberSpinner.ValueChangingFcn = createCallbackFcn(app, @FramenumberSpinnerValueChanging, true);
            app.FramenumberSpinner.ValueChangedFcn = createCallbackFcn(app, @TimerSliderValueChanged, true);
            app.FusionrangeLabel = uilabel(app.UIFigure);
            app.FusionrangeLabel.Text = 'Fusion range';
            app.FusionSlider = uislider(app.UIFigure, 'range');
            app.FusionSlider.ValueChangingFcn = createCallbackFcn(app, @FusionSliderValueChanging, true);
            app.ColorbarhighEditFieldLabel = uilabel(app.UIFigure);
            app.ColorbarhighEditFieldLabel.Text = 'Colorbar high';
            app.ColorbarhighEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColorbarhighEditField.ValueChangedFcn = createCallbackFcn(app, @ColorbarhighEditFieldValueChanged, true);
            app.ColorbarlowEditFieldLabel = uilabel(app.UIFigure);
            app.ColorbarlowEditFieldLabel.Text = 'Colorbar low';
            app.ColorbarlowEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColorbarlowEditField.ValueChangedFcn = createCallbackFcn(app, @ColorbarlowEditFieldValueChanged, true);
            app.ColorbarhighEditField_2Label = uilabel(app.UIFigure);
            app.ColorbarhighEditField_2Label.Text = 'Colorbar high';
            app.ColorbarhighEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ColorbarhighEditField_2.ValueChangedFcn = createCallbackFcn(app, @ColorbarhighEditField_2ValueChanged, true);
            app.ColorbarlowEditField_2Label = uilabel(app.UIFigure);
            app.ColorbarlowEditField_2Label.Text = 'Colorbar low';
            app.ColorbarlowEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ColorbarlowEditField_2.ValueChangedFcn = createCallbackFcn(app, @ColorbarlowEditField_2ValueChanged, true);
            app.ROIprocessstartButton = uibutton(app.UIFigure, 'push');
            app.ROIprocessstartButton.ButtonPushedFcn = createCallbackFcn(app, @ROIprocessstartButtonPushed, true);
            app.ROIprocessstartButton.Text = 'ROI process start';
            app.ROIsizeDropDownLabel = uilabel(app.UIFigure);
            app.ROIsizeDropDownLabel.Text = 'ROI size';
            app.ROIsizeDropDown = uidropdown(app.UIFigure);
            app.ROIsizeDropDown.Items = {'8x8', '16x16', '32x32', '64x64', '128x128'};
            app.ROIsizeDropDown.Value = '8x8';
            app.Analysis_RegionDropDownLabel = uilabel(app.UIFigure);
            app.Analysis_RegionDropDownLabel.Text = 'Analysis_Region';
            app.Analysis_RegionDropDown = uidropdown(app.UIFigure);
            app.Analysis_RegionDropDown.Items = {'Foramen magnum', 'Superior Sagital Sinus', 'C2/C3_CSF', 'Aqueduct', 'Lateral ventricle', 'Forth ventricle'};
            app.Analysis_RegionDropDown.ValueChangedFcn = createCallbackFcn(app, @Analysis_RegionDropDownValueChanged, true);
            app.Analysis_RegionDropDown.Value = 'Foramen magnum';
            app.ThresholdtypesDropDownLabel = uilabel(app.UIFigure);
            app.ThresholdtypesDropDownLabel.Text = 'Threshold types';
            app.ThresholdtypesDropDown = uidropdown(app.UIFigure);
            app.ThresholdtypesDropDown.Items = {'All', 'Artery', 'Vein', 'LCS', 'LCS_Breathing'};
            app.ThresholdtypesDropDown.Value = 'LCS';
            app.ExtractedROII_PtLabel = uilabel(app.UIFigure);
            app.ExtractedROII_PtLabel.Text = 'Extracted ROI (I_Pt)';
            app.ExtractedROI_afternoisecorrectionLabel = uilabel(app.UIFigure);
            app.ExtractedROI_afternoisecorrectionLabel.Text = 'Extracted ROI _ after noise correction';
            app.ExtractedROImaskLabel = uilabel(app.UIFigure);
            app.ExtractedROImaskLabel.Text = 'Extracted ROI mask';
            app.SelectedROIPCMRISliderLabel = uilabel(app.UIFigure);
            app.SelectedROIPCMRISliderLabel.Text = 'Selected ROI PCMRI';
            app.SelectedROIPCMRISlider = uislider(app.UIFigure);
            app.SelectedROIPCMRISlider.ValueChangedFcn = createCallbackFcn(app, @SelectedROIPCMRISliderValueChanged, true);
            app.SelectedROIPCMRISlider.ValueChangingFcn = createCallbackFcn(app, @SelectedROIPCMRISliderValueChanging, true);
            app.ROIMaskSliderLabel = uilabel(app.UIFigure);
            app.ROIMaskSliderLabel.Text = 'ROI Mask';
            app.ROIMaskSlider = uislider(app.UIFigure);
            app.ROIMaskSlider.ValueChangingFcn = createCallbackFcn(app, @ROIMaskSliderValueChanging, true);
            app.TransparencycontrolpanelLabel = uilabel(app.UIFigure);
            app.TransparencycontrolpanelLabel.Text = 'Transparency control panel';
            app.MaskcolorDropDownLabel = uilabel(app.UIFigure);
            app.MaskcolorDropDownLabel.Text = 'Mask color';
            app.MaskcolorDropDown = uidropdown(app.UIFigure);
            app.MaskcolorDropDown.Items = {'Red', 'Blue', 'Green'};
            app.MaskcolorDropDown.Value = 'Red';
            app.ColorbarhighEditField_3Label = uilabel(app.UIFigure);
            app.ColorbarhighEditField_3Label.Text = 'Colorbar high';
            app.ColorbarhighEditField_Overlay = uieditfield(app.UIFigure, 'numeric');
            app.ColorbarhighEditField_Overlay.ValueChangedFcn = createCallbackFcn(app, @ColorbarhighEditField_OverlayValueChanged, true);
            app.ColorbarlowEditField_3Label = uilabel(app.UIFigure);
            app.ColorbarlowEditField_3Label.Text = 'Colorbar low';
            app.ColorbarlowEditField_Overlay = uieditfield(app.UIFigure, 'numeric');
            app.ColorbarlowEditField_Overlay.ValueChangedFcn = createCallbackFcn(app, @ColorbarlowEditField_OverlayValueChanged, true);
            app.SaveROIImgButton = uibutton(app.UIFigure, 'push');
            app.SaveROIImgButton.ButtonPushedFcn = createCallbackFcn(app, @SaveROIImgButtonPushed, true);
            app.SaveROIImgButton.Text = 'Save ROI Img';
            app.AnlaysismaskedsignalButton = uibutton(app.UIFigure, 'push');
            app.AnlaysismaskedsignalButton.ButtonPushedFcn = createCallbackFcn(app, @AnlaysismaskedsignalButtonPushed, true);
            app.AnlaysismaskedsignalButton.Text = 'Anlaysis masked signal';
            app.PleasedoanalyzingmasksignalLabel = uilabel(app.UIFigure);
            app.PleasedoanalyzingmasksignalLabel.Text = '* Please do analyzing mask signal';
            app.MagnitudeImageBtn = uibutton(app.UIFigure, 'push');
            app.MagnitudeImageBtn.ButtonPushedFcn = createCallbackFcn(app, @MagnitudeImageBtnButtonPushed, true);
            app.MagnitudeImageBtn.Text = 'Plot Magnitude image';
            app.ColorbarhighEditField_3Label_2 = uilabel(app.UIFigure);
            app.ColorbarhighEditField_3Label_2.Text = 'VENC';
            app.VENCEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaskloadtypeButtonGroup = uibuttongroup(app.UIFigure);
            app.MaskloadtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MaskloadtypeButtonGroupSelectionChanged, true);
            app.MaskloadtypeButtonGroup.Title = 'Mask load type';
            app.CalculatemaskButton = uiradiobutton(app.MaskloadtypeButtonGroup);
            app.CalculatemaskButton.Text = 'Calculate mask';
            app.CalculatemaskButton.Value = true;
            app.LoadpredefinedmaskButton = uiradiobutton(app.MaskloadtypeButtonGroup);
            app.LoadpredefinedmaskButton.Text = 'Load predefined mask';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '---------------------------------------';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '---------------------------------------';
            app.SaveROIimageLabel = uilabel(app.UIFigure);
            app.SaveROIimageLabel.Text = '-----* Save ROI image';
            app.VisualizeROImaskwithSelectedROIPCMRILabel = uilabel(app.UIFigure);
            app.VisualizeROImaskwithSelectedROIPCMRILabel.Text = '* Visualize ROI mask with Selected ROI PC MRI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Open_image_slide

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end